classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainUIFigure              matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        gridTab                   matlab.ui.container.GridLayout
        gridSettings              matlab.ui.container.GridLayout
        runModeListEdit           matlab.ui.control.Button
        stretchToFillCheck        matlab.ui.control.CheckBox
        userGuidePackageButton    matlab.ui.control.Button
        periodicboundariesText    matlab.ui.control.EditField
        periodicboundariesLabel   matlab.ui.control.Label
        sizeWarningDisplay        matlab.ui.control.NumericEditField
        sizeWarningText           matlab.ui.control.Label
        sectionSeparatorDisplay   matlab.ui.control.EditField
        sectionSeparatorText      matlab.ui.control.Label
        filestodeleteListBox      matlab.ui.control.ListBox
        FilestodeleteLabel        matlab.ui.control.Label
        packageModeList           matlab.ui.control.DropDown
        packageModeLabel          matlab.ui.control.Label
        runModeList               matlab.ui.control.DropDown
        runModeLabel              matlab.ui.control.Label
        viewTypeList              matlab.ui.control.DropDown
        viewTypeLabel             matlab.ui.control.Label
        ignoredOverview           matlab.ui.control.CheckBox
        settingsButton            matlab.ui.container.GridLayout
        userGuideButton           matlab.ui.control.Button
        shortcutsListButton       matlab.ui.control.Button
        displayNodes              matlab.ui.control.CheckBox
        manualPos                 matlab.ui.control.CheckBox
        backgroundValue           matlab.ui.control.EditField
        backgroundLabel           matlab.ui.control.Label
        colormapList              matlab.ui.control.DropDown
        colormapLabel             matlab.ui.control.Label
        durationAnimationText     matlab.ui.control.Label
        durationAnimationDisplay  matlab.ui.control.NumericEditField
        sizePathText              matlab.ui.control.Label
        sizePathDisplay           matlab.ui.control.NumericEditField
        scalePlotText             matlab.ui.control.Label
        scalePlotDisplay          matlab.ui.control.NumericEditField
        scaleGraphicsDisplay      matlab.ui.control.NumericEditField
        scaleGraphicsText         matlab.ui.control.Label
        spaceGraphics             matlab.ui.control.CheckBox
        displayMesh               matlab.ui.control.CheckBox
        displayCurrentState       matlab.ui.control.CheckBox
        initialization            matlab.ui.control.CheckBox
        executableFromFolder      matlab.ui.control.CheckBox
        displayCMD                matlab.ui.control.CheckBox
        settingsLabel             matlab.ui.control.Label
        gridTime                  matlab.ui.container.GridLayout
        updateTimeSave            matlab.ui.control.Button
        regressionButton          matlab.ui.control.Button
        regressionValue           matlab.ui.control.NumericEditField
        regressionText            matlab.ui.control.Label
        timeGraph                 matlab.ui.control.UIAxes
        gridButtons               matlab.ui.container.GridLayout
        gridPlot                  matlab.ui.container.GridLayout
        stopCancel                matlab.ui.control.Button
        autoRefresh               matlab.ui.control.CheckBox
        timeRefreshValue          matlab.ui.control.NumericEditField
        stopCheck                 matlab.ui.control.CheckBox
        stopCriterionList         matlab.ui.control.DropDown
        stopEq                    matlab.ui.control.EditField
        gridGraphics              matlab.ui.container.GridLayout
        gridGraphicsControl       matlab.ui.container.GridLayout
        stepFB                    matlab.ui.control.Button
        stepSB                    matlab.ui.control.Button
        stepRefr                  matlab.ui.control.StateButton
        stepSF                    matlab.ui.control.Button
        stepFF                    matlab.ui.control.Button
        graphicsSlider            matlab.ui.control.Slider
        hideBodiesButton          matlab.ui.control.Button
        infoButton                matlab.ui.control.Button
        loadGraphics              matlab.ui.control.Button
        animation                 matlab.ui.control.Button
        refreshGraphs             matlab.ui.control.Button
        gridSpies                 matlab.ui.container.GridLayout
        plotStartButton           matlab.ui.control.Button
        plotEndButton             matlab.ui.control.Button
        exportButton              matlab.ui.control.Button
        gridGraphButtons          matlab.ui.container.GridLayout
        gridGraph1                matlab.ui.container.GridLayout
        graph1SameView            matlab.ui.control.Button
        graph1Limits              matlab.ui.control.Button
        graph1Opts                matlab.ui.control.Button
        graph1Cancel              matlab.ui.control.Button
        graph1Plot                matlab.ui.control.Button
        graph1YEq                 matlab.ui.control.EditField
        graph1YList               matlab.ui.control.DropDown
        graph1XEq                 matlab.ui.control.EditField
        graph1XList               matlab.ui.control.DropDown
        graph1End                 matlab.ui.control.EditField
        graph1Start               matlab.ui.control.EditField
        gridGraph2                matlab.ui.container.GridLayout
        graph2SameView            matlab.ui.control.Button
        graph2Limits              matlab.ui.control.Button
        graph2Opts                matlab.ui.control.Button
        graph2Cancel              matlab.ui.control.Button
        graph2Plot                matlab.ui.control.Button
        graph2End                 matlab.ui.control.EditField
        graph2YEq                 matlab.ui.control.EditField
        graph2YList               matlab.ui.control.DropDown
        graph2Start               matlab.ui.control.EditField
        graph2XEq                 matlab.ui.control.EditField
        graph2XList               matlab.ui.control.DropDown
        gridGraph3                matlab.ui.container.GridLayout
        graph3sameView            matlab.ui.control.Button
        graph3Limits              matlab.ui.control.Button
        graph3Opts                matlab.ui.control.Button
        graph3Cancel              matlab.ui.control.Button
        graph3Plot                matlab.ui.control.Button
        graph3XList               matlab.ui.control.DropDown
        graph3XEq                 matlab.ui.control.EditField
        graph3Start               matlab.ui.control.EditField
        graph3End                 matlab.ui.control.EditField
        graph3YEq                 matlab.ui.control.EditField
        graph3YList               matlab.ui.control.DropDown
        gridGraph4                matlab.ui.container.GridLayout
        graph4SameView            matlab.ui.control.Button
        graph4Limits              matlab.ui.control.Button
        graph4Opts                matlab.ui.control.Button
        graph4Cancel              matlab.ui.control.Button
        graph4Plot                matlab.ui.control.Button
        graph4End                 matlab.ui.control.EditField
        graph4YEq                 matlab.ui.control.EditField
        graph4YList               matlab.ui.control.DropDown
        graph4Start               matlab.ui.control.EditField
        graph4XEq                 matlab.ui.control.EditField
        graph4XList               matlab.ui.control.DropDown
        gridProcessing            matlab.ui.container.GridLayout
        DIRECTORYPanel            matlab.ui.container.Panel
        gridSave                  matlab.ui.container.GridLayout
        displayPath               matlab.ui.control.EditField
        loadPath                  matlab.ui.control.Button
        openPath                  matlab.ui.control.Button
        savePath                  matlab.ui.control.Button
        logButton                 matlab.ui.control.Button
        settings                  matlab.ui.control.StateButton
        tabMove                   matlab.ui.control.Button
        TOOLSPanel                matlab.ui.container.Panel
        gridTools                 matlab.ui.container.GridLayout
        EDITORPanel               matlab.ui.container.Panel
        gridControl               matlab.ui.container.GridLayout
        clearSection              matlab.ui.control.Button
        sectionsListButton        matlab.ui.control.DropDown
        filesListButton           matlab.ui.control.DropDown
        openControl               matlab.ui.control.Button
        textControl               matlab.ui.control.TextArea
        saveControl               matlab.ui.control.Button
        COMPUTATIONPanel          matlab.ui.container.Panel
        gridSteps                 matlab.ui.container.GridLayout
        stepsList                 matlab.ui.control.DropDown
        stepOpen                  matlab.ui.control.Button
        refreshStepsButton        matlab.ui.control.Button
        time                      matlab.ui.control.StateButton
        displayTime               matlab.ui.control.Label
        runButton                 matlab.ui.control.Switch
        clusterInfo               matlab.ui.control.Button
        gridGraph                 matlab.ui.container.GridLayout
        gridContinue              matlab.ui.container.GridLayout
        cancelFullScreen          matlab.ui.control.StateButton
        continueFullScreen        matlab.ui.control.StateButton
        graph5                    matlab.ui.control.UIAxes
        maskingPanel              matlab.ui.control.UIAxes
        graph1                    matlab.ui.control.UIAxes
        graph2                    matlab.ui.control.UIAxes
        graph3                    matlab.ui.control.UIAxes
        graph4                    matlab.ui.control.UIAxes
        OverviewTab               matlab.ui.container.Tab
        gridOverview              matlab.ui.container.GridLayout
        stepSBOverview            matlab.ui.control.Button
        stepFBOverview            matlab.ui.control.Button
        stepSFOverview            matlab.ui.control.Button
        stepFFOverview            matlab.ui.control.Button
        sliderOverview            matlab.ui.control.Slider
        changeOverview            matlab.ui.control.Button
        optionOverview            matlab.ui.control.Button
        hideOverview              matlab.ui.control.Button
        overviewPanel             matlab.ui.container.Panel
    end


    %% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%
    %%                                ModLab                                 %%
    %%                             Kévin Daigne                              %%
    %%                        kevin.daigne@hotmail.fr                        %%
    %% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%

    properties (Access = public)
        pwdSave=[]; % GUI main folder
        id=[]; % Component indices
        fontSize=[]; % Font size that will be used for several graphs
        addCount=0; % Number of successive clicks on new tab
        sett={}; % Settings of each tab
        computationRenamed=[]; % Renamed file names to run the computation program
        codeString=[];
        reader=[];
        stepIniRun=[]; % Step at the beginning of the computation
        eulerView={}; % Parameters for Euler view
        lagrangianView={}; % Parameters for Lagrangian view
        planarView={}; % Parameters for Planar view
        radialView={}; % Parameters for Radial view
        bodyIndex={}; % Save the name of body index data
        bodiesToRemove=[]; % Bodies to hide
        stepDigits=5; % Number of digits for step name
        fontColorUnchanged=[1 1 1];
        fontColorChanged=[0.64,0.08,0.18];
        fontColorProgress=[.47 .67 .19];
        graphicsFormat={'.vtk'}; % Format of data graphic files
        clusterRemoteMethod=struct(); % putty, sshpass or java
        tabDependentVariables=["computationRenamed","stepIniRun","bodyIndex","eulerView","lagrangianView","planarView","radialView","bodiesToRemove","sett"];
    end    
    
    methods (Access = public)

        function [bodyIndex,bodyList,connectivity,x,y,step]=bodiesListForGUIFunction(app,tabNumber,step,bodyIndex)
            % Abstract: Loads an existing body index or computes it

            % #. Initialization
            bodyList=[]; connectivity=[]; x=[]; y=[];

            % #. List
            [dataList,dataListFormat,~,dataFile,dataExt]=dataMainListFunction(app,tabNumber,'Refresh');

            % #. Find the current step
            % In all cases, we trace the body_index of a XXXXX.
            % To find which one, browse the potential GRAPHICS traced and
            % take the 1st one found, otherwise take the number of the
            % STEP selected in the list.
            if isempty(step)
                for graphNum=1:4
                    graphNumFunction(app, graphNum);
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                        ind=find(strcmpi(selection.type.global.cat,'STEPS'),1,'first');
                        if ~isempty(ind)
                            step=selection.arg1.global.format.cat{ind};
                            break;
                        end
                    end
                end
                if isempty(step)
                    [~,stepsIntersection]=loadListStep(app,tabNumber);
                    step=stepsIntersection{end};
                end
            end

            if isempty(app.bodyIndex{tabNumber})

                % #. DLG to seletec body index
                dataListDisplayed=['None' dataList];
                ind=listdlg('Name','Body index','PromptString',{'Select the data item containing' 'the body index. If ''none'', will be' 'computed automatically' 'according to certain assumptions,' 'and may increase computation time.'},'ListSize',[300,600],'listString',dataListDisplayed);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(ind); return; end
                app.bodyIndex{tabNumber}=dataListDisplayed{ind};

            end
            if strcmpi(app.bodyIndex{tabNumber},'none')

                % #. Computed body index
                [bodyIndex,bodyList,~,~,connectivity,x,y,step]=bodiesListFunction(app,tabNumber,step,bodyIndex);

            else

                % #. Already existing body index
                pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                indData=find(strcmp(dataListFormat,app.bodyIndex{tabNumber}),1,'first');
                file=dataFile{indData};
                ext=dataExt{indData};
                mainLoading(app,{pathSimu},{{file}},{{{step}}},ext,0);
                dataTemp=load([pathSimu filesep 'SAVE' filesep file step '.mat'],...
                    'X','Y','CONNECTIVITY',app.bodyIndex{tabNumber});
                connectivity=dataTemp.CONNECTIVITY;
                x=dataTemp.X;
                y=dataTemp.Y;
                bodyIndex=dataTemp.(app.bodyIndex{tabNumber});
                bodyList=unique(bodyIndex);

            end

        end

        function [bodyIndex,bodyList,bodyIndexMesh,bodyListMesh,connectivity,x,y,step]=bodiesListFunction(app,tabNumber,step,bodyIndex)
            % Abstract: gives the body index for each node

            % #. Initialization
            bodyList=[]; bodyIndexMesh=[]; bodyListMesh=[];
            connectivity=[]; x=[]; y=[];

            % #. Files containing step data
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            [~,~,dataType,dataFile,dataExt]=dataMainListFunction(app,pathSimu,'Refresh');
            indSteps=find(strcmpi(dataType,'STEPS'));
            [~,indUnique]=unique(strcat(dataFile(indSteps),dataExt(indSteps)));
            files=dataFile(indSteps(indUnique));
            ext=dataExt(indSteps(indUnique));
            if isempty(files); return; end

            % #. Find the current step
            % In all cases, we trace the body_index of a XXXXX.
            % To find which one, browse the potential GRAPHICS traced and
            % take the 1st one found, otherwise take the number of the
            % STEP selected in the list.
            if isempty(step)
                for graphNum=1:4
                    graphNumFunction(app, graphNum);
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                        ind=find(strcmpi(selection.type.global.cat,'STEPS'),1,'first');
                        if ~isempty(ind)
                            step=selection.arg1.global.format.cat{ind};
                            break;
                        end
                    end
                end
                if isempty(step)
                    [~,stepsIntersection]=loadListStep(app,tabNumber);
                    step=stepsIntersection{end};
                end
            end
            
            
            % #. Find connectivity
            % body index is extracted from connectivity
            for fileNum=1:numel(files)
                mainLoading(app,{pathSimu},{files(fileNum)},{{{step}}},ext{fileNum},0);
                path=[pathSimu filesep 'SAVE' filesep files{fileNum} step '.mat'];
                infoVars=who('-file',path);
                if any(strcmpi(infoVars,'CONNECTIVITY'))
                    data=load(path,'CONNECTIVITY','X','Y');
                    connectivity=data.CONNECTIVITY;
                    x=data.X;
                    y=data.Y;
                    break;
                end
            end
            if isempty(connectivity); return; end

            % #. Periodic boundaries
            % Some bodies may be divided according to mesh, but have the
            % same body index in practice. This "real" body index must be
            % found for some processing, such as body hiding. Otherwise,
            % half the body may be hidden and, more importantly, the
            % number of bodies will change constantly, so there is no
            % absolute reference to body index.
            periodicBoundaries=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.periodicboundariesTextNumber).Value;
            periodicBoundaries=str2double(strsplit(periodicBoundaries,';'));

            % #. Body index
            [bodyIndexTemp,bodyList,bodyIndexMesh,bodyListMesh]=BodyIndexFunction(connectivity,x,y,'PeriodicBoundaries',periodicBoundaries);
            if isempty(bodyIndex)
                bodyIndex=bodyIndexTemp;
            else
                bodyList=unique(bodyIndex);
            end

        end

        function bodiesList=bodiesSelection(app,tabNumber,defaultBodies,stepToFindInput,bodyIndex)
            % Abstract: body selection
            
            % #. Selection type
            answer = questdlg('Bodies selection method:', ...
                'Bodies selection', ...
                'List','Evaluation','Graphic','List');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(answer)
                bodiesList=defaultBodies;
                return;
            end
            
            % #. Selection
            if strcmp(answer,'List')
                % #.#. List
                if isempty(bodyIndex)
                    [~,bodyList]=bodiesListForGUIFunction(app,tabNumber,stepToFindInput,bodyIndex);
                else
                    bodyList=unique(min(bodyIndex):max(bodyIndex)); % some processing can be performed on deactivated bodies
                end
                bodyList=strcat({'Body '},arrayfun(@num2str,bodyList, 'UniformOutput',0));
                [indBodies,tf] = listdlg('Name','Bodies selection','PromptString',{'Select the BODIES:'},'listString',bodyList,'InitialValue',defaultBodies+1);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if ~tf
                    bodiesList=defaultBodies; return;
                else
                    bodiesList=indBodies-1;
                end
            elseif strcmp(answer,'Evaluation')
                % #.#. Matrix
                prompt = {'Enter the bodies as a matrix (e.g. 0:2:10 20) or an equation with surface data (e.g. [velocity%00001]>0):'};
                dlgtitle = 'Bodies selection'; dims = 1; definput = {num2str(defaultBodies)};
                bodiesMatrix = inputdlg(prompt,dlgtitle,dims,definput);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(bodiesMatrix)
                    bodiesList=defaultBodies; return;
                else
                    % #.#. Information
                    if contains(bodiesMatrix{1},'[') && contains(bodiesMatrix{1},']')
                        % #.#.#. Criterion
                        info.selection=selectionInfo(app,tabNumber,[],{bodiesMatrix{1} ''},[]);
                        criterion=info.selection.equation.global.rough.X;
                        info=dataLoad(app,info);
                        if isfield(info.grounds.ground1,'Data')
                            criterion=info.grounds.ground1.Data;
                        elseif isfield(info.grounds.ground1,'X')
                            criterion=info.grounds.ground1.X;
                        end
                        if isempty(bodyIndex)
                            bodyIndex=bodiesListForGUIFunction(app,tabNumber,info.selection.arg1.global.format.cat{1},[]);
                        end
                        bodiesList=unique(bodyIndex(criterion));
                    else
                        % #.#.#. Matrix
                        bodiesList=eval(['[' bodiesMatrix{1} ']']);
                    end
                end
            elseif strcmp(answer,'Graphic')
                % #.#. Graphic
                bodiesList=unique(graphSelection(app,tabNumber,stepToFindInput,defaultBodies,bodyIndex));
            end
            bodiesList=reshape(bodiesList,1,[]);

        end

        function busyOff(app)
            % Abstract: displays the busy status

            app.mainUIFigure.Name=erase(app.mainUIFigure.Name,' (busy)');

        end
        
        function busyOn(app)
            % Abstract: displays the busy status
            
            app.mainUIFigure.Name=[erase(app.mainUIFigure.Name,' (busy)') ' (busy)'];

        end
        
        function buttonsRunActivation(app,tabNumber,var)
            % Abstract: activates or deactivates the buttons related to the execution
            % var = activation of elements
            
            % #. SETTINGS
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Enable=var;

            % #. Running button
            if ~strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local')
                if strcmpi(var,'off')
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='On';
                else
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                end
            else
                if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'on') ...
                        && strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Enable,'off')
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                    runFunction(app,tabNumber,0);
                else
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Enable='On';
                end
            end

        end

        function buttonsSaveActivation(app,tabNumber,var)
            % Abstract: activates or deactivates interface elements
            
            % #. Editor
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.openControlNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.saveControlNumber).Enable=var;
            
            % #. Step
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepOpenNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.clusterInfoNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.refreshStepsButtonNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.timeNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Enable=var;
            
            % #. Plot
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCancelNumber).Enable=var;
            
            % #. Spies
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridSpiesNumber).Children(app.id.plotStartButtonNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridSpiesNumber).Children(app.id.plotEndButtonNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridSpiesNumber).Children(app.id.exportButtonNumber).Enable=var;
            
            % #. Graphics
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepFBNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepSBNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepSFNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepFFNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.hideBodiesButtonNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.infoButtonNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.loadGraphicsNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.animationNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.refreshGraphsNumber).Enable=var;
            
            % #. Graphs
            for graphNum=1:4
                graphNumFunction(app, graphNum);
                graphNumFunction(app, graphNum);
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphCancelNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphOptsNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphPlotNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphLimitsNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphStartNumberVar).Enable=var;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphEndNumberVar).Enable=var;
            end
            
            % #. Element deactivation
            buttonsRunActivation(app,tabNumber,'On');

        end

        function cancelGraphFunction(app,tabNumber,graphNum)
            % Abstract: Deletes the graph "graphNum" and the corresponding
            % data from lists, texts, etc.

            % #. Indices
            graphNumFunction(app, graphNum);

            % #. Graph
            clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber),['graph' num2str(graphNum)]);
            axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar),[0 1 0 1]);
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Visible='off';
            if graphNum==5; return; end

            % #. Texts
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value='';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value='';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphStartNumberVar).Value='';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphEndNumberVar).Value='';

            % #. Lists
            dataDisplayList(app,tabNumber,'Refresh',0,graphNum);

        end

        function clearGraph(~,panel,tagToFind)
            % Abstract: reset a graph with all associated elements (legends, etc.)
            
            % #. Displaying hidden objects 
            % e.g. allows to clear legends
            set(0,'showhiddenhandles','on');
            
            % #. Graph index
            % As the hidden objects are displayed, the indices change
            i=0;
            while 1
                i=i+1;
                if strcmp(panel.Children(i).Tag,tagToFind) % The tag is used to find the graph
                    indTag=i; 
                    break;
                end
                if i==numel(panel.Children)
                    msgbox(['Cannot find the tag "' tagToFind '", should probably restart the interface.'],'Information','help');
                    set(0,'showhiddenhandles','off');
                    return;
                end
            end
            
            % #. Clear elements associated with the graph
            i=numel(panel.Children)+1;
            while 1
                i=i-1;
                if i>0
                    if strcmp(panel.Children(i).Tag,['background' tagToFind])
                        delete(panel.Children(i));
                        break;
                    end
                else
                    break;
                end
            end
            
            % #. Clear the graph
            % As the i elements are deleted with a final verification at i-1, 
            % the graph index will necessarily be i+1
            cla(panel.Children(indTag),'reset');
            set(panel.Children(indTag),'Tag',tagToFind);

            % #. Hide toolbar
            panel.Children(indTag).Toolbar.Visible='off';
            
            % #. Format
            panel.Children(indTag).Visible='off';
            set(0,'showhiddenhandles','off');
        end

        function clusterFileManager(app,tabNumber,s_conn)
            % Abstract: opens the file manager
            
            % #. Tab selection
            tabList=cell(1,numel(app.TabGroup.Children)-2);
            for tabNumberVar=3:numel(app.TabGroup.Children)
                tabList{tabNumberVar-2}=app.TabGroup.Children(tabNumberVar).Title;
            end
            [indTab,~]=listdlg('PromptString',{'Select the tab(s) to manage:'},'listString',tabList,'InitialValue',tabNumber-2);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(indTab)
                return;
            end
            tabNumberVect=indTab+2;
            for tabNumberTemp=numel(tabNumberVect):-1:1
                pathSimu=app.TabGroup.Children(tabNumberVect(tabNumberTemp)).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                if isempty(pathSimu) || ~exist(pathSimu,'dir')
                    tabNumberVect(tabNumberTemp)=[];
                end
            end
            if isempty(tabNumberVect)
                return;
            end
            
            % #. Initialization
            directory=cell(1,numel(app.TabGroup.Children));
            nameFolder=cell(1,numel(app.TabGroup.Children));
            key=cell(1,numel(app.TabGroup.Children));
            pathSimuCluster=cell(1,numel(app.TabGroup.Children));
            cluster=cell(1,numel(app.TabGroup.Children));
            simu=cell(1,numel(app.TabGroup.Children));
            % #. Loading paths and lists
            for tabNumberVar=1:numel(tabNumberVect)
                % 2 loops because the Apply changes button needs all
                % the tabs, and so the function must contain the data
                % from all the tabs as inputs.
                % #.#. Find the folder
                [directory{tabNumberVect(tabNumberVar)},nameFolder{tabNumberVect(tabNumberVar)},key{tabNumberVect(tabNumberVar)},pathSimuCluster{tabNumberVect(tabNumberVar)}]=clusterPath(app,tabNumberVect(tabNumberVar),s_conn);
                % #.#. List
                [cluster{tabNumberVect(tabNumberVar)},simu{tabNumberVect(tabNumberVar)},~]=clusterListLoad(app,s_conn,tabNumberVect(tabNumberVar),pathSimuCluster{tabNumberVect(tabNumberVar)});
            end
            % #. GUI
            % #.#. Window
            figCluster=uifigure('Name',['File manager (' s_conn.hostname ')'],'Units','Normalized','Position',[0.2 0.2 0.6 0.6],'Color',[.98 .98 .98]);
            drawnow;
            tabGroupCluster=uitabgroup(figCluster,'Units','Normalized','Position',[0 0 1 1]);
            drawnow;
            tab=uitab(tabGroupCluster,'BackgroundColor',[.98 .98 .98]);
            drawnow;
            pan=uipanel(tab,'Units','Normalized','Position',[0 0 1 1],'BackgroundColor',[.98 .98 .98]);
            drawnow;
            set(pan,'Units','Pixels');
            drawnow;
            pos=get(tab,'Position');
            for tabNumberVar=1:numel(tabNumberVect)
                if tabNumberVar~=1
                    tab=uitab(tabGroupCluster,'BackgroundColor',[.98 .98 .98]);
                    pan=uipanel(tab,'Units','Normalized','Position',[0 0 1 1],'BackgroundColor',[.98 .98 .98]);
                end
                
                % #.#. Names without step
                % e.g. EXAMPLE_001.vtk -> EXAMPLE_.vtk
                filesWithoutStepUnique=[simu{tabNumberVect(tabNumberVar)}.files cluster{tabNumberVect(tabNumberVar)}.files];
                for fileNum=numel(filesWithoutStepUnique):-1:1
                    [~,typeTemp,extTemp]=fileparts(filesWithoutStepUnique{fileNum});
                    ind=find(~isstrprop(typeTemp,'digit'),1,'last');
                    filesWithoutStepUnique{fileNum}=[typeTemp(1:ind) extTemp];
                end
                filesWithoutStepUnique=unique(filesWithoutStepUnique);
                % #.#. Simu
                uibutton(pan,'Position',[0 0.95*pos(4) pos(3)/3 0.05*pos(4)],'Text','LOCAL: copy inside [+0.0Mo/-0.0Mo]','fontweight','bold','fontsize',12,'ButtonPushedFcn',@(bSimu,event) clusterManagerMoveList(app,figCluster.Children(1).SelectedTab.Children(1),bSimu,simu{tabNumberVect(tabNumberVar)},cluster{tabNumberVect(tabNumberVar)}),'Tag','simuButton');
                uilistbox(pan,'Position',[0 0.83*pos(4) pos(3)/3 0.12*pos(4)],'Items',filesWithoutStepUnique,'ToolTip',[{'Fast import ⚡'} {''} {'Press (A) to get the first file, (Z) to get the last, (Q) to get all except the first, (S) to get all except the last, and an other key to reset this option.'}],'fontsize',12,'Value',{},'ValueChangedFcn',@(fSimu,event) clusterManagerMoveList(app,figCluster.Children(1).SelectedTab.Children(1),fSimu,simu{tabNumberVect(tabNumberVar)},cluster{tabNumberVect(tabNumberVar)}),'Tag','simuFiles');
                uilistbox(pan,'Position',[0 0.05*pos(4) pos(3)/3 0.78*pos(4)],'Items',sort(strcat('[LOCAL]',{' '},simu{tabNumberVect(tabNumberVar)}.files)),'ToolTip','Files imported here will not be deleted from their original locations. If more than one file exists in a folder, the most recent will be kept.','Multiselect','on','Value',{},'ValueChangedFcn',@(lSimu,event) clusterManagerResetList(app,figCluster.Children(1).SelectedTab.Children(1),lSimu),'Tag','simuList');
                % #.#. Cluster
                uibutton(pan,'Position',[pos(3)/3 0.95*pos(4) pos(3)/3 0.05*pos(4)],'Text','SERVER: copy inside [+0.0Mo/-0.0Mo]','fontweight','bold','fontsize',12,'ButtonPushedFcn',@(bCluster,event) clusterManagerMoveList(app,figCluster.Children(1).SelectedTab.Children(1),bCluster,simu{tabNumberVect(tabNumberVar)},cluster{tabNumberVect(tabNumberVar)}),'Tag','clusterButton');
                uilistbox(pan,'Position',[pos(3)/3 0.83*pos(4) pos(3)/3 0.12*pos(4)],'Items',filesWithoutStepUnique,'ToolTip',[{'Fast import ⚡'} {''} {'Press (A) to get the first file, (Z) to get the last, (Q) to get all except the first, (S) to get all except the last, and an other key to reset this option.'}],'fontsize',12,'Value',{},'ValueChangedFcn',@(fCluster,event) clusterManagerMoveList(app,figCluster.Children(1).SelectedTab.Children(1),fCluster,simu{tabNumberVect(tabNumberVar)},cluster{tabNumberVect(tabNumberVar)}),'Tag','clusterFiles');
                uilistbox(pan,'Position',[pos(3)/3 0.05*pos(4) pos(3)/3 0.78*pos(4)],'Items',sort(strcat('[SERVER]',{' '},cluster{tabNumberVect(tabNumberVar)}.files)),'ToolTip','Files imported here will not be deleted from their original locations. If more than one file exists in a folder, the most recent will be kept.','Multiselect','on','Value',{},'ValueChangedFcn',@(lCluster,event) clusterManagerResetList(app,figCluster.Children(1).SelectedTab.Children(1),lCluster),'Tag','clusterList');
                % #.#. Recycle bin
                uibutton(pan,'Position',[pos(3)*2/3 0.95*pos(4) pos(3)/3 0.05*pos(4)],'Text','RECYCLE BIN: move inside [-0.0Mo]','fontweight','bold','fontsize',12,'ButtonPushedFcn',@(bBin,event) clusterManagerMoveList(app,figCluster.Children(1).SelectedTab.Children(1),bBin,simu{tabNumberVect(tabNumberVar)},cluster{tabNumberVect(tabNumberVar)}),'Tag','binButton');
                uilistbox(pan,'Position',[pos(3)*2/3 0.83*pos(4) pos(3)/3 0.12*pos(4)],'Items',filesWithoutStepUnique,'ToolTip',[{'Fast import ⚡'} {''} {'Press (A) to get the first file, (Z) to get the last, (Q) to get all except the first, (S) to get all except the last, and an other key to reset this option.'}],'fontsize',12,'Value',{},'ValueChangedFcn',@(fBin,event) clusterManagerMoveList(app,figCluster.Children(1).SelectedTab.Children(1),fBin,simu{tabNumberVect(tabNumberVar)},cluster{tabNumberVect(tabNumberVar)}),'Tag','binFiles');
                uilistbox(pan,'Position',[pos(3)/3*2 0.05*pos(4) pos(3)/3 0.78*pos(4)],'Items',{},'Multiselect','on','Value',{},'ValueChangedFcn',@(lBin,event) clusterManagerResetList(app,figCluster.Children(1).SelectedTab.Children(1),lBin),'Tag','binList');
                % #.#. Delete files after transport
                uicheckbox(pan,'Position',[0 0 pos(3)/3 0.05*pos(4)],'Text','Delete transferred files from the server after completion','fontweight','bold','fontsize',12,'ValueChangedFcn',@(bAutoDelete,event) clusterManagerCheckBox(app,tabGroupCluster),'Value',0,'Tag','autoDeleteButton');
                % #.#. Reset
                uibutton(pan,'Position',[pos(3)/3 0.0 pos(3)/3 0.05*pos(4)],'Text','Reset','fontweight','bold','fontsize',12,'ButtonPushedFcn',@(bReset,event) clusterManagerResetGUI(app,tabGroupCluster));
                % #.#. Save
                uibutton(pan,'Position',[pos(3)/3*2 0 pos(3)/3 0.05*pos(4)],'Text','Apply change [+0.0Mo/-0.0Mo]','fontweight','bold','fontsize',12,'ButtonPushedFcn',@(bSave,event) clusterManagerApplyChange(app,s_conn,tabGroupCluster,directory,nameFolder,key,pathSimuCluster,simu,cluster),'Tag','saveButton');
                % #.#. Format
                tabGroupCluster.Children(end).Title=app.TabGroup.Children(tabNumberVect(tabNumberVar)).Title;
                set(tabGroupCluster.Children(end),'ForegroundColor',[0 0 0]);
            end

        end

        function [cluster,args,runningFrom,cancel]=clusterFilesStartingImport(app,tabNumber,simu,cluster,exe,nameExe)
            % Abstract: processes files to be deleted or copied to the cluster
            
            % #. Initialization
            args=''; 
            runningFrom='cluster';
            cancel=0;

            % #. FromSimu
            % #.#. List
            [~,indSort]=sort(lower(simu.files));
            filesListSimu=simu.files(indSort);
            filesSizeSimu=simu.size(indSort);
            filesDateSimu=simu.date(indSort);
            indToRemove=ismember(filesListSimu,{'.' '..' '.DS_Store' 'Thumbs.db'});
            filesListSimu(indToRemove)=[];
            filesSizeSimu(indToRemove)=[];
            filesDateSimu(indToRemove)=[];
            % #.#. Format
            % Indicates whether files are found in the cluster
            if ~isempty(filesListSimu) % otherwise issue when == []
                filesListSimuFormat=filesListSimu;
                indDuplicatedSimu=find(ismember(filesListSimu,cluster.files));
                indDuplicatedCluster=find(ismember(cluster.files,filesListSimu));
                dateFormat=cell(1,numel(indDuplicatedSimu)); % date for duplicated
                indDuplicatedSimuSameDate=filesDateSimu(indDuplicatedSimu)==cluster.date(indDuplicatedCluster);
                dateFormat(~indDuplicatedSimuSameDate)=strcat({' ('}, ...
                    'local:', ...
                    {' '}, ...
                    cellstr(string(filesDateSimu(indDuplicatedSimu(~indDuplicatedSimuSameDate)))), ...
                    {' - '}, ...
                    'cluster:', ...
                    {' '}, ...
                    cellstr(string(cluster.date(indDuplicatedCluster(~indDuplicatedSimuSameDate)))), ...
                    ')'); % duplicated files with different dates
                dateFormat(indDuplicatedSimuSameDate)=strcat({' ('},...
                    'local/cluster:',...
                    {' '},...
                    cellstr(string(filesDateSimu(indDuplicatedSimu(indDuplicatedSimuSameDate)))),...
                    ')'); % duplicated files with the same date
                filesListSimuFormat(indDuplicatedSimu)=strcat(filesListSimuFormat(indDuplicatedSimu),dateFormat);
                % #.#. Default
                % Select the unique
                % #.#. DLG
                [ind,tf]=listdlg('Name','Selection','PromptString',{'Select files to import (cluster version of duplicated files will be deleted):'},'ListString',filesListSimuFormat,'ListSize',[600,600]);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if ~tf; cancel=1; return; end
                % #.#. Processing
                cluster.FromSimu.Files=[cluster.FromSimu.Files filesListSimu{ind}];
                cluster.FromSimu.Size=[cluster.FromSimu.Size filesSizeSimu(ind)];
            end

            % #. Last local step
            stepLastLocalDisplay=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items{1};
            if isempty(stepLastLocalDisplay)
                % Files without step
                % As there is no step (detect with graphics), display last files
                stepLastLocalDisplay=simu.files;
                for fileNum=numel(stepLastLocalDisplay):-1:1
                    [~,typeTemp,extTemp]=fileparts(stepLastLocalDisplay{fileNum});
                    ind=find(~isstrprop(typeTemp,'digit'),1,'last');
                    stepLastLocalDisplay{fileNum}=[typeTemp(1:ind) extTemp];
                end
                stepLastLocalDisplay=unique(stepLastLocalDisplay);
                % Last files
                for fileNum=numel(stepLastLocalDisplay):-1:1
                    [~,typeTemp,extTemp]=fileparts(stepLastLocalDisplay{fileNum});
                    ind=find(contains(simu.files,typeTemp) & contains(simu.files,extTemp),1,'last');
                    stepLastLocalDisplay{fileNum}=simu.files{ind};
                end
                stepLastLocalDisplay=unique(stepLastLocalDisplay);
                stepLastLocalDisplay=['not found, the most recent file(s) found are ' strjoin(stepLastLocalDisplay,', ')];
            end

            % #. Last cluster step
            % #.#. Data information
            [~,~,dataType,dataFile]=dataMainListFunction(app,tabNumber,'Refresh');
            indSteps=strcmpi(dataType,'STEPS');
            files=unique(dataFile(indSteps));
            % #.#. List
            [~,stepsCluster]=fileparts([cluster.files cluster.FromSimu.Files]);
            stepsCluster=erase(stepsCluster,files);
            stepsClusterNum=str2double(stepsCluster); % The name must contain numbers only if the type/extension is removed from the name
            % #.#. Last
            [val,ind]=max(stepsClusterNum); % ind to keep the step format (e.g. 00001 instead of 1)
            if isempty(val)
                stepLastClusterInput='';
                % Files without step
                % As there is no step (detect with graphics), display last files
                stepLastClusterDisplay=cluster.files;
                for fileNum=numel(stepLastClusterDisplay):-1:1
                    [~,typeTemp,extTemp]=fileparts(stepLastClusterDisplay{fileNum});
                    ind=find(~isstrprop(typeTemp,'digit'),1,'last');
                    stepLastClusterDisplay{fileNum}=[typeTemp(1:ind) extTemp];
                end
                stepLastClusterDisplay=unique(stepLastClusterDisplay);
                % Last files
                for fileNum=numel(stepLastClusterDisplay):-1:1
                    [~,typeTemp,extTemp]=fileparts(stepLastClusterDisplay{fileNum});
                    ind=find(contains(cluster.files,typeTemp) & contains(cluster.files,extTemp),1,'last');
                    stepLastClusterDisplay{fileNum}=cluster.files{ind};
                end
                stepLastClusterDisplay=unique(stepLastClusterDisplay);
                stepLastClusterDisplay=['not found, the most recent file(s) found are ' strjoin(stepLastClusterDisplay,', ')];
            else
                stepLastClusterInput=stepsCluster{ind};
                stepLastClusterDisplay=stepsCluster{ind};
            end

            % #. Argument
            args=inputdlg({['Enter arguments if necessary (e.g. > ' nameExe ' arguments):' newline ...
                ['- Last cluster step (after transfer): ' stepLastClusterDisplay] newline...
                ['- Last local step: ' stepLastLocalDisplay]]},...
                'Argument',[1 100],{stepLastClusterInput});
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(args) && ~ischar([args{:}]); cancel=1; return; end
            args=args{1};
            
            % #. ToSupr
            ind=ismember(cluster.files,cluster.FromSimu.Files); % Duplicated files
            cluster.ToSupr.Files=[cluster.ToSupr.Files cluster.files{ind}];
            cluster.ToSupr.Size=[cluster.ToSupr.Size cluster.size(ind)];

            % #. FromExe
            serversList=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items(2:end);
            serversListFormat=lower(replace(serversList,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            serverCurrent=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value;
            serverCurrentFormat=lower(replace(serverCurrent,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            filesFormat=lower(replace(exe.files,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            ind=~contains(filesFormat,serversListFormat) | contains(filesFormat,serverCurrentFormat); % To keep only the right launcher
            cluster.FromExe.Files=[cluster.FromExe.Files exe.files{ind}];
            cluster.FromExe.Size=[cluster.FromExe.Size exe.size(ind)];
            
        end

        function [pathSimuCluster]=clusterFilesTransfer(app,s_conn,pathSimu,directory,nameFolder,key,pathSimuCluster,simu,cluster,tabNumber,autoDeleteFromCluster,displayComplete)
            % Abstract: Transfers files from cluster to simu and vice versa
            % Issue: Import process can be quite long (more than the usual
            % file manager). Try to set a timer on transfer state but it
            % does not start if import is not completed

            % #. Path
            selectedPackage=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.packageModeListNumber).Value;
            pathPackages=[app.pwdSave filesep 'Packages' filesep selectedPackage];
            serverFormat=lower(replace(s_conn.hostname,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            
            % #. Empty cell
            % Tab 1 or 3 is taken as the reference, depending on the function
            ind=cellfun(@isempty,pathSimu) | cellfun(@isempty,simu) | cellfun(@isempty,cluster);
            pathSimu(ind)=[];
            directory(ind)=[];
            nameFolder(ind)=[];
            key(ind)=[];
            pathSimuCluster(ind)=[];
            simu(ind)=[];
            cluster(ind)=[];
            
            % #. Pre-processing
            suprSize=cell(1,numel(pathSimu));
            copySize=cell(1,numel(pathSimu));
            copySizeFromCluster=cell(1,numel(pathSimu));
            openKey=s_conn.pem_file;
            for tabNumberVar=1:numel(pathSimu)
                
                % #.#. Empty tab
                if isempty(nameFolder{tabNumberVar})
                    continue;
                end
                
                % #.#. Folder creation
                % #.#.#. Local
                if ~exist([pathSimu{tabNumberVar} filesep 'CODE'], 'dir')
                    mkdir(pathSimu{tabNumberVar},'CODE')
                end
                % #.#.#. Server
                if isempty(pathSimuCluster{tabNumberVar})
                    % The file is to be created
                    % Directory
                    existVerif=str2double(ssh2_command_response(ssh2_command(s_conn,['test -d "' directory{tabNumberVar} '" && echo 1 || echo 0 2>&1'])));
                    if ~existVerif
                        ssh2_command(s_conn,['mkdir 2>&1 ' directory{tabNumberVar}]);
                    end
                    % Simulation folder
                    ssh2_command(s_conn,['mkdir 2>&1 ' directory{tabNumberVar} nameFolder{tabNumberVar} '_' key{tabNumberVar}]);
                    pathSimuCluster{tabNumberVar}=[directory{tabNumberVar} nameFolder{tabNumberVar} '_' key{tabNumberVar}];
                end
                existVerif=str2double(ssh2_command_response(ssh2_command(s_conn,['test -d "' directory{tabNumberVar} nameFolder{tabNumberVar} '_' key{tabNumberVar} '/CODE' '" && echo 1 || echo 0 2>&1'])));
                if ~existVerif
                    ssh2_command(s_conn,['mkdir 2>&1 ' directory{tabNumberVar} nameFolder{tabNumberVar} '_' key{tabNumberVar} '/CODE']);
                end
                
                % #.#. Processing to remove duplicates
                % Only the most recent files are kept
                % #.#.#. Removes files being deleted in .Files
                % #.#.#.#. Simu
                indSupr=ismember(simu{tabNumberVar}.files,simu{tabNumberVar}.ToSupr.Files);
                simu{tabNumberVar}.files(indSupr)=[];
                simu{tabNumberVar}.size(indSupr)=[];
                simu{tabNumberVar}.date(indSupr)=[];
                % #.#.#.#. Cluster
                indSupr=ismember(cluster{tabNumberVar}.files,cluster{tabNumberVar}.ToSupr.Files);
                cluster{tabNumberVar}.files(indSupr)=[];
                cluster{tabNumberVar}.size(indSupr)=[];
                cluster{tabNumberVar}.date(indSupr)=[];
                % #.#.#. Delete older duplicates
                % #.#.#.#. Simu
                indOld=find(ismember(simu{tabNumberVar}.files,simu{tabNumberVar}.FromCluster.Files)); % Duplicate index in simu
                indNew=find(ismember(cluster{tabNumberVar}.files,simu{tabNumberVar}.files(indOld))); % Duplicate index in cluster
                if ~isempty(indOld) && ~isempty(indNew)
                    indSupr=simu{tabNumberVar}.date(indOld)<cluster{tabNumberVar}.date(indNew);
                    simu{tabNumberVar}.ToSupr.Files=[simu{tabNumberVar}.ToSupr.Files simu{tabNumberVar}.files(indOld(indSupr))]; % Deletes older duplicates
                    simu{tabNumberVar}.ToSupr.Size=[simu{tabNumberVar}.ToSupr.Size simu{tabNumberVar}.size(indOld(indSupr))]; % Deletes older duplicates
                    simu{tabNumberVar}.files(indOld(indSupr))=[]; % Deletes older duplicates
                    simu{tabNumberVar}.size(indOld(indSupr))=[]; % Deletes older duplicates
                    simu{tabNumberVar}.date(indOld(indSupr))=[]; % Deletes older duplicates
                    simu{tabNumberVar}.FromCluster.Size(ismember(simu{tabNumberVar}.FromCluster.Files,cluster{tabNumberVar}.files(indNew(~indSupr))))=[]; % Older duplicates are not copied
                    simu{tabNumberVar}.FromCluster.Files(ismember(simu{tabNumberVar}.FromCluster.Files,cluster{tabNumberVar}.files(indNew(~indSupr))))=[]; % Older duplicates are not copied
                    if autoDeleteFromCluster  % Deletes older duplicates
                        cluster{tabNumberVar}.ToSupr.Files=[cluster{tabNumberVar}.ToSupr.Files cluster{tabNumberVar}.files(indNew(~indSupr))]; % Deletes older duplicates
                        cluster{tabNumberVar}.ToSupr.Size=[cluster{tabNumberVar}.ToSupr.Size cluster{tabNumberVar}.size(indNew(~indSupr))]; % Deletes older duplicates
                        cluster{tabNumberVar}.files(indNew(~indSupr))=[]; % Deletes older duplicates
                        cluster{tabNumberVar}.size(indNew(~indSupr))=[]; % Deletes older duplicates
                        cluster{tabNumberVar}.date(indNew(~indSupr))=[]; % Deletes older duplicates
                    end
                end
                % #.#.#.#. Cluster
                indOld=find(ismember(cluster{tabNumberVar}.files,cluster{tabNumberVar}.FromSimu.Files)); % Duplicate index in cluster
                indNew=find(ismember(simu{tabNumberVar}.files,cluster{tabNumberVar}.files(indOld))); % Duplicate index in simu
                if ~isempty(indOld) && ~isempty(indNew)
                    indSupr=cluster{tabNumberVar}.date(indOld)<simu{tabNumberVar}.date(indNew);
                    cluster{tabNumberVar}.ToSupr.Files=[cluster{tabNumberVar}.ToSupr.Files cluster{tabNumberVar}.files(indOld(indSupr))]; % Deletes older duplicates
                    cluster{tabNumberVar}.ToSupr.Size=[cluster{tabNumberVar}.ToSupr.Size cluster{tabNumberVar}.size(indOld(indSupr))]; % Deletes older duplicates
                    cluster{tabNumberVar}.files(indOld(indSupr))=[]; % Deletes older duplicates
                    cluster{tabNumberVar}.size(indOld(indSupr))=[]; % Deletes older duplicates
                    cluster{tabNumberVar}.date(indOld(indSupr))=[]; % Deletes older duplicates
                    cluster{tabNumberVar}.FromSimu.Size(ismember(cluster{tabNumberVar}.FromSimu.Files,simu{tabNumberVar}.files(indNew(~indSupr))))=[]; % Older duplicates are not copied
                    cluster{tabNumberVar}.FromSimu.Files(ismember(cluster{tabNumberVar}.FromSimu.Files,simu{tabNumberVar}.files(indNew(~indSupr))))=[]; % Older duplicates are not copied                   
                end
                
                % #.#. Processing by increasing size
                % Gives you an idea of transfer time before copying large files
                % Simu To Supr
                [~,ind]=sort(simu{tabNumberVar}.ToSupr.Size);
                simu{tabNumberVar}.ToSupr.Size=simu{tabNumberVar}.ToSupr.Size(ind);
                simu{tabNumberVar}.ToSupr.Files=simu{tabNumberVar}.ToSupr.Files(ind);
                % Cluster To Supr
                [~,ind]=sort(cluster{tabNumberVar}.ToSupr.Size);
                cluster{tabNumberVar}.ToSupr.Size=cluster{tabNumberVar}.ToSupr.Size(ind);
                cluster{tabNumberVar}.ToSupr.Files=cluster{tabNumberVar}.ToSupr.Files(ind);
                % Exe -> Cluster
                [~,ind]=sort(cluster{tabNumberVar}.FromExe.Size);
                cluster{tabNumberVar}.FromExe.Size=cluster{tabNumberVar}.FromExe.Size(ind);
                cluster{tabNumberVar}.FromExe.Files=cluster{tabNumberVar}.FromExe.Files(ind);
                % Simu -> Cluster
                [~,ind]=sort(cluster{tabNumberVar}.FromSimu.Size);
                cluster{tabNumberVar}.FromSimu.Size=cluster{tabNumberVar}.FromSimu.Size(ind);
                cluster{tabNumberVar}.FromSimu.Files=cluster{tabNumberVar}.FromSimu.Files(ind);
                % Cluster -> Simu
                [~,ind]=sort(simu{tabNumberVar}.FromCluster.Size);
                simu{tabNumberVar}.FromCluster.Size=simu{tabNumberVar}.FromCluster.Size(ind);
                simu{tabNumberVar}.FromCluster.Files=simu{tabNumberVar}.FromCluster.Files(ind);

                % #.#. Size
                suprSize{tabNumberVar}=[simu{tabNumberVar}.ToSupr.Size cluster{tabNumberVar}.ToSupr.Size];
                copySize{tabNumberVar}=[cluster{tabNumberVar}.FromExe.Size cluster{tabNumberVar}.FromSimu.Size simu{tabNumberVar}.FromCluster.Size];
                copySizeFromCluster{tabNumberVar}=[simu{tabNumberVar}.FromCluster.Size];

            end

            % #. Size
            suprSize=[suprSize{:}];
            suprSize=cumsum(suprSize);
            copySize=[copySize{:}];
            copySize=cumsum(copySize);
            copySizeFromCluster=[copySizeFromCluster{:}];
            copySizeFromCluster=cumsum(copySizeFromCluster);
            
            % #. Deleting files
            if ~isempty(suprSize)
                % #.#. Bar
                msg=waitbar(0,'Files removing','Name','Progress');
                tStart=tic; k=0;
                for tabNumberVar=1:numel(pathSimu)
                    % #.#. Simu
                    for fileNum=1:numel(simu{tabNumberVar}.ToSupr.Files)
                        k=k+1;
                        delete([pathSimu{tabNumberVar} filesep 'CODE' filesep simu{tabNumberVar}.ToSupr.Files{fileNum}]);
                        if ishandle(msg)
                            waitbar(suprSize(k)/suprSize(end),msg,['Files removing - Finishing on ' char(datetime('now')+duration(0,0,(suprSize(end)-suprSize(k))*toc(tStart)/suprSize(k)))]);
                        else
                            msgbox('Removing has been stopped.','Information','help');
                            return;
                        end
                    end
                    % #.#. Cluster
                    for fileNum=1:numel(cluster{tabNumberVar}.ToSupr.Files)
                        k=k+1;
                        if strcmpi(app.clusterRemoteMethod.(serverFormat),'putty')
                            if isempty(openKey)
                                systCommand=sprintf('plink -pw %s %s@%s "rm %s"', ...
                                    s_conn.password, ...
                                    s_conn.username, ...
                                    s_conn.hostname, ...
                                    [pathSimuCluster{tabNumberVar} '/CODE/' cluster{tabNumberVar}.ToSupr.Files{fileNum}]);
                            else
                                systCommand = sprintf('plink -i "%s" %s@%s "rm %s"', ...
                                    openKey, ...  % Replace with the path to your private key
                                    s_conn.username, ...
                                    s_conn.hostname, ...
                                    [pathSimuCluster{tabNumberVar} '/CODE/' cluster{tabNumberVar}.ToSupr.Files{fileNum}]);
                            end
                            system(systCommand);
                        elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'sshpass')
                            if isempty(openKey)
                                systCommand=sprintf('sshpass -p "%s" ssh %s@%s "rm %s"', ...
                                    s_conn.password, ...
                                    s_conn.username, ...
                                    s_conn.hostname, ...
                                    [pathSimuCluster{tabNumberVar} '/CODE/' cluster{tabNumberVar}.ToSupr.Files{fileNum}]);
                            else
                                systCommand = sprintf('ssh -i "%s" %s@%s "rm %s"', ...
                                    openKey, ...  % Specify the path to your private key
                                    s_conn.username, ...
                                    s_conn.hostname, ...
                                    [pathSimuCluster{tabNumberVar} '/CODE/' cluster{tabNumberVar}.ToSupr.Files{fileNum}]);

                            end
                            system(systCommand);
                        elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'java')
                            ssh2_command(s_conn,['rm ' pathSimuCluster{tabNumberVar} '/CODE/' cluster{tabNumberVar}.ToSupr.Files{fileNum} ' 2>&1']);
                        end
                        if ishandle(msg)
                            waitbar(suprSize(k)/suprSize(end),msg,['Files removing - Finishing on ' char(datetime('now')+duration(0,0,(suprSize(end)-suprSize(k))*toc(tStart)/suprSize(k)))]);
                        else
                            msgbox('Removing has been stopped.','Information','help');
                            return;
                        end
                    end
                end
                % #.#. Bar
                if ishandle(msg)
                    delete(msg);
                end
            end
            
            % #. Copying files
            if ~isempty(copySize)
                % #.#. Bar
                msg=waitbar(0,'Transferring files','Name','Progress');
                tStart=tic; k=0;
                for tabNumberVar=1:numel(pathSimu)
                    % #.#. Exe -> Cluster
                    if ~isempty(cluster{tabNumberVar}.FromExe.Files)
                        for fileNum=1:numel(cluster{tabNumberVar}.FromExe.Files)
                            k=k+1;
                            if strcmpi(app.clusterRemoteMethod.(serverFormat),'putty')
                                if isempty(openKey)
                                    systCommand=sprintf('pscp -pw %s -p %s %s@%s:%s', ...
                                        s_conn.password, ...
                                        [pathPackages filesep 'Cluster' filesep cluster{tabNumberVar}.FromExe.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                else
                                    systCommand = sprintf('pscp -i %s -p %s %s@%s:%s', ...
                                        openKey, ... % Specify the path to your private key here
                                        [pathPackages filesep 'Cluster' filesep cluster{tabNumberVar}.FromExe.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'sshpass')
                                if isempty(openKey)
                                    systCommand=sprintf('sshpass -p "%s" scp -p %s %s@%s:%s', ...
                                        s_conn.password, ...
                                        [pathPackages filesep 'Cluster' filesep cluster{tabNumberVar}.FromExe.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                else
                                    systCommand = sprintf('scp -i "%s" -p %s %s@%s:%s', ...
                                        openKey, ... % Path to the SSH private key
                                        [pathPackages filesep 'Cluster' filesep cluster{tabNumberVar}.FromExe.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'java')
                                scp_put(s_conn,cluster{tabNumberVar}.FromExe.Files{fileNum},[pathSimuCluster{tabNumberVar} '/CODE/'],[pathPackages filesep 'Cluster' filesep]);
                            end
                            if ishandle(msg)
                                waitbar(copySize(k)/copySize(end),msg,['Files uploading - Finishing on ' char(datetime('now')+duration(0,0,(copySize(end)-copySize(k))*toc(tStart)/copySize(k)))]);
                            else
                                msgbox('Uploading has been stopped.','Information','help');
                                return;
                            end
                        end
                    end
                    % #.#. Simu -> Cluster
                    if ~isempty(cluster{tabNumberVar}.FromSimu.Files)
                        for fileNum=1:numel(cluster{tabNumberVar}.FromSimu.Files)
                            k=k+1;
                            if strcmpi(app.clusterRemoteMethod.(serverFormat),'putty')
                                if isempty(openKey)
                                    systCommand=sprintf('pscp -pw %s -p %s %s@%s:%s', ...
                                        s_conn.password, ...
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep cluster{tabNumberVar}.FromSimu.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                else
                                    systCommand = sprintf('pscp -i "%s" -p %s %s@%s:%s', ...
                                        openKey, ...  % Path to the private key file
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep cluster{tabNumberVar}.FromSimu.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'sshpass')
                                if isempty(openKey)
                                    systCommand=sprintf('sshpass -p "%s" scp -p %s %s@%s:%s', ...
                                        s_conn.password, ...
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep cluster{tabNumberVar}.FromSimu.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                else
                                    systCommand = sprintf('scp -i "%s" -p %s %s@%s:%s', ...
                                        openKey, ...  % Path to your private key
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep cluster{tabNumberVar}.FromSimu.Files{fileNum}], ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/']);
                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'java')
                                scp_put(s_conn,cluster{tabNumberVar}.FromSimu.Files{fileNum},[pathSimuCluster{tabNumberVar} '/CODE/'],[pathSimu{tabNumberVar} filesep 'CODE' filesep]);
                            end
                            if ishandle(msg)
                                waitbar(copySize(k)/copySize(end),msg,['Files uploading - Finishing on ' char(datetime('now')+duration(0,0,(copySize(end)-copySize(k))*toc(tStart)/copySize(k)))]);
                            else
                                msgbox('Uploading has been stopped.','Information','help');
                                return;
                            end
                        end
                    end
                    % #.#. Cluster -> Simu
                    if ~isempty(simu{tabNumberVar}.FromCluster.Files)
                        for fileNum=1:numel(simu{tabNumberVar}.FromCluster.Files)
                            k=k+1;
                            if strcmpi(app.clusterRemoteMethod.(serverFormat),'putty')
                                if isempty(openKey)
                                    systCommand=sprintf('pscp -pw %s -p %s@%s:%s %s', ...
                                        s_conn.password, ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}], ...
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep]);
                                else
                                    systCommand = sprintf('pscp -i "%s" -p %s@%s:%s %s', ...
                                        openKey, ...   % Path to your private key
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}], ...
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep]);
                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'sshpass')
                                if isempty(openKey)
                                    %scpCommand = sprintf('sshpass -p "%s" scp -o StrictHostKeyChecking=no -v %s@%s:%s %s', s_conn.password, s_conn.username, s_conn.hostname, [pathSimuCluster{tabNumberVar} '/CODE' filesep simu{tabNumberVar}.FromCluster.Files{fileNum}], [pathSimu{tabNumberVar} filesep 'CODE' filesep]);
                                    systCommand=sprintf('sshpass -p "%s" scp -p %s@%s:%s %s', ...
                                        s_conn.password, ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}], ...
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep]);
                                else
                                    systCommand = sprintf('scp -i "%s" -p %s@%s:%s %s', ...
                                        openKey, ... % Path to your private SSH key
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}], ...
                                        [pathSimu{tabNumberVar} filesep 'CODE' filesep]);
                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'java')
                                scp_get(s_conn,simu{tabNumberVar}.FromCluster.Files{fileNum},[pathSimu{tabNumberVar} filesep 'CODE' filesep],[pathSimuCluster{tabNumberVar} '/CODE']);
                            end
                            if ishandle(msg)
                                waitbar(copySize(k)/copySize(end),msg,['Files downloading - Finishing on ' char(datetime('now')+duration(0,0,(copySize(end)-copySize(k))*toc(tStart)/copySize(k)))]);
                            else
                                msgbox('Downloading has been stopped.','Information','help');
                                return;
                            end
                        end
                    end
                end
                % #.#. Bar
                if ishandle(msg)
                    delete(msg);
                end
            end

            % #. Auto delete from cluster
            if autoDeleteFromCluster && ~isempty(copySizeFromCluster)
                % #.#. Bar
                msg=waitbar(0,'Automatic deletion','Name','Progress');
                tStart=tic; k=0;
                for tabNumberVar=1:numel(pathSimu)
                    if ~isempty(simu{tabNumberVar}.FromCluster.Files)
                        for fileNum=1:numel(simu{tabNumberVar}.FromCluster.Files)
                            k=k+1;
                            if strcmpi(app.clusterRemoteMethod.(serverFormat),'putty')
                                if isempty(openKey)
                                    systCommand=sprintf('plink -pw %s %s@%s "rm %s"', ...
                                        s_conn.password, ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}]);
                                else
                                    systCommand = sprintf('plink -i "%s" %s@%s "rm %s"', ...
                                        openKey, ...  % Replace with the path to your private key
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}]);
                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'sshpass')
                                if isempty(openKey)
                                    systCommand=sprintf('sshpass -p "%s" ssh %s@%s "rm %s"', ...
                                        s_conn.password, ...
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}]);
                                else
                                    systCommand = sprintf('ssh -i "%s" %s@%s "rm %s"', ...
                                        openKey, ...  % Specify the path to your private key
                                        s_conn.username, ...
                                        s_conn.hostname, ...
                                        [pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum}]);

                                end
                                system(systCommand);
                            elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'java')
                                ssh2_command(s_conn,['rm ' pathSimuCluster{tabNumberVar} '/CODE/' simu{tabNumberVar}.FromCluster.Files{fileNum} ' 2>&1']);
                            end
                            if ishandle(msg)
                                waitbar(copySizeFromCluster(k)/copySizeFromCluster(end),msg,['Automatic deletion - Finishing on ' char(datetime('now')+duration(0,0,(copySizeFromCluster(end)-copySizeFromCluster(k))*toc(tStart)/copySizeFromCluster(k)))]);
                            else
                                msgbox('Automatic deletion has been stopped.','Information','help');
                                return;
                            end
                        end
                    end
                end
                % #.#. Bar
                if ishandle(msg)
                    delete(msg);
                end
            end

            % #. Log
            for tabNumberVar=1:numel(pathSimu)
                if ~exist([pathSimu{tabNumberVar} filesep 'SAVE'],'dir')
                    mkdir([pathSimu{tabNumberVar} filesep 'SAVE']);
                end
                % #.#. Deleted locally
                filesList=reshape(simu{tabNumberVar}.ToSupr.Files,1,[]);
                if ~isempty(filesList)
                    [~,~]=LogSave(filesList, {''},'Deleted files locally', [pathSimu{tabNumberVar} filesep 'SAVE' filesep 'LOG_save.log']);
                end
                % #.#. Deleted on server
                if autoDeleteFromCluster && ~isempty(copySizeFromCluster)
                    filesList=[reshape(cluster{tabNumberVar}.ToSupr.Files,1,[]) reshape(simu{tabNumberVar}.FromCluster.Files,1,[])];
                    autoChar=' with automatic deletion after transfer enabled';
                else
                    filesList=reshape(cluster{tabNumberVar}.ToSupr.Files,1,[]);
                    if autoDeleteFromCluster
                        autoChar=' with automatic deletion after transfer enabled';
                    else
                        autoChar='';
                    end
                end
                if ~isempty(filesList)
                    [~,~]=LogSave(filesList, {''},['Deleted files on the server (' s_conn.hostname ')' autoChar], [pathSimu{tabNumberVar} filesep 'SAVE' filesep 'LOG_save.log']);
                end
                % #.#. Uploaded to server
                filesList=[reshape(cluster{tabNumberVar}.FromExe.Files,1,[]) reshape(cluster{tabNumberVar}.FromSimu.Files,1,[])];
                if ~isempty(filesList)
                    [~,~]=LogSave(filesList, {''},['Uploaded files to the server (' s_conn.hostname ')'], [pathSimu{tabNumberVar} filesep 'SAVE' filesep 'LOG_save.log']);
                end
                % #.#. Downloaded from server
                filesList=reshape(simu{tabNumberVar}.FromCluster.Files,1,[]);
                if ~isempty(filesList)
                    [~,~]=LogSave(filesList, {''},['Downloaded files from the server (' s_conn.hostname ')'], [pathSimu{tabNumberVar} filesep 'SAVE' filesep 'LOG_save.log']);
                end
            end

            % #. Display
            if displayComplete
                msgbox('File transfer is complete.','Information','help');
            end

        end

        function clusterInfoInitialization(app)
            % Abstract: initialize cluster variables

            load([app.pwdSave filesep 'Save' filesep 'cluster_save.mat'],'info');
            servers=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items(2:end);
            serversFormat=string(lower(replace(servers,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_')));
            indToRemove=cellfun(@isempty,serversFormat) | ~cellfun(@isvarname,serversFormat);
            servers(indToRemove)=[];
            serversFormat(indToRemove)=[];
            for serverFormatVar=serversFormat
                app.codeString.(serverFormatVar)=[];
                app.reader.(serverFormatVar)=[];
                for opt=["user" "mail" "directory" "account" "sshKey"]
                    if ~isfield(info.(opt),serverFormatVar)
                        info.(opt).(serverFormatVar)='';
                    elseif isnumeric(info.(opt).(serverFormatVar)) % [] instead of ''
                        info.(opt).(serverFormatVar)='';
                    end
                    fns=fieldnames(info.(opt));
                    info.(opt)=rmfield(info.(opt),fns(~ismember(fns,serversFormat))); % Removal of old servers
                end
            end
            info.servers=servers;
            save([app.pwdSave filesep 'Save' filesep 'cluster_save.mat'],'info');

        end

        function [info,squeueValue,squeueValueUser]=clusterJob(~,s_conn,key)
            % Abstract: job details
            
            % #. squeue
            squeueValueTemp=ssh2_command_response(ssh2_command(s_conn,'squeue 2>&1'));
            squeueValueTemp=cellfun(@(x) strsplit(x,' '),squeueValueTemp,'UniformOutput',false);
            squeueValue=cell(size(squeueValueTemp,1),size(squeueValueTemp{1},2));
            for lineNum=1:size(squeueValue,1)
                squeueValue(lineNum,:)=squeueValueTemp{lineNum}(1:size(squeueValue,2));
            end
            
            % #. Index
            indJob=ismember(squeueValue(1,:),'JOBID');
            indPartition=ismember(squeueValue(1,:),'PARTITION');
            indName=ismember(squeueValue(1,:),'NAME');
            indUser=ismember(squeueValue(1,:),'USER');
            indStatut=ismember(squeueValue(1,:),'ST');
            indTime=ismember(squeueValue(1,:),'TIME');
            indNodes=ismember(squeueValue(1,:),'NODES');
            indNodeList=ismember(squeueValue(1,:),'NODELIST(REASON)');
            
            % #. Processing
            % #.#. 8 characters maximum
            username=s_conn.username;
            if numel(username)>8
                username(9:end)=[];
            end
            % #.#. Index
            if isempty(key)
                squeueValueUser=[];
            else
                squeueValueUser=squeueValue;
                squeueValueUser(~ismember(squeueValueUser(:,indUser),username),:)=[]; % remove jobs from other users
                squeueValueUser(~ismember(squeueValueUser(:,indName),key),:)=[]; % remove other jobs
            end
            
            % #. Outputs
            if size(squeueValueUser,1)==1
                info.JOBID=squeueValueUser{1,indJob};
                info.PARTITION=squeueValueUser{1,indPartition};
                info.NAME=squeueValueUser{1,indName};
                info.USER=squeueValueUser{1,indUser};
                info.ST=squeueValueUser{1,indStatut};
                info.TIME=squeueValueUser{1,indTime};
                info.NODES=squeueValueUser{1,indNodes};
                info.NODELIST=squeueValueUser{1,indNodeList};
            elseif size(squeueValueUser,1)>1
                msgbox('Warning: if several simulations are run with the same identification key, this could lead to a problem later on.','Information','help');
                info=[];
            else
                info=[];
            end
        end

        function [s_conn,user,password,account,mail,servers,serversFormat]=clusterLoadInfo(app,tabNumber,servers)
            % Abstract: loads various information about the cluster
            
            % #. Initialization
            s_conn=[]; user=[]; password=[]; account=[]; mail=[];
            load([app.pwdSave filesep 'Save' filesep 'cluster_save.mat'],'info');
            
            % #. Server
            % #.#. Default
            if ~isempty(tabNumber) && isempty(servers)
                servers={app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value};
            end
            % #.#. DLG
            if isempty(servers)
                serversList=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items(2:end);
                ind=listdlg('PromptString',{'Select the server:'},'listString',serversList,'SelectionMode','single');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(ind)
                    return;
                else
                    servers=serversList(ind);
                end
            end
            % #.#. Format
            serversFormat=lower(replace(servers,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            
            % #. Identifiers
            for serverNum=1:numel(servers)
                serverVar=servers{serverNum};
                serverFormatVar=serversFormat{serverNum};
                if isempty(app.codeString.(serverFormatVar)) % identifiers must be loaded
                    % #.# Window 1
                    % #.#.#. DLG
                    answer=inputdlg({'User:','Mail:','Computation directory (be sure to be allowed to compute in this directory)','Account (if applicable):','OpenSSH key path (if applicable):'},['Login (' serverVar ')'],[1 1 1 1 1],{info.user.(serverFormatVar),info.mail.(serverFormatVar),info.directory.(serverFormatVar),info.account.(serverFormatVar),info.sshKey.(serverFormatVar)});
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(answer); return; end
                    % #.#.#. Directory format
                    answer{3}=replace(erase(answer{3},' '),filesep,'/');
                    if ~isempty(answer{3})
                        if answer{3}(1)~='/'
                            answer{3}=['/' answer{3}];
                        end
                        if answer{3}(end)=='/'
                            answer{3}(end)=[];
                        end
                    end
                    % #.#.#. Save
                    info.user.(serverFormatVar)=answer{1}; info.mail.(serverFormatVar)=answer{2}; info.directory.(serverFormatVar)=answer{3}; info.account.(serverFormatVar)=answer{4}; info.sshKey.(serverFormatVar)=answer{5}; % Note that mail is useful for other operations
                    save([app.pwdSave filesep 'Save' filesep 'cluster_save.mat'],'info');
                    % #.#. Window 2
                    password=passwordEntryDialog([],['Password (' serverVar ')']);
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(password); return; end
                    [app.codeString.(serverFormatVar),app.reader.(serverFormatVar)]=BasicCryptography(password); % Encryption
                else % identifiers already loaded
                    password=BasicCryptography(app.codeString.(serverFormatVar),'Key',app.reader.(serverFormatVar)); % Decryption
                end
                user=info.user.(serverFormatVar); mail=info.mail.(serverFormatVar); account=info.account.(serverFormatVar);
                
                % #. Initialization
                if isempty(info.sshKey.(serverFormatVar))
                    s_conn=ssh2_config(serverVar,info.user.(serverFormatVar),password);
                else
                    s_conn=ssh2_config_publickey(serverVar,info.user.(serverFormatVar),info.sshKey.(serverFormatVar),password); % key authentication
                end
                
                % #. Checks the information
                try
                    existVerif=str2double(ssh2_command_response(ssh2_command(s_conn,['test -d "' info.directory.(serverFormatVar) '" && echo 1 || echo 0 2>&1']))); % If the password, username, etc. are incorrect, it will give an error and the connection will be aborted
                    if ~existVerif % The remote directory does not exist
                        msgbox('Computation directory does not exist.','Information','help'); plus; % Create an error to abort the connection
                    else
                        s_conn.remote_target_direcory=info.directory.(serverFormatVar); % It is not possible to permanently change the remote directory (with cd..), so it is stored in the variable
                    end
                catch
                    msgbox('Incorrect login/password or not connected to the internal network (establish a secured VPN link if you are on a laptop or outside of the establishment).','Information','help');
                    app.codeString.(serverFormatVar)=[]; % Allows to change the identifiers
                    clearvars('s_conn'); s_conn=[]; return;
                end

                % #. Method
                % putty, sshpass or java
                % #.#. Initialization
                if ~isfield(app.clusterRemoteMethod,serverFormatVar)
                    app.clusterRemoteMethod.(serverFormatVar)='';
                end
                if isempty(app.clusterRemoteMethod.(serverFormatVar))
                    % #.#. Java by default
                    app.clusterRemoteMethod.(serverFormatVar)='java';
                    % #.#. Putty
                    status=system('where putty');
                    if status==0
                        app.clusterRemoteMethod.(serverFormatVar)='putty';
                    else
                        % #.#. Sshpass
                        % 2 possible installation directories
                        currentPath = getenv('PATH');
                        % homebrew
                        % Apple Silicon M Macs
                        newPath = ['/opt/homebrew/bin:' currentPath];
                        setenv('PATH', newPath);
                        status=system('where sshpass');
                        if status==0
                            app.clusterRemoteMethod.(serverFormatVar)='sshpass';
                        else
                            % local
                            % Intel-based Macs
                            newPath = ['/usr/local/bin:' currentPath];
                            setenv('PATH', newPath);
                            status=system('where sshpass');
                            if status==0
                                app.clusterRemoteMethod.(serverFormatVar)='sshpass';
                            end
                        end
                    end
                    msgbox(['Transfer method: ' app.clusterRemoteMethod.(serverFormatVar)],'Information','help');
                end

            end
        end

        function [cluster,simu,exe]=clusterListLoad(app,s_conn,tabNumber,pathSimuCluster)
            % Abstract: List of files contained on the cluster, the
            % simulation folder and the executables (for the cluster)
            
            % #. Initialization
            % #.#. Path
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            selectedPackage=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.packageModeListNumber).Value;
            pathPackages=[app.pwdSave filesep 'Packages' filesep selectedPackage];
            % #.#. Cluster
            cluster.files={}; cluster.size=[]; cluster.date=datetime([],[],[]);
            cluster.ToSupr.Files={}; cluster.ToSupr.Size=[];
            cluster.FromSimu.Files={}; cluster.FromSimu.Size=[];
            cluster.FromExe.Files={}; cluster.FromExe.Size=[];
            % #.#. Simu
            simu.ToSupr.Files={}; simu.ToSupr.Size=[];
            simu.FromCluster.Files={}; simu.FromCluster.Size=[];
            
            % #. Cluster
            % openKey=s_conn.pem_file;
            if ~isempty(pathSimuCluster)
                % if strcmpi(app.clusterRemoteMethod.(serverFormat),'putty')
                %     if isempty(openKey)
                %         % systCommand = sprintf('"%s" -ssh %s@%s "ls --full-time %s/CODE 2>&1"', ...
                %         %     plinkPath, ...         % Path to Plink executable
                %         %     s_conn.username, ...    % Username for SSH
                %         %     s_conn.hostname, ...    % Hostname or IP address of the remote server
                %         %     pathSimuCluster);       % Remote path
                %     else
                %         % systCommand = sprintf('plink -i "%s" %s@%s "rm %s"', ...
                %         %     openKey, ...  % Replace with the path to your private key
                %         %     s_conn.username, ...
                %         %     s_conn.hostname, ...
                %         %     [pathSimuCluster{tabNumberVar} '/CODE/' cluster{tabNumberVar}.ToSupr.Files{fileNum}]);
                %     end
                %     system(systCommand);
                % elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'sshpass')
                %     if isempty(openKey)
                %         systCommand = sprintf('sshpass -p "%s" ssh %s@%s "ls --full-time %s 2>&1"', ...
                %             s_conn.password, ...
                %             s_conn.username, ...
                %             s_conn.hostname, ...
                %             [pathSimuCluster '/CODE']);
                %     else
                %         % systCommand = sprintf('ssh -i "%s" %s@%s "rm %s"', ...
                %         %     openKey, ...  % Specify the path to your private key
                %         %     s_conn.username, ...
                %         %     s_conn.hostname, ...
                %         %     [pathSimuCluster{tabNumberVar} '/CODE/' cluster{tabNumberVar}.ToSupr.Files{fileNum}]);
                % 
                %     end
                %     [~,data]=system(systCommand);
                % elseif strcmpi(app.clusterRemoteMethod.(serverFormat),'java')
                % 
                % end
                data=ssh2_command_response(ssh2_command(s_conn,['ls --full-time ' pathSimuCluster '/CODE 2>&1']));
                data=cellfun(@(x)strsplit(x,' '),data,'UniformOutput',false);
                for fileNum=1:size(data,1)
                    if size(data{fileNum,1},2)==9
                        cluster.files{end+1}=data{fileNum,1}{1,end};
                        cluster.size(end+1)=str2double(data{fileNum,1}{1,5});
                        cluster.date(end+1)=datetime([data{fileNum,1}{1,6} ' ' data{fileNum,1}{1,7}]); % datestr gives a different format than datenum
                    end
                end
            end
            
            % #. Simu
            dirSimu=dir([pathSimu filesep 'CODE']);
            simu.files={dirSimu.name};
            simu.size=[dirSimu.bytes];
            simu.date=cellfun(@(x) datetime(x,'ConvertFrom','datenum'),{dirSimu.datenum}); % datestr gives a different format than datenum
            ind=ismember(simu.files,{'.' '..' '.DS_Store' 'Thumbs.db'});
            simu.files(ind)=[];
            simu.size(ind)=[];
            simu.date(ind)=[];
            
            % #. Exe
            dirExe=dir([pathPackages filesep 'Cluster']);
            exe.files={dirExe.name};
            exe.size=[dirExe.bytes];
            exe.date=cellfun(@(x) datetime(x,'ConvertFrom','datenum'),{dirExe.datenum}); % datestr gives a different format than datenum
            ind=ismember(exe.files,{'.' '..' '.DS_Store' 'Thumbs.db'});
            exe.files(ind)=[];
            exe.size(ind)=[];
            exe.date(ind)=[];

        end

        function clusterManagerApplyChange(app,s_conn,tabGroupCluster,directory,nameFolder,key,pathSimuCluster,simu,cluster)
            % Abstract: function that processes data contained in lists
            % so that it can be transferred
            
            % #. Index
            for ind=1:numel(tabGroupCluster.Children(1).Children(1).Children)
                if strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'simuList')
                    indSimuList=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'clusterList')
                    indClusterList=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'binList')
                    indBinList=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'autoDeleteButton')
                    indAutoDelete=ind;
                end
            end
            
            % #. Processing
            tabNumberList=find(~cellfun(@isempty,simu)); kTab=0;
            for tabNumberVar=tabNumberList
                kTab=kTab+1;
                % #.#. Simu to copy
                ind=ismember(cluster{tabNumberVar}.files,erase(tabGroupCluster.Children(kTab).Children(1).Children(indSimuList).Items(~contains(tabGroupCluster.Children(kTab).Children(1).Children(indSimuList).Items,'[LOCAL] ')),'[SERVER] '));
                simu{tabNumberVar}.FromCluster.Files=cluster{tabNumberVar}.files(ind);
                simu{tabNumberVar}.FromCluster.Size=cluster{tabNumberVar}.size(ind);
                % #.#. Simu to supr
                ind=ismember(simu{tabNumberVar}.files,erase(tabGroupCluster.Children(kTab).Children(1).Children(indBinList).Items(~contains(tabGroupCluster.Children(kTab).Children(1).Children(indBinList).Items,'[SERVER] ')),'[LOCAL] '));
                simu{tabNumberVar}.ToSupr.Files=simu{tabNumberVar}.files(ind);
                simu{tabNumberVar}.ToSupr.Size=simu{tabNumberVar}.size(ind);
                % #.#. Cluster to copy
                ind=ismember(simu{tabNumberVar}.files,erase(tabGroupCluster.Children(kTab).Children(1).Children(indClusterList).Items(~contains(tabGroupCluster.Children(kTab).Children(1).Children(indClusterList).Items,'[SERVER] ')),'[LOCAL] '));
                cluster{tabNumberVar}.FromSimu.Files=simu{tabNumberVar}.files(ind);
                cluster{tabNumberVar}.FromSimu.Size=simu{tabNumberVar}.size(ind);
                % #.#. Cluster to supr
                ind=ismember(cluster{tabNumberVar}.files,erase(tabGroupCluster.Children(kTab).Children(1).Children(indBinList).Items(~contains(tabGroupCluster.Children(kTab).Children(1).Children(indBinList).Items,'[LOCAL] ')),'[SERVER] '));
                cluster{tabNumberVar}.ToSupr.Files=cluster{tabNumberVar}.files(ind);
                cluster{tabNumberVar}.ToSupr.Size=cluster{tabNumberVar}.size(ind);
            end
            if tabGroupCluster.Children(1).Children(1).Children(indAutoDelete).Value==1
                autoDeleteFromCluster=1;
            else
                autoDeleteFromCluster=0;
            end

            % #. Copy
            close(tabGroupCluster.Parent);
            pathSimu=cell(1,numel(app.TabGroup.Children));
            for tabNumberVar=3:numel(app.TabGroup.Children)
                pathSimu{tabNumberVar}=app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            end
            clusterFilesTransfer(app,s_conn,pathSimu,directory,nameFolder,key,pathSimuCluster,simu,cluster,1,autoDeleteFromCluster,1);
            
            % #. Refresh
            for tabNumberVar=tabNumberList
                refreshSteps(app,tabNumberVar,0);
            end

        end

        function clusterManagerCheckBox(~,tabGroupCluster)
            % Abstract: applies the same checkbox value to all tabs
            
            % #. Index
            for ind=1:numel(tabGroupCluster.Children(1).Children(1).Children)
                if strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'autoDeleteButton')
                    indAutoDelete=ind;
                    break;
                end
            end

            % #. Processing
            valueSave=tabGroupCluster.SelectedTab.Children(1).Children(indAutoDelete).Value;
            for tabNumberVar=1:numel(tabGroupCluster.Children)
               tabGroupCluster.Children(tabNumberVar).Children(1).Children(indAutoDelete).Value=valueSave;
               if valueSave==1
                   tabGroupCluster.Children(tabNumberVar).Children(1).Children(indAutoDelete).FontColor='r';
               else
                   tabGroupCluster.Children(tabNumberVar).Children(1).Children(indAutoDelete).FontColor='k';
               end
            end

        end

        function clusterManagerMoveList(~,tabCurrent,btn,simu,cluster)
            % Abstract: moves items from one list to another in the file
            % manager
            
            % #. Key
            key=get(tabCurrent.Parent.Parent.Parent,'currentcharacter'); % if a certain key is selected, modifies the action (e.g. selects the entire list)

            % #. Index
            for ind=1:numel(tabCurrent.Children)
                if strcmp(tabCurrent.Children(ind).Tag,'simuList')
                    indSimuList=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'clusterList')
                    indClusterList=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'binList')
                    indBinList=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'simuButton')
                    indSimuButton=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'clusterButton')
                    indClusterButton=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'binButton')
                    indBinButton=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'saveButton')
                    indSaveButton=ind;
                end
            end
            
            % #. Value assignment
            if strcmp(btn.Tag,'simuButton') || strcmp(btn.Tag,'clusterButton') || strcmp(btn.Tag,'binButton')
                if strcmp(btn.Tag,'simuButton')
                    buttonNum=1;
                elseif strcmp(btn.Tag,'clusterButton')
                    buttonNum=2;
                elseif strcmp(btn.Tag,'binButton')
                    buttonNum=3;
                end
                listInd=[indSimuList indClusterList indBinList];
                for listNum=setdiff(1:3,buttonNum)
                    if ~isempty(tabCurrent.Children(listInd(listNum)).Value)
                        tabCurrent.Children(listInd(buttonNum)).Items=sort([tabCurrent.Children(listInd(buttonNum)).Items tabCurrent.Children(listInd(listNum)).Value]);
                        if buttonNum==2
                            indSimu=contains(tabCurrent.Children(listInd(buttonNum)).Items,'[LOCAL]');
                            tabCurrent.Children(listInd(buttonNum)).Items=[sort(tabCurrent.Children(listInd(buttonNum)).Items(indSimu)) sort(tabCurrent.Children(listInd(buttonNum)).Items(~indSimu))];
                        end
                        ind=find(ismember(tabCurrent.Children(listInd(buttonNum)).Items,tabCurrent.Children(listInd(listNum)).Value));
                        tabCurrent.Children(listInd(buttonNum)).Value=tabCurrent.Children(listInd(buttonNum)).Items(ind);
                        tabCurrent.Children(listInd(listNum)).Items(ismember(tabCurrent.Children(listInd(listNum)).Items,tabCurrent.Children(listInd(listNum)).Value))=[];
                        tabCurrent.Children(listInd(listNum)).Value={};
                        break;
                    end
                end
            else
                % #.#. Informations
                if contains(btn.Tag,'simu')
                    prefix={'[SERVER]'};
                    listImport=indSimuList;
                    listExport=indClusterList;
                    listClear=indBinList;
                elseif contains(btn.Tag,'cluster')
                    prefix={'[LOCAL]'};
                    listImport=indClusterList;
                    listExport=indSimuList;
                    listClear=indBinList;
                elseif contains(btn.Tag,'bin')
                    prefix={'[SERVER]' '[LOCAL]'};
                    listImport=indBinList;
                    listExport=[indClusterList indSimuList] ;
                    listClear=indBinList;
                end
                [~,type,ext]=fileparts(btn.Value);
                % #.#. Processing
                for exportNum=1:numel(listExport)
                    ind=contains(tabCurrent.Children(listExport(exportNum)).Items,prefix{exportNum}) & contains(tabCurrent.Children(listExport(exportNum)).Items,type)  & contains(tabCurrent.Children(listExport(exportNum)).Items,ext);
                    if strcmpi(key,'q')
                        ind=find(ind); ind(1)=[];
                    elseif strcmpi(key,'a')
                        ind=find(ind,1,'first');
                    elseif strcmpi(key,'z')
                        ind=find(ind,1,'last');
                    elseif strcmpi(key,'s')
                        ind=find(ind); ind(end)=[];
                    end
                    oldItems=contains(tabCurrent.Children(listImport).Items,prefix);
                    tabCurrent.Children(listImport).Items=[sort([tabCurrent.Children(listExport(exportNum)).Items(ind) tabCurrent.Children(listImport).Items(oldItems)]) sort(tabCurrent.Children(listImport).Items(~oldItems))];
                    tabCurrent.Children(listImport).Value=tabCurrent.Children(listImport).Items(ismember(tabCurrent.Children(listImport).Items,tabCurrent.Children(listExport(exportNum)).Items(ind)));
                    tabCurrent.Children(listExport(exportNum)).Items(ind)=[];
                    tabCurrent.Children(listExport(exportNum)).Value={};
                    tabCurrent.Children(listClear).Value={};
                end
                % #.#. Reset list
                btn.Value={};
            end
            
            % #. Size
            % Look at the sizes of the elements to be processed
            % #.#. Calculation
            % #.#.#. Simu
            ind=ismember(cluster.files,erase(tabCurrent.Children(indSimuList).Items(~contains(tabCurrent.Children(indSimuList).Items,'[LOCAL] ')),'[SERVER] '));
            if all(~ind)
                sizeSaveCopy=0;
            else
                sizeSaveCopy=max(cumsum(cluster.size(ind)))/1e6;
            end
            % #.#.#. Cluster
            ind=ismember(simu.files,erase(tabCurrent.Children(indClusterList).Items(~contains(tabCurrent.Children(indClusterList).Items,'[SERVER] ')),'[LOCAL] '));
            if all(~ind)
                sizeClusterCopy=0;
            else
                sizeClusterCopy=max(cumsum(simu.size(ind)))/1e6;
            end
            % #.#.#. Bin
            % SIMU to delete
            ind=ismember(simu.files,erase(tabCurrent.Children(indBinList).Items(~contains(tabCurrent.Children(indBinList).Items,'[SERVER] ')),'[LOCAL] '));
            if all(~ind)
                sizeSimuSupr=0;
            else
                sizeSimuSupr=max(cumsum(simu.size(ind)))/1e6;
            end
            % CLUSTER to delete
            ind=ismember(cluster.files,erase(tabCurrent.Children(indBinList).Items(~contains(tabCurrent.Children(indBinList).Items,'[LOCAL] ')),'[SERVER] '));
            if all(~ind)
                sizeClusterSupr=0;
            else
                sizeClusterSupr=max(cumsum(cluster.size(ind)))/1e6;
            end
            % #.#. Processing
            tabCurrent.Children(indSimuButton).Text=['SAVE: copy inside [+' num2str(sizeSaveCopy,'%10.1f') 'Mo/-' num2str(sizeSimuSupr,'%10.1f') 'Mo]'];
            tabCurrent.Children(indClusterButton).Text=['CLUSTER: copy inside [+' num2str(sizeClusterCopy,'%10.1f') 'Mo/-' num2str(sizeClusterSupr,'%10.1f') 'Mo]'];
            tabCurrent.Children(indBinButton).Text=['RECYCLE BIN: move inside [-' num2str(sizeSimuSupr+sizeClusterSupr,'%10.1f') 'Mo]'];
            plusValue=0; minusValue=0;
            for tabNumberVar=1:numel(tabCurrent.Parent.Parent.Children)
                ind=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indSimuButton).Text,'+');
                shift=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indSimuButton).Text(ind:end),'M');
                plusValue=plusValue+str2double(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indSimuButton).Text(ind+1:ind+shift(1)-2));
                ind=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indSimuButton).Text,'-');
                shift=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indSimuButton).Text(ind:end),'M');
                minusValue=minusValue+str2double(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indSimuButton).Text(ind+1:ind+shift(1)-2));
                ind=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indClusterButton).Text,'+');
                shift=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indClusterButton).Text(ind:end),'M');
                plusValue=plusValue+str2double(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indClusterButton).Text(ind+1:ind+shift(1)-2));
                ind=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indClusterButton).Text,'-');
                shift=strfind(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indClusterButton).Text(ind:end),'M');
                minusValue=minusValue+str2double(tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indClusterButton).Text(ind+1:ind+shift(1)-2));
            end
            for tabNumberVar=1:numel(tabCurrent.Parent.Parent.Children)
                tabCurrent.Parent.Parent.Children(tabNumberVar).Children(1).Children(indSaveButton).Text=['Apply change [+' num2str(plusValue,'%10.1f') 'Mo/-' num2str(minusValue,'%10.1f') 'Mo]'];
            end
        end

        function clusterManagerResetGUI(~,tabGroupCluster)
            % Abstract: reinitializes the file manager GUI
            
            % #. Index
            for ind=1:numel(tabGroupCluster.Children(1).Children(1).Children)
                if strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'simuList')
                    indSimuList=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'clusterList')
                    indClusterList=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'binList')
                    indBinList=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'simuButton')
                    indSimuButton=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'clusterButton')
                    indClusterButton=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'binButton')
                    indBinButton=ind;
                elseif strcmp(tabGroupCluster.Children(1).Children(1).Children(ind).Tag,'saveButton')
                    indSaveButton=ind;
                end
            end
            
            % #. Browse tabs
            listInd=[indSimuList indClusterList indBinList];
            for tabNumberVar=1:numel(tabGroupCluster.Children)
                simuList=cell(1,3); clusterList=cell(1,3);
                for listNum=1:3
                    simuList{listNum}=tabGroupCluster.Children(tabNumberVar).Children(1).Children(listInd(listNum)).Items(contains(tabGroupCluster.Children(tabNumberVar).Children(1).Children(listInd(listNum)).Items,'[LOCAL]'));
                    clusterList{listNum}=tabGroupCluster.Children(tabNumberVar).Children(1).Children(listInd(listNum)).Items(contains(tabGroupCluster.Children(tabNumberVar).Children(1).Children(listInd(listNum)).Items,'[SERVER]'));
                end
                simuList=[simuList{:}];
                clusterList=[clusterList{:}];
                % #.#. Assignment
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indSimuList).Items=sort(simuList);
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indSimuList).Value={};
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indClusterList).Items=sort(clusterList);
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indClusterList).Value={};
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indBinList).Items={};
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indBinList).Value={};
                % #.#. Size
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indSimuButton).Text='SAVE: copy inside [+0.0Mo/-0.0Mo]';
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indClusterButton).Text='CLUSTER: copy inside [+0.0Mo/-0.0Mo]';
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indBinButton).Text='RECYCLE BIN: move inside [-0.0Mo]';
                tabGroupCluster.Children(tabNumberVar).Children(1).Children(indSaveButton).Text='Apply change [+0.0Mo/-0.0Mo]';
            end

        end

        function clusterManagerResetList(~,tabCurrent,list)
            % Abstract: resets other lists in the file manager when another
            % is selected
            
            % #. Index
            for ind=1:numel(tabCurrent.Children)
                if strcmp(tabCurrent.Children(ind).Tag,'simuList')
                    indSimuList=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'clusterList')
                    indClusterList=ind;
                elseif strcmp(tabCurrent.Children(ind).Tag,'binList')
                    indBinList=ind;
                end
            end
            
            % #. Value assignment
            if strcmp(list.Tag,'simuList')
                tabCurrent.Children(indClusterList).Value={};
                tabCurrent.Children(indBinList).Value={};
            elseif strcmp(list.Tag,'clusterList')
                tabCurrent.Children(indSimuList).Value={};
                tabCurrent.Children(indBinList).Value={};
            elseif strcmp(list.Tag,'binList')
                tabCurrent.Children(indSimuList).Value={};
                tabCurrent.Children(indClusterList).Value={};
            end

        end

        function [directory,nameFolder,key,pathSimuCluster]=clusterPath(app,tabNumber,s_conn)
            % Abstract: useful information concerning the cluster and
            % renames folders that don't have the “name_key” format
            
            % #. Directory
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            if isempty(pathSimu) || exist(pathSimu,'dir')==0
                directory=[]; nameFolder=[]; key=[]; pathSimuCluster=[];
                return;
            end
            idNameSimuTemp=strfind(pathSimu,filesep);
            directory=[s_conn.remote_target_direcory '/' pathSimu(idNameSimuTemp(end-1)+1:idNameSimuTemp(end)-1) '/'];
            
            % #. Folder
            nameFolder=pathSimu(idNameSimuTemp(end)+1:end);
            
            % #. Key
            % 8 random letters
            listChar=[65:90 97:122];
            a=1; b=numel(listChar);
            r=round((b-a).*rand(1,8)+a);
            idx=listChar(r);
            key=char(idx);
            
            % #. Find the directory on the cluster
            pathSimuCluster=[];
            dirVerif=ssh2_command_response(ssh2_command(s_conn,['ls -r ' directory ' 2>&1'])); % Folders in the directory
            if ~any(contains(dirVerif,'Aucun'))
                % #.#. If the directory exists
                for dirNum=1:size(dirVerif,1)
                    ind=find(dirVerif{dirNum}=='_',1,'last'); % To remove the key from folder names
                    if ~isempty(ind) && ind>1 && ind+8==size(dirVerif{dirNum},2)
                        % #.#.#. Folder with key
                        if strcmp(dirVerif{dirNum}(1:ind-1),nameFolder)
                            key=dirVerif{dirNum}(ind+1:end);
                            pathSimuCluster=[directory nameFolder '_' key];
                            return;
                        end
                    end
                end
                if sum(strcmp(dirVerif,nameFolder))==1
                    % #.#. Good folder in good directory but without key
                    % -> We renamed it
                    ssh2_command(s_conn,['mv ' directory nameFolder ' ' directory nameFolder '_' key ' 2>&1']);
                    pathSimuCluster=[directory nameFolder '_' key];
                end
            end

        end

        function clusterRefresh(app,tabNumber,s_conn)
            % Abstract: Updates and displays job status on the cluster
            
            % #. Find the folder
            [~,~,key,pathSimuCluster]=clusterPath(app,tabNumber,s_conn);
            %msgCell={};
            
            if ~isempty(pathSimuCluster)

                % #. Existing directory
                
                % #.#. Format
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorProgress;
                
                % #.#. Finds execution-related information
                server=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value;
                [info]=clusterJob(app,s_conn,key);
                
                % #.#. MSG
                if ~isempty(info)

                    % #.#.#. Button deactivation
                    buttonsRunActivation(app,tabNumber,'Off');
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='On';

                else

                    % #.#.#. No computation in progress
                    if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'On')
                        pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                        if ~exist([pathSimu filesep 'SAVE'],'dir')
                            mkdir([pathSimu filesep 'SAVE']);
                        end
                        [~,~]=LogSave({'Duration: finished'},{''},['Job has finished on ' char(server)], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']); % Job was already finished
                    end
                    buttonsRunActivation(app, tabNumber,'On');
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorProgress;
                    %msgCell=[msgCell ; {'Simulation: none is running'}];

                end
            else

                % #. No directory
                %msgCell=[msgCell ; {'Cannot find the simulation path inside the cluster'}];
                buttonsRunActivation(app,tabNumber,'On');
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorUnchanged;

            end
            
        end

        function [timeValue,partition,nodes,cpus]=clusterResources(app)
            % Abstract: gives the duration and type of partition to be written in the launcher
            
            % #. Initialization
            timeValue=[]; partition=[]; nodes=[]; cpus=[];
            
            % #. User selection
            initialDuration='02:00:00:00';
            answer=inputdlg({'Duration [days:hours:minutes:seconds]:','Number of nodes:','Number of CPUs:'},'Resources',1,{initialDuration,'1','16'});
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(answer); return; end
            timeValue=answer{1};
            
            % #. Compatible format
            % For example, avoid entering a number of hours greater
            % than 24, etc. (MATLAB cannot handle this).
            try
                duration(timeValue);
            catch
                msgbox('Duration format is incorrect (make sure that hours number is lower or equal to 24, 60 for minutes, etc.)','Information','help');
                timeValue=[]; partition=[];
                return;
            end
            
            % #. Partition type
            if duration(timeValue)<duration('00:00:00:00')
                msgbox('Duration cannot be negative.','Information','help');
                timeValue=[]; partition=[];
                return;
            elseif duration(timeValue)<=duration('01:00:00:00')
                partition='tiny';
            elseif duration(timeValue)<=duration('02:00:00:00')
                partition='small';
            elseif duration(timeValue)<=duration('04:00:00:00')
                partition='medium';
            elseif duration(timeValue)<=duration('08:00:00:00')
                partition='long';
            else
                msgbox('Duration cannot be greater than 8 days.','Information','help');
                timeValue=[]; partition=[];
                return;
            end
            
            % #. Format
            ind=strfind(timeValue,':');
            if numel(ind)~=3
                % The user must enter the number of days to
                % facilitate subsequent formatting.
                msgbox('Duration format is incorrect (make sure that days are indicated).','Information','help');
                timeValue=[]; partition=[];
                return;
            end
            timeValue(ind(1))='-'; % DD-HH:MM:SS (=SLURM) et non DD:HH:MM:SS (=MATLAB)
            timeValue(1)=[];
            nodes=answer{2};
            cpus=answer{3};

        end

        function copyFilesFunction(app,tabNumber)
            % Abstract: checks whether all the necessary simulation files
            % are present in the directory, and proposes to import them
            % if this is not the case.
            
            % #. Checks if simu missing
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathCode=[pathSimu filesep 'CODE'];
            if isempty(pathSimu) || exist(pathSimu,'dir')==0
                return;
            end
            filesListSimu=dir(pathCode);
            filesListSimu={filesListSimu.name};
            filesListSimu(ismember(filesListSimu,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
            filesMissing=isempty(filesListSimu);

            % #. Checks if package missing
            packageMissing=false;
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                % #.#. Path
                selectedPackage=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.packageModeListNumber).Value;
                pathPackage=[app.pwdSave filesep 'Packages' filesep selectedPackage];
                % #.#. Files
                packageList=dir([pathPackage filesep 'Local']);
                packageList={packageList.name};
                packageList(ismember(packageList,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
                packageListPwd=strcat(pathPackage,filesep,'Local',filesep,packageList);
                packageListSimu=strcat(pathCode,filesep,packageList);
                packageMissing=~all(isfile(packageListSimu));
            end

            % #. DLG to import files
            % #.#. MSG
            if filesMissing && packageMissing
                msg={'No files in the simulation folder, do you want to import them?','Yes (files)','Yes (files + package)','No','No'};
            elseif filesMissing
                msg={'No files in the simulation folder, do you want to import them?','Yes (files)','No','No'};
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            elseif packageMissing
                msg={'No package in the simulation folder, do you want to import it?','Yes (package)','No','No'};
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            else
                return;
            end
            % #.#. Selection
            if numel(msg)==4
                answer=questdlg(msg{1},'Warning',msg{2},msg{3},msg{4});
            else
                answer=questdlg(msg{1},'Warning',msg{2},msg{3},msg{4},msg{5});
            end
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(answer) || contains(answer,'No')
                return;
            end

            % #. SAVE copy
            if contains(answer,'files')
                % #.#. Save folder
                pathID=strfind(pathSimu,filesep); % Start in the folder containing the simulation
                pathSave=uigetdir(pathSimu(1:pathID(end)),['Select the save folder (for ' pathSimu(pathID(end)+1:end) ')']);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(pathSave) || isequal(pathSave,0)
                    return;
                elseif strcmp(pathSimu,pathSave)
                    msgbox('Cannot copy a directory onto itself.','Information','help');
                    return;
                end
                % #.#. Files list
                filesList=dir([pathSave filesep 'CODE']);
                filesListSave={filesList.name};
                filesSizeSave=[filesList.bytes];
                [~,indSort]=sort(lower(filesListSave));
                filesListSave=filesListSave(indSort);
                filesSizeSave=filesSizeSave(indSort);
                indToRemove=ismember(filesListSave,{'.' '..' '.DS_Store' 'Thumbs.db'});
                filesListSave(indToRemove)=[];
                filesSizeSave(indToRemove)=[];
                % #.#. Selection
                [ind,~]=listdlg('Name','Selection','PromptString',{'Select files to import:'},'ListString',filesListSave);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if ~isempty(ind)
                    filesPathSave=strcat(pathSave,filesep,'CODE',filesep,filesListSave(ind));
                    filesSizeSave=filesSizeSave(ind);
                    cumSize=cumsum(filesSizeSave);
                    totalSize=cumSize(end);
                    % #.#. Directory creation
                    if ~exist([pathSimu filesep 'CODE'], 'dir')
                        mkdir(pathSimu, 'CODE')
                    end
                    % #.#. Copy
                    tStart=tic;
                    msg=waitbar(0,'Copy in progress 0.0%','Name','Progress');
                    for fileNum=1:numel(filesPathSave)
                        copyfile(filesPathSave{fileNum},pathCode);
                        if ishandle(msg)
                            waitbar(cumSize(fileNum)/totalSize,msg,['Copy in progress ' num2str(cumSize(fileNum)/totalSize*100,'%.1f') '% - ' char(duration(0,0,(totalSize-cumSize(fileNum))*toc(tStart)/cumSize(fileNum)))],'Name','Progress');
                        else
                            return;
                        end
                    end
                    delete(msg);
                else
                    filesPathSave={};
                end
            end

            % #. Package copy
            if contains(answer,'package')
                if ~exist([pathSimu filesep 'CODE'], 'dir')
                    mkdir(pathSimu, 'CODE')
                end
                for fileNum=1:numel(packageListPwd)
                    copyfile(packageListPwd{fileNum},pathCode);
                end
            end

            % #. Log
            if ~exist([pathSimu filesep 'SAVE'],'dir')
                mkdir([pathSimu filesep 'SAVE']);
            end
            % #.#. Directory
            if contains(answer,'files')
                [~,saveTemp]=fileparts(filesPathSave);
                filesList=reshape(saveTemp,1,[]);
            else
                filesList='';
            end
            if ~isempty(filesList)
                [~,saveName]=fileparts(pathSave);
                [~,~]=LogSave(filesList, {''},['Files imported from another directory (' saveName ')'], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
            end
            % #.#. Package
            if contains(answer,'package')
                [~,packageTemp]=fileparts(packageListPwd);
                filesList=reshape(packageTemp,1,[]);
            else
                filesList='';
            end
            if ~isempty(filesList)
                [~,~]=LogSave(filesList, {''},['Files imported from the package (' selectedPackage ')'], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
            end

        end

        function criterionComputation(app,tabNumber)
            % Abstract: stops the job if the criterion becomes true
            
            % #. Path
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;

            % #. Criterion
            eq=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value;
            
            if ~isnan(str2double(eq))
                
                % #. Step criterion
                % Number of computation steps before stopping
                
                stepCurrent=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items{1};
                computedSteps=str2double(stepCurrent)-str2double(app.stepIniRun{tabNumber});
                if computedSteps>=str2double(eq)
                    criterion=true;
                else
                    criterion=false;
                end
                
            else
                
                % #. Equation criterion
                % Stop if the equation becomes true
                
                % #.#. Information
                info.selection=selectionInfo(app,tabNumber,[],{eq ''},[]);

                % #.#. Takes the last step if %.
                info=sliderToSteps(app,info,1);
                eq=info.selection.equation.global.rough.X;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value=eq;

                % #.#. Evaluation
                info=dataLoad(app,info);
                if isfield(info.grounds.ground1,'Data')
                    criterion=info.grounds.ground1.Data;
                elseif isfield(info.grounds.ground1,'X')
                    criterion=info.grounds.ground1.X;
                else
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value=0;
                    msgbox('The criterion could not be evaluated.','Information','help');
                    return;
                end

                if numel(criterion)~=1
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value=0;
                    msgbox('The criterion evaluation must be a logical scalar value.','Information','help');
                    return;
                end

            end
            
            % #. Stop if criterion is true
            if criterion==1
                % Log
                if ~exist([pathSimu filesep 'SAVE'],'dir')
                    mkdir([pathSimu filesep 'SAVE']);
                end
                [~,~]=LogSave({'The job has been stopped because the following criterion becomes true:';app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value},{''},'Criterion becomes true', [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
                % Criterion
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value=0;
                autoRefreshValueChanged(app,[]);
                % Run
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                runFunction(app,tabNumber,0);
                % Msg
                msgbox(['The job has been stopped because the following criterion becomes true:' ...
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value],'Information','help');
            end

        end

        function [BX,BY,BID]=dataBrushedCallback(~,~,currentFig)
            % Abstract: gets the brush data from a graph
            
            % #. Initialization
            BX=[]; BY=[]; BID=[];
            
            % #. Graph to analyze
            h=[findobj(currentFig,'type','line') findobj(currentFig,'type','scatter')]; % Takes the last plot by default
            if isempty(h)
                return;
            elseif numel(h(end))==0
                return;
            end
            
            % #. Processing
            BID=find(get(h(end),'BrushData')); % Indices of the selected points
            if isempty(BID); return; end
            BX=h(end).XData(BID); % x values
            BY=h(end).YData(BID); % y values
            
        end

        function dataDisplayList(app,tabNumber,modeLoad,keepOldValue,graphNumList)
            % Abstract: updates data lists (graphics and criterion)
            % and finds the old value in the new list, whose index may
            % have changed, and assigns it to the modified list

            % #. Available data
            [~,dataListFormat,~,~,~,~,~,~,~,~,~]=dataMainListFunction(app,tabNumber,modeLoad);
            if isempty(dataListFormat)
                dataListFormat={''};
            end
            if isempty(graphNumList)
                graphNumList=1:4;
            end

            % #. X
            for graphNum=graphNumList
                graphNumFunction(app, graphNum);
                if numel(app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Items)<=numel(dataListFormat)
                    app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Items=repmat({''},1,numel(dataListFormat));
                end
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Items=dataListFormat;
                if ~keepOldValue
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Value=dataListFormat{1};
                end
            end

            % #. Y
            for graphNum=graphNumList
                graphNumFunction(app, graphNum);
                if numel(app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items)<=numel(dataListFormat)
                    app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items=repmat({''},1,numel(dataListFormat));
                end
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items=dataListFormat;
                if ~keepOldValue
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value=dataListFormat{1};
                end
            end

            % #. Criterion
            if numel(app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Items)<=numel(dataListFormat)
                app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Items=repmat({''},1,numel(dataListFormat));
            end
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Items=dataListFormat;
            if ~keepOldValue
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Value=dataListFormat{1};
            end

        end

        function dataExportFunction(app)
            % Abstract: export data

            % #. Inputs
            tabNumber=findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;

            % #. Find a graph with brushed data
            for graphNum=1:4
                graphNumFunction(app,graphNum);
                graphTemp=findobj(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar),'type','line');
                if ~isempty(graphTemp)
                    % Non-empty graph
                    if any(get(graphTemp(end),'BrushData'))
                        % Graph with brushed data
                        break;
                    end
                end
                if graphNum==4
                    % Cannot find a non-empty graph with brushed data
                    msgbox('No brush has been detected.','Information','help');
                    busyOff(app);
                    return;
                end
            end

            % #. Brush processing
            [x,y,~]=dataBrushedCallback(app,tabNumber,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));

            % #. File name
            [~,~,pathsList,~]=GetFreeNames('Folder',pathSimu,'DefaultNames','DATA','Format','mat');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background

            % #. Save
            save(pathsList{1},'x','y');

        end

        function [info,newData]=dataLoad(app,info)
            % Abstract: function for loading data (surface, image, etc.).

            % #. Other
            tabNumber=info.selection.other.tabNumber;
            newData=0; % Detects when new data is loaded and limits change

            % #. Paths
            info.data=struct();
            pathFile=strcat(info.selection.other.pathSimu,filesep,...
                'CODE',filesep,...
                info.selection.file.global.unique,...
                info.selection.arg1.global.format.unique,...
                info.selection.ext.global.unique);
            pathFile(~isfile(pathFile))={''};
            pathFileUnique=unique(pathFile);

            % #. Loading

            for pathNum=1:numel(pathFileUnique)

                % #.#. Indices
                indData=find(strcmp(pathFile,pathFileUnique{pathNum}));
                dataNumber=numel(indData);
                file=info.selection.file.global.unique{indData(1)};
                ext=info.selection.ext.global.unique{indData(1)};
                type=info.selection.type.global.unique{indData(1)};

                if strcmpi(type,'STEPS')

                    % #.#. Steps

                    % #.#.#. Paths
                    [~,pathFileMatTemp]=fileparts(pathFile{indData(1)});
                    pathFileMat=[info.selection.other.pathSimu filesep 'SAVE' filesep pathFileMatTemp,'.mat'];
                    pathFileGlobal=[info.selection.other.pathSimu filesep 'SAVE' filesep file 'Global.mat'];

                    % #.#. Conversion
                    if ~isfile(pathFileMat) % prevents systematic loading
                        if ~isempty(tabNumber) && isnumeric(tabNumber) ...
                                && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.initializationNumber).Value
                            steps=loadStepsListFunction(app,info.selection.other.pathSimu,file,'CODE');
                        else
                            steps=info.selection.arg1.global.format.unique(indData(1));
                        end
                        mainLoading(app,{info.selection.other.pathSimu},{{file}},{{steps}},ext,0);
                    end

                    for dataNum=1:dataNumber
                        % #.#.#. Data
                        dataNameVar=info.selection.data.global.withoutArg2.format.unique{indData(dataNum)};
                        dataNameFormat=info.selection.data.global.withoutArgs.format.unique{indData(dataNum)};
                        info.data.(dataNameVar)=cell2mat(struct2cell(load(pathFileMat,dataNameFormat)));
                        % #.#.#. Limits
                        if isscalar(size(info.data.(dataNameVar)))
                            info.limits.(dataNameVar)=load(pathFileGlobal,'X_MIN','X_MAX',[dataNameFormat '_min'],[dataNameFormat '_max']);
                        elseif numel(size(info.data.(dataNameVar)))==2
                            info.limits.(dataNameVar)=load(pathFileGlobal,'X_MIN','X_MAX','Y_MIN','Y_MAX',[dataNameFormat '_min'],[dataNameFormat '_max']);
                        elseif numel(size(info.data.(dataNameVar)))==3
                            info.limits.(dataNameVar)=load(pathFileGlobal,'X_MIN','X_MAX','Y_MIN','Y_MAX','Z_MIN','Z_MAX',[dataNameFormat '_min'],[dataNameFormat '_max']);
                        end
                    end

                elseif strcmpi(type,'UNIQUE')

                    % #.#. Unique

                    dataTemp=load(pathFile{indData(1)});
                    for dataNum=1:dataNumber
                        dataNameVar=info.selection.data.global.withoutArg2.format.unique{indData(dataNum)};
                        column=info.selection.column.global.unique{indData(dataNum)};
                        if column==0
                            info.data.(dataNameVar)=dataTemp(:,:);
                        else
                            info.data.(dataNameVar)=dataTemp(:,column);
                        end
                    end

                end

            end

            % #. Additional data
            % For some data, additional data must be loaded (e.g. connectivity)
            info.grounds=struct();
            groundNumber=numel(info.selection.data.grounds.rough.cat);
            for groundNum=1:groundNumber
                % #.#. Initialization
                groundName=['ground' num2str(groundNum)];
                info.grounds.(groundName)=struct();
                % #.#. Find reference data
                % Takes the first one arbitrarily
                indData=find(strcmpi(info.selection.class.grounds.cat{groundNum},'SURFACE') ...
                    | strcmpi(info.selection.class.grounds.cat{groundNum},'IMAGEXYZ'),1,'first');
                if isempty(indData); continue; end
                dataNameVar=info.selection.data.grounds.withoutArg2.format.unique{groundNum}{indData};
                % #.#. Information
                step=info.selection.arg1.grounds.format.cat{groundNum}{indData};
                file=info.selection.file.grounds.cat{groundNum}{indData};
                % #.#. Loading
                if strcmpi(info.selection.class.grounds.cat{groundNum}{indData},'SURFACE')
                    if isscalar(size(info.data.(dataNameVar)))
                        dataToLoad=["X" "CONNECTIVITY"];
                    elseif numel(size(info.data.(dataNameVar)))==2
                        dataToLoad=["X" "Y" "CONNECTIVITY"];
                    elseif numel(size(info.data.(dataNameVar)))==3
                        dataToLoad=["X" "Y" "Z" "CONNECTIVITY"];
                    end
                elseif strcmpi(info.selection.class.grounds.cat{groundNum}{indData},'IMAGEXYZ')
                    if isscalar(size(info.data.(dataNameVar)))
                        dataToLoad="X";
                    elseif numel(size(info.data.(dataNameVar)))==2
                        dataToLoad=["X" "Y"];
                    elseif numel(size(info.data.(dataNameVar)))==3
                        dataToLoad=["X" "Y" "Z"];
                    end
                end
                pathFile=[info.selection.other.pathSimu filesep 'SAVE' filesep file step '.mat'];
                for dataToLoadVar=dataToLoad
                    % #.#.#. Import
                    info.grounds.(groundName).(dataToLoadVar)=cell2mat(struct2cell(load(pathFile,dataToLoadVar)));
                    % #.#.#. XYZ correction
                    % The XYZ coordinates of images can be described by
                    % their extremal coordinates only (i.e. numel(X)=2);
                    % this is corrected to have one coordinate per pixel
                    % to avoid problems later.
                    if strcmpi(info.selection.class.grounds.cat{groundNum}{indData},'IMAGEXYZ')
                        if isequal(dataToLoadVar,"X") && numel(info.grounds.(groundName).(dataToLoadVar))~=size(info.data.(dataNameVar),2)
                            info.grounds.(groundName).(dataToLoadVar)=linspace(...
                                info.grounds.(groundName).(dataToLoadVar)(1),...
                                info.grounds.(groundName).(dataToLoadVar)(end),...
                                size(info.data.(dataNameVar),2));
                        elseif isequal(dataToLoadVar,"Y") && numel(info.grounds.(groundName).(dataToLoadVar))~=size(info.data.(dataNameVar),1)
                            info.grounds.(groundName).(dataToLoadVar)=linspace(...
                                info.grounds.(groundName).(dataToLoadVar)(1),...
                                info.grounds.(groundName).(dataToLoadVar)(end),...
                                size(info.data.(dataNameVar),1));
                        elseif isequal(dataToLoadVar,"Z") && numel(info.grounds.(groundName).(dataToLoadVar))~=size(info.data.(dataNameVar),3)
                            info.grounds.(groundName).(dataToLoadVar)=linspace(...
                                info.grounds.(groundName).(dataToLoadVar)(1),...
                                info.grounds.(groundName).(dataToLoadVar)(end),...
                                size(info.data.(dataNameVar),3));
                        end
                    end
                end
            end

            % #. CUSTOM
            if info.selection.other.customMode
                dataInd=find(contains(info.selection.data.global.rough.cat,'!'));
                for dataNum=dataInd
                    mode=info.selection.data.global.rough.cat{dataNum}(2:end-1); % remove brackets
                    dataName=lower(replace(erase(mode,'!'),...
                            {'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                    [~,info.data.(dataName)]=DataManager(app,mode,info.selection);
                end
                return;
            end

            % #. Equation
            % replace: in the .format version, the data does not contain
            % the info.data identifier in front, which is necessary for
            % evaluation purposes. This choice was made to avoid
            % hard-coding the inputs of a function 1 in a function 2
            for groundNum=1:groundNumber
                groundName=['ground' num2str(groundNum)];
                fns=fieldnames(info.grounds.(groundName));

                if any(strcmpi(fns,'CONNECTIVITY') | strcmpi(fns,'X') | strcmpi(fns,'Y') | strcmpi(fns,'Z'))

                    % #.#. SURFACE or IMAGEXYZ
                    eqTemp=replace(...
                        info.selection.equation.grounds.rough.X{groundNum},...
                        info.selection.data.grounds.rough.X{groundNum},...
                        strcat('info.data.',info.selection.data.grounds.format.X{groundNum}));
                    info.grounds.(groundName).Data=eval(eqTemp);
                    if any(strcmpi(fns,'CONNECTIVITY'))
                        info.selection.class.grounds.main{groundNum}='SURFACE';
                    else
                        info.selection.class.grounds.main{groundNum}='IMAGEXYZ';
                    end

                else

                    % #.#. IMAGE or PLOT

                    prefix=repmat({'info.data.'},1,numel(info.selection.data.grounds.withoutArg2.rough.X{groundNum}));
                    indEmpty=find(cellfun(@isempty,info.selection.file.grounds.X{groundNum}));
                    prefix(indEmpty)=repmat({''},1,numel(indEmpty)); % allows to use matrix such as [1:2]
                    eqXTemp=replace(...
                        info.selection.equation.grounds.rough.X{groundNum},...
                        info.selection.data.grounds.withoutArg2.rough.X{groundNum},...
                        strcat(prefix,info.selection.data.grounds.withoutArg2.format.X{groundNum}));
                    
                    % IMAGE
                    try
                        valueXTemp=eval(eqXTemp);
                        if min(size(valueXTemp))>1
                            if size(valueXTemp,2)>1 % image
                                info.grounds.(groundName).Data=valueXTemp;
                                if numel(size(info.grounds.(groundName).Data))==3
                                    info.selection.class.grounds.main{groundNum}='IMAGEXYZ';
                                else
                                    info.selection.class.grounds.main{groundNum}='IMAGE';
                                end
                                continue;
                            end
                        end
                    catch
                    end

                    % PLOT
                    prefix=repmat({'info.data.'},1,numel(info.selection.data.grounds.withoutArg2.rough.Y{groundNum}));
                    indEmpty=find(cellfun(@isempty,info.selection.file.grounds.Y{groundNum}));
                    prefix(indEmpty)=repmat({''},1,numel(indEmpty)); % allows to use matrix such as [1:2]
                    eqYTemp=replace(...
                        info.selection.equation.grounds.rough.Y{groundNum},...
                        info.selection.data.grounds.withoutArg2.rough.Y{groundNum},...
                        strcat(prefix,info.selection.data.grounds.withoutArg2.format.Y{groundNum}));
                    if ~isempty(eqXTemp) && isempty(eqYTemp) % only X is specified
                        info.grounds.(groundName).X=valueXTemp;
                        info.grounds.(groundName).Y=1:numel(info.grounds.(groundName).X);
                    elseif isempty(eqXTemp) && ~isempty(eqYTemp) % only Y is specified
                        info.grounds.(groundName).Y=eval(eqYTemp);
                        info.grounds.(groundName).X=1:numel(info.grounds.(groundName).Y);
                    else % both are specified
                        info.grounds.(groundName).X=valueXTemp;
                        info.grounds.(groundName).Y=eval(eqYTemp);
                    end
                    info.selection.class.grounds.main{groundNum}='PLOT';

                end

            end

            % #. Updates additional data according to indices
            for groundNum=1:groundNumber
                groundName=['ground' num2str(groundNum)];

                % #.#. Surface
                % If only certain nodes of a surface are displayed,
                % the connectivity matrix must be updated (and X Y)
                if ~isempty(tabNumber) && isnumeric(tabNumber) ...
                        && strcmpi(info.selection.class.grounds.main{groundNum},'SURFACE')
                    % #.#.#. BodyIndex
                    % Must be before periodic boundaries correction
                    if ~isempty(app.bodiesToRemove{tabNumber}) ...
                            || contains(lower(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value),'lagrangian')
                        % List
                        [dataList,dataListFormat,~,dataFile,dataExt]=dataMainListFunction(app,tabNumber,'Refresh');
                        if isempty(app.bodyIndex{tabNumber})
                            % DLG to seletec body index
                            dataListDisplayed=['None' dataList];
                            ind=listdlg('Name','Body index','PromptString',{'Select the data item containing' 'the body index. If ''none'', will be' 'computed automatically' 'according to certain assumptions,' 'and may increase computation time.'},'listString',dataListDisplayed);
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                            if isempty(ind); return; end
                            app.bodyIndex{tabNumber}=dataListDisplayed{ind};
                        end
                        if strcmpi(app.bodyIndex{tabNumber},'none')
                            % Computed body index
                            periodicBoundaries=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.periodicboundariesTextNumber).Value;
                            periodicBoundaries=str2double(strsplit(periodicBoundaries,';'));
                            info.grounds.(groundName).BodyIndex=BodyIndexFunction(info.grounds.(groundName).CONNECTIVITY,...
                                info.grounds.(groundName).X,...
                                info.grounds.(groundName).Y,...
                                'PeriodicBoundaries',periodicBoundaries);
                        else
                            % Already existing body index
                            step=info.selection.arg1.global.format.cat{find(contains(info.selection.arg1.global.rough.cat,'%'),1,'first')};
                            indData=find(strcmp(dataListFormat,app.bodyIndex{tabNumber}),1,'first');
                            file=dataFile{indData};
                            ext=dataExt{indData};
                            mainLoading(app,{info.selection.other.pathSimu},{{file}},{{{step}}},ext,0);
                            info.grounds.(groundName).BodyIndex=cell2mat(struct2cell(load([info.selection.other.pathSimu filesep 'SAVE' filesep file step '.mat'],app.bodyIndex{tabNumber})));
                        end
                    end
                    % #.#.#. Information
                    indData=find(strcmpi(info.selection.class.grounds.cat{groundNum},'SURFACE'),1,'first');
                    arg2=info.selection.arg2.grounds.format.cat{groundNum}{indData};
                    if ~strcmp(arg2,'(1:1:end)') || ~isempty(app.bodiesToRemove{tabNumber}) % if all the data is displayed
                        dataNameVar=info.selection.data.grounds.withoutArg2.format.cat{groundNum}{indData};
                        nodesNumber=numel((info.data.(dataNameVar)));
                        % #.#.#. Indices
                        % Evaluates the index matrix
                        arg2=replace(arg2,'end',num2str(nodesNumber));
                        arg2=eval(arg2);
                        nodesToRemove=~ismember(1:nodesNumber,arg2);
                        % #.#.#. Bodies to remove
                        if ~isempty(app.bodiesToRemove{tabNumber})
                            nodesToRemove=nodesToRemove | reshape(ismember(info.grounds.(groundName).BodyIndex,app.bodiesToRemove{tabNumber}),1,[]);
                        end
                        % #.#.#. Removing
                        info.grounds.(groundName).CONNECTIVITY=NodesRemoving(info.grounds.(groundName).CONNECTIVITY,nodesToRemove);
                        info.grounds.(groundName).X(nodesToRemove)=[];
                        info.grounds.(groundName).Y(nodesToRemove)=[];
                        if isfield(info.grounds.(groundName),'Data')
                            % Data has already been evaluated with arg2,
                            % whereas coordinates have not. The indices
                            % are therefore different.
                            indToRemoveWithArg2=reshape(ismember(info.grounds.(groundName).BodyIndex(arg2),app.bodiesToRemove{tabNumber}),1,[]);
                            info.grounds.(groundName).Data(indToRemoveWithArg2)=[];
                        end
                        if isfield(info.grounds.(groundName),'BodyIndex')
                            info.grounds.(groundName).BodyIndex(nodesToRemove)=[];
                        end
                    end
                    % #.#.#. Periodic boundaries
                    periodicBoundaries=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.periodicboundariesTextNumber).Value;
                    periodicBoundaries=str2double(strsplit(periodicBoundaries,';'));
                    info.grounds.(groundName).X(info.grounds.(groundName).X<periodicBoundaries(1))=periodicBoundaries(1);
                    info.grounds.(groundName).X(info.grounds.(groundName).X>periodicBoundaries(2))=periodicBoundaries(2);
                end

                % #.#. IMAGEXYZ
                % X and Y data must be updated
                if strcmpi(info.selection.class.grounds.main{groundNum},'IMAGEXYZ')
                    % #.#.#. Information
                    indData=find(strcmpi(info.selection.class.grounds.cat{groundNum},'IMAGEXYZ'),1,'first');
                    arg2=info.selection.arg2.grounds.format.cat{groundNum}{indData};
                    if strcmp(arg2,'(1:1:end,1:1:end)') % if all the data is displayed
                        continue;
                    end
                    dataNameVar=info.selection.data.grounds.withoutArg2.format.cat{groundNum}{indData};
                    imageSize=size((info.data.(dataNameVar)));
                    arg2=erase(strsplit(arg2,','),["(" ")"]);
                    % #.#.#. X
                    arg2X=replace(arg2{2},'end',[num2str(imageSize(2))]);
                    arg2X=eval(arg2X);
                    pixelsToRemove=~ismember(1:imageSize(2),arg2X);
                    info.grounds.(groundName).X(pixelsToRemove)=[];
                    % #.#.#. Y
                    arg2Y=replace(arg2{1},'end',[num2str(imageSize(1))]);
                    arg2Y=eval(arg2Y);
                    pixelsToRemove=~ismember(1:imageSize(1),arg2Y);
                    info.grounds.(groundName).Y(pixelsToRemove)=[];
                    % #.#.#. Z
                    if isfield(info.grounds.(groundName),'Z')
                        arg2Z=replace(arg2{3},'end',[num2str(imageSize(3))]);
                        arg2Z=eval(arg2Z);
                        pixelsToRemove=~ismember(1:imageSize(3),arg2Z);
                        info.grounds.(groundName).Z(pixelsToRemove)=[];
                    end
                end

            end

        end

        function [dataList,dataListFormat,dataType,dataFile,dataExt,dataClass,dataColumn,uniqueDataListFormat,uniqueDataList,stepsDataListFormat,stepsDataList,customListFormat,customList]=dataMainListFunction(app,tabNumberOrPath,mode)
            % Abstract: Displays data list
            % mode:
            % 'Reset': loads/computes data with potential dlg
            % 'Refresh': loads existing data without dlg

            % #. Paths
            if ischar(tabNumberOrPath)
                pathSimu=tabNumberOrPath;
            else
                pathSimu=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            end
            pathSave=[pathSimu filesep 'SAVE'];
            pathHeaders=[pathSave filesep 'headers_save.mat'];

            if isfile(pathHeaders) && strcmpi(mode,'refresh')

                % #. Load
                headersInput=load(pathHeaders);
                indColumn=strcmpi(headersInput.headersClass,'MATRIX');

                % #. Unique (rough)
                uniqueDataList=headersInput.headersName(indColumn);
                uniqueDataType=headersInput.headersType(indColumn);
                uniqueDataFile=headersInput.headersFile(indColumn);
                uniqueDataExt=headersInput.headersExt(indColumn);
                uniqueDataClass=headersInput.headersClass(indColumn);
                uniqueDataColumn=headersInput.headersColumn(indColumn);

                % #. Steps (rough)
                stepsDataList=headersInput.headersName(~indColumn);
                stepsDataType=headersInput.headersType(~indColumn);
                stepsDataFile=headersInput.headersFile(~indColumn);
                stepsDataExt=headersInput.headersExt(~indColumn);
                stepsDataClass=headersInput.headersClass(~indColumn);
                stepsDataColumn=headersInput.headersColumn(~indColumn);

            else

                % #. Unique (rough)
                [uniqueDataList,uniqueDataType,uniqueDataFile,uniqueDataExt,uniqueDataClass,uniqueDataColumn]=dataUniqueListFunction(app,tabNumberOrPath,0);
                
                % #. Steps (rough)
                [stepsDataList,stepsDataType,stepsDataFile,stepsDataExt,stepsDataClass,stepsDataColumn]=dataStepsListFunction(app,tabNumberOrPath,1);

            end

            % #. Unique list (format)
            % #.#. Types
            [fileListUnique,ind]=unique(uniqueDataFile);
            extListUnique=uniqueDataExt(ind);
            fileNumber=numel(fileListUnique);
            % #.#. Processing
            uniqueDataListFormat=cell(1,fileNumber);
            for fileNum=1:fileNumber
                fileName=fileListUnique{fileNum};
                ext=extListUnique{fileNum};
                ind=strcmp(uniqueDataFile,fileName);
                uniqueDataListFormat{fileNum}=[{['%%% ' fileName ext ' %%%']} uniqueDataList(ind)];
            end
            uniqueDataListFormat=[uniqueDataListFormat{:}];

            % #. Steps list (format)
            % #.#. Types
            [fileListUnique,ind]=unique(stepsDataFile);
            extListUnique=stepsDataExt(ind);
            fileNumber=numel(fileListUnique);
            % #.#. Processing
            stepsDataListFormat=cell(1,fileNumber);
            for fileNum=1:fileNumber
                fileName=fileListUnique{fileNum};
                ext=extListUnique{fileNum};
                ind=strcmp(stepsDataFile,fileName);
                stepsDataListFormat{fileNum}=[{['%%% ' fileName ext ' %%%']} stepsDataList(ind)];
            end
            stepsDataListFormat=[stepsDataListFormat{:}];

            % #. Custom
            customData=DataManager([],[],[]);
            customList=customData(:,1)';
            customListFormat=[{'%%% CUSTOM %%%'} customData(:,1)'];
            customType=repmat({'CUSTOM'},1,numel(customList));
            customFile=repmat({''},1,numel(customList));
            customExt=repmat({''},1,numel(customList));
            customClass=repmat({''},1,numel(customList));
            customColumn=repmat({''},1,numel(customList));

            % #. Data list
            dataList=[uniqueDataList stepsDataList customList];
            dataListFormat=[uniqueDataListFormat stepsDataListFormat customListFormat];
            dataType=[uniqueDataType stepsDataType customType];
            dataFile=[uniqueDataFile stepsDataFile customFile];
            dataExt=[uniqueDataExt stepsDataExt customExt];
            dataClass=[uniqueDataClass stepsDataClass customClass];
            dataColumn=[uniqueDataColumn stepsDataColumn customColumn];

            % #. Empty lists
            if isempty(uniqueDataListFormat); uniqueDataListFormat={}; end
            if isempty(stepsDataListFormat); stepsDataListFormat={}; end
            if isempty(dataListFormat); dataListFormat={}; end

            % #. Files to delete
            if ~strcmpi(mode,'refresh') && ~ischar(tabNumberOrPath)
                oldList=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.filestodeleteListBoxNumber).Items;
                oldInd=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.filestodeleteListBoxNumber).ValueIndex;
                oldValues=oldList(oldInd);
                newList=[unique(stepsDataFile) unique(strcat(uniqueDataFile,uniqueDataExt))];
                if isempty(newList)
                    newList={};
                end
                newInd=find(ismember(newList,oldValues));
                if isempty(newInd)
                    newInd=[];
                end
                app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.filestodeleteListBoxNumber).Items=newList;
                app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.filestodeleteListBoxNumber).ValueIndex=newInd;
            end

        end

        function info=dataOptions(app,info,mode)
            % Abstract: processes data for graphical display
            % Mode:
            % 'clear': recreates graph without preserving view
            % 'reset': recreates graph with view preserved
            % 'refresh': modifies the graph
            % 'limits': updates limits
            
            % #. Inputs
            tabNumber=info.selection.other.tabNumber;
            groundNumber=numel(info.selection.equation.grounds.format.X);
            groundNameList=string(strcat({'ground'},arrayfun(@(x) cellstr(num2str(x)),1:groundNumber)));
            if ~isfield(info,'opts')
                info.opts=struct();
            end
            
            if ~strcmp(mode,'refresh')
                % #. Same ranges
                % Data are not necessarily calculated on the same range
                if isfield(info,'limits')
                    rowNames=[];
                    fns=string(fieldnames(info.limits))';
                    for name=fns
                        fnsSub=string(fieldnames(info.limits.(name)))';
                        for nameSub=fnsSub
                            if isempty(rowNames)
                                % #.#. Initialization
                                rowNames=info.limits.(name).(nameSub).Properties.RowNames;
                            else
                                % #.#. Comparison
                                rowNames=rowNames(ismember(rowNames,info.limits.(name).(nameSub).Properties.RowNames));
                            end
                        end
                    end
                end
                
                % #. Patch (surface/image)
                if any(strcmpi(info.selection.class.grounds.main,'SURFACE') ...
                        | strcmpi(info.selection.class.grounds.main,'IMAGEXYZ') ...
                        | strcmpi(info.selection.class.grounds.main,'IMAGE'))
                    % #.#.#. Colormap
                    if ~isempty(tabNumber) && isnumeric(tabNumber)
                        info.opts.Colormap=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.colormapListNumber).Value;
                    else
                        info.opts.Colormap=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.colormapListNumber).Value;
                    end
                    % #.#.#. BackgroundColor
                    if ~isempty(tabNumber) && isnumeric(tabNumber)
                        info.opts.BackgroundColor=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.backgroundValueNumber).Value;
                    else
                        info.opts.BackgroundColor=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.backgroundValueNumber).Value;
                    end
                    % #.#.#. StretchToFill
                    if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.stretchToFillCheckNumber).Value ...
                            && ~isempty(info.selection.other.graphNum) ... % to disable it for animations
                            && info.selection.other.graphNum<=4 % to disable it for animations
                        info.opts.StretchToFill='on';
                    else
                        info.opts.StretchToFill='off';
                    end
                    if any(strcmpi(info.selection.class.grounds.main,'SURFACE') ...
                            | strcmpi(info.selection.class.grounds.main,'IMAGEXYZ'))
                        % #.#.#. Spatial limits
                        fns=string(fieldnames(info.limits))';
                        dataNameTemp=fns{1};
                        if isfield(info.limits.(dataNameTemp),'X_MIN') && isfield(info.limits.(dataNameTemp),'Y_MIN') && isfield(info.limits.(dataNameTemp),'Z_MIN')
                            varList='XYZ'; info.opts.XLim=[inf -inf]; info.opts.YLim=[inf -inf]; info.opts.ZLim=[inf -inf];
                        elseif isfield(info.limits.(dataNameTemp),'X_MIN') && isfield(info.limits.(dataNameTemp),'Y_MIN')
                            varList='XY'; info.opts.XLim=[inf -inf]; info.opts.YLim=[inf -inf];
                        elseif isfield(info.limits.(dataNameTemp),'X_MIN')
                            varList='X'; info.opts.XLim=[inf -inf];
                        end
                        for dataName=fns
                            for var=varList
                                info.opts.([var 'Lim'])=[min([info.opts.([var 'Lim'])(1) min(info.limits.(dataName).([var '_MIN']){rowNames,1})]) max([info.opts.([var 'Lim'])(2) max(info.limits.(dataName).([var '_MAX']){rowNames,1})])];
                            end
                        end
                    end
                end
                if strcmp(mode,'limits')
                    return;
                end
                
                % #. Plot
                % #.#. YYAxis
                if all(strcmpi(info.selection.class.grounds.main,'PLOT')) && groundNumber>1 ...
                        && isscalar(unique(info.selection.equation.grounds.format.X)) % several equations but a unique x equation
                    for groundNum=1:groundNumber-1
                        info.grounds.(groundNameList{groundNum}).opts.Yyaxis='left';
                    end
                    info.grounds.(groundNameList{end}).opts.Yyaxis='right';
                end
                % #.#. Format
                for groundNum=1:groundNumber
                    if strcmpi(info.selection.class.grounds.main{groundNum},'PLOT')
                        info.opts.Grid=1;
                        if isfield(info.grounds.(groundNameList{groundNum}),'opts') ...
                                && isfield(info.grounds.(groundNameList{groundNum}).opts,'Yyaxis') ...
                                && ~isempty(info.grounds.(groundNameList{groundNum}).opts.Yyaxis)
                            info.opts.Xlabel=info.selection.label.X{1};
                        else
                            info.opts.Xlabel{groundNum}=info.selection.label.X{groundNum};
                        end
                        info.opts.Ylabel{groundNum}=info.selection.label.Y{groundNum};
                    end
                end

            end
            
            % #. Options associated with a plan (+ index processing)
            
            for groundNum=1:groundNumber
                
                groundName=['ground' num2str(groundNum)];

                if strcmpi(info.selection.class.grounds.main{groundNum},'SURFACE') ...
                        || strcmpi(info.selection.class.grounds.main{groundNum},'IMAGEXYZ') ...
                        || strcmpi(info.selection.class.grounds.main{groundNum},'IMAGE')
                    
                    % #. Patch

                    if ~strcmp(mode,'refresh')

                        % #.#. EdgeColor
                        if ~isempty(tabNumber) && isnumeric(tabNumber) ...
                                && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.displayMeshNumber).Value
                            info.grounds.(groundNameList{groundNum}).opts.EdgeColor=[0,0,0.5020];
                        else
                            info.grounds.(groundNameList{groundNum}).opts.EdgeColor='None';
                        end

                        % #.#. Pixel coordinates
                        if strcmpi(info.selection.class.grounds.main{groundNum},'IMAGEXYZ') && numel(size(info.grounds.(groundName).Data))>2

                                % #.#.#. Image
                                % if strcmpi(info.selection.arg2.grounds.format.X{groundNum}{1},'(1:1:end)') ...
                                %         || strcmpi(info.selection.arg2.grounds.format.X{groundNum}{1},'(1:1:end,1:1:end)')
                                % #.#.#. Indices not specified
                                % Coordinates have already been imported
                                % info.grounds.(groundNameList{groundNum}).opts.XData=info.grounds.(groundNameList{groundNum}).X;
                                % info.grounds.(groundNameList{groundNum}).opts.YData=info.grounds.(groundNameList{groundNum}).Y;
                                % else
                                %     % #.#.#. Indices specified
                                %     % The coordinates of the extremum pixels must be found
                                %     indDataImage=find(strcmpi(info.selection.class.grounds.cat{groundNum},'IMAGEXYZ'),1,'first'); % takes the first arbitrarily
                                %     [yPixels,xPixels]=size(info.data.(info.selection.data.grounds.format{groundNum}{indDataImage})); % Number of pixels
                                %     indSep=strfind(info.selection.arg2.unique.format.cat{indDataInCat(1)},','); % To separate x from y
                                %     xVect=eval(replace(info.selection.arg2.unique.format.cat{indDataInCat(1)}(indSep+1:end),'end',num2str(xPixels))); % Matrix of x-indices
                                %     yVect=eval(replace(info.selection.arg2.unique.format.cat{indDataInCat(1)}(1:indSep-1),'end',num2str(yPixels))); % Matrix of y-indices
                                %     x0=info.grounds.(groundNameList{groundNum}).X(1); x1=info.grounds.(groundNameList{groundNum}).X(2); % Initial x extremal coordinates
                                %     y0=info.grounds.(groundNameList{groundNum}).Y(1); y1=info.grounds.(groundNameList{groundNum}).Y(2); % Initial y extremal coordinates
                                %     dx=x1-x0; dy=y1-y0;
                                %     xMin=x0+(min(xVect)-1)/(xPixels-1)*dx; xMax=x0+(max(xVect)-1)/(xPixels-1)*dx;
                                %     yMin=y0+(min(yVect)-1)/(yPixels-1)*dy; yMax=y0+(max(yVect)-1)/(yPixels-1)*dy;
                                %     info.grounds.(groundNameList{groundNum}).opts.XData=[xMin xMax]; % Final x extremal coordinates
                                %     info.grounds.(groundNameList{groundNum}).opts.YData=[yMin yMax]; % Final y extremal coordinates
                                % end

                            %else

                            % #.#.#. Slices
                            groundName=['ground' num2str(groundNum)];
                            % #.#. DLG
                            prompt = {'Coordinates of slice X:','Coordinates of slice Y:','Coordinates of slice Z:'};
                            dlgtitle = 'Slices';
                            fieldsize = [1 45; 1 45; 1 45];
                            definput={num2str((info.opts.XLim(1)+info.opts.XLim(end))/2),...
                                num2str((info.opts.YLim(1)+info.opts.YLim(end))/2),...
                                num2str([info.opts.ZLim(1) ...
                                (info.opts.ZLim(1)+info.opts.ZLim(end))/2 ...
                                info.opts.ZLim(end)])};
                            answer=inputdlg(prompt,dlgtitle,fieldsize,definput);
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                            if isempty(answer); return; end
                            % #.#. Processing
                            info.grounds.(groundName).opts.xslice=str2double(strsplit(answer{1}));
                            info.grounds.(groundName).opts.yslice=str2double(strsplit(answer{2}));
                            info.grounds.(groundName).opts.zslice=str2double(strsplit(answer{3}));
                            % #.#. Format
                            info.grounds.(groundName).opts.TicksColor=[0 0 0];

                            %end
                        end

                        % #.#. Data limits
                        % limits=[mean(min(eq)) mean(max(eq))]
                        % Limits simply evaluated by replacing the data in the equation by their extremum
                        if any(strcmpi(info.selection.type.grounds.X{groundNum},'STEPS'))
                            lim=zeros(1,2);
                            limChar={'min' 'max'};
                            for limNum=1:2
                                if isempty(info.selection.limits.(limChar{limNum}).grounds.rough{groundNum})
                                    % #.#.#. Default
                                    varOld=info.selection.data.grounds.rough.X{groundNum};
                                    varNew=strcat( ...
                                        'mean(table2array(info.limits.', ...
                                        info.selection.data.grounds.withoutArg2.format.cat{groundNum},...
                                        '.', ...
                                        info.selection.data.grounds.withoutArgs.format.cat{groundNum},...
                                        '_', ...
                                        limChar{limNum} ...
                                        ,'),"all","omitnan")');
                                    eqTemp=replace(...
                                        info.selection.equation.grounds.rough.X{groundNum},...
                                        varOld,...
                                        varNew);
                                    lim(limNum)=eval(eqTemp);
                                else
                                    % #.#.#. Specified
                                    lim(limNum)=str2double(info.selection.limits.(limChar{limNum}).grounds.format{groundNum});
                                end
                            end
                            if lim(1)==lim(2) % Not possible to have identical upper and lower limits
                                lim=[-1 1];
                            end
                            info.grounds.(groundNameList{groundNum}).opts.DataLim=sort(lim);
                        end
                        % #.#. Colobar
                        % #.#.#. Name
                        info.grounds.(groundNameList{groundNum}).opts.DataName=info.selection.label.X{groundNum};
                        info.grounds.(groundNameList{groundNum}).opts.ColorbarName=info.selection.label.X{groundNum};
                        % #.#.#. Location
                        if ~isequal(info.selection.other.graphNum,1) && ~isequal(info.selection.other.graphNum,5) && ~isempty(info.selection.other.graphNum)
                            if groundNum==1
                                info.grounds.(groundNameList{groundNum}).opts.ColorbarLocation='westOutside';
                            elseif groundNum==2
                                info.grounds.(groundNameList{groundNum}).opts.ColorbarLocation='eastOutside';
                            end
                            info.grounds.(groundNameList{groundNum}).opts.ColorbarNameColor=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).BackgroundColor; % For the overview, we need the title, so we cannot remove it, so we make it invisible by setting it to the same color as the background
                        elseif ~isempty(tabNumber) && isnumeric(tabNumber) ...
                                && contains(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'3D')
                            info.grounds.(groundNameList{groundNum}).opts.ColorbarLocation='outside';
                        else
                            info.grounds.(groundNameList{groundNum}).opts.ColorbarLocation='inside';
                        end

                    end

                    if strcmpi(info.selection.class.grounds.main{groundNum},'SURFACE')

                        % #.#. Display nodes
                        if ~isempty(tabNumber) && isnumeric(tabNumber) ...
                                && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.displayNodesNumber).Value
                            info.grounds.(groundNameList{groundNum}).opts.ShowNodes='On';
                        else
                            info.grounds.(groundNameList{groundNum}).opts.ShowNodes='Off';
                        end

                        % #.#. View type
                        if ~isempty(tabNumber) && isnumeric(tabNumber)
                            if contains(lower(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value),'lagrangian')
                                % #.#.#. Lagrangian
                                info.grounds.(groundNameList{groundNum}).opts.BodyIndex=info.grounds.(groundNameList{groundNum}).BodyIndex;
                                info.grounds.(groundNameList{groundNum}).opts.Lagrangian=[app.lagrangianView{1,tabNumber} app.lagrangianView{2,tabNumber}];
                            elseif contains(lower(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value),'planar')
                                % #.#.#. Planar
                                info.grounds.(groundNameList{groundNum}).opts.ConversionTo3D=app.planarView{1,tabNumber};
                            elseif contains(lower(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value),'radial')
                                % #.#.#. Radial
                                info.grounds.(groundNameList{groundNum}).opts.ConversionTo3D=[app.radialView{1,tabNumber},app.radialView{2,tabNumber},app.radialView{3,tabNumber}];
                            end
                        end

                        % #.#. List of bodies
                        % if (isfield(info.grounds.(groundNameList{groundNum}).opts,'Lagrangian') || isfield(info.grounds.(groundNameList{groundNum}).opts,'BodiesToRemove'))
                        %     pathCord=[info.selection.other.pathSimu filesep 'SAVE' filesep info.selection.type.grounds.X{groundNum}{1} '_' info.selection.arg1.grounds.format.X{groundNum}{1} '.mat'];
                        %     infoVars=who('-file',pathCord);
                        %     if any(strcmp(infoVars,'body_index'))
                        %         % #.#.#. Loading the body list
                        %         info.grounds.(groundNameList{groundNum}).opts.BodiesList=cell2mat(struct2cell(load(pathCord,'body_index')));
                        %     else
                        %         % #.#.#. If there is no list, delete the associated options
                        %         if isfield(info.grounds.(groundNameList{groundNum}).opts,'Lagrangian')
                        %             info.grounds.(groundNameList{groundNum}).opts=rmfield(info.grounds.(groundNameList{groundNum}).opts,'Lagrangian');
                        %         end
                        %         if isfield(info.grounds.(groundNameList{groundNum}).opts,'BodiesToRemove')
                        %             info.grounds.(groundNameList{groundNum}).opts=rmfield(info.grounds.(groundNameList{groundNum}).opts,'BodiesToRemove');
                        %         end
                        %     end
                        % end

                        % #.#. Index processing
                        % According to the indicated indexes, the body list must be processed
                        % ind=erase(strsplit(info.selection.arg2.grounds.format.X{groundNum}{1},','),{'(' ')'});
                        % if isfield(info.grounds.(groundNameList{groundNum}).opts,'BodiesList') ...
                        %         && sum(ismember(ind,{':','1:end','1:1:end'}))~=numel(ind)
                        %     nodesNumber=numel(info.grounds.(groundNameList(groundNum)).X);
                        %     index=eval(replace(info.selection.arg2.grounds.format.X{groundNum}{1},'end',num2str(nodesNumber)));
                        %     nodesToRemove=~ismember(1:nodesNumber,index);
                        %     info.grounds.(groundNameList{groundNum}).opts.BodiesList(nodesToRemove)=[];
                        % end
                    end

                    % #.#. Normalization
                    % Normalizing all grounds except the last one
                    if all(~strcmpi(info.selection.class.grounds.cat(groundNum+1:end),'SURFACE') ...
                            | ~strcmpi(info.selection.class.grounds.cat(groundNum+1:end),'IMAGEXYZ') ...
                            | ~strcmpi(info.selection.class.grounds.cat(groundNum+1:end),'IMAGE'))...
                            && groundNum~=groundNumber
                        info.grounds.(groundNameList{groundNum}).opts.Normalized=1;
                    end

                elseif strcmpi(info.selection.class.grounds.main{groundNum},'PLOT')

                    % #. Plot

                    if ~strcmp(mode,'refresh')

                        % #.#. LineWidth

                        % #.#. Marker
                        info.grounds.(groundNameList{groundNum}).opts.Marker='.';
                        info.grounds.(groundNameList{groundNum}).opts.LineStyle='none';
                    end

                end
            end

            % #. Marker
            if isfield(info.selection,'markerTime')
                info=loadMarker(app,info);
            end
        end

        function [headersName,headersType,headersFile,headersExt,headersClass,headersColumn,headersFileExclude]=dataStepsListFunction(app,tabNumberOrPath,ignoringExclusion)
            % Abstract: list of data that can be extracted from graphic files in \CODE
            % headersName: data name with formatting
            % headersType: data container which can be UNIQUE or STEPS
            % headersFile: name of the file containing data without ext/steps (e.g. EXAMPLE_001.vtk -> EXAMPLE_)
            % headersExt: extension of the file containing data
            % headersColumn: column of data
            % headersClass: associated data type; SURFACE if connectivity associated; IMAGEXYZ if matrix with coordinates associated; MATRIX if MATRIX data
            % headersFileExclude: file to exclude from analysis

            % #. Paths
            if ischar(tabNumberOrPath)
                pathSimu=tabNumberOrPath;
            else
                pathSimu=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            end
            pathSave=[pathSimu filesep 'SAVE'];
            pathCode=[pathSimu filesep 'CODE'];
            pathHeaders=[pathSave filesep 'headers_save.mat'];

            % #. Initialization
            headersName={};
            headersType={};
            headersFile={};
            headersExt={};
            headersClass={};
            headersColumn=[];
            headersFileExclude={};

            % #. Loading
            if isfile(pathHeaders)
                headersInput=load(pathHeaders);
            else
                if ~exist(pathSave,'dir')
                    mkdir(pathSave);
                end
                headersInput=struct();
                headersInput.headersName={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersType={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersFile={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersExt={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersClass={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersColumn=[];
                headersInput.headersFileExclude={}; % do not use '' otherwise it will cause problems when concatenating
            end

            % #. Directory
            filesTemp=dir(pathCode);
            namesAllDataWithStep={filesTemp.name};
            namesAllDataWithStep(ismember(namesAllDataWithStep,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
            if isempty(namesAllDataWithStep); return; end

            % #. Names without step
            % e.g. EXAMPLE_001.vtk -> EXAMPLE_.vtk
            namesAllDataWithoutStep=namesAllDataWithStep;
            for fileNum=numel(namesAllDataWithoutStep):-1:1
                [~,typeTemp,extTemp]=fileparts(namesAllDataWithoutStep{fileNum});
                ind=find(~isstrprop(typeTemp,'digit'),1,'last');
                namesAllDataWithoutStep{fileNum}=[typeTemp(1:ind) extTemp];
            end

            % #. Step file names
            indToRemove=~cellfun(@(x)contains(x,app.graphicsFormat),namesAllDataWithoutStep);
            [~,namesStepDataWithStep]=fileparts(namesAllDataWithStep(~indToRemove));
            [~,namesStepDataWithoutStep]=fileparts(namesAllDataWithoutStep(~indToRemove));
            if isempty(namesStepDataWithStep); return; end

            % #. Detects changes to avoid DLG at each load
            % To verify that there has been a change, steps must be
            % removed before exclusion. We therefore keep the list
            % without steps as non unique, to detect changes in the
            % list with steps, and apply exclusion to the list without
            % steps, and finally performed unique
            if isfile(pathHeaders)
                namesTemp=namesStepDataWithoutStep;
                if ~isempty(headersInput.headersFileExclude)
                    indToRemove=cellfun(@(x)contains(x,lower(headersInput.headersFileExclude)),lower(namesStepDataWithStep));
                    namesTemp(indToRemove)=[];
                end
                namesTemp=unique(namesTemp);
                if all(ismember(namesTemp,headersInput.headersFile))
                    indToKeep=strcmpi(headersInput.headersType,'STEPS');
                    headersName=headersInput.headersName(indToKeep);
                    headersType=headersInput.headersType(indToKeep);
                    headersFile=headersInput.headersFile(indToKeep);
                    headersExt=headersInput.headersExt(indToKeep);
                    headersClass=headersInput.headersClass(indToKeep);
                    headersColumn=headersInput.headersColumn(indToKeep);
                    headersFileExclude=headersInput.headersFileExclude;
                    return;
                end
            end

            % #. File exclusion
            % Removes files with a format similar to files that may contain graphics

            % #.#. DLG
            [~,filesList]=fileparts(unique(namesAllDataWithoutStep));
            headersFileExcludeTemp={strjoin(headersInput.headersFileExclude,'; ')};
            if ~ignoringExclusion
                while 1
                    % Input
                    initialValues=find(ismember(filesList,strip(strsplit(headersFileExcludeTemp{1},';'))));
                    filesInd=listdlg('Name','Exclude files','PromptString',{'Specify files that do not ' 'contain displayable raw data' 'i.e. not .vtk, .txt containing' 'only a matrix, etc.'},'listString',filesList,'InitialValue',initialValues);
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(filesInd); return; end
                    headersFileExcludeTemp={strjoin(filesList(filesInd),'; ')};
                    % Processing
                    if isempty(headersFileExcludeTemp{1}) && ischar(headersFileExcludeTemp{1})
                        indToRemove=false(1,numel(namesAllDataWithStep));
                    else
                        headersFileExcludeTempSplit=strsplit(headersFileExcludeTemp{1},';');
                        headersFileExcludeTempSplit=strip(headersFileExcludeTempSplit);
                        indToRemove=cellfun(@(x)contains(x,lower(headersFileExcludeTempSplit)),lower(namesAllDataWithStep));
                    end
                    namesWithStepDataTemp=strjoin(unique(namesAllDataWithoutStep(~indToRemove)),', ');
                    % Validation
                    answer=questdlg(['The following files will be scanned for data:' newline namesWithStepDataTemp],'Data files','Continue','Change','Cancel','Cancel');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(answer) || strcmpi(answer,'Cancel')
                        return;
                    elseif strcmpi(answer,'Continue')
                        break;
                    end
                end
            end

            % #.#. Processing
            
            if isempty(headersFileExcludeTemp{1}) && ischar(headersFileExcludeTemp{1}) % empty selection

                headersFileExcludeTemp={}; % do not use '' otherwise it will cause problems when concatenating
                namesStepDataWithoutStep=unique(namesStepDataWithoutStep);

            else  % Format

                % Names
                headersFileExcludeTemp=strsplit(headersFileExcludeTemp{1},';');
                headersFileExcludeTemp=strip(headersFileExcludeTemp);
                indToRemove=cellfun(@(x)contains(x,lower(headersFileExcludeTemp)),lower(namesStepDataWithStep));
                namesStepDataWithStep(indToRemove)=[];
                namesStepDataWithoutStep(indToRemove)=[];
                namesStepDataWithoutStep=unique(namesStepDataWithoutStep);

                % No files after exclusion
                if isempty(namesStepDataWithStep)

                    % Save
                    headersName=headersInput.headersName;
                    headersType=headersInput.headersType;
                    headersFile=headersInput.headersFile;
                    headersExt=headersInput.headersExt;
                    headersClass=headersInput.headersClass;
                    headersColumn=headersInput.headersColumn;
                    headersFileExclude=headersFileExcludeTemp;
                    save([pathSave filesep 'headers_save.mat'],'headersName','headersFile','headersExt','headersColumn','headersType','headersClass','headersFileExclude');

                    % Outputs
                    indToRemove=~strcmpi(headersInput.headersType,'STEPS');
                    headersName(indToRemove)=[]; % empty a priori
                    headersType(indToRemove)=[]; % empty a priori
                    headersFile(indToRemove)=[]; % empty a priori
                    headersExt(indToRemove)=[]; % empty a priori
                    headersClass(indToRemove)=[]; % empty a priori
                    headersColumn(indToRemove)=[]; % empty a priori
                    return;

                end


            end

            % #. Initialization
            typesNumber=numel(namesStepDataWithoutStep);
            headersNameTemp=cell(1,typesNumber);
            headersTypeTemp=cell(1,typesNumber);
            headersFileTemp=cell(1,typesNumber);
            headersExtTemp=cell(1,typesNumber);
            headersClassTemp=cell(1,typesNumber);
            headersColumnTemp=cell(1,typesNumber);
            
            % #. Data contained in each file
            for typeNum=1:typesNumber

                % #.#. Find corresponding files
                [step,ext]=loadStepsListFunction(app,pathSimu,namesStepDataWithoutStep{typeNum},'CODE');
                step=step{end};
                ext=ext{1}{1};
                % #.#. Loading
                mainLoading(app,{pathSimu},{namesStepDataWithoutStep(typeNum)},{{{step}}},ext,0);
                dataTemp=load([pathSave filesep namesStepDataWithoutStep{typeNum} step '.mat']);
                headersNameTempTemp=reshape(fieldnames(dataTemp),1,[]);

                % #.#. Class
                indConn=strcmp(headersNameTempTemp,'CONNECTIVITY');
                indX=strcmp(headersNameTempTemp,'X');
                indY=strcmp(headersNameTempTemp,'Y');
                indZ=strcmp(headersNameTempTemp,'Z');
                if any(indConn)
                    headersClassTempTemp='SURFACE';
                elseif any(indX) && any(indY)
                    headersClassTempTemp='IMAGEXYZ';
                else
                    headersClassTempTemp='MATRIX';
                end
                headersNameTempTemp(indX | indY | indZ | indConn)=[];

                % #.#. Processing
                dataNumber=numel(headersNameTempTemp);
                headersNameTemp{typeNum}=lower(replace(headersNameTempTemp ...
                    ,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                headersTypeTemp{typeNum}=repmat({'STEPS'},1,dataNumber);
                headersFileTemp{typeNum}=repmat(namesStepDataWithoutStep(typeNum),1,dataNumber);
                headersExtTemp{typeNum}=repmat({ext},1,dataNumber);
                headersClassTemp{typeNum}=repmat({headersClassTempTemp},1,dataNumber);
                for dataNum=1:dataNumber
                    dataSize=size(dataTemp.(headersNameTempTemp{dataNum}));
                    if numel(dataSize)==2
                        headersColumnTemp{typeNum}{dataNum}=1:dataSize(2);
                    elseif numel(dataSize)==3
                        headersColumnTemp{typeNum}{dataNum}=repmat(1:dataSize(2),dataSize(3),1);
                    end
                end

            end

            % #. Duplicate
            % Note that we don't remove all the old options of the same
            % category, because for some options (e.g. delete files) we
            % want to keep the name of these files even if they are not
            % found at time t.
            ind=~strcmpi(headersInput.headersType,'STEPS');
            input0=lower(replace(headersInput.headersFile(ind),...
                            {'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            input1=headersInput.headersName(ind);
            output0=lower(replace([headersFileTemp{:}],...
                            {'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            output1=[headersNameTemp{:}];
            headersNameTempTemp=[strcat(input0,input1) strcat(output0,output1)];
            [~,~,idx] = unique(headersNameTempTemp);
            counts=histcounts(idx,'BinMethod','integers');
            duplicateIndices=find(counts>1);
            if ~isempty(duplicateIndices)
                headersNameTempTempUnique=unique(headersNameTempTemp);
                duplicateNames=strjoin(headersNameTempTempUnique(duplicateIndices),', ');
                msgbox(['The following data name(s) were found several times: ' duplicateNames],'Warning','warn');
                return;
            end

            % #. Concat
            headersName=[headersInput.headersName [headersNameTemp{:}]];
            headersType=[headersInput.headersType [headersTypeTemp{:}]];
            headersFile=[headersInput.headersFile [headersFileTemp{:}]];
            headersExt=[headersInput.headersExt [headersExtTemp{:}]];
            headersClass=[headersInput.headersClass [headersClassTemp{:}]];
            headersColumn=[headersInput.headersColumn [headersColumnTemp{:}]];
            headersFileExclude=headersFileExcludeTemp;

            % #. Unique
            [~,indUnique]=unique(strcat(headersFile,headersName));
            headersName=headersName(indUnique);
            headersType=headersType(indUnique);
            headersFile=headersFile(indUnique);
            headersExt=headersExt(indUnique);
            headersClass=headersClass(indUnique);
            headersColumn=headersColumn(indUnique);
            headersFileExclude=unique(headersFileExclude);
            save([pathSave filesep 'headers_save.mat'],'headersName','headersFile','headersExt','headersColumn','headersType','headersClass','headersFileExclude');

            % #. Outputs
            indToRemove=~strcmpi(headersType,'STEPS');
            headersName(indToRemove)=[];
            headersType(indToRemove)=[];
            headersFile(indToRemove)=[];
            headersExt(indToRemove)=[];
            headersClass(indToRemove)=[];
            headersColumn(indToRemove)=[];

            % #. Sort
            [~,indSort]=sort(lower(strcat(headersFile,headersName)));
            headersName=headersName(indSort);
            headersType=headersType(indSort);
            headersFile=headersFile(indSort);
            headersExt=headersExt(indSort);
            headersClass=headersClass(indSort);
            headersColumn=headersColumn(indSort);
            
        end

        function [headersName,headersType,headersFile,headersExt,headersClass,headersColumn,headersFileExclude]=dataUniqueListFunction(app,tabNumberOrPath,ignoringExclusion)
            % Abstract: list of data that can be extracted from matrix files in \CODE
            % headersName: data name with formatting
            % headersType: data container which can be UNIQUE or STEPS
            % headersFile: name of the file containing data without ext/steps (e.g. EXAMPLE_001.vtk -> EXAMPLE_)
            % headersExt: extension of the file containing data
            % headersColumn: column of data
            % headersClass: associated data type; SURFACE if connectivity associated; IMAGEXYZ if matrix with coordinates associated; MATRIX if MATRIX data
            % headersFileExclude: file to exclude from analysis


            % #. Paths
            if ischar(tabNumberOrPath)
                pathSimu=tabNumberOrPath;
            else
                pathSimu=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            end
            pathSave=[pathSimu filesep 'SAVE'];
            pathCode=[pathSimu filesep 'CODE'];
            pathHeaders=[pathSave filesep 'headers_save.mat'];

            % #. Initialization
            headersName={};
            headersType={};
            headersFile={};
            headersExt={};
            headersClass={};
            headersColumn=[];
            headersFileExclude={};

            % #. Loading
            if isfile(pathHeaders)
                headersInput=load(pathHeaders);
            else
                if ~exist(pathSave,'dir')
                    mkdir(pathSave);
                end
                headersInput=struct();
                headersInput.headersName={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersType={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersFile={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersExt={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersClass={}; % do not use '' otherwise it will cause problems when concatenating
                headersInput.headersColumn=[];
                headersInput.headersFileExclude={}; % do not use '' otherwise it will cause problems when concatenating
            end

            % #. Directory content
            filesTemp=dir(pathCode);
            fileNamesAllData={filesTemp.name};
            fileSizes=[filesTemp.bytes].*1e-6; % b to Mb
            indToRemove=ismember(fileNamesAllData,{'.' '..' '.DS_Store' 'Thumbs.db'});
            fileNamesAllData(indToRemove)=[];
            fileSizes(indToRemove)=[];
            fileNamesUniqueData=fileNamesAllData;
            
            if isempty(fileNamesAllData); return; end

            % #. Matrix files
            indToRemove=~cellfun(@(x)~contains(x,app.graphicsFormat),fileNamesUniqueData) ...
                | ismember(fileNamesUniqueData,strcat(headersInput.headersFile,headersInput.headersExt)); % already loaded
            fileNamesUniqueData(indToRemove)=[];
            fileSizes(indToRemove)=[];
            

            % #. Detects changes to avoid DLG at each load
            % It is assumed that the excluded files have not changed,
            % and that if the same files containing matrices are found,
            % then nothing has changed.
            % #.#. After exclusion
            if ~isempty(headersInput.headersFileExclude)
                indToKeep=~cellfun(@(x)contains(x,lower(headersInput.headersFileExclude)),lower(fileNamesUniqueData));
                fileNamesTemp=fileNamesUniqueData(indToKeep);
            else
                fileNamesTemp=fileNamesUniqueData;
            end
            [~,fileNamesTemp]=fileparts(fileNamesTemp);
            % #.#. Verification
            if isempty(fileNamesUniqueData) ...
                    || all(ismember(fileNamesTemp,headersInput.headersFile))
                indToKeep=strcmpi(headersInput.headersType,'UNIQUE');
                headersName=headersInput.headersName(indToKeep);
                headersType=headersInput.headersType(indToKeep);
                headersFile=headersInput.headersFile(indToKeep);
                headersExt=headersInput.headersExt(indToKeep);
                headersClass=headersInput.headersClass(indToKeep);
                headersColumn=headersInput.headersColumn(indToKeep);
                headersFileExclude=headersInput.headersFileExclude;
                return;
            end

            % #. Names without step
            % e.g. EXAMPLE_001.vtk -> EXAMPLE_
            % To decrease the size of the displayed list for file
            % exclusion
            fileNamesAllDataWithoutStep=fileNamesAllData;
            for fileNum=numel(fileNamesAllData):-1:1
                [~,nameTemp,extTemp]=fileparts(fileNamesAllData{fileNum});
                ind=find(~isstrprop(nameTemp,'digit'),1,'last');
                fileNamesAllDataWithoutStep{fileNum}=[nameTemp(1:ind) extTemp];
            end

            % #. File exclusion
            % Removes files with a format similar to files that may contain matrices

            % #.#. DLG
            [~,filesList]=fileparts(unique(fileNamesAllDataWithoutStep));
            headersFileExcludeTemp={strjoin(headersInput.headersFileExclude,'; ')};
            if ~ignoringExclusion
                while 1
                    % Input
                    initialValues=find(ismember(filesList,strip(strsplit(headersFileExcludeTemp{1},';'))));
                    filesInd=listdlg('Name','Exclude files','PromptString',{'Specify files that do not ' 'contain displayable raw data' 'i.e. not .vtk, .txt containing' 'only a matrix, etc.'},'listString',filesList,'InitialValue',initialValues);
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(filesInd); return; end
                    headersFileExcludeTemp={strjoin(filesList(filesInd),'; ')};
                    % Processing
                    if isempty(headersFileExcludeTemp{1}) && ischar(headersFileExcludeTemp{1})
                        indToRemove=false(1,numel(fileNamesAllDataWithoutStep));
                    else
                        headersFileExcludeTempSplit=strsplit(headersFileExcludeTemp{1},';');
                        headersFileExcludeTempSplit=strip(headersFileExcludeTempSplit);
                        indToRemove=cellfun(@(x)contains(x,lower(headersFileExcludeTempSplit)),lower(fileNamesAllDataWithoutStep));
                    end
                    fileNamesDataTemp=strjoin(unique(fileNamesAllDataWithoutStep(~indToRemove)),', ');
                    % Validation
                    answer=questdlg(['The following files will be scanned for data:' newline fileNamesDataTemp],'Data files','Continue','Change','Cancel','Cancel');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(answer) || strcmpi(answer,'Cancel')
                        return;
                    elseif strcmpi(answer,'Continue')
                        break;
                    end
                end
            end

            % #.#. Processing

            if isempty(headersFileExcludeTemp{1}) && ischar(headersFileExcludeTemp{1}) % empty selection

                headersFileExcludeTemp={}; % do not use '' otherwise it will cause problems when concatenating

            else  % Format

                % Names
                headersFileExcludeTemp=strsplit(headersFileExcludeTemp{1},';');
                headersFileExcludeTemp=strip(headersFileExcludeTemp);
                indToRemove=cellfun(@(x)contains(x,lower(headersFileExcludeTemp)),lower(fileNamesUniqueData));
                fileNamesUniqueData(indToRemove)=[];
                fileSizes(indToRemove)=[];

                % No files after exclusion
                if isempty(fileNamesUniqueData)

                    % #. Remove old values
                    % Allows change without deleting the files
                    % ind=strcmpi(headersInput.headersType,'UNIQUE');
                    % headersInput.headersName(ind)=[];
                    % headersInput.headersType(ind)=[];
                    % headersInput.headersFile(ind)=[];
                    % headersInput.headersExt(ind)=[];
                    % headersInput.headersClass(ind)=[];
                    % headersInput.headersColumn(ind)=[];

                    % Save
                    headersName=headersInput.headersName;
                    headersType=headersInput.headersType;
                    headersFile=headersInput.headersFile;
                    headersExt=headersInput.headersExt;
                    headersClass=headersInput.headersClass;
                    headersColumn=headersInput.headersColumn;
                    headersFileExclude=headersFileExcludeTemp;
                    save([pathSave filesep 'headers_save.mat'],'headersName','headersFile','headersExt','headersColumn','headersType','headersClass','headersFileExclude');

                    % Outputs
                    indToRemove=~strcmpi(headersInput.headersType,'UNIQUE');
                    headersName(indToRemove)=[]; % empty a priori
                    headersType(indToRemove)=[]; % empty a priori
                    headersFile(indToRemove)=[]; % empty a priori
                    headersExt(indToRemove)=[]; % empty a priori
                    headersClass(indToRemove)=[]; % empty a priori
                    headersColumn(indToRemove)=[]; % empty a priori
                    return;

                end


            end

            % #. Validation
            msg=['There are ' num2str(numel(fileNamesUniqueData)) ' matrix files (' num2str(round(sum(fileSizes),1)) ' Mb): ' strjoin(fileNamesUniqueData,', ')];
            answer=questdlg(msg,'Data files','Continue','Cancel','Cancel');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(answer) || contains(answer,'Cancel')
                return;
            end

            % #. Processing

            % #.#. Initialization
            filesNumber=numel(fileNamesUniqueData);
            headersNameTemp=cell(1,filesNumber);
            headersTypeTemp=cell(1,filesNumber);
            headersFileTemp=cell(1,filesNumber);
            headersExtTemp=cell(1,filesNumber);
            headersClassTemp=cell(1,filesNumber);
            headersColumnTemp=cell(1,filesNumber);

            for fileNum=1:filesNumber

                try

                    % #.#. Loading
                    pathTemp=[pathCode filesep fileNamesUniqueData{fileNum}];
                    dataTemp=load(pathTemp);
                    columnsNumber=size(dataTemp,2); % to assume at the beginning that there is one data item per column
                    [~,fileNamesTemp,extTemp]=fileparts(fileNamesUniqueData{fileNum});

                    % #.#. DLG
                    % #.#.#. Default names
                    % file_XXe
                    [~,fileNameFormat]=fileparts(fileNamesUniqueData{fileNum});
                    fileNameFormat=lower(replace(fileNameFormat ...
                        ,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                    digitsNumber=num2str(numel(num2str(columnsNumber-1)));
                    indexList=arrayfun(@(x)num2str(x,['%0' digitsNumber '.f']),0:columnsNumber-1,'UniformOutput',false);
                    definput={strjoin(strcat(fileNameFormat,'_',indexList),'; ')};
                    % #.#.#. Selection
                    dataNamesTemp=inputdlg([fileNamesUniqueData{fileNum} ': enter the names of each data item (' num2str(columnsNumber) ') using semicolon separators (e.g. fileA_01; fileA_02) or a single name for the entire file:' newline 'Warning: each name must be unique'],'Data names',[1 150],definput);
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(dataNamesTemp); return; end
                    % #.#.#. Processing
                    dataNamesTemp=strsplit(dataNamesTemp{1},';');
                    dataNamesTemp=strip(dataNamesTemp);
                    dataNumber=numel(dataNamesTemp);

                    % #.#. Error
                    if dataNumber~=columnsNumber && dataNumber~=1
                        msgbox('Must contain either one name per column or a single name for the entire file','Warning','warn');
                        return;
                    end

                    % #.#. Format
                    dataNamesTemp=lower(replace(dataNamesTemp...
                        ,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                    headersNameTemp{fileNum}=dataNamesTemp;
                    headersTypeTemp{fileNum}=repmat({'UNIQUE'},1,dataNumber);
                    headersFileTemp{fileNum}=repmat({fileNamesTemp},1,dataNumber);
                    headersExtTemp{fileNum}=repmat({extTemp},1,dataNumber);
                    headersClassTemp{fileNum}=repmat({'MATRIX'},1,dataNumber);
                    if dataNumber~=columnsNumber && dataNumber==1
                        headersColumnTemp{fileNum}={1:columnsNumber};
                    else
                        headersColumnTemp{fileNum}=num2cell(1:columnsNumber);
                    end
                    
                catch

                    % #.#. Error
                    msgbox([fileNamesUniqueData{fileNum} ' could not be opened.'],'Warning','warn');
                    return;

                end
            end

            % #. Duplicate
            % Note that we don't remove all the old options of the same
            % category, because for some options (e.g. delete files) we
            % want to keep the name of these files even if they are not
            % found at time t.
            ind=~strcmpi(headersInput.headersType,'UNIQUE');
            input0=lower(replace(headersInput.headersFile(ind),...
                            {'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            input1=headersInput.headersName(ind);
            output0=lower(replace([headersFileTemp{:}],...
                            {'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
            output1=[headersNameTemp{:}];
            headersNameTempTemp=[strcat(input0,input1) strcat(output0,output1)];
            [~,~,idx] = unique(headersNameTempTemp);
            counts=histcounts(idx,'BinMethod','integers');
            duplicateIndices=find(counts>1);
            if ~isempty(duplicateIndices)
                headersNameTempTempUnique=unique(headersNameTempTemp);
                duplicateNames=strjoin(headersNameTempTempUnique(duplicateIndices),', ');
                msgbox(['The following data name(s) were found several times: ' duplicateNames],'Warning','warn');
                return;
            end

            % #. Concat
            headersName=[headersInput.headersName [headersNameTemp{:}]];
            headersType=[headersInput.headersType [headersTypeTemp{:}]];
            headersFile=[headersInput.headersFile [headersFileTemp{:}]];
            headersExt=[headersInput.headersExt [headersExtTemp{:}]];
            headersClass=[headersInput.headersClass [headersClassTemp{:}]];
            headersColumn=[headersInput.headersColumn [headersColumnTemp{:}]];
            headersFileExclude=headersFileExcludeTemp;

            % #. Unique
            [headersName,indUnique]=unique(headersName);
            headersType=headersType(indUnique);
            headersFile=headersFile(indUnique);
            headersExt=headersExt(indUnique);
            headersClass=headersClass(indUnique);
            headersColumn=headersColumn(indUnique);
            headersFileExclude=unique(headersFileExclude);
            save([pathSave filesep 'headers_save.mat'],'headersName','headersFile','headersExt','headersColumn','headersType','headersClass','headersFileExclude');

            % #. Outputs
            indToRemove=~strcmpi(headersType,'UNIQUE');
            headersName(indToRemove)=[];
            headersType(indToRemove)=[];
            headersFile(indToRemove)=[];
            headersExt(indToRemove)=[];
            headersClass(indToRemove)=[];
            headersColumn(indToRemove)=[];

            % #. Sort
            [~,indSort]=sort(lower(strcat(headersFile,headersName)));
            headersName=headersName(indSort);
            headersType=headersType(indSort);
            headersFile=headersFile(indSort);
            headersExt=headersExt(indSort);
            headersClass=headersClass(indSort);
            headersColumn=headersColumn(indSort);

        end

        function deleteTabFunction(app)
            % Abstract: deletes the current tab

             % #. Pre-processing
            tabNumber=findTab(app);
            app.addCount=app.addCount-1; % Number of successive clicks

            % #. Tabs to delete
            if app.addCount<-1
                % #.#. Deletes X tab(s)
                tabList=cell(1,numel(app.TabGroup.Children)-2);
                for tabNumberVar=3:numel(app.TabGroup.Children)
                    tabList{tabNumberVar-2}=app.TabGroup.Children(tabNumberVar).Title;
                end
                tabNumberList=listdlg('PromptString',{'Select the tabs to delete:'},'ListString',tabList);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(tabNumberList); busyOff(app); return; end
                tabNumberList=reshape(tabNumberList+2,1,[]);
            else
                % #.#. Deletes 1 tab
                tabNumberList=tabNumber;
            end

            % #. Deletion
            for tabNumberVar=sort(tabNumberList,'descend') % to keep the same index after deletion
                app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value='';
                loadPathFunctionMain(app,tabNumberVar);
            end

        end

        function deleteTimerloadGraphics(~,tabNumber)
            % Abstract: deletes the timer to convert the selected steps to .mat
            if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Load']))
                stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Load']));
                delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Load']));
            end
        end

        function [ax,info]=displayGraphMain(app,ax,tabNumber,info,mode)
            % Abstract: plots graphics
            % Mode:
            % 'clear': recreates graph without preserving view
            % 'reset': recreates graph with view preserved
            % 'refresh': modifies the graph
            % 'limits': updates limits
            
            if ~isempty(info.selection.other.graphNum)
                graphNumFunction(app,info.selection.other.graphNum);
            end
            
            % #. Resetting the previous graph
            if ~isempty(info.selection.other.graphNum) && isempty(ax)
                ax=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar);
            end
            
            % #. Load data
            [info,newData]=dataLoad(app,info);
            if isempty(info)
                return;
            elseif info.selection.other.customMode
                ax=PlotManager(ax,info);
                ax.Toolbar.Visible='on';
                return;
            elseif newData==1 && (strcmpi(mode,'limits') || strcmpi(mode,'refresh'))
                mode='reset'; % Detects when new data is loaded and limits change as a result
            end

            % #. View
            v=[]; vRot=[];
            if ~isempty(ax)
                if ~isempty(ax.Children) && strcmpi(mode,'reset')
                    v=axis(ax); [az,el]=view(ax); vRot=[az,el];
                    if ~isempty(info.selection.other.graphNum)
                        clearGraph(app,ax.Parent,['graph' num2str(info.selection.other.graphNum)]);
                    end
                else
                    if strcmpi(mode,'clear') && ~isempty(info.selection.other.graphNum)
                        clearGraph(app,ax.Parent,['graph' num2str(info.selection.other.graphNum)]);
                    end
                end
            end

            % #. Options
            info=dataOptions(app,info,mode);
            
            % #. Limits
            if strcmpi(mode,'limits')
                limits=[info.opts.XLim info.opts.YLim];
                if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.stretchToFillCheckNumber).Value ...
                        && numel(axis(ax))/2==2
                    pos=getpixelposition(ax);
                    axisRatio=pos(3)/pos(4);
                    xRange = diff(limits(1:2));
                    yRange = diff(limits(3:4));
                    centerX = mean(limits(1:2));
                    centerY = mean(limits(3:4));
                    coordRatio=xRange/yRange;
                    if coordRatio<axisRatio
                        xRange=yRange*axisRatio;
                    else
                        yRange=xRange/axisRatio;
                    end
                    axis(ax,[centerX - xRange/2, centerX + xRange/2, centerY - yRange/2, centerY + yRange/2]);
                else
                    axis(ax,limits);
                end
                return;
            end

            % #. Patch
            if any(strcmpi(info.selection.type.global.cat,'STEPS'))
                
                % #.#. Preserving the view
                if ~isfield(info.opts,'View')
                    info.opts.View=v;
                end
                if ~isfield(info.opts,'Rotation')
                    info.opts.Rotation=vRot;
                end
                
                % #.#. Slider
                % The difficulty is that the size of the lists or indices may be different for each data item
                if isnumeric(tabNumber) && ~isempty(tabNumber) ...
                        && ~isempty(info.selection.other.graphNum)
                    sliderValue=stepsToSlider(app,info);
                    if ~isempty(sliderValue)...
                            && ~isnan(sliderValue)  % e.g. NaN if only one step available (the slider value is meaningless)
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=sliderValue;
                    end
                end
            end
            
            % #. Plot
            ax=PlotManager(ax,info);
            ax.Toolbar.Visible='on';
            
            % #. Marker
            if isnumeric(tabNumber) && ~isempty(tabNumber) ...
                    && ~isempty(info.selection.other.graphNum) ...
                    && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.displayCurrentStateNumber).Value
                if info.selection.other.graphNum<=4 ...
                        && any(strcmpi(info.selection.type.global.cat,'STEPS'))
                % #.#. Time
                [~,markerTimeSave]=timeSimulationRead(app,info.selection.other.pathSimu,[info.selection.type.unique.cat{1} '_' info.selection.arg1.unique.format.cat{1} '.vtk']); % Must be outside the selection function to avoid losing reference graph information
                % #.#. Loop
                graphNumList=find(1:4~=info.selection.other.graphNum);
                for graphNum=graphNumList
                    % #.#. Index
                    graphNumFunction(app,graphNum);
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        infoMarker=struct();
                        infoMarker.selection.markerTime=markerTimeSave;
                        infoMarker.selection=selectionInfo(app,tabNumber,graphNum,[],infoMarker.selection);
                        if any(strcmp(infoMarker.selection.class.unique.cat,'array'))
                            % #.#. Plot type
                            if numel(info.selection.equation.grounds.rough.X)==numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                                % Marker not initialized
                                modeMarker='reset';
                            else
                                % Marker initialized
                                modeMarker='refresh';
                            end
                            displayGraphMain(app,[],tabNumber,infoMarker,modeMarker);
                        end
                    end
                end
                end
            end

        end

        function exportGraphFunction(app)
            % Abstract: export a graph to an external window

            % #. Parent selection
            % #.#. Available
            parentAvailable=findall(0,'type','figure');
            if isempty(parentAvailable)
                busyOff(app); return;
            end
            % #.#. Selection
            if isscalar(parentAvailable)
                % #.#.#. Only one figure
                parentExport=parentAvailable;
            else
                % #.#.#. Several figures
                indToImport=listdlg('Name','Export','PromptString','From which figure do you want to export graphs?','listString',{parentAvailable.Name},'SelectionMode','single');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(indToImport)
                    busyOff(app); return;
                end
                parentExport=parentAvailable(indToImport);
            end

            % #. GUI -> Only looks at the current tab
            if isequal(app.TabGroup.Parent,parentExport)
                parentExport=app.TabGroup.SelectedTab;
            end

            % #. Axes selection
            axesAvailable=flip(findobj(parentExport,'type','axes'));
            ind=ismember({axesAvailable.Tag},{'maskingPanel','graph5'});
            axesAvailable(ind)=[];
            indToImport=listdlg('Name','Export','PromptString','Which graphs do you want to export?','listString',{axesAvailable.Tag});
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(indToImport)
                busyOff(app); return;
            end

            % #. Processing
            for graphNum=indToImport
                % #.#.#.#. Axes to copy
                axesToExport=axesAvailable(graphNum);
                % #.#.#.#. Figure creation
                fig=figure('units','normalized','Position',[0.1 0.1 0.8 0.8],'Color',[1 1 1]);
                % #.#.#.#. Copy
                if isscalar(axesToExport.YAxis)
                    [~,indObjects,indAxes]=FindObjects(axesToExport);
                    set(0,'showhiddenhandles','on');
                    copyobj(axesToExport.Parent.Children([indAxes indObjects]),fig); % If copyobj is used directly with axesToExport, colorbars are not copied
                    set(0,'showhiddenhandles','off');
                else
                    CopyobjYyaxis(axesToExport,fig);
                end
                % #.#.#.#. Figure processing
                BordersRemoving(fig);
                fig.Children.Units='Normalized';
            end

        end

        function findExecution(app,tabNumberVect)
            % Abstract: checks if one or more simulations are running and
            % applies several changes in the state of GUI buttons.
            % If an execution is in progress: several buttons are disabled
            % If an execution cannot be performed for several reasons: the running button is disabled
            % Otherwise: all the buttons are enabled
            
            % #. Initialization
            s_conn=struct();
            tabNumberToDisable=false(1,numel(app.TabGroup.Children)); % Some tabs must be inactive due to the paste option
            if isempty(tabNumberVect)
                tabNumberVect=1:numel(app.TabGroup.Children);
            end
            
            for tabNumberVar=reshape(tabNumberVect,1,[])
                
                if strcmpi(app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local')
                    
                    % #. Local
                    
                    app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorUnchanged;
                    
                    if verifExecution(app,tabNumberVar) % Simulation is running
                        
                        % #.#. Timer
                        if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumberVar) 'Run']))
                            t=timer('Name',['timerGUITab' num2str(tabNumberVar) 'Run'],...
                                'BusyMode','queue',...
                                'StartDelay',45,...
                                'Period',90,...
                                'ExecutionMode','fixedSpacing',...
                                'TimerFcn', @(~,~) verifExecutionMain(app, tabNumberVar));
                            start(t);
                        end
                        
                        % #.#. Format
                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='On';
                        buttonsRunActivation(app,tabNumberVar,'Off');
                        
                        % #.#. Multiple executions are not allowed
                        % If the paste option is disabled, several simulations
                        % cannot be run because the names of the tasks are identical,
                        % so it is not possible to stop them individually.
                        if app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                            for tabNumberVarSub=setdiff(1:numel(app.TabGroup.Children),[2 tabNumberVar])
                                if strcmpi(app.TabGroup.Children(tabNumberVarSub).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local') ...
                                        && app.TabGroup.Children(tabNumberVarSub).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                                tabNumberToDisable(tabNumberVarSub)=true;
                                app.TabGroup.Children(tabNumberVarSub).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Enable='Off';
                                end
                            end
                        end
                        
                    else % No simulation is running
                        
                        if ~tabNumberToDisable(tabNumberVar) % Multiple executions are allowed or no execution is in progress
                            % #.#. Current tab
                            app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                            buttonsRunActivation(app,tabNumberVar,'On');
                            % #.#. Other tabs
                            % If the current tab was previously in "running
                            % in progress", it may change the state of the
                            % other tabs
                            if app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                                for tabNumberVarSub=setdiff(1:numel(app.TabGroup.Children),[2 tabNumberVar])
                                    if ~tabNumberToDisable(tabNumberVarSub) ...
                                            && strcmpi(app.TabGroup.Children(tabNumberVarSub).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local') ...
                                            && app.TabGroup.Children(tabNumberVarSub).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                                    buttonsRunActivation(app,tabNumberVarSub,'On');
                                    end
                                end
                            end
                        end
                        
                    end
                    
                else
                    
                    % #. Cluster
                    
                    % #.#. Initialization
                    app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Enable='On';
                    server={app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value};
                    serverFormat=string(lower(replace(server,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_')));
                    
                    % #.#. Connection
                    if ~isfield(s_conn,serverFormat)
                        s_conn.(serverFormat)=clusterLoadInfo(app,tabNumberVar,[]);
                    end
                    
                    % #.#. Connection could not be established
                    % -> Return to local mode
                    if isempty(s_conn.(serverFormat))
                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value=...
                            app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items{1};
                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        if any(tabNumberToDisable) && app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                            app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Enable='Off'; % Multiple executions are not allowed and an execution is in progress
                        else
                            buttonsRunActivation(app,tabNumberVar,'On'); % Multiple executions are allowed or no execution is in progress
                        end
                        continue;
                    end
                    
                    % #.#. Finds an execution
                    [~,~,key,pathSimuCluster]=clusterPath(app,tabNumberVar,s_conn.(serverFormat));
                    if ~isempty(pathSimuCluster)
                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorProgress;
                        [info]=clusterJob(app,s_conn.(serverFormat),key);
                        if ~isempty(info)
                            buttonsRunActivation(app,tabNumberVar,'Off'); % Simulation is running
                        else
                            buttonsRunActivation(app,tabNumberVar,'On'); % No simulation is running
                        end
                    else
                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorUnchanged;
                        buttonsRunActivation(app,tabNumberVar,'On'); % No simulation is running
                    end
                    
                end
            end
            
            % #. Closing of cluster connection
            serversFormat=string(fieldnames(s_conn))';
            for serverFormatVar=serversFormat
                ssh2_close(s_conn.(serverFormatVar));
            end
            clearvars('s_conn');
        end

        function graphLimitsFunction(app,graphNum)
            % Abstract: resets the limits of the current graph

            % #. Inputs
            tabNumber=findTab(app);
            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            % #. Processing
            if all(strcmpi(info.selection.class.global.unique,'MATRIX'))
                % The limits of the PLOTS correspond to the default limits (which are hard to predict)
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).XLimMode='auto';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).YLimMode='auto';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).ZLimMode='auto';
            else
                % The limits of the GRAPHICS do not correspond to the default limits
                % i.e. The limits are calculated according to all the steps,
                % whereas MATLAB only takes into account the current step.
                vOld=axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));
                if numel(vOld)>4
                    % #.#. 3D
                    displayGraphMain(app,[],tabNumber,info,'clear');
                elseif strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'eulerian')
                    % #.#. 2D - euler
                    displayGraphMain(app,[],tabNumber,info,'limits');
                elseif strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'lagrangian')
                    % #.#. 2D - lagrangian
                    % #.#.#. Single click - centering
                    graphNumFunction(app,graphNum);
                    displayGraphMain(app,[],tabNumber,info,'refresh');
                    graphNumFunction(app,graphNum);
                    vNew=axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));
                    % #.#.#. Double click - reset
                    if isequal(vOld,vNew)
                        displayGraphMain(app,[],tabNumber,info,'clear');
                    end
                end
            end
        end

        function graphNumberSave=graphNumFunction(app,graphNum)
            % Abstract: gives the value of the component index as
            % a function of graphNum

            % #. Structure
            if graphNum<=4
                app.id.graphNumberVar=app.id.(['graph' num2str(graphNum) 'Number']);
                app.id.gridGraphVar=app.id.(['gridGraph' num2str(graphNum) 'Number']);
                app.id.graphEndNumberVar=app.id.(['graph' num2str(graphNum) 'EndNumber']);
                app.id.graphStartNumberVar=app.id.(['graph' num2str(graphNum) 'StartNumber']);
                app.id.graphXListNumberVar=app.id.(['graph' num2str(graphNum) 'XListNumber']);
                app.id.graphYEqNumberVar=app.id.(['graph' num2str(graphNum) 'YEqNumber']);
                app.id.graphXEqNumberVar=app.id.(['graph' num2str(graphNum) 'XEqNumber']);
                app.id.graphYListNumberVar=app.id.(['graph' num2str(graphNum) 'YListNumber']);
                app.id.graphOptsNumberVar=app.id.(['graph' num2str(graphNum) 'OptsNumber']);
                app.id.graphCancelNumberVar=app.id.(['graph' num2str(graphNum) 'CancelNumber']);
                app.id.graphPlotNumberVar=app.id.(['graph' num2str(graphNum) 'PlotNumber']);
                app.id.graphLimitsNumberVar=app.id.(['graph' num2str(graphNum) 'LimitsNumber']);
                app.id.graphSameViewNumberVar=app.id.(['graph' num2str(graphNum) 'SameViewNumber']);
            elseif graphNum==5
                app.id.graphNumberVar=app.id.graph5Number;
            end

            % #. Processing
            if graphNum==5
                graphNumberSave.graphNumberVar=app.id.graph5Number;
            else
                graphNumberSave.graphNumber=app.id.graphNumberVar;
                graphNumberSave.gridGraph=app.id.gridGraphVar;
                graphNumberSave.graphEndNumber=app.id.graphEndNumberVar;
                graphNumberSave.graphStartNumber=app.id.graphStartNumberVar;
                graphNumberSave.graphXListNumber=app.id.graphXListNumberVar;
                graphNumberSave.graphYEqNumber=app.id.graphYEqNumberVar;
                graphNumberSave.graphXEqNumber=app.id.graphXEqNumberVar;
                graphNumberSave.graphYListNumber=app.id.graphYListNumberVar;
            end

        end

        function [body,nodeGlob,nodeLoc]=graphSelection(app,tabNumber,stepToFindInput,defaultBodies,bodyIndex)
            % Abstract: function to select a point on a graph and find the
            % associated body, as well as the node number in the global
            % frame of reference and in that of the body.
            % Problem: surface datatips could not be permanently 
            % deactivated, so they were created twice (once for the 
            % surface and once for the 3DPlot).

            % #. Initialization
            body=[]; nodeGlob=[]; nodeLoc=[];

            % #. Masking panel
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.maskingPanelNumber).Visible='on';
            for graphNum=1:4
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.(['graph' num2str(graphNum) 'Number'])).HitTest='off';
            end

            % #. Find the graph to plot for the selection
            % In all cases, we trace the body_index of a XXXXX.
            % To find which one, browse the potential GRAPHICS traced and
            % take the 1st one found, otherwise take the number of the
            % STEP selected in the list.
            % #.#. GRAPHICS
            % #.#.#. Loop on graphics
            if isempty(stepToFindInput)
                stepToFind='';
                for graphNum=1:4
                    graphNumFunction(app, graphNum);
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                        ind=find(strcmpi(selection.type.global.cat,'STEPS'),1,'first');
                        if ~isempty(ind)
                            stepToFind=selection.arg1.global.format.cat{ind};
                            break;
                        end
                    end
                end
                if isempty(stepToFind)
                    [~,stepsIntersection]=loadListStep(app,tabNumber);
                    stepToFind=stepsIntersection{end};
                end
            else
                stepToFind=stepToFindInput;
            end

            % #. Body index
            [bodyIndex,bodyList,connectivity,x,y,~]=bodiesListForGUIFunction(app,tabNumber,stepToFind,bodyIndex);
            bodyIndex=reshape(bodyIndex,[],1);
            % #.#.#. Periodic boundaries
            % periodicBoundaries=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.periodicboundariesTextNumber).Value;
            % periodicBoundaries=str2double(strsplit(periodicBoundaries,';'));
            % nodesToRemove=x<periodicBoundaries(1) | x>periodicBoundaries(2);
            % connectivity=NodesRemoving(connectivity,nodesToRemove);
            % x(nodesToRemove)=[];
            % y(nodesToRemove)=[];
            % bodyIndex(nodesToRemove)=[];

            % #. Nodes indices
            bodyNumber=numel(bodyList);
            nodeGlobList=1:numel(bodyIndex);
            nodeLocList=zeros(1,numel(bodyIndex));
            for bodyNum=1:bodyNumber
                indBody=find(bodyIndex==bodyList(bodyNum));
                nodeLocList(indBody)=1:numel(indBody);
            end
            
            % #. Plot
            % #.#. Main plot
            ax=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graph5Number);
            opts.Colormap=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.colormapListNumber).Value;
            opts.BackgroundColor=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.backgroundValueNumber).Value;
            opts.ShowNodes='on';
            MultiPlot(ax,connectivity,x,y,'Data',bodyIndex,opts);
            % #.#. Scatter plot
            plotWithNodes=findall(ax,'type','scatter');
            plotWithNodes.HandleVisibility='on';
            % #.#. Datatip
            % #.#.#. Values
            % A value must be defined for each value in the connectivity matrix
            nodeGlobListTips=reshape(nodeGlobList(reshape(connectivity,[1,size(connectivity,1)*size(connectivity,2)])),size(connectivity))';
            nodeLocListTips=reshape(nodeLocList(reshape(connectivity,[1,size(connectivity,1)*size(connectivity,2)])),size(connectivity))';
            % #.#.#. Dimension
            if numel(axis(ax))/2==2 % Dimension
                dt1 = datatip(ax.Children(2),0,0,'Visible','off'); % To load datatips
                dt2 = datatip(ax.Children(1),0,0,'Visible','off'); % To load datatips
            else
                dt1 = datatip(ax.Children(2),0,0,0,'Visible','off'); % To load datatips
                dt2 = datatip(ax.Children(1),0,0,0,'Visible','off'); % To load datatips
            end
            % #.#.#. Surface
            ax.Children(2).DataTipTemplate.DataTipRows(3).Label = 'Body';
            ax.Children(2).DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Node number according to global reference',nodeGlobListTips);
            ax.Children(2).DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Node number according to body reference',nodeLocListTips);
            delete(dt1); % To load datatips
            % #.#.#. Scatter
            ax.Children(1).DataTipTemplate.DataTipRows(3).Label = 'Body';
            ax.Children(1).DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Node number according to global reference',nodeGlobListTips);
            ax.Children(1).DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Node number according to body reference',nodeLocListTips);
            delete(dt2); % To load datatips
            % #.#. Format
            axtoolbar(ax,{'export','brush','datacursor','pan','zoomin','zoomout'});
            brush(ax,'on');
            % #.#. Visibility
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graph5Number).Visible='on';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Visible='on';
            % #.#. Default selection
            if ~isempty(defaultBodies)
                defaultBrush=zeros([1 numel(x)],'uint8');
                defaultBrush(ismember(bodyIndex,defaultBodies))=1;
                set(ax.Children(1),'BrushData',defaultBrush);
            else
                defaultBrush=[];
            end
            % #.#. Tab
            tabNumberSave=findTab(app);
            app.TabGroup.SelectedTab=app.TabGroup.Children(tabNumber);
            % #.#. Waiting for selection
            waitfor(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.continueFullScreenNumber),'Value');
            % #.#. Tab
            app.TabGroup.SelectedTab=app.TabGroup.Children(tabNumberSave);

            % #. Post-selection
            % #.#. Brush processing
            [~,~,BID]=dataBrushedCallback(app,tabNumber,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graph5Number));
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.cancelFullScreenNumber).Value
                BID=logical(defaultBrush);
            end
            % #.#. Format
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.continueFullScreenNumber).Value=0;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.cancelFullScreenNumber).Value=0;
            clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber),'graph5');
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graph5Number).Visible='off';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Visible='off';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.maskingPanelNumber).Visible='off';
            for graphNum=1:4
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.(['graph' num2str(graphNum) 'Number'])).HitTest='on';
            end

            % #. Cancel
            if isempty(BID)
                return;
            end
            
            % #. Outputs
            body=bodyIndex(BID);
            nodeGlob=nodeGlobList(BID);
            nodeLoc=nodeLocList(BID);

        end

        function eqX=graphXCallbackFunction(app,tabNumber,graphNum,mode)
            % Abstract: apply selection from X list
            % mode:
            % 'Refresh': updates the Y list without adding the selection
            % 'Add': updates the Y list and adds the selection
            
            % #. Selection
            selection=selectionInfo(app,tabNumber,graphNum,[],[]);

            % #. Custom mode
            customData=DataManager([],[],[]);
            if any(strcmp(selection.choice.string.rough.X,customData(:,1)))
                indCustomData=find(strcmp(customData(:,1),selection.choice.string.rough.X),1,'first');
                customMode=true;
            else
                customMode=false;
            end
            
            % #. Adds selection
            if strcmpi(mode,'add')
                if customMode || selection.other.customMode
                    % #.#. Custom
                    eqX=strjoin(strcat('[',[{selection.choice.string.rough.X} reshape([customData{indCustomData,3}],1,[])],']'),'#');
                else
                    % #.#. Standard
                    eqX=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value; % May be different from selection structure
                    if isempty(eqX)
                        % Empty
                        eqX=['[',selection.choice.string.rough.X,']'];
                    elseif isequal(eqX,selection.data.global.rough.X{1})
                        % Erase
                        eqX=['[',selection.choice.string.rough.X,']'];
                    elseif eqX(1)=='#'
                        % Before
                        eqX=['[',selection.choice.string.rough.X,']' eqX];
                    else
                        % After
                        eqX=[eqX '[',selection.choice.string.rough.X,']'];
                    end
                end
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value=eqX;
            end

            % #. Steps
            [dataList,~,dataType,dataFile,~,~,~,matrixListFormat,~,~,~]=dataMainListFunction(app,tabNumber,'Refresh');
            steps={};
            if customMode || selection.other.customMode
                dataForStep=customData{indCustomData,3};
            else
                dataForStep={selection.choice.string.rough.X};
            end
            ind=find(ismember(dataList,dataForStep));
            type=dataType(ind);
            if any(ismember(type,'STEPS'))
                file=unique(dataFile(ind));
                steps=loadStepsListFunction(app,selection.other.pathSimu,file,'CODE');
            end

            % #. Y list update
            % e.g. load list of steps when a GRAPHIC is selected in list X
            if customMode
                % #.#. Custom
                % #.#.#. Processing
                listTemp=customData{indCustomData,2};
                modeInd=find(contains(listTemp,'!'));
                for modeNum=modeInd
                    [~,listTemp{modeNum}]=DataManager(app,listTemp{modeNum},selection);
                end
                % #.#.#. Assignment
                listTemp=[listTemp{:}];
                if ~iscell(listTemp); listTemp={listTemp}; end
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items=listTemp;
            elseif any(ismember(type,'STEPS'))
                % #.#. Step
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items=[{''} flip(steps)];
            else
                % #.#. Reset
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items=matrixListFormat;
            end

            if any(ismember(type,'STEPS'))

                % #. STEPS
                % #. Automatically adds the first step
                % Must be after modifying the X equation

                if ~isempty(steps)
                    indStep=find(strcmpi(steps,[selection.arg1.global.format.cat{:}]),1,'first');
                    if ~isempty(indStep) % Keeps the previous step
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value=steps{indStep};
                    else
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value=steps{1};
                    end
                    graphYCallbackFunction(app,tabNumber,graphNum,mode);
                end

            else

                % #. Other

                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items=matrixListFormat;

            end

        end
        
        function graphYCallbackFunction(app,tabNumber,graphNum,mode)
            % Abstract: apply selection from X list
            % mode:
            % 'Refresh': updates the Y list without adding the selection
            % 'Add': updates the Y list and adds the selection
            
            % #. Selection
            if ~strcmpi(mode,'add'); return; end
            selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            
            % #. Index where to add data in the equation text
            % Data is added at the point where 2 operators follow each 
            % other, or if an operator is added at the end
            eqX=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value;
            eqY=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value; % May be different from selection structure
            
            % #. Adds selection

            if ~isnan(str2double(selection.choice.string.rough.Y))

                % #. Steps

                indStep=strcmpi(selection.type.global.cat,'STEPS');
                varOld=strcat(selection.data.global.withoutArg2.rough.cat(indStep));
                varNew=strcat(erase(selection.data.global.withoutArgs.rough.cat(indStep),']'),'%',selection.choice.string.rough.Y,']');
                eqX=replace(eqX,varOld,varNew);
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value=eqX;

                % #. Direct display
                if isequal(selection.equation.global.rough.cat,selection.data.global.rough.cat{1}) ...
                        && all(indStep)
                    info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                    displayGraphMain(app,[],tabNumber,info,'Reset');
                end

            else

                % #.#. Data

                if isempty(eqY)
                    % Empty
                    eqY=['[',selection.choice.string.rough.Y,']'];
                elseif isequal(eqY,selection.data.global.rough.Y{1})
                    % Erase
                    eqY=['[',selection.choice.string.rough.Y,']'];
                elseif eqY(1)=='#'
                    % Before
                    eqY=['[',selection.choice.string.rough.Y,']' eqY];
                else
                    % After
                    eqY=[eqY '[',selection.choice.string.rough.Y,']'];
                end
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value=eqY;
            
            end

        end

        function guiStateExport(app)
            % Abstract: saves the state (in a .mat) of the interface when it is closed
            
            % #. Initialization
            guiExport=table();
            varExport=table();
            
            % #. Variables
            varExport.bodyIndex={app.bodyIndex};
            varExport.biDimVarExport={app.eulerView};
            varExport.lagrangianViewVarExport={app.lagrangianView};
            varExport.planarViewVarExport={app.planarView};
            varExport.radialViewVarExport={app.radialView};
            varExport.stepIniRunExport={app.stepIniRun};
            varExport.computationRenamedExport={app.computationRenamed};
            varExport.bodiesToRemoveExport={app.bodiesToRemove};
            
            % #. Tab
            for tabNumber=3:numel(app.TabGroup.Children)

                % #.#. Path
                displayPathExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value},'VariableNames',{'displayPathExport'});
                
                % #.#. STEPS
                stepListExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value},'VariableNames',{'stepListExport'});
                timeRegExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Children(app.id.regressionValueNumber).Value},'VariableNames',{'timeRegExport'});
                
                % #.#. SETTINGS
                settTemp=[];
                % #.#.#. First loop for main components (checkbox, etc.)
                for elementNum=1:numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children)
                    if strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uinumericeditfield') ...
                            || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uicheckbox') ...
                            || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uiradiobutton') ...
                            || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uidropdown')
                        settTemp.([app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Tag 'Export']) ...
                            ={app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Value};
                    elseif strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uibuttongroup')
                        % #.#.#. Second loop for secondary components (radio buttons, etc.)
                        for elementSubNum=1:numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children)
                            if strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uinumericeditfield') ...
                                    || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uicheckbox') ...
                                    || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uiradiobutton')
                                settTemp.([app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Tag 'Export']) ...
                                    ={app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Value};
                            end
                        end
                    end
                end
                
                % #.#. PLOT
                timeRefreshValueExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Value},'VariableNames',{'timeRefreshValueExport'});
                stopCheckExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value},'VariableNames',{'stopCheckExport'});
                stopCriterionExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Value},'VariableNames',{'stopCriterionExport'});
                stopEqExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value},'VariableNames',{'stopEqExport'});
                
                % #.#. Export
                guiExportTemp1=[displayPathExport,stepListExport,timeRegExport,timeRefreshValueExport,stopCheckExport,stopCriterionExport,stopEqExport,...
                    struct2table(settTemp)];
                
                % #.#. Graphs
                guiExportTemp2=cell(1,4);
                for graphNum=1:4
                    graphNumFunction(app, graphNum);
                    % #.#.#. Elements
                    graphXListExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Value},'VariableNames',{['graphXListExport' num2str(graphNum)]});
                    % If GRAPHICS, do not take the value directly, because
                    % if the list is not refreshed when the list is 
                    % reloaded on startup, a more recent GRAPHICS will be
                    % loaded, which not only displays the wrong GRAPHICS,
                    % but also triggers a load.
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                        if any(strcmpi(selection.type.global.unique,'STEPS'))
                            graphYListExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value},'VariableNames',{['graphYListExport' num2str(graphNum)]});
                        else
                            graphYListExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value},'VariableNames',{['graphYListExport' num2str(graphNum)]});
                        end
                    else
                        graphYListExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value},'VariableNames',{['graphYListExport' num2str(graphNum)]});
                    end
                    graphXEqExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value},'VariableNames',{['graphXEqExport' num2str(graphNum)]});
                    graphYEqExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value},'VariableNames',{['graphYEqExport' num2str(graphNum)]});
                    graphStartExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphStartNumberVar).Value},'VariableNames',{['graphStartExport' num2str(graphNum)]});
                    graphEndExport=table({app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphEndNumberVar).Value},'VariableNames',{['graphEndExport' num2str(graphNum)]});
                    % #.#.#. View
                    graphViewExport=table({axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar))},'VariableNames',{['graphViewExport' num2str(graphNum)]});
                    [az,el]=view(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));
                    graphViewRotExport=table({[az,el]},'VariableNames',{['graphViewRotExport' num2str(graphNum)]});
                    % #.#.#. Save
                    guiExportTemp2{graphNum}=[graphXListExport,graphYListExport,graphXEqExport,graphYEqExport,graphStartExport,graphEndExport,graphViewExport,graphViewRotExport];
                    clearvars('graphXListExport','graphYListExport','graphXEqExport','graphYEqExport','graphStartExport','graphEndExport','graphViewExport');
                end
                
                % #.#. Save
                guiExport(tabNumber-2,:)=[guiExportTemp1 guiExportTemp2{:}];
                clearvars('displayPathExport','stepListExport','timeRefreshValueExport','stopCheckExport','stopCriterionExport','stopEqExport');
            end
            
            % #. Export
            save([app.pwdSave filesep 'Save' filesep 'gui_save.mat'],'guiExport','varExport')

        end
        
        function guiStateImport(app)
            % Abstract: loads the state (in a .mat) of the interface
            
            if isfile([app.pwdSave filesep 'Save' filesep 'gui_save.mat'])
                
                % #. Read file
                load([app.pwdSave filesep 'Save' filesep 'gui_save.mat'],'guiExport','varExport');

                % #. Waitbar
                msg=waitbar(0,['Loading tabs (0/' num2str(size(guiExport,1)) ')'],'Name','Progress');
                
                % #. Variables
                app.bodyIndex=varExport.bodyIndex{1};
                app.eulerView=varExport.biDimVarExport{1};
                app.lagrangianView=varExport.lagrangianViewVarExport{1};
                app.planarView=varExport.planarViewVarExport{1};
                app.radialView=varExport.radialViewVarExport{1};
                app.stepIniRun=varExport.stepIniRunExport{1};
                app.computationRenamed=varExport.computationRenamedExport{1};
                app.bodiesToRemove=varExport.bodiesToRemoveExport{1};
                
                % #. Cluster
                % You are asked to enter your login details only once, otherwise (cancel or error) you are left in local mode
                serverList=unique(guiExport.runModeListExport);
                serverList(strcmpi(serverList,app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items{1}))=[]; % removes local
                for serverNum=1:numel(serverList)
                    s_conn=clusterLoadInfo(app,[],serverList(serverNum));
                    if isempty(s_conn)
                        serverInd=strcmp(guiExport.runModeListExport,serverList{serverNum});
                        guiExport.runModeListExport(serverInd)=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items(1); % Force the return to local
                    end
                end
                
                % #. Browse each tab stored in the .mat file
                for tabNumberVar=1:size(guiExport,1)
                    
                    % #.#. Tab creation
                    tabCreation(app);
                    app.TabGroup.Children(end).Title=['#' num2str(size(app.TabGroup.Children,1)-2,'%02.f')];
                    tabNumber=numel(app.TabGroup.Children);
                    
                    % #.#. Settings
                    % #.#.#. First loop for main components (checkbox, etc.)
                    for elementNum=1:numel(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children)
                        if strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uinumericeditfield') ...
                                || strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uicheckbox') ...
                                || strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uiradiobutton') ...
                                || strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uidropdown')
                        app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Value=...
                            guiExport.([app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Tag 'Export']){tabNumberVar};
                        elseif strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uibuttongroup')==1
                            % #.#.#. Second loop for secondary components (radio buttons, etc.)
                            for elementSubNum=1:numel(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children)
                                if strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uinumericeditfield') ...
                                        || strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uicheckbox') ...
                                        || strcmp(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uiradiobutton')
                                app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Value=...
                                    guiExport.([app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Tag 'Export']){tabNumberVar};
                                end
                            end
                        end
                    end
                    
                    % #.#. SAVE
                    app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value=guiExport.displayPathExport{tabNumberVar};
                    app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Children(app.id.regressionValueNumber).Value=guiExport.timeRegExport{tabNumberVar};

                    % #.#.#. Reading CONTROL
                    loadPathFunction(app,tabNumber);

                    % #.#. STEP
                    stepListValueChangedFunction(app,tabNumber);
                    %app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value=guiExport.stepListExport{tabNumberVar};
                    
                    % #.#. PLOT
                    app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Value=guiExport.timeRefreshValueExport{tabNumberVar};
                    app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Value=guiExport.stopCriterionExport{tabNumberVar};
                    app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value=guiExport.stopEqExport{tabNumberVar};

                    % #.#. GRAPHS
                    for graphNum=1:4
                        % List
                        graphNumFunction(app, graphNum);
                        try
                            app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Value=guiExport.(['graphXListExport' num2str(graphNum)]){tabNumberVar};
                            graphXCallbackFunction(app,tabNumber,graphNum,'Refresh');
                            app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value=guiExport.(['graphYListExport' num2str(graphNum)]){tabNumberVar};
                        catch
                            % Do nothing
                        end
                        % Eq
                        app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value=guiExport.(['graphXEqExport' num2str(graphNum)]){tabNumberVar};
                        app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value=guiExport.(['graphYEqExport' num2str(graphNum)]){tabNumberVar};
                        app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphStartNumberVar).Value=guiExport.(['graphStartExport' num2str(graphNum)]){tabNumberVar};
                        app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphEndNumberVar).Value=guiExport.(['graphEndExport' num2str(graphNum)]){tabNumberVar};
                        try
                            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                            ax=displayGraphMain(app,[],tabNumber,info,'reset');
                            axis(ax,guiExport.(['graphViewExport' num2str(graphNum)]){tabNumberVar});
                            view(ax,guiExport.(['graphViewRotExport' num2str(graphNum)]){tabNumberVar});
                        catch
                            % Do nothing
                        end
                    end

                    if ishandle(msg)
                        waitbar(tabNumberVar/size(guiExport,1),msg,['Loading tabs (' num2str(tabNumberVar) '/' num2str(size(guiExport,1)) ')']);
                    else
                        msgbox('Importation has been canceled.','Information','help');
                        break;
                    end
                end
            end
            
            % #. MSG
            if ishandle(msg)
                delete(msg);
            end
            
        end

        function infoFunction(~,fig)
            % Abstract: provides geometric information on a selection of nodes
            
            % #. Inputs
            ax=findobj(fig,'Tag','rulerContourAxes');
            x=reshape(ax.Children(end).XData,[],1); y=reshape(ax.Children(end).YData,[],1); % Reshape required for boundary
            if length(x)<=2 || length(y)<=2
                % If 2 points are selected, there is no information to add
                return;
            end
            
            % #. Calculation
            coef=findobj(fig,'Tag','rulerContourCoef');
            kContour=coef.Value;
            kBoundary=boundary(x,y,kContour);
            area=polyarea(x(kBoundary),y(kBoundary));
            perimeter=sum(vecnorm(([x(kBoundary(1:end-1))-x(kBoundary(2:end)) y(kBoundary(1:end-1))-y(kBoundary(2:end))])'));
            equivRadius=sqrt(area/pi); % Equivalent radius of a circle with the same area
            equivDiameter=equivRadius*2; % Equivalent diameter of a circle with the same area
            
            % #. MSG
            msgNew{1,1}='#. Contour (based on the contour detection)';
            msgNew{2,1}=['Number of nodes on the contour: ' num2str(length(kBoundary)-1)]; % -1 because closed border
            msgNew{3,1}=['Area: ' num2str(area)];
            msgNew{4,1}=['Perimeter: ' num2str(perimeter)];
            msgNew{5,1}=['Equivalent radius (based on an area equivalency): ' num2str(equivRadius)];
            msgNew{6,1}=['Equivalent diameter (based on an area equivalency): ' num2str(equivDiameter)];
            textBox=findobj(fig,'Tag','rulerContourText');
            ind=find(contains(textBox.Value,'#. Contour'));
            textBox.Value=[textBox.Value(1:ind-1) ; msgNew];
            
            % #. Plot
            cla(ax,'reset');
            plot(ax,x,y,'rx','DisplayName','Selected nodes');  hold(ax,'on'); axis(ax,'equal'); grid(ax,'on'); xlabel(ax,'X'); ylabel(ax,'Y');
            plot(ax,x(kBoundary),y(kBoundary),'b--','DisplayName','Contour');
            legend(ax);
            set(ax,'Tag','rulerContourAxes');

        end

        function loadGraphicsFunction(app,pathSimu,typeNamesCell,stepNamesCell,loadingMsg)
            % Abstract: converts the selected steps into .mat
            
            % #. Steps to load
            if isempty(stepNamesCell)
                stepNamesCell=cell(1,numel(pathSimu)); % Steps to load
                for pathNum=1:numel(pathSimu)
                    [~,~,~,stepNamesCell{pathNum}]=loadStepsListFunction(app,pathSimu{pathNum},typeNamesCell{pathNum},'CODE');
                end
            end
            indToDel=cellfun(@isempty,stepNamesCell);
            pathSimu(indToDel)=[];  typeNamesCell(indToDel)=[]; stepNamesCell(indToDel)=[];
            
            % #. Processing
            mainLoading(app,pathSimu,typeNamesCell,stepNamesCell,'.vtk',loadingMsg);
            
        end

        function [stepsFormat,stepsIntersection,ext,stepsUnion,stepsAll]=loadListStep(app,tabNumberOrPath)
            % Abstract: loads the list of steps

            % #. Path
            if ischar(tabNumberOrPath)
                pathSimu=tabNumberOrPath;
            else
                pathSimu=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            end

            % #. Data information
            [~,~,dataType,dataFile]=dataMainListFunction(app,tabNumberOrPath,'Refresh');
            indSteps=strcmpi(dataType,'STEPS');
            files=unique(dataFile(indSteps));

            % #. Steps
            [stepsIntersection,ext,stepsUnion,stepsAll]=loadStepsListFunction(app,pathSimu,files,'Code');
            stepsFormat=stepsUnion;
            if isempty(stepsFormat)
                stepsFormat={''};
            end

        end

        function info=loadMarker(app,info)
            % Abstract: Plots a graphical marker of the current time of the 
            % selected GRAPHICS on the plots
            
            % #. Data
            % Cell containing the name of the associated time for each data
            varTransformOld=dataUniqueListFunction(app,info.selection.other.tabNumber,0);
            [~,indSort]=sort(cellfun(@numel,varTransformOld),'descend');% Some data names (e.g. [example]) may contain other data names (e.g. [exa])
            if numel(varTransformOld)<=1
                return;
            end
            indLogicalTime=contains(varTransformOld,'Time'); indFindTime=find(indLogicalTime);
            indItt=find(contains(varTransformOld,'Iteration')); % Is located before time and therefore requires special processing
            ind=double(indLogicalTime); ind(indFindTime)=indFindTime; ind=cummax(ind);
            ind(indItt)=ind(indItt+1);
            varTransformNew=varTransformOld(ind);
            varTransformNew=strcat('info.data.',lower(replace(varTransformNew(indSort),{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_')));
            varTransformOld=strcat('info.data.',lower(replace(varTransformOld(indSort),{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_')));
            
            % #. Grounds
            groundInd=find(ismember(info.selection.class.grounds.cat,'array'));
            groundNumber=length(info.selection.equation.grounds.format.X);
            groundNameListOld=string(fieldnames(info.grounds));
            groundNameList=string(strcat({'ground'},arrayfun(@(x) cellstr(num2str(x)),groundNumber+1:groundNumber+numel(groundInd))));
            numX=numel(strsplit(app.TabGroup.Children(info.selection.other.tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value,'#'));
            numY=numel(strsplit(app.TabGroup.Children(info.selection.other.tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value,'#'));
            kGround=0;
            
            for groundNum=groundInd
                kGround=kGround+1;
                % Add “[” and “]” to differentiate data from functions
                eqOld={replace(info.selection.equation.grounds.var.X{groundNum},info.selection.data.unique.var.cat,strcat('[',info.selection.data.unique.var.cat,']')) ...
                    replace(info.selection.equation.grounds.var.Y{groundNum},info.selection.data.unique.var.cat,strcat('[',info.selection.data.unique.var.cat,']'))};
                % #.#. Non-empty plots
                for axeNum=1:2
                    
                    % #.#. Input
                    eqVar=eqOld{axeNum};
                    
                    % #.#. Add subscript (:) if none specified
                    indVar=strfind(eqVar,']');
                    indVar(ismember(indVar,strfind(eqVar,'](')))=[];
                    for varNum=size(indVar,2):-1:1
                        eqVar=[eqVar(1:indVar(varNum)) '(:)' eqVar(indVar(varNum)+1:end)];
                    end
                    
                    % #.#. Enclosing brackets
                    % - Enables functions to be processed
                    % e.g. For mean([Time S1](indices)), do not replace the indices
                    % e.g. For flip([Time S1](indices)), the indices must be replaced by
                    % e.g. For mean(flip([Time S1](indices))), do not replace the indices
                    % -> This looks at the size of the outputs of the enclosing function
                    % - The while loop is used to remove the calculation brackets to be removed from the processing
                    % e.g. (mean([Time S1](indices))*2
                    % -> The first pair of brackets should be ignored
                    % #.#.#. Parenthesis indices
                    indOpen=strfind(eqVar,'('); indClose=strfind(eqVar,')');
                    while 1
                        indVerifInd=sort([indOpen indClose]);
                        % #.#.#. Matrix with the number of open parentheses after the addition of an ith parenthesis
                        % e.g. ( ( ) ) == 1 2 1 0
                        indVerif=zeros(1,indClose(end)); indVerif(indOpen)=1; indVerif(indClose)=-1; indVerif(indVerif==0)=[];
                        indVerif=cumsum(indVerif);
                        % #.#.#. Remove non-overlapping parentheses
                        % == 1 -> First opening of the parenthesis
                        % == 0 -> Closing of the first parenthesis
                        indOpenTemp=intersect(indVerifInd(indVerif==1),indOpen);
                        indCloseTemp=intersect(indVerifInd(indVerif==0),indClose);
                        % #.#.#. Remove calculation brackets
                        if any(indOpenTemp==1)
                            % If parenthesis in 1st position, it is 
                            % necessarily a calculation parenthesis
                            indClose(indClose==indCloseTemp(indOpenTemp==1))=[];
                            indOpen(indOpenTemp==1)=[];
                            continue
                        elseif any(~ismember(eqVar(indOpenTemp-1),']') & ~isletter(eqVar(indOpenTemp-1)))
                            % If an opening parenthesis does not follow a 
                            % '}' or a letter, it is a calculation parenthesis.
                            indClose(ismember(indClose,indCloseTemp(~ismember(eqVar(indOpenTemp-1),']') & ~isletter(eqVar(indOpenTemp-1)))))=[];
                            indOpen(ismember(indOpen,indOpenTemp(~ismember(eqVar(indOpenTemp-1),']') & ~isletter(eqVar(indOpenTemp-1)))))=[];
                            continue;
                        end
                        break;
                    end
                    
                    indBrackets=[indOpenTemp', indCloseTemp'];
                    
                    % #.#. Modification of indices as appropriate
                    % Since the indices are enclosing brackets, it must be possible to detect them
                    for bracketNum=size(indBrackets,1):-1:1
                        if indBrackets(bracketNum,1)==1
                            % #.#.#. Calculation bracket
                            continue;
                        elseif strcmp(eqVar(indBrackets(bracketNum,1)-1),']')==1
                            % #.#.#. Index bracket
                            dataName=eqVar(find(eqVar(1:indBrackets(bracketNum,1)-2)=='[',1,'last')+1:indBrackets(bracketNum,1)-2); % Data
                            timeName=varTransformNew{find(strcmp(varTransformOld,dataName),1,'first')}; % Corresponding time
                            [~,argNew]=min(abs(eval(strcat(timeName,eqVar(indBrackets(bracketNum,1):indBrackets(bracketNum,2))))-info.selection.markerTime));
                            indDot=strfind(eqVar(indBrackets(bracketNum,1):indBrackets(bracketNum,2)),':')+indBrackets(bracketNum,1)-1;
                            shift=str2double(eqVar(indBrackets(bracketNum,1)+1:indDot-1))-1; % Offset according to starting index
                            if ~isnan(shift)
                                argNew=argNew+shift;
                            end
                            eqVar=[eqVar(1:indBrackets(bracketNum,1)) num2str(argNew) eqVar(indBrackets(bracketNum,2):end)];
                        elseif isletter(eqVar(indBrackets(bracketNum,1)-1))==0
                            % #.#.#. Calculation bracket
                            continue;
                        else
                            % #.#.#. Function bracket
                            % #.#.#.#. Find the beginning of the function
                            indBrackets(bracketNum,1)=indBrackets(bracketNum,1)-1;
                            while isletter(eqVar(indBrackets(bracketNum,1)))==1
                                indBrackets(bracketNum,1)=indBrackets(bracketNum,1)-1;
                                if indBrackets(bracketNum,1)==0
                                    break;
                                end
                            end
                            indBrackets(bracketNum,1)=indBrackets(bracketNum,1)+1;
                            % #.#.#.#. Evaluation
                            sizeFunc=size(eval(eqVar(indBrackets(bracketNum,1):indBrackets(bracketNum,2))));
                            if ~isequal(sizeFunc,[1 1])
                                % #.#.#.#. Non-scalar output
                                % Modify all the indices inside
                                % New reference: indSubBrackets=indBrackets(bracketNum,1):indBrackets(bracketNum,2)
                                indSubBrackets1=(strfind(eqVar(indBrackets(bracketNum,1):indBrackets(bracketNum,2)),'](')+indBrackets(bracketNum,1))'; % No -1 because it gives the index of the '}' and we want '(' i.e. -1+1=0
                                indSubBrackets2=zeros(size(indSubBrackets1));
                                indSubBrackets=[indSubBrackets1 indSubBrackets2];
                                for subBracketNum=size(indSubBrackets,1):-1:1
                                    % #.#.#.#. Closing parenthesis index
                                    indSubBrackets(subBracketNum,2)=find(eqVar(indSubBrackets(subBracketNum,1):indBrackets(bracketNum,2))==')',1,'first')+indSubBrackets(subBracketNum,1)-1;
                                    % #.#.#.#. Index bracket
                                    % #.#.#.#.#. Index of the variable concerned
                                    dataName=eqVar(find(eqVar(1:indSubBrackets(subBracketNum,1)-2)=='[',1,'last')+1:indSubBrackets(subBracketNum,1)-2); % Data
                                    timeName=varTransformNew{find(strcmp(varTransformOld,dataName),1,'first')}; % Corresponding time
                                    [~,argNew]=min(abs(eval(strcat(timeName,eqVar(indSubBrackets(subBracketNum,1):indSubBrackets(subBracketNum,2))))-info.selection.markerTime));
                                    indDot=strfind(eqVar(indSubBrackets(subBracketNum,1):indSubBrackets(subBracketNum,2)),':')+indSubBrackets(subBracketNum,1)-1;
                                    shift=str2double(eqVar(indSubBrackets(subBracketNum,1)+1:indDot-1))-1; % Offset according to starting index
                                    if ~isnan(shift)
                                        argNew=argNew+shift;
                                    end
                                    eqVar=[eqVar(1:indSubBrackets(subBracketNum,1)) num2str(argNew) eqVar(indSubBrackets(subBracketNum,2):end)];
                                end
                            end
                        end
                    end
                    if axeNum==1
                        info.grounds.(groundNameList(kGround)).X=eval(erase(eqVar,{'[' ']'}));
                    else
                        info.grounds.(groundNameList(kGround)).Y=eval(erase(eqVar,{'[' ']'}));
                    end
                end
                
                % #. Options
                
                if isscalar(numX) && numY==2
                    if kGround==1
                        info.grounds.(groundNameListOld(kGround)).opts.Yyaxis='left';
                        info.grounds.(groundNameList(kGround)).opts.Yyaxis='left';
                    else
                        info.grounds.(groundNameListOld(kGround)).opts.Yyaxis='right';
                        info.grounds.(groundNameList(kGround)).opts.Yyaxis='right';
                    end
                end
                info.grounds.(groundNameList(kGround)).opts.Marker='.';
                info.grounds.(groundNameList(kGround)).opts.MarkerSize=25;
                info.grounds.(groundNameList(kGround)).opts.MarkerEdgeColor='r';
            end
        end

        function loadPathFunction (app,tabNumber)
            % Abstract: loads several information about the simulation and
            % displays the result on the interface
            
            % #. Resets the tab
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).FontColor=app.fontColorChanged; % Orange font to indicate that the information displayed may be inaccurate if the loading is not complete
            resetTab(app,tabNumber);
            
            % #. Path
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            
            if ~isempty(pathSimu)
                
                % #. Tab loading

                % #.#. Existing path
                if exist(pathSimu,'dir')==0
                    msgbox('This path does not exist.','Information','help'); return;
                end

                % #.#. Lists
                dataDisplayList(app,tabNumber,'Reset',0,[]);
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.filestodeleteListBoxNumber).ValueIndex=[]; % Avoid mistakes

                % #.#. EDITOR
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Value='';
                editorFiles=dir([pathSimu filesep 'CODE']);
                editorFilesList={editorFiles.name};
                indToRemove=ismember(editorFilesList,{'.' '..' '.DS_Store' 'Thumbs.db'});
                editorFilesList(indToRemove)=[];
                [~,indSort]=sort(lower(editorFilesList));
                editorFilesList=editorFilesList(indSort); % case-insensitive display
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Items=[{''} editorFilesList];

                % #.#. STEPS
                refreshSteps(app,tabNumber,1);

                % #.#. Update of the SAVE folder
                resetSave(app,tabNumber);
                timeSaveReset(app,tabNumber);

                % #.#. Font color
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;
                %app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).FontColor=app.fontColorUnchanged;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).FontColor=app.fontColorUnchanged;

                % #.#. Buttons activation
                %buttonsSaveActivation(app,tabNumber,'On');
                findExecution(app,tabNumber);

                % #.#. Tab name
                [nameSimu]=nameSimuFunction(app,tabNumber,[],[]);
                app.TabGroup.Children(tabNumber).Title=nameSimu;

                % #.#. Time
                timeSaveMainDisplay(app,tabNumber);

            else
                
                % #. Tab deletion
                if tabNumber~=numel(app.TabGroup.Children)
                    % #.#. Updates the variables associated to a tab index as it will change
                    tabChange=zeros(numel(app.TabGroup.Children),2);
                    tabChange(1:end,1)=1:numel(app.TabGroup.Children);
                    tabChange(1:end,2)=1:numel(app.TabGroup.Children);
                    tabChange(tabNumber:numel(app.TabGroup.Children),2)=tabChange(tabNumber:numel(app.TabGroup.Children),2)-1;
                    tabChange(tabNumber,:)=[];
                    refreshTabVar(app,tabChange);
                    % #.#. Deletion
                    delete(app.TabGroup.Children(tabNumber));
                    % #.#. Updates the tab names
                    for tabNumberVar=tabNumber:numel(app.TabGroup.Children)
                        [nameSimu]=nameSimuFunction(app,tabNumberVar,[],[]);
                        app.TabGroup.Children(tabNumberVar).Title=nameSimu;
                    end
                end
            end
            
        end

        function loadPathFunctionMain(app,tabNumber)
            % Abstract: loads a simulation folder and formats the GUI

            % #. Computation in progress
            if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'On')
                answer = questdlg('A computation is in progress, do you want to continue?','Warning','Yes','No','Cancel','Cancel');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(answer) || strcmpi(answer,'Cancel')
                    busyOff(app); return; % Cancel
                end
                if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']))
                    stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
                    delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
                end
            end

            % #. Potential file copying
            copyFilesFunction(app,tabNumber);

            % #. Loading
            loadPathFunction(app,tabNumber);

            % #. Settings by default
            % #.#. Eulerian view
            % Note that it must be outside the load function otherwise
            % during the GUI opening with a tabs import, the previous view
            % cannot be loaded
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value...
                =app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Items{2};
            % #.#. Periodic boundaries
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.periodicboundariesTextNumber).Value='-inf;inf;-inf;inf;-inf;inf';

            % #. The last tab must be empty
            if ~isempty(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value)
                tabCreation(app);
            end

            % #. Error message
            % pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            % if isempty(pathSimu)
            %     msgbox('The path is empty.','Information','help');
            % elseif ~exist(pathSimu,'dir')
            %     msgbox('This path does not exist.','Information','help');
            % end

        end

        function [stepsIntersection,ext,stepsUnion,stepsAll]=loadStepsListFunction(app,pathSimu,files,mode)
            % Abstract: gives the list of steps associated with a file and
            % the extension
            % mode:
            % 'Code': from the \CODE folder
            % 'Save': from the \Save folder

            % #. Directory content
            if strcmpi(mode,'Code')
                fileNames=dir([pathSimu filesep 'CODE']);
            else
                fileNames=dir([pathSimu filesep 'SAVE']);
            end
            fileNames={fileNames.name};
            fileNames(ismember(fileNames,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];

            % #. Format
            if ~iscell(files)
                files={files};
            end
            filesCatNumber=numel(files);

            % #. Initialization
            stepsAll=cell(1,filesCatNumber);
            stepsIntersection={};
            ext=cell(1,filesCatNumber);

            for fileCatNum=1:filesCatNumber

                % #. Steps
                [~,stepsAll{fileCatNum},ext{fileCatNum}]=fileparts(fileNames);
                stepsAll{fileCatNum}=erase(stepsAll{fileCatNum},files{fileCatNum});
                indToRemove=isnan(str2double(stepsAll{fileCatNum})); % The name must contain numbers only if the type/extension is removed from the name
                % #. Ext
                if strcmpi(mode,'CODE')
                    ind=find(ismember(ext{fileCatNum},app.graphicsFormat),1,'first');
                    if isempty(ind)
                        indToRemove=true(1,numel(ext{fileCatNum}));
                    else
                        extToKeep=ext{fileCatNum}{ind};
                        indToRemove=indToRemove | ~ismember(ext{fileCatNum},extToKeep); % limits the presence of duplicates
                    end
                end
                % #. Processing
                stepsAll{fileCatNum}(indToRemove)=[];
                ext{fileCatNum}(indToRemove)=[];
                if fileCatNum==1
                    stepsIntersection=stepsAll{fileCatNum};
                else
                    stepsIntersection=intersect(stepsIntersection,stepsAll{fileCatNum});
                end

                % #. Extension
                if isempty(ext{fileCatNum})
                    ext{fileCatNum}='';
                end

            end

            % #. Processing
            stepsUnion=unique([stepsAll{:}]);

        end

        function mainLoading(app,pathSimu,typeNamesCell,listSteps,ext,loadingMsg)
            % Abstract: converts some files into .mat
            
            % #. Pre-processing of files
            % #.#. Initialization
            k=0; filesSize=cell(1,numel(cellfun(@numel,listSteps))); % Useful to estimate the computing time that is proportional to the size of each file
            for pathNumberVar=numel(pathSimu):-1:1
                
                % #.#. Directory
                dirTemp=dir([pathSimu{pathNumberVar} filesep 'CODE']);
                
                for typeNum=numel(typeNamesCell{pathNumberVar}):-1:1
                    
                    % #.#. Files to load
                    % Files must exist and not already be loaded
                    indToRead=~isfile(strcat(pathSimu{pathNumberVar},filesep,'SAVE',filesep,typeNamesCell{pathNumberVar}{typeNum},listSteps{pathNumberVar}{typeNum},'.mat')) ...
                        & isfile(strcat(pathSimu{pathNumberVar},filesep,'CODE',filesep,typeNamesCell{pathNumberVar}{typeNum},listSteps{pathNumberVar}{typeNum},ext));
                    
                    % #.#. Removes unwanted files
                    if ~any(indToRead)
                        % #.#.#. Removes an empty type
                        typeNamesCell{pathNumberVar}(typeNum)=[];
                        listSteps{pathNumberVar}(typeNum)=[];
                        continue;
                    else
                        % #.#.#. Removes unwanted steps
                        listSteps{pathNumberVar}{typeNum}(~indToRead)=[];
                    end
                    
                    % #.#. File sizes
                    k=k+1; filesSize{k}=flip([dirTemp(ismember({dirTemp.name},strcat(typeNamesCell{pathNumberVar}{typeNum},listSteps{pathNumberVar}{typeNum},ext))).bytes]);
                    
                end
                
                % #.#. Removes an empty path
                if isempty(listSteps{pathNumberVar})
                    pathSimu(pathNumberVar)=[];
                    typeNamesCell(pathNumberVar)=[];
                    listSteps(pathNumberVar)=[];
                end
                
            end
            
            % #. Processing
            filesSize=[filesSize{:}]; filesSize=cumsum(flip(filesSize)); % Flip is used because the file sizes have not been computed in the same order as the loading below
            kTot=numel(filesSize); % Number of files to be loaded
            
            % #. Reads each path
            waitbarCreate=0; kLoc=0;
            if kTot~=0
                for pathNumberVar=1:numel(pathSimu)
                    
                    % #. Creates the save folder
                    if ~exist([pathSimu{pathNumberVar} filesep 'SAVE'],'dir')
                        mkdir([pathSimu{pathNumberVar} filesep 'SAVE']);
                    end
                    
                    % #. Reads each type
                    for typeNum=1:numel(typeNamesCell{pathNumberVar})
                        updateGraphics=0;
                        
                        % #. Reads each step
                        for stepNum=1:numel(listSteps{pathNumberVar}{typeNum})
                            stepToFind=listSteps{pathNumberVar}{typeNum}{stepNum};
                            kLoc=kLoc+1;
                            
                            % #.#. Waitbar
                            if ~waitbarCreate && kTot>1 % To create the waitbar only once and if there is more than one file to load
                                waitbarCreate=1;
                                tStart=tic;
                                msg=waitbar(0,['Path ' num2str(pathNumberVar) '/' num2str(numel(pathSimu)) ' - ' typeNamesCell{pathNumberVar}{typeNum} filesep stepToFind '.vtk (0/' num2str(kTot) ')'],'Name','Loading');
                            end
                            
                            % #.#. Initialization of XXXXX_Global
                            if ~updateGraphics
                                updateGraphics=1; graphicsGlobal=[];
                                if ~isfile([pathSimu{pathNumberVar} filesep 'SAVE' filesep typeNamesCell{pathNumberVar}{typeNum} 'Global.mat'])
                                    % #.#.#. If the file does not exist
                                    graphicsGlobal.X_MIN=table();
                                    graphicsGlobal.X_MAX=table();
                                    graphicsGlobal.Y_MIN=table();
                                    graphicsGlobal.Y_MAX=table();
                                    graphicsGlobal.Z_MIN=table();
                                    graphicsGlobal.Z_MAX=table();
                                else
                                    % #.#.#. If the file exist
                                    graphicsGlobal=load([pathSimu{pathNumberVar} filesep 'SAVE' filesep typeNamesCell{pathNumberVar}{typeNum} 'Global.mat']);
                                end
                            end
                            
                            % #.#. Conversion
                            try
                                % #.#.#. vtk file -> matlab workspace
                                [graphicsGlobal,graphicsCord]=readGraphics(app,pathSimu{pathNumberVar},typeNamesCell{pathNumberVar}{typeNum},stepToFind,ext,graphicsGlobal);
                                % #.#.#. matlab workspace -> mat file
                                save([pathSimu{pathNumberVar} filesep 'SAVE' filesep typeNamesCell{pathNumberVar}{typeNum} stepToFind '.mat'],'-struct','graphicsCord');
                            catch
                            end
                            
                            % #.#. Waitbar
                            % Closing the waitbar allows to stop the loading
                            if updateGraphics && waitbarCreate
                                if ishandle(msg)
                                    % #.#.#. Refresh
                                    waitbar(filesSize(kLoc)/filesSize(end),msg,['Path ' num2str(pathNumberVar) '/' num2str(numel(pathSimu)) ' - ' typeNamesCell{pathNumberVar}{typeNum} filesep stepToFind '.vtk (' num2str(kLoc) '/' num2str(kTot) ') - ' char(duration(0,0,(filesSize(end)-filesSize(kLoc))*toc(tStart)/filesSize(kLoc)))]);
                                else
                                    % #.#.#. Stops the loading
                                    save([pathSimu{pathNumberVar} filesep 'SAVE' filesep typeNamesCell{pathNumberVar}{typeNum} 'Global.mat'],'-struct','graphicsGlobal');
                                    delete(msg);
                                    msgbox('Loading has been stopped.','Information','help');
                                    return;
                                end
                            end
                        end
                        
                        % #. Save of XXXXX_Global
                        if updateGraphics
                            save([pathSimu{pathNumberVar} filesep 'SAVE' filesep typeNamesCell{pathNumberVar}{typeNum} 'Global.mat'],'-struct','graphicsGlobal');
                        end
                        
                        % #. Waitbar
                        if waitbarCreate
                            if ishandle(msg)
                                waitbar(filesSize(kLoc)/filesSize(end),msg,['Path ' num2str(pathNumberVar) '/' num2str(numel(pathSimu)) ' - ' typeNamesCell{pathNumberVar}{typeNum} filesep stepToFind '.vtk (' num2str(kLoc) '/' num2str(kTot) ') - ' char(duration(0,0,(filesSize(end)-filesSize(kLoc))*toc(tStart)/(filesSize(kLoc))))]);
                            end
                        end
                        
                    end
                    
                end
            end
            
            % #. Waitbar
            if waitbarCreate
                if ishandle(msg)
                    delete(msg);
                end
            end
            
            % #. MSG
            if loadingMsg
                msgbox('Loading is complete.','Success','help');
            end
        end

        function markerTime=markerTimeFunction(app,tabNumber,graphToExclude)
            % Abstract: gives the simulation time of the first step found and used in a graph
            % /!\ Not updated
            
            % #. Initialization
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            markerTime=[];
            graphNumVect=1:4;
            graphNumVect(graphNumVect==graphToExclude)=[];
            
            for graphNum=graphNumVect
                if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.(['graph' num2str(graphNum) 'Number'])).Children)
                    for eq='XY'
                        
                        % #. Global equations
                        marker.equation.global.rough.(eq)=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.(['gridGraph' num2str(graphNum) 'Number'])).Children(app.id.(['graph' num2str(graphNum) eq 'EqNumber'])).Value;
                        
                        % #. Ground equations
                        marker.equation.grounds.rough.(eq)=strsplit(marker.equation.global.rough.(eq),'#');
                        marker.equation.grounds.rough.(eq)(cellfun(@isempty,strtrim(marker.equation.grounds.rough.(eq))))='';
                        groundNumber=numel(marker.equation.grounds.rough.(eq));
                        
                        for groundNum=1:groundNumber
                            
                            % #. Data indices (without arguments)
                            % e.g. indOpen(dataGroundNum):indClose(dataGroundNum) -> '[Data]'
                            indOpen=strfind(marker.equation.grounds.rough.(eq){groundNum},'[');
                            indClose=strfind(marker.equation.grounds.rough.(eq){groundNum},']');
                            indToSupr=(indClose-indOpen)==1; % Some functions need []
                            indOpen(indToSupr)=[];
                            indClose(indToSupr)=[];
                            
                            % #. Initialization
                            marker.data.grounds.rough.(eq){groundNum}=repmat({''},1,numel(indOpen));
                            marker.arg1.grounds.rough.(eq){groundNum}=marker.data.grounds.rough.(eq){groundNum};
                            marker.data.grounds.roughWithoutArgs.(eq){groundNum}=marker.data.grounds.rough.(eq){groundNum};
                            marker.type.grounds.(eq){groundNum}=marker.data.grounds.rough.(eq){groundNum};
                            marker.format.grounds.(eq){groundNum}=marker.data.grounds.rough.(eq){groundNum};
                            marker.arg1.grounds.format.(eq){groundNum}=marker.data.grounds.rough.(eq){groundNum};
                            
                            for dataGroundNum=1:numel(indOpen)
                                
                                % #. rough
                                marker.data.grounds.rough.(eq){groundNum}{dataGroundNum}=marker.equation.grounds.rough.(eq){groundNum}(indOpen(dataGroundNum):indClose(dataGroundNum));
                                
                                % #. arg1 rough
                                % e.g. [Data%arg1] or [Data$arg1] -> %arg1 or $arg1
                                ind=max([strfind(marker.data.grounds.rough.(eq){groundNum}{dataGroundNum},'$') strfind(marker.data.grounds.rough.(eq){groundNum}{dataGroundNum},'%')]);
                                if ~isempty(ind)
                                    marker.arg1.grounds.rough.(eq){groundNum}{dataGroundNum}=marker.data.grounds.rough.(eq){groundNum}{dataGroundNum}(ind:end-1);
                                else
                                    marker.arg1.grounds.rough.(eq){groundNum}{dataGroundNum}='';
                                end
                                
                                % #. roughWithoutArgs
                                % e.g. [Data%arg1] -> Data
                                marker.data.grounds.roughWithoutArgs.(eq){groundNum}{dataGroundNum}=erase(marker.data.grounds.rough.(eq){groundNum}{dataGroundNum},{'[' ']' marker.arg1.grounds.rough.(eq){groundNum}{dataGroundNum}});
                                
                                if strcmpi(eq,'X')
                                    
                                    % #. Type
                                    % Type of the corresponding data
                                    choiceValue=find(strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Items,marker.data.grounds.roughWithoutArgs.(eq){groundNum}{dataGroundNum}),1,'last');
                                    indType=find(contains(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Items(1:choiceValue),'%%%'),1,'last');
                                    if isempty(indType)
                                        % Without type
                                        continue;
                                    else
                                        % With a type
                                        marker.type.grounds.(eq){groundNum}{dataGroundNum}=strtrim(erase(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Items{indType},'%'));
                                    end
                                    
                                    % #. Format
                                    % Information about the file format of the data (useful for loading them)
                                    if ~isempty(marker.type.grounds.(eq){groundNum}{dataGroundNum}) && ~strcmpi(marker.type.grounds.(eq){groundNum}{dataGroundNum},'custom')
                                        % Primary data
                                        indTypeInData=find(strcmp(app.primaryData(:,1),marker.type.grounds.(eq){groundNum}{dataGroundNum}),1,'first');
                                        marker.format.grounds.(eq){groundNum}{dataGroundNum}=strjoin(app.primaryData{indTypeInData,4});
                                    end
                                    
                                end
                                
                                % #. arg1 format
                                % e.g. [Data%arg1] or [Data$arg1] or [Data] (arg1 specified in Y equation) -> arg1
                                if strcmpi(eq,'X')
                                    % #.#. X
                                    stepTemp=str2double(erase(marker.arg1.grounds.rough.(eq){groundNum}{dataGroundNum},{'%' '$' '[' ']'}));
                                    if ~isnan(stepTemp)
                                        marker.arg1.grounds.format.(eq){groundNum}{dataGroundNum}=num2str(stepTemp,['%0' num2str(app.stepDigits) '.f']);
                                    end
                                else
                                    % #.#. Y
                                    % If the argument is not specified directly in
                                    % the data (% or $), it will be determined from
                                    % the Y equation.
                                    if ~isnan(str2double(marker.data.grounds.roughWithoutArgs.(eq){groundNum}{dataGroundNum})) && numel(marker.data.grounds.roughWithoutArgs.(eq){groundNum}{dataGroundNum})==app.stepDigits
                                        ind=cellfun(@isempty,marker.arg1.grounds.rough.X{groundNum});
                                        marker.arg1.grounds.format.X{groundNum}(ind)=deal(marker.data.grounds.roughWithoutArgs.(eq){groundNum}(dataGroundNum));
                                    end
                                end
                                
                            end
                        end
                        
                    end
                    
                    % #. Multiple
                    % All the data in a row cell for each equation
                    marker.type.multiple.X=[marker.type.grounds.X{:}];
                    marker.format.multiple.X=[marker.format.grounds.X{:}];
                    marker.arg1.multiple.rough.X=[marker.arg1.grounds.rough.X{:}];
                    marker.arg1.multiple.format.X=[marker.arg1.grounds.format.X{:}];
                    
                    % #. Time
                    % Finds the simulation time of the first relative step
                    ind=find(contains(marker.format.multiple.X,'!step') & ~contains(marker.arg1.multiple.rough.X,'$'),1,'first');
                    if ~isempty(ind)
                        [~,timeValue]=timeSimulationRead(app,pathSimu,[marker.type.multiple.X{ind} '_' marker.arg1.multiple.format.X{ind} '.vtk']);
                        if ~isnan(timeValue) && ~isempty(timeValue)
                            markerTime=timeValue;
                            break;
                        end
                    end
                    
                end
            end
        end

        function moveTabsFunction(app)
            % Abstract: updates tab variables

            % #. tabNumber
            tabNumber=findTab(app);
            
            % #. Selection of the new tab position
            % #.#. List of the tabs
            tabList=cell(1,numel(app.TabGroup.Children)-2);
            for tabNumberVar=3:numel(app.TabGroup.Children)
                tabList{tabNumberVar-2}=app.TabGroup.Children(tabNumberVar).Title;
            end
            % #.#. Selection
            [ind,~]=listdlg('PromptString',{'Select the new position:'},'ListString',tabList,'InitialValue',tabNumber-2,'SelectionMode','single');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(ind)
                busyOff(app); return;
            end
            
            % #. Moving the tabs
            % Note that strictly speaking is not possible to move tabs, the tabs are recreated with a new order
            newTabNumber=ind+2;
            if newTabNumber==tabNumber
                % #.#. No change
                busyOff(app); return;
            elseif newTabNumber<tabNumber
                % #.#. Moving backward
                app.TabGroup.Children=[app.TabGroup.Children(1:newTabNumber-1);app.TabGroup.Children(tabNumber);app.TabGroup.Children(newTabNumber:tabNumber-1);app.TabGroup.Children(tabNumber+1:end)];
            elseif newTabNumber>tabNumber
                % #.#. Moving forward
                app.TabGroup.Children=[app.TabGroup.Children(1:tabNumber-1);app.TabGroup.Children(tabNumber+1:newTabNumber);app.TabGroup.Children(tabNumber);app.TabGroup.Children(newTabNumber+1:end)];
            end
            
            % #. Changes the selected tab
            app.TabGroup.SelectedTab=app.TabGroup.Children(newTabNumber);
            tabNumberSave=newTabNumber;
            save([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave');
            
            % #. Refreshes the tab names
            for tabNumberVar=min([tabNumber newTabNumber]):numel(app.TabGroup.Children)
                [nameSimu]=nameSimuFunction(app,tabNumberVar,[],[]);
                app.TabGroup.Children(tabNumberVar).Title=nameSimu;
            end
            
            % #. The last tab must be empty
            if ~isempty(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value)
                tabCreation(app);
            end
            
            % #. Updating variables that are associated to a tab index
            tabChange=zeros(numel(app.TabGroup.Children),2);
            tabChange(:,1)=1:numel(app.TabGroup.Children);
            if newTabNumber<tabNumber
                % #.#. Moving backward
                % Unchanged tabs
                tabChange(1:newTabNumber-1,2)=tabChange(1:newTabNumber-1,1);
                tabChange(tabNumber+1:end,2)=tabChange(tabNumber+1:end,1);
                % Changed tabs
                tabChange(newTabNumber:tabNumber,2)=tabChange(newTabNumber:tabNumber,1)+1;
                tabChange(tabNumber,2)=newTabNumber;
            elseif newTabNumber>tabNumber
                % #.#. Moving forward
                % Unchanged tabs
                tabChange(1:tabNumber-1,2)=tabChange(1:tabNumber-1,1);
                tabChange(newTabNumber+1:end,2)=tabChange(newTabNumber+1:end,1);
                % Changed tabs
                tabChange(tabNumber:newTabNumber,2)=tabChange(tabNumber:newTabNumber,1)-1;
                tabChange(tabNumber,2)=newTabNumber;
            end
            refreshTabVar(app,tabChange);
            
        end

        function [nameSimu]=nameSimuFunction(app,tabNumber,pathToDisplay,sizePathToDisplay)
            % Abstract: gives tab name according to path and parameters entered by user
            
            % #. Initialization
            if isnumeric(pathToDisplay) % To take in account empty path
                pathToDisplay=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            end
            indPathToDisplay=strfind(pathToDisplay,filesep);
            if isempty(sizePathToDisplay)
                sizePathToDisplay=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.sizePathDisplayNumber).Value;
            end
            
            % #. Adapts name to parameters
            if isempty(pathToDisplay) ...
                    || ~sizePathToDisplay
            % #.#. Without path display
            nameSimu=['#' num2str(tabNumber-2,'%02.f')];
            
            elseif sizePathToDisplay<-1*numel(indPathToDisplay) ...
                    || sizePathToDisplay>numel(indPathToDisplay)
            % #.#. Fullpath because size too large
            nameSimu=['#' num2str(tabNumber-2,'%02.f') ' (' pathToDisplay ')'];
            
            elseif sizePathToDisplay>=0
                % #.#. Path from the end
                indPathToDisplay=strfind(pathToDisplay,filesep);
                nameSimu=['#' num2str(tabNumber-2,'%02.f') ' (' pathToDisplay(indPathToDisplay(end-sizePathToDisplay+1)+1:end) ')'];
                
            elseif sizePathToDisplay<0
                % #.#. Path from the beginning
                indPathToDisplay=strfind(pathToDisplay,filesep);
                nameSimu=['#' num2str(tabNumber-2,'%02.f') ' (' pathToDisplay(1:indPathToDisplay(abs(sizePathToDisplay))-1) ')'];
            end
        end

        function openFile(~,pathFile)
            % Abstract: opens a file or a folder
            
            % #. Inputs
            [~,fileName1,fileName2]=fileparts(pathFile); fileNameWithoutPath=[fileName1 fileName2];
            
            % #. Processing
            if isfile(pathFile) || exist(pathFile,'dir')~=0
                % #.#. Opens the file if it exists
                if filesep=='\' % windows
                    winopen(pathFile);
                else  % linux/MacOs
                    system(['open "', pathFile, '" &']);
                end
            else
                % #.#. File does not exist
                msgbox([fileNameWithoutPath ' does not exist.'],'Information','help');
            end

        end

        function openProgram(app,fileName,logName)
            % Abstract: opens a MATLAB program
            
            % #. Computation in progress
            tabNumber=findTab(app);
            if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'On')
                answer = questdlg('A computation is in progress, do you want to continue?','Warning','Yes','No','Cancel','Cancel');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(answer) || strcmpi(answer,'Cancel')
                    return; % Cancel
                end
            end
            
            % #. Paths
            % #.#. Input
            [folderName,fileName1,fileName2]=fileparts(fileName);
            fileNameWithoutPath=[fileName1 fileName2];
            % #.#. Simulation
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            if ~exist(pathSimu,'dir')
                msgbox('Simulation path cannot be found.','Information','help');
                return;
            end
            
            % #. If previous files are found in the current simulation folder
            if isfile([pathSimu filesep 'PREPROCESSING' filesep fileNameWithoutPath])
                answer = questdlg('Do you want to open the session found in the PREPROCESSING folder?','Previous session','Yes','No','No');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if strcmpi(answer,'Yes')
                    copyfile([pathSimu filesep 'PREPROCESSING' filesep fileNameWithoutPath],folderName);
                end
            end
            
            % #. Opening
            openFile(app,fileName);
            
            % #. Log
            if ~exist([pathSimu filesep 'SAVE'],'dir')
                mkdir([pathSimu filesep 'SAVE']);
            end
            [~,~]=LogSave({'Has been opened'},{''},logName,[pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
        end

        function optionsGraphFunction(app,tabNumber,graphNumOrObject)
            % Abstract: modification of some graph properties
            % Some issues about which object is in foreground
            % ax
            % object
            % propMain
            % propSub

            while 1

                % #. Axes
                % #.#. Obj
                if isnumeric(graphNumOrObject)
                    graphNumFunction(app,graphNumOrObject);
                    ax=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar);
                else
                    ax=flip(findobj(graphNumOrObject,'type','axes','-or','type','uiaxes'));  % creation order
                end
                % #.#. Dlg
                % Even if one axes allows to return
                axNames=strcat( ...
                    {ax.Tag}', ...
                    {' - '},'[', ...
                    cellstr(string(num2str(reshape([ax.Position],4,[])'))), ...
                    ']' ...
                    );
                [ind,tf]=listdlg('PromptString',{'Select the axes:'},'ListString',axNames,'CancelString','End','SelectionMode','single');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if ~tf; return; end
                ax=ax(ind);

                % #. Objects that can be modified
                while 1
                    % #.#. DLG
                    objectList={'Axes' 'Graphs' 'Colorbars'};
                    [ind,tf]=listdlg('PromptString',{'What do you want to change?'},'ListString',objectList,'CancelString','Back','SelectionMode','single');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if ~tf; break; end
                    answer=objectList{ind};
                    if isempty(answer); break; end
                    % #.#. Object
                    yyaxisNum=1;
                    if strcmpi(answer,'Axes')
                        obj=ax;
                        if numel(ax.YAxis)==2 % yyaxis
                            [ind,tf]=listdlg('PromptString',{'Select a side for y axis:'},'ListString',{'Left' 'Right'},'CancelString','Back','SelectionMode','single');
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                            if ~tf; break; end
                            if ind==1
                                yyaxis(ax,'left');
                                yyaxisNum=1;
                            else
                                yyaxis(ax,'right');
                                yyaxisNum=2;
                            end
                        end
                    elseif strcmpi(answer,'Graphs')
                        obj=flip(ax.Children); % creation order
                        if numel(obj)>1
                            [ind,tf]=listdlg('PromptString',{'Select the object:'},'ListString',arrayfun(@class,obj,'UniformOutput',false),'CancelString','Back','SelectionMode','single');
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                            if ~tf; break; end
                            obj=obj(ind);
                        end
                    elseif strcmpi(answer,'Colorbars')
                        obj=FindObjects(ax,'Type','colorbar');
                        if numel(obj)>1
                            [ind,tf]=listdlg('PromptString',{'Select the object:'},'ListString',arrayfun(@class,obj,'UniformOutput',false),'CancelString','Back','SelectionMode','single');
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                            if ~tf; break; end
                            obj=obj(ind);
                        end
                    end
                    if isempty(obj)
                        %msgbox('No objects were found.','Information','help'); continue;
                    end

                    % #. Processing
                    propMain=sort(properties(obj));

                    % #.#. Main
                    while 1
                        [indMain,tf]=listdlg('PromptString',{'Select the property' 'to be modified'},'ListString',propMain,'CancelString','Back','SelectionMode','single');
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                        if ~tf; break; end
                        propMainCurrent=propMain{indMain};
                        propSub=sort(properties(obj.(propMainCurrent)));
                        % #.#. Sub
                        while 1
                            if ~isempty(propSub)
                                [indSub,tf]=listdlg('PromptString',{'Select the sub-property' 'to be modified'},'ListString',propSub,'CancelString','Back','SelectionMode','single');
                                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                                if ~tf; break; end
                                propSubCurrent=propSub{indSub};
                            end

                            % #.#. Reading value
                            % #.#.#. Current obj
                            if isempty(propSub)
                                propOldCurrent=obj.(propMainCurrent);
                            else
                                propOldCurrent=obj.(propMainCurrent).(propSubCurrent);
                            end
                            if isnumeric(propOldCurrent) && length(propOldCurrent)==numel(propOldCurrent)
                                % #.#.#. Numeric
                                propNewCurrent=num2str(propOldCurrent);
                            elseif ischar(propOldCurrent)
                                % #.#.#. Char
                                propNewCurrent=propOldCurrent;
                            elseif isstring(propOldCurrent)
                                % #.#.#. String
                                propNewCurrent=char(propOldCurrent);
                            elseif isempty(propSub) ...
                                    && strcmpi(class(obj.(propMainCurrent)),'matlab.lang.OnOffSwitchState')
                                % #.#.#. switchOnOff
                                propNewCurrent=char(propOldCurrent);
                            elseif ~isempty(propSub) ...
                                    && strcmpi(class(obj.(propMainCurrent).(propSubCurrent)),'matlab.lang.OnOffSwitchState')
                                % #.#.#. switchOnOff
                                propNewCurrent=char(propOldCurrent);
                            elseif iscell(propOldCurrent) && length(propOldCurrent)==numel(propOldCurrent)
                                % #.#.#. Cell containing char or string
                                if all(cellfun(@ischar,propOldCurrent))
                                    % Char
                                    propNewCurrent=strjoin(propOldCurrent,'; ');
                                elseif all(cellfun(@isstring,propOldCurrent)) && all(cellfun(@numel,propOldCurrent)==1)
                                    % String
                                    propNewCurrent=strjoin(cellstr(propOldCurrent),'; ');
                                else
                                    %msgbox('This property cannot be modified.','Information','help');
                                    if ~isempty(propSub)
                                        continue;
                                    else
                                        break;
                                    end
                                end
                            else
                                %msgbox('This property cannot be modified.','Information','help');
                                if ~isempty(propSub)
                                    continue;
                                else
                                    break;
                                end
                            end
                            % #.#.#. DLG
                            if isempty(propSub)
                                propNewCurrent=inputdlg({['Enter the new value (' propMainCurrent '):']},'Input',[1 100],{propNewCurrent});
                            else
                                propNewCurrent=inputdlg({['Enter the new value (' propMainCurrent ' > ' propSubCurrent '):']},'Input',[1 100],{propNewCurrent});
                            end
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                            if isempty(propNewCurrent); break; end
                            propNewCurrent=propNewCurrent{1};

                            % #.#. Saving value
                            if isempty(propSub)
                                % Main property
                                if isnumeric(propOldCurrent) && length(propOldCurrent)==numel(propOldCurrent)
                                    % #.#.#. Numeric
                                    obj.(propMainCurrent)=str2double(strsplit(propNewCurrent));
                                elseif ischar(propOldCurrent)
                                    % #.#.#. Char
                                    obj.(propMainCurrent)=propNewCurrent;
                                elseif isstring(propOldCurrent)
                                    % #.#.#. String
                                    obj.(propMainCurrent)=string(propNewCurrent);
                                elseif isempty(propSub) ...
                                        && strcmpi(class(obj.(propMainCurrent)),'matlab.lang.OnOffSwitchState')
                                    % #.#.#. switchOnOff
                                    obj.(propMainCurrent)=propNewCurrent;
                                elseif iscell(propOldCurrent) && length(propOldCurrent)==numel(propOldCurrent)
                                    % #.#.#. Cell containing char or string
                                    if all(cellfun(@ischar,propOldCurrent))
                                        % Char
                                        obj.(propMainCurrent)=strsplit(propNewCurrent,'; ');
                                    elseif all(cellfun(@isstring,propOldCurrent)) && all(cellfun(@numel,propOldCurrent)==1)
                                        % String
                                        propNewCurrent=strsplit(propNewCurrent,';');
                                        for i=1:numel(propNewCurrent)
                                            propNewCurrent{i}=string(propNewCurrent{i});
                                        end
                                        obj.(propMainCurrent)=propNewCurrent{i};
                                    end
                                end
                            else
                                % Sub property
                                if isnumeric(propOldCurrent)
                                    % #.#.#. Numeric
                                    obj.(propMainCurrent).(propSubCurrent)=str2double(strsplit(propNewCurrent));
                                elseif ischar(propOldCurrent)
                                    % #.#.#. Char
                                    obj.(propMainCurrent).(propSubCurrent)=propNewCurrent;
                                elseif isstring(propOldCurrent)
                                    % #.#.#. String
                                    obj.(propMainCurrent).(propSubCurrent)=string(propNewCurrent);
                                elseif ~isempty(propSub) ...
                                        && strcmpi(class(obj.(propMainCurrent).(propSubCurrent)),'matlab.lang.OnOffSwitchState')
                                    % #.#.#. switchOnOff
                                    obj.(propMainCurrent).(propSubCurrent)=propNewCurrent;
                                elseif iscell(propOldCurrent) && length(propOldCurrent)==numel(propOldCurrent)
                                    % #.#.#. Cell containing char or string
                                    if all(cellfun(@ischar,propOldCurrent))
                                        % Char
                                        obj.(propMainCurrent).(propSubCurrent)=strsplit(propNewCurrent,'; ');
                                    elseif all(cellfun(@isstring,propOldCurrent)) && all(cellfun(@numel,propOldCurrent)==1)
                                        % String
                                        propNewCurrent=strsplit(propNewCurrent,';');
                                        for i=1:numel(propNewCurrent)
                                            propNewCurrent{i}=string(propNewCurrent{i});
                                        end
                                        obj.(propMainCurrent).(propSubCurrent)=propNewCurrent{i};
                                    end
                                end
                                % Automatic addition of axes if label
                                if strcmpi(class(obj),'matlab.graphics.axis.Axes') || strcmpi(class(obj),'matlab.ui.control.UIAxes')
                                    if strcmpi(propMainCurrent,'XLabel') && strcmpi(propSubCurrent,'String') ...
                                            && strcmpi(ax.XAxis.Color,'none') ...
                                            && isempty(propOldCurrent) && ~isempty(propNewCurrent)
                                        if isprop(ax.Parent,'BackgroundColor')
                                            nearestColor=imbinarize(rgb2gray(ax.Parent.BackgroundColor),0.5);
                                            if isequal(nearestColor,[1 1 1])
                                                ax.XAxis.Color=[0 0 0];
                                            else
                                                ax.XAxis.Color=[1 1 1];
                                            end
                                        else
                                            ax.XAxis.Color=[0 0 0];
                                        end
                                        ax.XAxis.TickValuesMode='auto';
                                    elseif strcmpi(propMainCurrent,'YLabel') && strcmpi(propSubCurrent,'String') ...
                                            && strcmpi(ax.YAxis(yyaxisNum).Color,'none') ...
                                            && isempty(propOldCurrent) && ~isempty(propNewCurrent)
                                        if isprop(ax.Parent,'BackgroundColor')
                                            nearestColor=imbinarize(rgb2gray(ax.Parent.BackgroundColor),0.5);
                                            if isequal(nearestColor,[1 1 1])
                                                ax.YAxis(yyaxisNum).Color=[0 0 0];
                                            else
                                                ax.YAxis(yyaxisNum).Color=[1 1 1];
                                            end
                                        else
                                            ax.YAxis(yyaxisNum).Color=[0 0 0];
                                        end
                                        ax.YAxis(yyaxisNum).TickValuesMode='auto';
                                    end
                                end
                            end
                            if ~isempty(propSub)
                                continue;
                            else
                                break;
                            end
                        end
                    end
                end

            end

        end

        function overViewFunction(app,fig,FontSize,LabelInput)
            % Abstract: function for displaying all interface graphics
            % in a single tab. Automatically calculates a common scale
            % to facilitate interpretation. Define 2 quantities as
            % identical (and therefore with the same scale) by dividing
            % names into parts, with a user-defined number of parts to
            % be considered.
            % e.g. Position X 0 -1, if the number of parts is -1, we
            % keep Position X 0 and apply a common scale to all axes
            % having this name after this transformation.
            % To be improved: the positioning of graphs still remains
            % unsatisfactory in some cases, but it is difficult to
            % define a general criterion without drastically increasing
            % calculation time (large number of combinations).

            % #. Loading useful data
            [opts,graphs]=overViewOptions(app,1,LabelInput);
            if isempty(graphs); return; end
            
            % #. Assignment
            if ~isempty(FontSize)
                opts.FontSize=FontSize;
            end
            OverView(graphs,opts,'Parent',fig);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            
            % #. Format
            app.sliderOverview.Visible='on';
            app.changeOverview.Visible='on';
            app.optionOverview.Visible='on';
            app.hideOverview.Visible='on';
            app.stepSBOverview.Visible='on';
            app.stepFBOverview.Visible='on';
            app.stepSFOverview.Visible='on';
            app.stepFFOverview.Visible='on';

        end

        function [opts,graphs]= overViewOptions(app,graphsImport,LabelInput)
            % Abstract: gives important options for the overview
            % Save graphics if and only if graphsImport==1

            % #. Initialization
            if graphsImport==1
                graphs=cell(1,numel(app.TabGroup.Children));
            else
                graphs=[];
            end
            opts.Label=cell(1,numel(app.TabGroup.Children));
            opts.View=cell(1,numel(app.TabGroup.Children));
            opts.AxisScale=cell(1,numel(app.TabGroup.Children));
            opts.ColorScale=cell(1,numel(app.TabGroup.Children));
            
            % #. Loop on each tab
            kLabel=0; labelSave=false(1,numel(app.TabGroup.Children)); % Save if a tab label has already been saved
            for tabNumberVar=3:numel(app.TabGroup.Children)
                if ~app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.ignoredOverviewNumber).Value
                    % #.#. Loop on each graph
                    for graphNum=1:4
                        graphNumFunction(app, graphNum);
                        if ~isempty(app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                            % #.#.#. GRAPHIC
                            if graphsImport==1
                                graphs{tabNumberVar}{graphNum}=app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar);
                            end
                            % #.#.#. Label
                            if isempty(LabelInput)
                                [nameSimu]=nameSimuFunction(app,tabNumberVar,[],[]);
                                nameSimuSplit=strsplit(nameSimu,' ');
                                opts.Label{tabNumberVar}=erase(join(nameSimuSplit{2:end}),{'(' ')'});
                            else
                                if ~labelSave(tabNumberVar)
                                    kLabel=kLabel+1;
                                    labelSave(tabNumberVar)=1;
                                    opts.Label{tabNumberVar}=LabelInput{kLabel};
                                end
                            end
                            % #.#.#. SpaceScale
                            if contains(lower(app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value),'lagrangian') ...
                                    && app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.spaceGraphicsNumber).Value
                            opts.View{tabNumberVar}='Follow';
                            elseif app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.spaceGraphicsNumber).Value
                                opts.View{tabNumberVar}='Equal';
                            end
                            % #.#.#. AxisScale
                            opts.AxisScale{tabNumberVar}=app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.scalePlotDisplayNumber).Value;
                            % #.#.#. ColorScale
                            opts.ColorScale{tabNumberVar}=app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.scaleGraphicsDisplayNumber).Value;
                            if graphsImport~=1
                                continue
                            end
                        end
                    end
                end
            end
            
            % #. Automatic positioning
            opts.ManualPositioning=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.manualPosNumber).Value;
            
            % #. Remove empty elements
            ind=cellfun(@isempty,opts.AxisScale); % Parameter must be non-empty
            if graphsImport==1
                graphs(ind)=[];
                for tabNumberVar=numel(graphs):-1:1
                    graphs{tabNumberVar}(cellfun(@isempty,graphs{tabNumberVar}))=[];
                end
            end
            opts.Label(ind)=[];
            opts.View(ind)=[];
            opts.AxisScale(ind)=[];
            opts.ColorScale(ind)=[];
            opts.TicksColor='auto';
            %opts.TicksNumber=5;
            %opts.FontSize=18;

        end

        function overViewStepChange(app,mode)
            % Abstract: updates overview graphics according to 3 modes:
            % - 'slider': updates all graphics on other tabs via their sliders and imports the results
            % - 'refresh': imports the results of a tab that has already been updated
            % - 'stepShift': shift in step number

            % #. Information
            [opts,graphs]=overViewOptions(app,1,[]);

            % #. Mode
            if ~strcmpi(mode,'refresh')
                % Slider and shift
                tabNumberEnd=numel(app.TabGroup.Children);
            end

            % #.#. Update tab graphics
            if ~strcmpi(mode,'refresh')
                for tabNumberVar=3:tabNumberEnd
                    if strcmpi(mode,'slider')
                        % Slider
                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=app.sliderOverview.Value;
                        sliderFunction(app,tabNumberVar);
                    else
                        % Shift
                        stepForwardBackward(app,tabNumberVar,mode);
                    end
                end
            end
            OverViewRefresh(app.overviewPanel,graphs,opts.View);
            
        end

        function plotBoundariesFunction(app,tabNumber,buttonValue)
            % Abstract: graphically defines the limits of the plots
            % buttonValue = 1 -> Lower limit
            % buttonValue = 2 -> Upper limit

            % #. Figure detection with brush
            for graphNum=1:4
                graphNumFunction(app, graphNum);
                hTemp=findobj(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar),'type','line');
                if ~isempty(hTemp)
                    % #.#. Brush found
                    if any(get(hTemp(end),'BrushData')==1)
                        break; % Takes the first graph found
                    end
                end
                if graphNum==4
                    % #.#. No brush found
                    msgbox('No brushed data have been found.','Information','help');
                    return;
                end
            end
            
            % #. Brush analysis
            [~,~,BID]=dataBrushedCallback(app,tabNumber,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));

            % #. Equations
            eqTemp=struct();
            eqTemp.X=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value;
            eqTemp.Y=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value;

            % #. Real index calculation
            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            if buttonValue==1
                % #.#. Lower limit
                for eq='XY'
                    indColumn=find(strcmpi(info.selection.class.global.(eq),'MATRIX'));
                    for dataNum=1:numel(indColumn)
                        % #.#.#. New arg
                        argTemp=strsplit(info.selection.arg2.global.format.(eq){dataNum},':');
                        arg0=str2double(argTemp{1}(2:end)); % If the graphic is already crop, take it into account
                        arg1=min(BID);
                        argNew=arg0+arg1-1;
                        argTemp{1}=['(' num2str(argNew)];
                        argTemp=strjoin(argTemp,':');
                        % #.#.#. Replace
                        eqTemp.(eq)=replace(eqTemp.(eq),info.selection.data.global.rough.(eq){dataNum},[info.selection.data.global.withoutArg2.rough.(eq){dataNum} argTemp]);
                    end
                end
            elseif buttonValue==2
                % #.#. Upper limit
                for eq='XY'
                    indColumn=find(strcmpi(info.selection.class.global.(eq),'MATRIX'));
                    for dataNum=1:numel(indColumn)
                        % #.#.#. New arg
                        argTemp=strsplit(info.selection.arg2.global.format.(eq){dataNum},':');
                        arg0=str2double(argTemp{1}(2:end)); % If the graphic is already crop, take it into account
                        arg1=max(BID);
                        argNew=arg0+arg1-1;
                        argTemp{end}=[num2str(argNew),')'];
                        argTemp=strjoin(argTemp,':');
                        % #.#.#. Replace
                        eqTemp.(eq)=replace(eqTemp.(eq),info.selection.data.global.rough.(eq){dataNum},[info.selection.data.global.withoutArg2.rough.(eq){dataNum} argTemp]);
                    end
                end
            end

            % #. Format
            for eq='XY'
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.(['graph' eq 'EqNumberVar'])).Value=eqTemp.(eq);
            end
            
            % #. Update with new limits
            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            displayGraphMain(app,[],tabNumber,info,'refresh');

        end
        
        function [textNew,textOld]=processingEditedText(app,tabNumber,updateMode)
            % Abstract: loads/saves the text file and find the 
            % corresponding potential sections
            
            % #. Text
            textOld={''};
            textNew=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).Value;

            % #. Selection
            fileName=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Value;
            sectionName=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Value;
            sectionIndex=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).ValueIndex;   

            % #. Paths
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathFile=[pathSimu filesep 'CODE' filesep fileName];
            if isempty(fileName)
                resetEditedText(app,tabNumber);
                return;
            elseif ~isfile(pathFile)
                resetEditedText(app,tabNumber);
                msgbox('File not found','Warning','warn');
                return;
            end
            
            % #. File size
            % Avoids loading large files by mistake
            fileInfo=dir(pathFile);
            fileSize=double([fileInfo.bytes(:)].*1e-6);
            if fileSize>app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.sizeWarningDisplayNumber).Value
                warnChoice=questdlg(['The file size is relatively large (' num2str(round(fileSize,1)) ' Mb). Are you sure you want to load it?'],'Warning','Yes','No','No');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(warnChoice) || strcmpi(warnChoice,'No')
                    % The file is not reset so that it can be opened with the button
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Value='';
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Items={''};
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).Value={''};
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;
                    return;
                end
            end

            % #. Old text
            fileID=fopen(pathFile);
            textOld=textscan(fileID, '%s', 'Delimiter', '\n');
            textOld=textOld{1};
            fclose(fileID);
            lineNumber=numel(textOld);

            % #. Sections
            % indSec:
            % [start of section name 1 ... start of section name N]
            % [end of section name 1 ... end of section name N]
            % [end of section 1 ... end of section N]
            sep=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.sectionSeparatorDisplayNumber).Value;
            if isempty(sep)
                sectionNames={};
            else
                indSep=contains(textOld,sep); % line index where separator is found
                if any(indSep)
                    % #.#. Indices
                    % A section name can be stored on several lines. 
                    % We therefore need to find these lines, as well as
                    % the end of the section
                    indSecTemp=[indSep(1:end-1) indSep(2:end)];
                    indSecNameStart=reshape(find(ismember(indSecTemp,[0 1],"rows")),1,[])+1; % begin if [0 1]
                    if indSep(1)==1
                        indSecNameStart=[1 indSecNameStart]; % first line is a section
                    end
                    indSecNameEnd=reshape(find(ismember(indSecTemp,[1 0],"rows")),1,[]); % End if [1 0]
                    indSec=[indSecNameStart;indSecNameEnd;[indSecNameStart(2:end)-1 lineNumber]]; % [header beginning, header ending, section ending]; Document ends with a non-detected section so we add lineNumber as end
                    secNumber=size(indSec,2);
                    % #.#. Names
                    sectionNames=cell(1,secNumber);
                    for secNum=1:secNumber
                        if isscalar(unique(sep))
                            sepFormat=unique(sep);
                        else
                            sepFormat=sep;
                        end
                        sectionNames{secNum}=strip(erase([textOld{indSec(1,secNum):indSec(2,secNum)}],sepFormat));
                    end
                else
                    sectionNames={};
                end
            end

            % #. Format
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Items=[{''} sectionNames];
            if ~any(strcmp(sectionName,sectionNames))
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Value='';
                sectionName='';
            end

            % #. indSecChange
            % Indices of lines displayed/modified in the file
            if isempty(sectionName)
                % Without section
                indSecChange=[1 lineNumber];
            else
                % Section
                ind=find(strcmp(sectionNames(1:sectionIndex-1),sectionName),1,'last'); % sectionIndex allows duplicate section names; -1 for sectionIndex as the first cell, which is empty, does not count
                indSecChange=[indSec(2,ind)+1 indSec(3,ind)];
            end
            textOldDisplayed=textOld(indSecChange(1):indSecChange(2)); % can be the entire text or a section

            if updateMode==0

                % #. Reading
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).Value=textOldDisplayed;

            else

                % #. Writting
                fileID=fopen(pathFile,'w');
                % Before section
                for lineNum=1:indSecChange(1)-1
                    fwrite(fileID,textOld{lineNum});
                    fwrite(fileID,newline);
                end
                % Section
                for lineNum=1:numel(textNew)
                    fwrite(fileID,textNew{lineNum});
                    fwrite(fileID,newline);
                end
                % After section
                for lineNum=indSecChange(2)+1:lineNumber
                    fwrite(fileID,textOld{lineNum});
                    fwrite(fileID,newline);
                end
                fclose(fileID);

                % #. Updates GUI
                if isempty(sectionName)
                    processingEditedText(app,tabNumber,0); % sections might change
                end

                % #.#.#. Log
                if ~exist([pathSimu filesep 'SAVE'],'dir')
                    mkdir([pathSimu filesep 'SAVE']);
                end
                if isempty(sectionName)
                    LogSave(textNew,textOldDisplayed,['Changes made to the file: ' fileName], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
                else
                    LogSave(textNew,textOldDisplayed,['Changes made to the file: ' fileName ' (' sectionName ')'], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
                end

            end

            % #. Format
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;

        end

        function [graphicsGlobal,graphicsCord]=readGraphics(~,pathSimu,typeToFind,stepToFind,ext,graphicsGlobal)
            % Abstract: conversion of .vtk files into matlab variables and
            % store them in structures with appropriate names
            
            % #. Importation of .vtk
            filename=[pathSimu filesep 'CODE' filesep typeToFind stepToFind ext];
            [graphicsCord]=ReadVTKFiles(filename);
            if isempty(graphicsCord)
                return;
            end

            % #. Mesh only
            fnsTemp=fieldnames(graphicsCord);
            if all(ismember({'connectivity' 'x' 'y'},lower(fnsTemp)))
                nameFormat=lower(replace(typeToFind,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                graphicsCord.(nameFormat)=zeros(size(graphicsCord.X));
            end
            
            % #. Formated names
            % #.#. graphicsCord
            fnsRead = fieldnames(graphicsCord);
            fnsRead(ismember(fnsRead,'CONNECTIVITY'))=[]; % because no operation on it afterwards
            % #.#. Data
            indCord=strcmpi(fnsRead,'X') ...
                | strcmpi(fnsRead,'Y') ...
                | strcmpi(fnsRead,'Z');
            fnsData=fnsRead(~indCord);
            fnsCord=fnsRead(indCord);
            % #.#. graphicsGlobal
            fnsGlobal=fieldnames(graphicsGlobal);

            % #. Reshape
            % Some pixel data are expressed in the form of a column matrix
            % (i.e. not a meshgrid)
            if ~isempty(fnsData) && isfield(graphicsCord,'X')
                % #.#. Data number
                dataNumber=struct;
                for dataVar='XYZ'
                    if any(strcmpi(fnsRead,dataVar))
                        dataNumber.(dataVar)=numel(graphicsCord.(dataVar));
                    else
                        dataNumber.(dataVar)=1;
                    end
                end
                % #.#. Reshape
                for fieldNumber=1:numel(fnsData)
                    if size(graphicsCord.(fnsData{fieldNumber}),1)~=size(graphicsCord.X,1) ...
                            && min(size(graphicsCord.(fnsData{fieldNumber})))==1
                        graphicsCord.(fnsData{fieldNumber})=permute(reshape(...
                            graphicsCord.(fnsData{fieldNumber}),...
                            dataNumber.X,dataNumber.Y,dataNumber.Z),[2 1 3]);
                    end
                end
            end
            
            % #. Initialization
            for fieldNumber=1:numel(fnsData)
                if ~strcmp(fnsGlobal,[fnsData{fieldNumber} '_min'])
                    graphicsGlobal.([fnsData{fieldNumber} '_min'])=table();
                end
                if ~strcmp(fnsGlobal,[fnsData{fieldNumber} '_max'])
                    graphicsGlobal.([fnsData{fieldNumber} '_max'])=table();
                end
            end
            
            % #. Processing
            % #.#. X Y Z (upper case to be 1st in .mat)
            for fieldNumber=1:numel(fnsCord)
                if ~isempty(graphicsCord.(fnsCord{fieldNumber}))
                    graphicsGlobal.([fnsCord{fieldNumber} '_MIN'])=sortrows([graphicsGlobal.([fnsCord{fieldNumber} '_MIN']); ...
                        table(min(graphicsCord.(fnsCord{fieldNumber}),[],'all'),...
                        'RowNames',{stepToFind},'VariableNames',{[fnsCord{fieldNumber} '_MIN']})],'RowNames','ascend');
                    graphicsGlobal.([fnsCord{fieldNumber} '_MAX'])=sortrows([graphicsGlobal.([fnsCord{fieldNumber} '_MAX']); ...
                        table(max(graphicsCord.(fnsCord{fieldNumber}),[],'all'),...
                        'RowNames',{stepToFind},'VariableNames',{[fnsCord{fieldNumber} '_MAX']})],'RowNames','ascend');
                end
            end
            % #.#. Variables (e.g. velocity, etc.)
            for fieldNumber=1:numel(fnsData)
                if ~isempty(graphicsCord.(fnsData{fieldNumber}))
                    graphicsGlobal.([fnsData{fieldNumber} '_min'])=sortrows([graphicsGlobal.([fnsData{fieldNumber} '_min']); ...
                        table(min(graphicsCord.(fnsData{fieldNumber}),[],'all'),...
                        'RowNames',{stepToFind},'VariableNames',{[fnsData{fieldNumber} '_min']})],'RowNames','ascend');
                    graphicsGlobal.([fnsData{fieldNumber} '_max'])=sortrows([graphicsGlobal.([fnsData{fieldNumber} '_max']); ...
                        table(max(graphicsCord.(fnsData{fieldNumber}),[],'all'),...
                        'RowNames',{stepToFind},'VariableNames',{[fnsData{fieldNumber} '_max']})],'RowNames','ascend');
                end
            end
            
            % #. SORT
            graphicsCord = orderfields(graphicsCord);
            graphicsGlobal = orderfields(graphicsGlobal);
        end

        function refreshGraphicsFunction(app,tabNumber)
            % Abstract: load new data and display the results
            
            % #. Time
            % Because this function is used in the automatic plot timer
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value
                refreshSteps(app,tabNumber,1);
            end
            
            % #. Criterion
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value ...
                    && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value ...
                    && strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'on')
            criterionComputation (app,tabNumber);
            end
            
            % #. Loop on the 4 graphs
            for graphNum=1:4
                % #.#. Empty list
                if numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items)==0
                    continue;
                end
                % #.#. Indices
                graphNumFunction(app, graphNum);
                % #.#. Info
                info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                % #.#. Equations update
                info=sliderToSteps(app,info,1);
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=1;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value=info.selection.equation.global.rough.X;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value=info.selection.equation.global.rough.Y;
                if any(strcmpi(info.selection.type.global.cat,'STEPS') & cellfun(@isempty,info.selection.arg1.global.format.cat)) % e.g. all the steps are deleted when a computation is started
                    cancelGraphFunction(app,tabNumber,graphNum);
                end
                % #.#. Plot
                if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                    displayGraphMain(app,[],tabNumber,info,'reset');
                end
            end
            
            % #. Overview update (if selected)
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value
                tabNumberOverview=findTab(app);
                if tabNumberOverview==2
                    overViewStepChange(app,tabNumber);
                end
            end
        end

        function refreshLimitsGraphFunction(app,tabNumber,graphNum)
            % Abstract: restores graph limits

            displayGraphMain(app,[],tabNumber,graphNum,'limits');

        end

        function refreshSteps(app,tabNumber,last)
            % Abstract: refreshes the list of steps
            
            % #. List creation
            [steps]=loadListStep(app,tabNumber);
            steps=flip(steps);
            
            % #. Keep the selected step (according to last)
            valueSave=[];
            if last==0
                currentStep=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value;
                valueSave=find(strcmpi(steps,currentStep));
            end
            if isempty(valueSave) && numel(steps)>0
                valueSave=1;
            end
            
            % #. Applying the results
            if numel(app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items)<=numel(steps)
                app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items=repmat({''},1,numel(steps));
            end
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items=steps;
            if ~isempty(valueSave)
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value...
                    =app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items{valueSave};
            end
            
            % #. Time
            stepListValueChangedFunction(app,tabNumber);

        end

        function refreshTabVar(app,tabChange)
            % Abstract: some variables are associated with a tab number,
            % which must be updated when a tab is created/deleted
            % tabChange : | old tab number | new tab number |

            % #. Modifying variables
            for varNum=1:numel(app.tabDependentVariables)
                varOld=app.(app.tabDependentVariables(varNum)); % Variable before modification
                varNew=cell(size(varOld,1),max(tabChange(:,2))); % Out of condition so that the variable always has a size close to the number of tabs
                if ~isempty(varOld)
                    if any(~any(cellfun(@isempty,varOld))) % Need to modify it if and only if it is not empty
                        % #.#. Indices
                        % The criterion inside takes into account the fact
                        % that the variable was not necessarily defined
                        % for all tabs, and thus avoids exceeding its
                        % limits
                        tabNumberOld=tabChange(tabChange(:,1)<=size(varOld,2),1); % Old tab index
                        tabNumberNew=tabChange(tabChange(:,1)<=size(varOld,2),2); % New tab index
                        % #.#. Modification
                        varNew(:,tabNumberNew)=varOld(:,tabNumberOld);
                    end
                end
                % #.#. Output
                app.(app.tabDependentVariables(varNum))=varNew; % Applying the new variable
            end

            % #. Deleting timers
            plotToCreate=false(1,size(tabChange,1));
            runToCreate=false(1,size(tabChange,1));
            for tabNumberVar=1:size(tabChange,1) % If the tab index has changed
                if tabChange(tabNumberVar,1)~=tabChange(tabNumberVar,2)
                    % #.#. Automatic plots
                    if ~isempty(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Plot']))
                        plotToCreate(tabNumberVar)=1;
                        stop(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Plot']));
                        delete(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Plot']));
                    end
                    % #.#. Automatic loading
                    if ~isempty(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Load']))
                        stop(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Load']));
                        delete(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Load']));
                    end
                    % #.#. Job verification
                    if ~isempty(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Run']))
                        runToCreate(tabNumberVar)=1;
                        stop(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Run']));
                        delete(timerfind('Name',['timerGUITab' num2str(tabChange(tabNumberVar,1)) 'Run']));
                    end
                end
            end

            % #. Timer creation
            % Works in 2 steps between deletion and creation as there may be duplicates
            % e.g. We move the 2 to 4 and there are timers on the 2
            for tabNumberVar=1:size(tabChange,1) % If the tab index has changed
                if tabChange(tabNumberVar,1)~=tabChange(tabNumberVar,2)
                    % #.#. Automatic plots
                    if plotToCreate(tabNumberVar)
                        % We assume that, on average, the time remaining
                        % before execution is the period/2, so we set a
                        % delay before execution
                        t=timer('Name',['timerGUITab' num2str(tabChange(tabNumberVar,2)) 'Plot'],...
                            'BusyMode','queue',...
                            'StartDelay',app.TabGroup.Children(tabChange(tabNumberVar,2)).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Value/2,...
                            'Period',app.TabGroup.Children(tabChange(tabNumberVar,2)).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Value,...
                            'ExecutionMode','fixedSpacing',...
                            'TimerFcn', @(~,~) refreshGraphicsFunction(app,tabChange(tabNumberVar,2)));
                        start(t);
                    end
                    % #.#. Job verification
                    if runToCreate(tabNumberVar)
                        % We assume that, on average, the time remaining
                        % before execution is the period/2, so we set a
                        % delay before execution
                        t=timer('Name',['timerGUITab' num2str(tabChange(tabNumberVar,2)) 'Run'],...
                            'BusyMode','queue',...
                            'StartDelay',90/2,...
                            'Period',90,...
                            'ExecutionMode','fixedSpacing',...
                            'TimerFcn', @(~,~) verifExecutionMain(app, tabChange(tabNumberVar,2)));
                        start(t);
                    end
                end
            end
            
        end

        function reloadGraph(app,tabNumber,mode,class)
            % Abstract: Reloads GRAPHICS graph
            
            % #. Mode
            if isempty(mode)
                mode='reset';
            end

            % #. Loop on graphs
            for graphNum=1:4
                graphNumFunction(app, graphNum);
                if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                    info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                    if any(ismember(info.selection.class.global.cat,class))
                        displayGraphMain(app,[],tabNumber,info,mode);
                    end
                end
            end

        end

        function resetEditedText(app,tabNumber)
            % Abstract: resets edited text

            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Value='';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Value='';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Items={''};
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).Value={''};
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;

        end

        function resetSave(app,tabNumber)
            % Abstract: modification of GRAPHICS (.mat) save in the SAVE
            % folder according to GRAPHICS files (.vtk) in the CODE folder
            
            % #. Path
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            
            % #. Data
            [~,~,dataType,dataFile]=dataMainListFunction(app,tabNumber,'Refresh');
            indSteps=strcmpi(dataType,'STEPS');
            files=unique(dataFile(indSteps));
            filesNumber=numel(files);

            for fileNum=1:filesNumber

                % #. Names
                typeToFind=files{fileNum};
                stepsCode=loadStepsListFunction(app,pathSimu,typeToFind,'Code');
                stepsSave=loadStepsListFunction(app,pathSimu,typeToFind,'Save');

                % #. To delete
                namesCode=strcat(typeToFind,stepsCode);
                namesSave=strcat(typeToFind,stepsSave);
                indToDel=~ismember(namesSave,namesCode);
                namesToDel=namesSave(indToDel);
                stepsToDel=stepsSave(indToDel);

                % #. TYPEXXXXX.mat
                for nameNum=1:numel(namesToDel)
                    delete([pathSimu filesep 'SAVE' filesep namesToDel{nameNum} '.mat']);
                end
                
                % #. TYPEGlobal
                if isfile([pathSimu filesep 'SAVE' filesep typeToFind 'Global.mat'])
                    % #.#. Input
                    graphicsGlobal=load([pathSimu filesep 'SAVE' filesep typeToFind 'Global.mat']);
                    fns = fieldnames(graphicsGlobal);
                    % #.#. Correspondence between GRAPHICS_Global and GRAPHICS from CODE
                    for fieldNum=1:numel(fns)
                        graphicsGlobal.(fns{fieldNum})(ismember(graphicsGlobal.(fns{fieldNum}).Properties.RowNames,stepsToDel),:)=[];
                    end
                    % #.#. Correspondence between GRAPHICS_Global and GRAPHICS from SAVE
                    % Allows user to manually delete GRAPHICS in 
                    % SAVE folder without having to modify GRAPHICS_Global
                    update=false;
                    for fieldNum=1:numel(fns)
                        ind=find(~ismember(graphicsGlobal.(fns{fieldNum}).Properties.RowNames,stepsSave));
                        if ~isempty(ind)
                            graphicsGlobal.(fns{fieldNum})(ind,:)=[];
                            update=true;
                        end
                    end
                    % #.#. Save
                    if update || ~isempty(stepsToDel)
                        save([pathSimu filesep 'SAVE' filesep typeToFind 'Global.mat'],'-struct','graphicsGlobal');
                    end
                end

            end
        end
        
        function resetTab(app,tabNumber)
            % Abstract: resets all the elements/variables associated to a tab
            
            % #. Graphs
            % #.#. Graphics
            for graphNum=1:4
                graphNumFunction(app,graphNum);
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value='';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value='';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphStartNumberVar).Value='';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphEndNumberVar).Value='';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Items={''};
                % app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).ItemsData=1;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXListNumberVar).Value='';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Items={''};
                % app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).ItemsData=1;
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYListNumberVar).Value='';
                clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber),['graph' num2str(graphNum)]);
            end
            clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber),'graph5');
            
            % #. Panels
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Visible='off';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Visible='off';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.settingsNumber).Value=0;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.timeNumber).Value=0;
            
            % #. Lists
            % #.#. CONTROL
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Items={''};
            % app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).ItemsData=1;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).Value='';
            %app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).FontColor=app.fontColorUnchanged;
            % #.#. STEP
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items={''};
            % app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).ItemsData=1;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value='';
            % #.#. Criterion
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Items={''};
            % app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).ItemsData=1;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Value='';
            
            % #. Texts
            % #.#. CONTROL
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).Value='';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;
            % #.#. STEP
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.displayTimeNumber).Text= ...
                {...
                'Total duration: ' ...
                '1 s: ' ...
                '1 min: ' ...
                '1 h: ' ...
                '1 d: ' ...
                '1 wk: ' ...
                '1 mo: ' ...
                'Current time: ' ...
                };
            % #.#. Criterion
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value='';
            
            % #. Run
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorUnchanged;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
            
            % #. Continue
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.continueFullScreenNumber).Value=0;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.cancelFullScreenNumber).Value=0;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Visible='off';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.maskingPanelNumber).Visible='off';

            % #. Tab name
            app.TabGroup.Children(tabNumber).Title=['#' num2str(tabNumber-2,'%02.f')];
            
            % #. Deactivation of some elements
            %buttonsSaveActivation(app,tabNumber,'Off');
            
            % #. Timers
            if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Plot']))
                stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Plot']));
                delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Plot']));
            end
            if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Load']))
                stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Load']));
                delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Load']));
            end
            if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']))
                stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
                delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
            end

            % #. Variables
            for varNum=1:numel(app.tabDependentVariables)
                for rowNum=1:size(app.tabDependentVariables(varNum),1) % tab corresponds to column
                    app.(app.tabDependentVariables(varNum)){rowNum,tabNumber}=[];
                end
            end
            
        end

        function runFunction(app,tabNumber,deleteEnable)
            % Abstract: starts computation locally or on a cluster
            
            % #. Paths
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathCode=[pathSimu filesep 'CODE'];
            selectedPackage=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.packageModeListNumber).Value;
            pathPackage=[app.pwdSave filesep 'Packages' filesep selectedPackage];
            
            if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'on')
                
                % #. Run in progress
                
                % #.#. Refresh steps
                refreshSteps(app,tabNumber,0);

                % #.#. Files to delete
                % If the starting step is not the last one available
                [status]=stepFilesToDelete(app,tabNumber);
                if ~isempty(status); return; end

                % #.#. Initial step
                app.stepIniRun{tabNumber}=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value; % to compute the duration only if the last step has changed

                if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local')

                    % #.#. Local

                    % #.#.#. Package
                    packageList=dir([pathPackage filesep 'Local']);
                    packageList={packageList.name};
                    packageList(ismember(packageList,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
                    nameExe=packageList{find(strcmpi(packageList,[selectedPackage '.exe']),1,'first')};

                    % #.#.#. Existing files
                    stepValue=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value;
                    pathPackageSimu=strcat(pathCode,filesep,packageList);
                    existingFiles=cellfun(@isfile,pathPackageSimu);
                    if sum(existingFiles)<numel(packageList) && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                        % All exe files must be found if paste and delete
                        % feature is activate
                        msgbox({'Unable to find all package files, paste them or activate the paste and delete feature.' 'List of missing files:' strjoin(strcat(packageList(existingFiles==0),{' '}))},'Information','help');
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        return;
                    elseif sum(existingFiles)~=0 && ~app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                        % No exe files should be found if paste and delete
                        % feature is deactivated
                        msgbox({'Some package files are located in the current simulation folder. Delete them to be able to use the paste and delete feature.' 'List of unwanted file(s):' strjoin(strcat(packageList(existingFiles)))},'Information','help');
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        return;
                    end

                    % #.#.#. Argument
                    args=inputdlg({['Enter arguments if necessary (e.g. > ' nameExe ' arguments):']},'Argument',[1 100],{stepValue});
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(args) && ~ischar([args{:}])
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        return;
                    end
                    args=args{1};

                    % #.#.#. Transfers files
                    if ~app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                        pathExe=strcat(pathPackage,filesep,'Local',filesep,packageList);
                        cellfun(@(x) copyfile(x,pathCode),pathExe);
                    end
                    
                    % #.#.#. Executable name
                    % The executable is renamed to obtain different job
                    % names in the task manager only if paste and delete
                    % feature is activate
                    % #.#.#.#. Initial name
                    if ~app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                        % #.#.#.#. Formatted name
                        % Format : <nameOfExe>_<nameSimu>_<date>
                        dateName=regexprep(char(datetime),'[ :-]','_');
                        nameSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                        idNameSimuTemp=strfind(nameSimu,filesep);
                        nameSimu=strrep(nameSimu(idNameSimuTemp(end)+1:end),' ','_');
                        app.computationRenamed{tabNumber}=[erase(nameExe,'.exe') '_' nameSimu '_' dateName '.exe'];
                        movefile([pathCode filesep nameExe],[pathCode filesep app.computationRenamed{tabNumber}]); % moves on itself to rename
                    else
                        % #.#.#.#. Unformatted name
                        app.computationRenamed{tabNumber}=nameExe;
                    end

                    % % #.#.#. Run
                    % pathCode(1:2) selects the correct disk before the cd command
                    if ~app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.displayCMDNumber).Value
                        % #.#.#.#. Without displaying the CMD window
                        system([pathCode(1:2) ' & cd ' pathCode ' & start /b ' app.computationRenamed{tabNumber} ' ' args '> NUL']);
                    else
                        % #.#.#.#. With the CMD window displayed
                        system([pathCode(1:2) ' & cd ' pathCode ' & ' app.computationRenamed{tabNumber} ' ' args '&']);
                    end

                    % #.#.#. Timer
                    % To periodically check whether the job has stopped
                    tabNumberSave=tabNumber;
                    t=timer('Name',['timerGUITab' num2str(tabNumber) 'Run'],...
                        'BusyMode','queue',...
                        'StartDelay',45,...
                        'Period',90,...
                        'ExecutionMode','fixedSpacing',...
                        'TimerFcn', @(~,~) verifExecutionMain(app, tabNumberSave));
                    start(t);
                    
                    % #.#.#. Log
                    pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                    if ~exist([pathSimu filesep 'SAVE'],'dir')
                        mkdir([pathSimu filesep 'SAVE']);
                    end
                    [~,~]=LogSave({['Step at the beginning: ' stepValue]}, {''}, ['Job (' selectedPackage ') is running locally'], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
                    
                else
                    
                    % #.#. Cluster

                    % #.#.#. Package
                    packageList=dir([pathPackage filesep 'Cluster']);
                    packageList={packageList.name};
                    packageList(ismember(packageList,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
                    nameExe=packageList{find(strcmpi(packageList,selectedPackage),1,'first')};
                    
                    % #.#.#. Account
                    [s_conn,~,~,account,mail,server,serverFormat]=clusterLoadInfo(app,tabNumber,[]);
                    if isempty(s_conn)
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        return;
                    end
                    
                    % #.#.#. Simulation directory
                    [directory,nameFolder,key,pathSimuCluster]=clusterPath(app,tabNumber,s_conn);
                    
                    % #.#.#. Simulation files
                    [cluster,simu,exe]=clusterListLoad(app,s_conn,tabNumber,pathSimuCluster);
                    
                    % #.#.#. Job options (duration, nodes, etc.)
                    [timeValue,partition,nodes,cpus]=clusterResources(app);
                    if isempty(timeValue) || isempty(partition)
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        ssh2_close(s_conn);
                        clearvars('s_conn');
                        return;
                    end
                    
                    % #.#.#. Transfer information
                    % #.#.#.#. Directory
                    [cluster,args,runningFrom,cancel]=clusterFilesStartingImport(app,tabNumber,simu,cluster,exe,nameExe);
                    if cancel
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        ssh2_close(s_conn);
                        clearvars('s_conn');
                        return;
                    end
                    % #.#.#.#. Simulation path
                    if isempty(pathSimuCluster)
                        messDisp={'The simulation path will be created'};
                    else
                        messDisp={'Simulation path already exists'};
                    end
                    % #.#.#.#. Transfer size
                    if isempty(cluster.ToSupr.Size)
                        clusterSupr=0;
                    else
                        clusterSupr=cluster.ToSupr.Size;
                    end
                    if isempty(cluster.FromSimu.Size)
                        simuSupr=0;
                    else
                        simuSupr=cluster.FromSimu.Size;
                    end
                    if isempty(cluster.FromExe.Size)
                        exeCopy=0;
                    else
                        exeCopy=cluster.FromExe.Size;
                    end
                    messDisp=[messDisp;{['Deletion: ' num2str(size(cluster.ToSupr.Files,2)) ' file(s) (' num2str((max(cumsum(clusterSupr)))/1e6,'%10.1f') ' Mo)']}];
                    messDisp=[messDisp;{['Copy: ' num2str(size(cluster.FromSimu.Files,2)+size(cluster.FromExe.Files,2)) ' file(s) (' num2str((max(cumsum(simuSupr))+max(cumsum(exeCopy)))/1e6,'%10.1f') ' Mo)']}];
                    % #.#.#.#. DLG
                    warnChoice=questdlg(messDisp,'Warning','Continue','Cancel','Cancel');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if strcmp(warnChoice,'Cancel') || isempty(warnChoice)
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        ssh2_close(s_conn);
                        clearvars('s_conn');
                        return;
                    end

                    % #.#.#. Refresh steps
                    if strcmpi(runningFrom,'local') && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.refreshOutputsNumber).Value
                        refreshSpies(app,tabNumber);
                    end
                    
                    % #.#.#.  Launcher update
                    dirLauncher=dir([pathPackage filesep 'Cluster']);
                    dirLauncher={dirLauncher.name};
                    dirLauncher(ismember(dirLauncher,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
                    nameLauncher=dirLauncher{contains(lower(dirLauncher),'launcher') & contains(lower(dirLauncher),serverFormat{1})};
                    pathLauncher=[pathPackage filesep 'Cluster' filesep nameLauncher];
                    % Main
                    opts=struct();
                    opts.TypeOpen={{'Empty'}};
                    opts.TrueOpen={{0}};
                    opts.CriterionClose=tempname;
                    opts.Update={{...
                        {'assignAfter','#SBATCH -J ','#SBATCH -J ',key} ...
                        {'assignAfter','--account=','--account=',account} ...
                        {'assignAfter','--time=','--time=',['''' timeValue '''']} ...
                        {'assignAfter','--nodes=','--nodes=',nodes} ...
                        {'assignAfter','--ntasks-per-node=','--ntasks-per-node=',cpus} ...
                        {'assignAfter','--mail-user=','--mail-user=',mail} ...
                        {'assign',['./' nameExe],['./' nameExe ' ' args]} ...
                        }};
                    fileTemp=TextEditor(pathLauncher,opts);
                    fileTemp=[fileTemp{:}];
                    % Partition
                    ind=contains(fileTemp,'--partition=') & ~contains(fileTemp,'parallel'); % if parellel, do not modify
                    if any(ind)
                        opts=struct();
                        opts.TypeOpen={{'Contains'}};
                        opts.CriterionOpen={{'--partition='}};
                        opts.TypeClose={{'Shift'}};
                        opts.CriterionClose={{0}};
                        opts.Update={{{'assignAfter','--partition=','--partition=',partition}}};
                        TextEditor(pathLauncher,opts);
                    end
                    
                    % #.#.#. Transfer
                    pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                    [pathSimuCluster]=clusterFilesTransfer(app,s_conn,{pathSimu},{directory},{nameFolder},{key},{pathSimuCluster},{simu},{cluster},tabNumber,0,0);
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).FontColor=app.fontColorProgress;
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background

                    % #.#.#. Run
                    ssh2_command(s_conn,char(strcat('chmod +x',{' '},pathSimuCluster,'/CODE/',nameExe,{' '},'2>&1')));
                    ssh2_command(s_conn,char(strcat('cd',{' '},pathSimuCluster,'/CODE/',{' '},'&& sbatch',{' '},nameLauncher,{' '},'2>&1')));
                    
                    % #.#.#. Close the connection
                    ssh2_close(s_conn);
                    clearvars('s_conn');
                    
                    % #.#.#. Log
                    if ~exist([pathSimu filesep 'SAVE'],'dir')
                        mkdir([pathSimu filesep 'SAVE']);
                    end
                    LogSave({['Step at the beginning: ' args];...
                        ['Duration: ' timeValue]; ...
                        ['Nodes: ' nodes]; ...
                        ['CPUs: ' cpus]},...
                        {''},['Job (' selectedPackage ') is running on ' char(server)], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
                end
                
            else

                % #. Stops the job

                % #.#. Refresh steps
                refreshSteps(app,tabNumber,1);

                if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local')
                    
                    % #.#. Local
                    
                    % #.#.#. Remove timers
                    % Check is a job is in progress
                    if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']))
                        stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
                        delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
                    end
                    % Check if an automatic plot is in progress
                    if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value=0;
                        autoRefreshValueChanged(app,[]);
                    end
                    
                    % #.#.#. Computation must not be interrupted during saving
                    minimumTime=5; % The last modification must be older than this number of seconds
                    pauseTime=1; % Pause in seconds if this is not the case
                    maximumIteration=5; % Maximum number of pauses to avoid an infinite loop
                    if verifExecution(app,tabNumber)
                        k=1;
                        while 1
                            dirCode=dir(pathCode);
                            fileDatetime=datetime([dirCode.datenum],ConvertFrom="datenum");
                            gap=min(seconds(datetime("now")-fileDatetime));
                            if gap>minimumTime || k>=maximumIteration
                                break
                            else
                                k=k+1;
                                pause(pauseTime);
                            end
                        end
                    end

                    % #.#.#. Stops
                    system(['taskkill /F /IM ' app.computationRenamed{tabNumber}]);
                    
                    % #.#.#. Deletes executable files
                    % #.#.#.#. List
                    packageList=dir([pathPackage filesep 'Local']);
                    packageList={packageList.name};
                    packageList(ismember(packageList,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
                    if ~app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.executableFromFolderNumber).Value
                        filesPath=strcat(pathCode,filesep,[packageList app.computationRenamed{tabNumber}]);
                        filesPath(~isfile(filesPath))=[];
                        pause(0.5); % Otherwise no write permission (probably just long enough for the job stop to be taken into account by the system)
                        cellfun(@delete,filesPath);
                    end

                    % #.#.#. Reset variables
                    app.computationRenamed{tabNumber}='';
                    app.stepIniRun{tabNumber}=[];
                    
                    % #.#.#. Deletes post-processing files
                    if deleteEnable==1
                        % Delete
                        typesToDelete=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.filestodeleteListBoxNumber).Items...
                            (app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.filestodeleteListBoxNumber).ValueIndex);
                        typesNumber=numel(typesToDelete);
                        filesToDelete=cell(1,typesNumber);
                        for typeNum=1:typesNumber
                            if isfile([pathSimu filesep 'CODE' filesep typesToDelete{typeNum}]) % unique file
                                delete([pathSimu filesep 'CODE' filesep typesToDelete{typeNum}]);
                                filesToDelete{typeNum}=typesToDelete(typeNum);
                            else % step file
                                [steps,ext]=loadStepsListFunction(app,pathSimu,typesToDelete{typeNum},'CODE');
                                filesToDelete{typeNum}=cell(1,numel(steps));
                                for stepNum=1:numel(steps)
                                    delete([pathSimu filesep 'CODE' filesep typesToDelete{typeNum} steps{stepNum} ext{1}{stepNum}]);
                                    filesToDelete{typeNum}{stepNum}=[typesToDelete{typeNum} steps{stepNum} ext{1}{stepNum}];
                                end
                            end
                        end
                        % Reset
                        resetSave(app,tabNumber);
                        % Log
                        if ~exist([pathSimu filesep 'SAVE'],'dir')
                            mkdir([pathSimu filesep 'SAVE']);
                        end
                        filesToDelete=[filesToDelete{:}];
                        if ~isempty(filesToDelete)
                            [~,~]=LogSave(filesToDelete, {''},['Files deleted when job (' selectedPackage ') stopped locally'], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
                        end
                    end
                    
                    % #.#.#. Log
                    stepEnd=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items{1};
                    if ~exist([pathSimu filesep 'SAVE'],'dir')
                        mkdir([pathSimu filesep 'SAVE']);
                    end
                    [~,~]=LogSave({['Step at the end: ' stepEnd]}, {''},['Job (' selectedPackage ') has been stopped locally'], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);

                else

                    % #.#. Cluster
                    
                    % #.#.#. MSG
                    answer=questdlg('Are you sure you want to cancel the job currently running on the cluster?','Warning','Yes','No','Yes');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(answer) || strcmp(answer,'No')
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='On';
                        return;
                    end
                    
                    % #.#.#. Account
                    [s_conn,~,~,~,~,server]=clusterLoadInfo(app,tabNumber,[]);
                    if isempty(s_conn)
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        return;
                    end
                    
                    % #.#.#. Directory
                    [~,~,key,pathSimuCluster]=clusterPath(app,tabNumber,s_conn);
                    
                    % #.#.#. Stops and log
                    if ~isempty(pathSimuCluster)
                        [info]=clusterJob(app,s_conn,key);
                        if ~exist([pathSimu filesep 'SAVE'],'dir')
                            mkdir([pathSimu filesep 'SAVE']);
                        end
                        if ~isempty(info)
                            ssh2_command_response(ssh2_command(s_conn,['scancel ' info.JOBID ' 2>&1']));
                            [~,~]=LogSave({['Duration: ' num2str(info.TIME)]},{''},['Job has been stopped on ' char(server)], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']); % Job has been stopped
                        else
                            [~,~]=LogSave({'Finished'},{''},['Job has finished on ' char(server)], [pathSimu filesep 'SAVE' filesep 'LOG_save.log']); % Job was already finished
                        end
                    end
                    
                    % #.#.#. Close the connection
                    ssh2_close(s_conn);
                    clearvars('s_conn');
                end
                
            end
            
            % #. GUI update
            findExecution(app,tabNumber);
            
        end

        function propagateView(app,tabNumber,graphNumInput)
            % Abstract: identical views (X and Y limits) for all the GRAPHICS
            
            v=[];

            if tabNumber>2

                % #. Tab
                % #.#. Graphs list
                if isempty(graphNumInput)
                    graphNumList=1:5;
                else
                    graphNumList=graphNumInput;
                end
                % #.#. Reads view
                v=[];
                for graphNum=graphNumList
                    graphNumFunction(app,graphNum);
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        classes=arrayfun(@(x) class(x), app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children,'UniformOutput', false);
                        if any(ismember(classes,{'matlab.graphics.primitive.Patch' 'matlab.graphics.primitive.Surface'})) % keeps only graphs with spatial coordinates
                            v=axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));
                            [vRot1,vRot2]=view(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));
                            vRot=[vRot1,vRot2];
                            break;
                        end
                    end
                end
                if isempty(v); return; end
                % #.#. Applies view
                for graphNum=1:5
                    graphNumFunction(app,graphNum);
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        classes=arrayfun(@(x) class(x), app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children,'UniformOutput', false);
                        if any(ismember(classes,{'matlab.graphics.primitive.Patch' 'matlab.graphics.primitive.Surface'})) % keeps only graphs with spatial coordinates
                            if numel(axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar)))==numel(v) % Modifies only graphics of the same dimension
                                axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar),v);
                                view(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar),vRot);
                            end
                        end
                    end
                end

            elseif tabNumber==2

                % #. Overview
                findAxes=findobj(app.gridOverview.Children(1).Children,'Type','axes');
                if ~isempty(findAxes)
                    for axesNum=numel(findAxes)-1:-1:1 % the order is reversed
                        if isa(findAxes(axesNum),'patch')
                            if isempty(v) % i.e. takes the reference view as the one of the first graph found
                                % #.#. Reference view
                                v=axis(findAxes(axesNum));
                                [vRot1,vRot2]=view(findAxes(axesNum));
                                vRot=[vRot1,vRot2];
                            elseif numel(axis(findAxes(axesNum)))==numel(v)  % Modifies only graphics of the same dimension
                                % #.#. Applying the reference view
                                axis(findAxes(axesNum),v);
                                view(findAxes(axesNum),vRot);
                            end
                        end
                    end
                end

            end
        end

        function selection=selectionInfo(app,tabNumberOrPath,graphNum,eqToLoad,selection)
            % Abstract: loads some information about the data
            % eqToLoad = {'equation X' 'equation Y'} : reads these equations and not the ones from the GUI
            % selection = struct : allows to skip some elements already treated
            % rough: as entered by user
            % format: after formatting
            % X: in X equation
            % Y: in Y equation
            % cat: Both ([X{:}] [Y{:}])
            % unique: Unique (according to formated data name)

            % #. Data
            [dataList,~,dataType,dataFile,dataExt,dataClass,dataColumn,~,~,~,~,~,customList]=dataMainListFunction(app,tabNumberOrPath,'Refresh');

            % #. Equations to read
            % #.#. Indexes
            if ~isempty(graphNum)
                graphNumFunction(app,graphNum);
            else
                graphNumFunction(app,1);
            end
            % #.#. Input
            if isempty(eqToLoad) && isnumeric(tabNumberOrPath) && ~isempty(tabNumberOrPath)
                eqToLoad{1}=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value;
                eqToLoad{2}=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphYEqNumberVar).Value;
            end
            % #.#. Format
            % Equations must not begin/end with a '#' to avoid some issues
            % for eqNum=1:2
            %     % #.#.#. Beginning
            %     eqToLoad{eqNum}=strtrim(eqToLoad{eqNum});
            %     if ~isempty(eqToLoad{eqNum})...
            %             && eqToLoad{eqNum}(1)=='#'
            %         eqToLoad{eqNum}(1)=[];
            %     end
            %     % #.#.#. Ending
            %     if ~isempty(eqToLoad{eqNum})...
            %             && eqToLoad{eqNum}(end)=='#'
            %         eqToLoad{eqNum}(end)=[];
            %     end
            % end

            % #. Corrected equations
            % Allows to specify one ground that will be applied to several
            % grounds (e.g. several X grounds and one Y ground)
            indX=[0 find(eqToLoad{1}=='#')]; indY=[0 find(eqToLoad{2}=='#')];
            numX=numel(indX); numY=numel(indY); groundNumber=max([numX numY]);
            if numX<numY
                % #.#. One X ground applied to several Y grounds
                eqToLoad{1}=[eqToLoad{1} repmat([' # ' eqToLoad{1}(indX(end)+1:end)],1,numY-numX)]; % Repeats only the last ground
            elseif numX>numY
                % #.#. One Y ground applied to several X grounds
                eqToLoad{2}=[eqToLoad{2} repmat([' # ' eqToLoad{2}(indY(end)+1:end)],1,numX-numY)]; % Repeats only the last ground
            end

            % #. Equations to check
            % e.g. for some animations, only the Y equation changes and therefore
            % some operations on the X equation are already performed
            if isfield(selection,'equation')
                eqList='';
                if ~strcmp(selection.equation.global.rough.X,eqToLoad{1})
                    eqList(1)='X';
                end
                if ~strcmp(selection.equation.global.rough.Y,eqToLoad{2})
                    eqList(2)='Y';
                end
                eqList=eqList(isletter(eqList));
            else
                eqList=['X' 'Y'];
            end

            % #. Global equations
            selection.equation.global.rough.X=eqToLoad{1};
            selection.equation.global.rough.Y=eqToLoad{2};

            % #. Other
            if ~isfield(selection,'other')
                if isnumeric(tabNumberOrPath)
                    selection.other.tabNumber=tabNumberOrPath;
                    if ~isempty(tabNumberOrPath)
                        selection.other.pathSimu=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                    else
                        selection.other.pathSimu='';
                    end
                else
                    selection.other.tabNumber=[];
                    selection.other.pathSimu='';
                end
            end
            selection.other.graphNum=graphNum;

            % #. Limits
            % #.#. Global
            if ~isfield(selection,'limits')
                if ~isempty(selection.other.graphNum) ...
                        && selection.other.graphNum<=4 ...
                        && isnumeric(tabNumberOrPath) && ~isempty(tabNumberOrPath)
                    selection.limits.min.global.rough=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphStartNumberVar).Value;
                    selection.limits.max.global.rough=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphEndNumberVar).Value;
                else
                    selection.limits.min.global.rough='';
                    selection.limits.max.global.rough='';
                end
            end
            % #.#. Grounds
            % Allows to specify only one ground
            for extr=["min" "max"]
                selection.limits.(extr).grounds.rough=repmat({''},1,groundNumber);
                limTemp=strsplit(selection.limits.(extr).global.rough,'#');
                selection.limits.(extr).grounds.rough(1:numel(limTemp))=limTemp;
            end
            selection.limits.min.global.format=selection.limits.min.global.rough;
            selection.limits.max.global.format=selection.limits.max.global.rough;
            selection.limits.min.grounds.format=selection.limits.min.grounds.rough;
            selection.limits.max.grounds.format=selection.limits.max.grounds.rough;

            % #. Choice
            if ~isfield(selection,'choice')
                for eq='XY'
                    if ~isempty(selection.other.graphNum) ...
                            && selection.other.graphNum<=4  && isnumeric(tabNumberOrPath) && ~isempty(tabNumberOrPath)
                        selection.choice.value.(eq)=find(strcmp(app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.(['graph' eq 'ListNumberVar'])).Value,app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.(['graph' eq 'ListNumberVar'])).Items),1,'first');
                        selection.choice.string.rough.(eq)=app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.(['graph' eq 'ListNumberVar'])).Items{selection.choice.value.(eq)};
                    else
                        selection.choice.value.(eq)='';
                        selection.choice.string.rough.(eq)='';
                    end
                    selection.choice.string.format.(eq)=lower(replace(selection.choice.string.rough.(eq),{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                end
            end

            % #. var
            varList1=["equation" "data" "arg1" "arg2"];
            varList2=["withoutArg1" "withoutArg2" "withoutArgs"];
            varList3=["type" "class" "file" "ext" "column"];
            formatList=["rough" "format"];

            % #. Equations
            for eq=eqList

                % #.#. Initialization
                for var=varList1(2:end) % without equation
                    for format=formatList
                        selection.(var).grounds.(format).(eq)=repmat({{}},1,groundNumber);
                    end
                end
                for var=varList2
                    for format=formatList
                        selection.data.grounds.(var).(format).(eq)=repmat({{}},1,groundNumber);
                    end
                end
                for var=varList3
                    selection.(var).grounds.(eq)=repmat({{}},1,groundNumber);
                end

                % #.#. Ground equations
                if isempty(selection.equation.global.rough.(eq))
                    selection.equation.grounds.rough.(eq)={''};
                else
                    selection.equation.grounds.rough.(eq)=strip(strsplit(replace(selection.equation.global.rough.(eq),'#',' # '),'#')); % replace because without space, not detected with split
                end
                %selection.equation.grounds.rough.(eq)(cellfun(@isempty,strtrim(selection.equation.grounds.rough.(eq))))='';
                selection.equation.grounds.format.(eq)=repmat({{}},1,groundNumber);
                if isempty(selection.equation.grounds.rough.(eq))
                    selection.equation.grounds.rough.(eq)=repmat({{}},1,groundNumber); % avoid problems during concatenation
                    continue;
                end

                for groundNum=1:groundNumber

                    % #.#. Data indices (without arg2)
                    % e.g. indOpen(dataGroundNum):indClose(dataGroundNum) -> '[Data]'
                    indOpen=strfind(selection.equation.grounds.rough.(eq){groundNum},'[');
                    indClose=strfind(selection.equation.grounds.rough.(eq){groundNum},']');
                    indToSupr=(indClose-indOpen)==1; % Some functions require []
                    indOpen(indToSupr)=[];
                    indClose(indToSupr)=[];

                    % #.#. Data indices (with arg2)
                    % e.g. indOpen(dataGroundNum):indCloseWithArg2(dataGroundNum) -> '[Data](1:2)'
                    indCloseWithArg2=indClose; % Initialization
                    indOpenArg2=strfind(selection.equation.grounds.rough.(eq){groundNum},']('); % Data with specified indices
                    indCloseArg2=strfind(selection.equation.grounds.rough.(eq){groundNum},')'); % All the potential closing round brackets of arg2
                    for indNum=1:numel(indOpenArg2)
                        ind=find(indCloseArg2>indOpenArg2(indNum),1,'first'); % Finds the first closing round bracket after the opening of arg2
                        indCloseWithArg2(indCloseWithArg2==indOpenArg2(indNum))=indCloseArg2(ind); % Applies the value
                    end

                    % #.#. Initialization
                    dataNumber=numel(indOpen);
                    for var=varList1(2:end) % without equation
                        for format=formatList
                            selection.(var).grounds.(format).(eq){groundNum}=repmat({''},1,dataNumber);
                        end
                    end
                    for var=varList2
                        for format=formatList
                            selection.data.grounds.(var).(format).(eq){groundNum}=repmat({''},1,dataNumber);
                        end
                    end
                    for var=varList3
                        selection.(var).grounds.(eq){groundNum}=repmat({''},1,dataNumber);
                    end

                    for dataGroundNum=1:numel(indOpen)

                        % #.#. rough (rough)
                        selection.data.grounds.rough.(eq){groundNum}{dataGroundNum}=selection.equation.grounds.rough.(eq){groundNum}(indOpen(dataGroundNum):indCloseWithArg2(dataGroundNum));

                        % #.#. arg1 (rough)
                        % e.g. [Data%arg1] or [Data$arg1] -> %arg1 or $arg1
                        ind=max([strfind(selection.data.grounds.rough.(eq){groundNum}{dataGroundNum},'$') strfind(selection.data.grounds.rough.(eq){groundNum}{dataGroundNum},'%')]);
                        if ~isempty(ind)
                            selection.arg1.grounds.rough.(eq){groundNum}{dataGroundNum}=selection.data.grounds.rough.(eq){groundNum}{dataGroundNum}(ind:end-(indCloseWithArg2(dataGroundNum)-indClose(dataGroundNum))-1);
                        else
                            selection.arg1.grounds.rough.(eq){groundNum}{dataGroundNum}='';
                        end

                        % #.#. arg2 (rough)
                        % e.g. [Data](arg2) -> (arg2)
                        selection.arg2.grounds.rough.(eq){groundNum}{dataGroundNum}=selection.equation.grounds.rough.(eq){groundNum}(indClose(dataGroundNum)+1:indCloseWithArg2(dataGroundNum));

                        % #.#. withoutArg1 (rough)
                        % e.g. [Data%arg1](arg2) -> [Data(arg2)]
                        selection.data.grounds.withoutArg1.rough.(eq){groundNum}{dataGroundNum}=erase(selection.data.grounds.rough.(eq){groundNum}{dataGroundNum},selection.arg1.grounds.rough.(eq){groundNum}{dataGroundNum});

                        % #.#. withoutArg2 (rough)
                        % e.g. [Data%arg1](arg2) -> Data%arg1
                        selection.data.grounds.withoutArg2.rough.(eq){groundNum}{dataGroundNum}=erase(selection.data.grounds.rough.(eq){groundNum}{dataGroundNum},selection.arg2.grounds.rough.(eq){groundNum}{dataGroundNum});

                        % #.#. withoutArgs (rough)
                        % e.g. [Data%arg1](arg2) -> Data
                        selection.data.grounds.withoutArgs.rough.(eq){groundNum}{dataGroundNum}=...
                            erase(...
                            selection.data.grounds.rough.(eq){groundNum}{dataGroundNum},...
                            sort([string(selection.arg1.grounds.rough.(eq){groundNum}{dataGroundNum}),...
                            string(selection.arg2.grounds.rough.(eq){groundNum}{dataGroundNum})],'descend')); % sort avoid issues with empty string

                        % #.#. Type/Class/File/Ext
                        % Type of the corresponding data
                        nameTemp=erase(selection.data.grounds.withoutArgs.rough.(eq){groundNum}{dataGroundNum},["[" "]"]);
                        indData=find(strcmp(dataList,nameTemp),1,'first');
                        if any(indData)
                            selection.type.grounds.(eq){groundNum}{dataGroundNum}=dataType{indData};
                            selection.class.grounds.(eq){groundNum}{dataGroundNum}=dataClass{indData};
                            selection.file.grounds.(eq){groundNum}{dataGroundNum}=dataFile{indData};
                            selection.ext.grounds.(eq){groundNum}{dataGroundNum}=dataExt{indData};
                            selection.column.grounds.(eq){groundNum}{dataGroundNum}=dataColumn{indData};
                        end

                        % #.#. arg1 (format)
                        % e.g. %arg1 or $arg1 -> arg1 or arg1
                        selection.arg1.grounds.format.(eq){groundNum}{dataGroundNum}=strtrim(erase(selection.arg1.grounds.rough.(eq){groundNum}{dataGroundNum},["$" "%"]));

                        % #.#. arg2 (format)
                        if any(indData)
                            % #.#.#. Data
                            % e.g. [velocity]
                            if isscalar(selection.column.grounds.(eq){groundNum}{dataGroundNum}) % column
                                dataSize=1;
                            elseif min(size(selection.column.grounds.(eq){groundNum}{dataGroundNum}))==1  % 2D matrix
                                dataSize=2;
                            else
                                dataSize=3;
                            end
                        else
                            % #.#.#. No a data
                            dataSize=0; % avoid format issues (e.g. [1:10](1:2))
                        end
                        % #.#.#. Save
                        if isempty(selection.arg2.grounds.rough.(eq){groundNum}{dataGroundNum}) && dataSize>0 % To avoid deleting the specified index
                            % Without argument
                            if dataSize==1
                                selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum}='(1:1:end)';
                            elseif dataSize==2
                                selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum}='(1:1:end,1:1:end)';
                            elseif dataSize==3
                                selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum}='(1:1:end,1:1:end,1:1:end)';
                            end
                        else
                            % With argument
                            selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum}=selection.arg2.grounds.rough.(eq){groundNum}{dataGroundNum};
                        end
                        % #.#.#. Add indice step if not specified
                        if any(indData)
                            argTemp=erase(strsplit(selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum},','),["(" ")"]);
                            for argNum=1:numel(argTemp)
                                if strcmpi(argTemp{argNum},':')
                                    argTemp{argNum}='1:1:end';
                                    continue;
                                end
                                argSubTemp=strsplit(argTemp{argNum},':');
                                if isscalar(argSubTemp) % scalar
                                    continue;
                                elseif numel(argSubTemp)==2 % a:b
                                    argTemp{argNum}=strcat(argSubTemp{1},':1:',argSubTemp{2});
                                end

                            end
                            selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum}=['(' strjoin(argTemp,',') ')'];
                        end

                        % #.#. withoutArgs (format)
                        if any(indData)
                            % #.#.#. Data
                            % e.g. [velocity
                            selection.data.grounds.withoutArgs.format.(eq){groundNum}{dataGroundNum}=lower(replace(erase(...
                                selection.data.grounds.withoutArgs.rough.(eq){groundNum}{dataGroundNum},["[" "]"]),...
                                {'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                        else
                            % #.#.#. Not a data
                            % e.g. [1:10]
                            selection.data.grounds.withoutArgs.format.(eq){groundNum}{dataGroundNum}=...
                                selection.data.grounds.withoutArgs.rough.(eq){groundNum}{dataGroundNum};
                        end

                        % #.#. Data name format
                        % e.g. [Data-Velocity x] -> data_velocity_x
                        prefix=lower(replace(selection.file.grounds.(eq){groundNum}{dataGroundNum},...
                            {'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                        selection.data.grounds.format.(eq){groundNum}{dataGroundNum}=strcat(prefix,...
                            selection.data.grounds.withoutArgs.format.(eq){groundNum}{dataGroundNum},...
                            selection.arg1.grounds.format.(eq){groundNum}{dataGroundNum}, ...
                            selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum});

                        % #.#. withoutArg1 (format)
                        % e.g. [Data%arg1](arg2) -> Data(arg2)
                        selection.data.grounds.withoutArg1.format.(eq){groundNum}{dataGroundNum}=erase(selection.data.grounds.format.(eq){groundNum}{dataGroundNum},selection.arg1.grounds.format.(eq){groundNum}{dataGroundNum});

                        % #.#. withoutArg2 (format)
                        selection.data.grounds.withoutArg2.format.(eq){groundNum}{dataGroundNum}=erase(selection.data.grounds.format.(eq){groundNum}{dataGroundNum},selection.arg2.grounds.format.(eq){groundNum}{dataGroundNum});

                    end

                    % #.#. Equation (format)
                    selection.equation.grounds.format.(eq){groundNum}=replace(...
                        selection.equation.grounds.rough.(eq){groundNum},...
                        selection.data.grounds.rough.(eq){groundNum},...
                        selection.data.grounds.format.(eq){groundNum});

                end

            end

            % #. Initialization
            for eq=["cat" "unique"]
                for var=varList1
                    for format=formatList
                        selection.(var).grounds.(format).(eq)=repmat({{}},1,groundNumber);
                    end
                end
                for var=varList2
                    for format=formatList
                        selection.data.grounds.(var).(format).(eq)=repmat({{}},1,groundNumber);
                    end
                end
                for var=varList3
                    selection.(var).grounds.(eq)=repmat({{}},1,numel(groundNumber));
                end
            end

            % #. Grounds
            for groundNum=1:groundNumber

                % #.#. Cat
                for var=varList1
                    for format=formatList
                        selection.(var).grounds.(format).cat{groundNum}=[selection.(var).grounds.(format).X{groundNum} selection.(var).grounds.(format).Y{groundNum}];
                    end
                end
                for var=varList2
                    for format=formatList
                        selection.data.grounds.(var).(format).cat{groundNum}=[selection.data.grounds.(var).(format).X{groundNum} selection.data.grounds.(var).(format).Y{groundNum}];
                    end
                end
                for var=varList3
                    selection.(var).grounds.cat{groundNum}=[selection.(var).grounds.X{groundNum} selection.(var).grounds.Y{groundNum}];
                end

                % #.#. Unique
                [~,ind]=unique(selection.data.grounds.format.cat{groundNum});
                for var=varList1(2:end) % without equation
                    for format=formatList
                        selection.(var).grounds.(format).unique{groundNum}=selection.(var).grounds.(format).cat{groundNum}(ind);
                    end
                end
                for var=varList2
                    for format=formatList
                        selection.data.grounds.(var).(format).unique{groundNum}=selection.data.grounds.(var).(format).cat{groundNum}(ind);
                    end
                end
                for var=varList3
                    selection.(var).grounds.unique{groundNum}=selection.(var).grounds.cat{groundNum}(ind);
                end

            end

            % #.#. Main class
            selection.class.grounds.main=cell(1,groundNumber);

            % #. Global
            % #.#. eq
            for eq=eqList
                for format=formatList
                    selection.equation.global.(format).(eq)=strcat(selection.equation.grounds.(format).(eq),'#');
                    selection.equation.global.(format).(eq)=[selection.equation.global.(format).(eq){:}];
                    selection.equation.global.(format).(eq)(end)=[]; % removes the last #
                end
                for var=varList1(2:end) % without equation
                    for format=formatList
                        selection.(var).global.(format).(eq)=[selection.(var).grounds.(format).(eq){:}];
                    end
                end
                for var=varList2
                    for format=formatList
                        selection.data.global.(var).(format).(eq)=[selection.data.grounds.(var).(format).(eq){:}];
                    end
                end
                for var=varList3
                    selection.(var).global.(eq)=[selection.(var).grounds.(eq){:}];
                end      
            end
            % #.#. cat
            for var=varList1
                for format=formatList
                    selection.(var).global.(format).cat=[selection.(var).global.(format).X selection.(var).global.(format).Y];
                end
            end
            for var=varList2
                for format=formatList
                    selection.data.global.(var).(format).cat=[selection.data.global.(var).(format).X selection.data.global.(var).(format).Y];
                end
            end
            for var=varList3
                selection.(var).global.cat=[selection.(var).global.X selection.(var).global.Y];
            end
            % #.#. Unique
            [~,ind]=unique(selection.data.global.format.cat);
            for var=varList1(2:end) % without equation
                for format=formatList
                    selection.(var).global.(format).unique=selection.(var).global.(format).cat(ind);
                end
            end
            for var=varList2
                for format=formatList
                    selection.data.global.(var).(format).unique=selection.data.global.(var).(format).cat(ind);
                end
            end
            for var=varList3
                selection.(var).global.unique=selection.(var).global.cat(ind);
            end

            % #. Custom mode
            if any(ismember(selection.data.global.withoutArgs.rough.cat,strcat('[',customList,']')))
                selection.other.customMode=true;
            else
                selection.other.customMode=false;
            end

            % #. Label
            % Axis or colorbar labels depending on the class

            for eq=eqList
                for groundNum=1:groundNumber

                    selection.label.(eq){groundNum}='';

                    if isempty(strip(selection.equation.grounds.rough.(eq){groundNum}))

                        % #.#. Empty

                        selection.label.(eq){groundNum}='';

                    else

                        % #.#. Non-empty

                        nameTemp=erase(selection.data.grounds.withoutArgs.rough.(eq){groundNum},["[" "]"]);
                        indData=find(ismember(nameTemp,dataList));
                        varOld=selection.data.grounds.rough.(eq){groundNum}(indData);
                        varNew=erase(selection.data.grounds.withoutArgs.rough.(eq){groundNum}(indData),{'[' ']'}); % rough to keep capitals
                        varNew=replace(varNew,{'_' ' '},{'~' '~'}); % before adding indices
                        indAbsolute=indData(contains(selection.arg1.grounds.rough.(eq){groundNum}(indData),'$'));
                        varNew(indAbsolute)=strcat(varNew(indAbsolute),'_{',selection.arg1.grounds.format.(eq){groundNum}(indAbsolute),'}'); % absolute steps are displayed
                        eqTemp=replace(...
                            selection.equation.grounds.rough.(eq){groundNum},...
                            varOld,...
                            varNew);
                        eqTemp=replace(eqTemp,{'.^' './' '.*'},{'^' '/' ''});
                        selection.label.(eq){groundNum}=replace(strip(eqTemp),{' '},{'~'});

                    end

                end
            end

            % #. Marker
            % if isnumeric(tabNumberOrPath) && ~isempty(tabNumberOrPath)
            %     if app.TabGroup.Children(tabNumberOrPath).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.displayCurrentStateNumber).Value ...
            %             && any(strcmp(selection.class.grounds.cat,'array')) ...
            %             && ~isempty(selection.other.graphNum) ...
            %             && selection.other.graphNum<=4
            %         markerTime=markerTimeFunction(app,tabNumberOrPath,selection.other.graphNum);
            %         if ~isempty(markerTime)
            %             selection.markerTime=markerTime;
            %         end
            %     end
            % end

            % #. Sort
            selection=orderfields(selection);

        end

        function settingsLoad(app,tabNumber)
            % Abstract: reads the settings contained in a .mat file and applies them to a tab
            
            % #. Importation
            load([app.pwdSave filesep 'Save' filesep 'settings_save.mat'],'settingsExport');
            
            % #. Processing
            % #.#. First loop for main components (checkbox, etc.)
            for elementNum=1:numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children)
                if strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uinumericeditfield') ...
                        || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uieditfield') ...
                        || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uicheckbox') ...
                        || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uiradiobutton') ...
                        || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uidropdown')
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Value=...
                    settingsExport.([app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Tag 'Export']){1};
                elseif strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Type,'uibuttongroup')
                    % #.#. Second loop for secondary components (radio buttons, etc.)
                    for elementSubNum=1:numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children)
                        if (strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uinumericeditfield') ...
                                || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uicheckbox') ...
                                || strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Type,'uiradiobutton')) ...
                                && ~strcmp(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Tag,'viewTypeList')
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Value=...
                            settingsExport.([app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(elementNum).Children(elementSubNum).Tag 'Export']){1};
                        end
                    end
                end
            end
        end
        
        function [tabNumberVect]=settingsSave(app,tabNumber)
            % Abstract: saves the settings in a .mat
            
            % #. Saves the current settings
            settingValues=struct();
            for compNum=1:numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children)
                if isprop(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum),'Value') % Some components do not correspond to a setting value (e.g. label, etc.)
                    settingValues.(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Tag)...
                        ={app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Value}; % Cell is used because sizes are non-uniforms (e.g. 1, 'turbo', etc.)
                end
            end
            
            % #. Processing
            if numel(app.sett)>=tabNumber ...
                    && ~isempty(app.sett{tabNumber}) ...
                    && ~isequal(app.sett{tabNumber},settingValues)
            
            % #.#. Tabs selection
            % #.#.#. DLG
            answer=questdlg('Change(s) in the settings have been detected:','Settings','Apply change(s) to this tab only','Apply change(s) to other tabs','Apply all the current settings to other tabs','Apply change(s) to this tab only');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(answer)
                % #.#.#. Resets the settings
                settingsLoad(app,tabNumber); return;
            elseif strcmpi(answer,'Apply change(s) to this tab only')
                % #.#.#. Apply to this tab
                tabNumberVect=tabNumber;
            else
                % #.#.#. Apply to other tabs
                % #.#.#.#. List of the tabs
                tabList=cell(1,numel(app.TabGroup.Children)-2);
                for tabNumberVar=3:numel(app.TabGroup.Children)
                    tabList{tabNumberVar-2}=app.TabGroup.Children(tabNumberVar).Title;
                end
                % #.#.#.#. DLG
                [ind,~]=listdlg('PromptString',{'Select the tabs:'},'ListString',tabList,'InitialValue',1:numel(tabList));
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(ind); settingsLoad(app,tabNumber); return; end % Resets the settings
                tabNumberVect=2+ind;
            end
            
            % #.#. Update
            for compNum=1:numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children)
                
                if isprop(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum),'Value') % Some components do not correspond to a setting value (e.g. label, etc.)
                    
                    propName=string(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Tag);
                    
                    if ~isequal(app.sett{tabNumber}.(propName),settingValues.(propName)) || strcmpi(answer,'Apply all the current settings to other tabs') % Only the settings that have been updated in the current tab will be applied to other tabs or all the settings according to the previous selection
                        
                        % #.#.#. First tab

                        if propName~="filestodeleteListBox" ... % Lists can be different for each tab
                                && propName~="periodicboundariesText"
                            app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Value=settingValues.(propName){1};
                        end

                        % #.#.#. Tab dependent setting
                        
                        for tabNumberVar=tabNumberVect
                            
                            % #.#.#.#. Assign
                            if ((tabNumberVar~=tabNumber && isequal(app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Value,settingValues.(propName){1})) ...
                                    || (tabNumberVar==tabNumber && isequal(app.sett{tabNumber}.(propName),settingValues.(propName)))) ...
                                    || (tabNumberVar~=tabNumber && propName=="filestodeleteListBox")
                                continue; % If a setting is already equal to the new value
                            else
                                app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Value=settingValues.(propName){1};
                            end
                            
                            % #.#.#.#. Refreshes tab name
                            if propName=="sizePathDisplay"
                                [nameSimu]=nameSimuFunction(app,tabNumberVar,[],[]);
                                app.TabGroup.Children(tabNumberVar).Title=nameSimu;
                            end
                            
                            % #.#.#.#. Refreshes graphs
                            if propName=="displayCurrentState"
                                reloadGraph(app,tabNumberVar,'reset','MATRIX');
                            end
                            if propName=="displayMesh" ...
                                    || propName=="displayNodes" ...
                                    || propName=="colormapList" ...
                                    || propName=="backgroundValue" ...
                                    || propName=="periodicboundariesText"
                                reloadGraph(app,tabNumberVar,'reset',{'SURFACE' 'IMAGEXYZ' 'IMAGE'});
                            end

                            % #.#.#.#. Refreshes limits
                            if propName=="stretchToFillCheck"
                                reloadGraph(app,tabNumberVar,'limits',{'SURFACE' 'IMAGEXYZ' 'IMAGE'});
                            end

                            % #.#.#.#. Refreshes view
                            if propName=="viewTypeList"
                                viewTypeFunction(app,tabNumberVar)
                            end
                            
                            % #.#.#.#. Section separator
                            if propName=="sectionSeparatorDisplay"
                                resetEditedText(app,tabNumberVar);
                            end

                        end
                        
                        % #.#.#.#. Running mode
                        if propName=="runModeList" || propName=="executableFromFolder"
                            % All the tabs must be checked. For example,
                            % for the tab number 3, if the tab number 5 is
                            % in progress, it may change its format
                            findExecution(app,[1 3:numel(app.TabGroup.Children)]);
                        end
                        
                        % #.#.#. GUI unique setting
                        
                        if propName=="manualPos"
                            for tabNumberVar=[1 3:numel(app.TabGroup.Children)]
                                app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Value=settingValues.(propName){1};
                            end
                        end
                        
                    end
                end
            end
            
            % #. Export of settings
            settingsExport=struct();
            for compNum=1:numel(app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children)
                if isprop(app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum),'Value') % Some components do not correspond to a setting value (e.g. label, etc.)
                    settingsExport.([app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Tag 'Export'])...
                        ={app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Value}; % Cell is used because sizes are non-uniforms (e.g. 1, 'turbo', etc.)
                end
            end
            settingsExport=struct2table(settingsExport);
            save([app.pwdSave filesep 'Save' filesep 'settings_save.mat'],'settingsExport');
            end

        end

        function sliderFunction(app,tabNumber)
            % Abstract: changes the GRAPHICS according to slider position
            
            sliderValue=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value;

            % #. Loop on surfaces/images
            for graphNum=1:4

                % #.#. Indices
                graphNumFunction(app,graphNum);

                if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                    info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                    if any(strcmpi(info.selection.type.global.cat,'STEPS'))

                        % #.#. Selection
                        [info,dataSave]=sliderToSteps(app,info,sliderValue);
                        if isempty(dataSave); continue; end

                        % #.#. Update equation
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value=info.selection.equation.global.rough.X;
                        
                        % #.#. Plot
                        info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                        displayGraphMain(app,[],tabNumber,info,'refresh');

                    end
                end

            end
            
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.displayCurrentStateNumber).Value % only the marker needs to be modified
                % #. Loop on plots
                for graphNum=1:4

                    % #.#. Indices
                    graphNumFunction(app, graphNum);

                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)

                        % #.#. Selection
                        info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);

                        % #.#. Plot
                        if any(strcmpi(info.selection.type.global.cat,'MATRIX'))
                            displayGraphMain(app,[],tabNumber,info,'refresh');
                        end

                    end
                end
            end

        end

        function [info,dataSave]=sliderToSteps(app,info,sliderValue)
            % Abstract: converts the slider value into steps that are used
            % in an equation (i.e. calculating the range of available data)
            
            % #. Initialization
            indToLook=find(contains(info.selection.arg1.global.rough.cat,'%') & strcmpi(info.selection.type.global.cat,'STEPS')); % Data for which the step can be changed;
            dataSave=[];
            
            if ~isempty(indToLook)
                % #. Steps available
                steps=loadStepsListFunction(app,info.selection.other.pathSimu,info.selection.file.global.cat(indToLook),'CODE');
                steps=flip(steps);
                % #. Individual range
                % e.g. [Velocity%2]+[Velocity%4] for steps=1:5
                % For [Velocity%2], it can be assumed that it is possible to subtract 1 and add 3 to the step index
                indCurrentStep=zeros(1,numel(indToLook)); % Index of the current step in the stepList
                deltaTemp=zeros(numel(indToLook),2); % [subtraction allowed, addition allowed]
                for dataNum=1:numel(indToLook)
                    indTemp=find(strcmp(sort(steps),info.selection.arg1.global.format.cat{indToLook(dataNum)}),1,'first');
                    if isempty(indTemp)
                        indCurrentStep(dataNum)=1; % Avoids errors
                    else
                        indCurrentStep(dataNum)=indTemp;
                    end
                    deltaTemp(dataNum,:)=[indCurrentStep(dataNum)-1 numel(steps)-indCurrentStep(dataNum)];
                end
                % #. Real range
                % e.g. In this case, it is not possible to add more than 1 step:
                % [Velocity%3]+[Velocity%5]
                deltaReal=min(deltaTemp,[],1);
                % #. Value of the new index
                indInList=round(sliderValue*(deltaReal(1)+deltaReal(2)));
                % #. Refreshes the indices
                dataSave=info.selection.arg1.global.rough.X;
                for dataNum=1:numel(indToLook)
                    indOpen=strfind(info.selection.arg1.global.rough.X{indToLook(dataNum)},'%');
                    indClose=strfind(info.selection.arg1.global.rough.X{indToLook(dataNum)},']');
                    if any(cellfun(@isempty,steps)) || isempty(steps)
                        argNew=''; indOpen=indOpen-1;
                    else
                        argNew=steps{numel(steps)-(indCurrentStep(dataNum)-deltaReal(1)+indInList)+1};
                    end
                    info.selection.arg1.global.rough.X{indToLook(dataNum)}=...
                        [info.selection.arg1.global.rough.X{indToLook(dataNum)}(1:indOpen)...
                        argNew ...
                        info.selection.arg1.global.rough.X{indToLook(dataNum)}(indClose:end)];
                end
                varOld=info.selection.data.global.withoutArg2.rough.X;
                varNew=strcat(erase(info.selection.data.global.withoutArgs.rough.X,']'),info.selection.arg1.global.rough.X,']');
                eqX=replace(info.selection.equation.global.rough.X,varOld,varNew);
                eqY=info.selection.equation.global.rough.Y;
                info.selection=selectionInfo(app,...
                    info.selection.other.tabNumber,...
                    info.selection.other.graphNum,...
                    {eqX eqY},info.selection);
            end

        end

        function squeueFunction(app)
            % Abstract: Squeue command on the cluster

            % #. Servers
            serversList=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items(2:end);
            ind=listdlg('PromptString',{'Select the server:'},'listString',serversList);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(ind)
                return;
            else
                servers=serversList(ind);
            end

            % #. Initialization
            shift=zeros(1,numel(servers));
            indCurrentJob=cell(1,numel(servers));
            squeueValue=cell(1,numel(servers));

            for serverNum=1:numel(servers)
                % #. Connection
                s_conn=clusterLoadInfo(app,[],servers(serverNum));
                if isempty(s_conn)
                    busyOff(app); return;
                end
                % #. Subfolders
                dirSave=ssh2_command_response(ssh2_command(s_conn,['find ' s_conn.remote_target_direcory ' -type d' ' 2>&1'])); % List of all the subfolders
                % #. Squeue
                tabNumber=findTab(app);
                [~,~,key]=clusterPath(app,tabNumber,s_conn);
                [~,squeueValueTemp,~]=clusterJob(app,s_conn,key);
                squeueValueTemp(:,all(cellfun(@isempty,squeueValueTemp)))=[]; % Some columns are empty
                for columnNum=size(squeueValueTemp,2):-1:2
                    if isempty(squeueValueTemp{1,columnNum})
                        for lineNum=1:size(squeueValueTemp,1)
                            squeueValueTemp{lineNum,columnNum-1}=strjoin(squeueValueTemp(lineNum,columnNum-1:columnNum)); % Some columns can be split into several columns
                        end
                        squeueValueTemp(:,columnNum)=[];
                    end
                end
                % #. Highlighting the current user's jobs (name editing + change of color thereafter)
                % #.#. Indexes
                indName=find(ismember(squeueValueTemp(1,:),'NAME'),1,'first'); % Columns that contain the job names
                indUser=find(ismember(squeueValueTemp(1,:),'USER'),1,'first'); % Columns that contain the job users
                % #.#.#.#. 8 characters maximum
                username=s_conn.username;
                if numel(username)>8
                    username(9:end)=[];
                end
                indCurrentJob{serverNum}=reshape(find(strcmpi(squeueValueTemp(:,indUser),username)),1,[]); % Current user's jobs
                % #.#. Name editing
                % 'jobName' -> 'folder/subfolder [jobName]'
                for indNum=indCurrentJob{serverNum}
                    indFolder=find(contains(dirSave,['_' squeueValueTemp{indNum,indName}]),1,'first'); % The name format for the folder is 'name_jobName', it allows to find easily which job corresponds to which simulation folder
                    if ~isempty(indFolder)
                        squeueValueTemp{indNum,indName}=[erase(dirSave{indFolder},{['_' squeueValueTemp{indNum,indName}] [s_conn.remote_target_direcory '/']}) ' [' squeueValueTemp{indNum,indName} ']' ];
                    end
                end
                indCurrentJob{serverNum}=indCurrentJob{serverNum}+sum(shift(1:serverNum))-1; % Shift because all servers are concatenated in the same table, -1 because the header will be removed.
                shift(serverNum)=sum(shift(1:serverNum))+size(squeueValueTemp,1)-1;
                % #.#.#.#. Save
                header=['SERVER' squeueValueTemp(1,:)];
                squeueValue{serverNum}=[repmat(servers(serverNum),size(squeueValueTemp(2:end,:),1),1) squeueValueTemp(2:end,:)]';
            end

            % #. Displays the results
            squeueValue=[squeueValue{:}]';
            tableVar=cell2table(squeueValue);
            tableVar.Properties.VariableNames=header;
            figQueue=uifigure('Name','squeue','Units','Normalized','Position',[0.1 0.2 0.8 0.6],'Color',[.98 .98 .98]);
            tabQueue=uitable(figQueue,'Data',tableVar,'Units','Normalized','Position',[0 0 1 1],'ColumnEditable',false);
            addStyle(tabQueue,uistyle('BackgroundColor','#B5CBF8'),'row',[indCurrentJob{:}]); % Change of color for the jobs of the current user

            % #. Closing the connection
            ssh2_close(s_conn);
            clearvars('s_conn');
            busyOff(app);
        end

        function status=stepFilesToDelete(app,tabNumber)
            % Abstract: Function that deletes files if a job is started
            % from a older step
            
            % #. Step
            status=[];
            stepChar=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value;
            stepNum=str2double(stepChar);
            if isnan(stepNum) || isempty(stepNum); return; end
            
            % #. Paths
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathCode=[pathSimu filesep 'CODE'];
            
            % #. Files to delete
            [~,~,dataType,dataFile,dataExt]=dataMainListFunction(app,pathSimu,'Refresh');
            ind0=find(strcmpi(dataType,'STEPS'));
            [~,ind1]=unique(strcat(dataFile(ind0),dataExt(ind0)));
            typeToDelete=dataFile(ind0(ind1));
            extToDelete=dataExt(ind0(ind1));
            [~,~,stepsUnion,stepsAll]=loadStepsListFunction(app,pathSimu,typeToDelete,'CODE');
            filesToDelete=cell(1,numel(typeToDelete));
            for typeNum=1:numel(typeToDelete)
                ind=str2double(stepsAll{typeNum})>=stepNum;
                filesToDelete{typeNum}=strcat(typeToDelete{typeNum},stepsAll{typeNum}(ind),extToDelete{typeNum});

            end
            filesToDelete=[filesToDelete{:}];
            if isempty(filesToDelete); return; end

            % #. DLG for the user to choose whether to delete files
            % #.#. Initial
            messDisp=char(strcat('Warning:',{' '},num2str(numel(filesToDelete)),{' '}, 'file(s)',{' ('},strjoin(typeToDelete,','),') correspond to more recent/current steps, would you like to delete them?'));
            warnChoice=questdlg(messDisp,'Warning',['Yes (including step ' stepChar ')'],['Yes (except step ' stepChar ')'],'No','No');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(warnChoice)
                status='cancel';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                return;
            elseif contains(warnChoice,'No')
                return;
            elseif contains(warnChoice,'except')
                filesToDelete(contains(filesToDelete,stepChar))=[];
                if isempty(filesToDelete); return; end
            end
            % #.#. Other
            % To select files containing the step but which are not data files
            % #.#.#. Potential step files
            if contains(warnChoice,'except')
                ind=str2double(stepsUnion)>stepNum;
            else
                ind=str2double(stepsUnion)>=stepNum;
            end
            stepsTemp=stepsUnion(ind);
            dirTemp=dir(pathCode);
            filesTemp={dirTemp.name};
            indToKeep=false(1,numel(filesTemp));
            for stepNum=1:numel(stepsTemp)
                indToKeep=indToKeep | contains(filesTemp,stepsTemp{stepNum});
            end
            indToKeep(ismember(filesTemp,filesToDelete))=0;
            filesTemp(~indToKeep)=[];
            % #.#.#. DLG
            if ~isempty(filesTemp)
                [ind,tf]=listdlg('PromptString',{'Select additional files to delete:'},'ListString',filesTemp);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if ~tf
                    status='cancel';
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                    return;
                else
                    filesToDelete=[filesToDelete filesTemp(ind)];
                end
            end
            % #.#.#. Confirmation
            warnChoice=questdlg('Do you confirm the deletion?','Warning','Yes','No','No');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if contains(warnChoice,'No') || isempty(warnChoice)
                status='cancel';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                return;
            end
            
            % #. Deleting files
            for filesNum=1:numel(filesToDelete)
                delete([pathCode filesep filesToDelete{filesNum}]);
            end
            
            % #. Log
            if ~exist([pathSimu filesep 'SAVE'],'dir')
                mkdir([pathSimu filesep 'SAVE']);
            end
            [~,~]=LogSave(filesToDelete, {''}, 'Files deleted during run attempt', [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
            
            % #. Update
            refreshSteps(app,tabNumber,1);
            resetSave(app,tabNumber);
            timeSaveReset(app,tabNumber);
            refreshGraphicsFunction(app,tabNumber);
            
        end
        
        function stepForwardBackward (app,tabNumber,mode)
            % Abstract: Displays the previous or next GRAPHICS according
            % to the offset value in steps (XXS) or % (XX%)

            % #. offset
            if strcmpi(mode,'fastBackward')
                offset='-10S';
            elseif strcmpi(mode,'slowBackward')
                offset='-1S';
            elseif strcmpi(mode,'slowForward')
                offset='1S';
            elseif strcmpi(mode,'fastForward')
                offset='10S';
            else
                return;
            end

            % #. Loop on graphs
            for graphNum=1:4

                % #.#. Indices
                graphNumFunction(app, graphNum);

                if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                    info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);

                    if any(strcmpi(info.selection.type.global.cat,'STEPS'))

                        % #.#. Surface / image

                        indToLook=find(contains(info.selection.arg1.global.rough.cat,'%') & strcmpi(info.selection.type.global.cat,'STEPS')); % Data for which the step can be changed

                        if ~isempty(indToLook)

                            % #.#.#. Steps available
                            steps=loadStepsListFunction(app,info.selection.other.pathSimu,info.selection.file.global.cat(indToLook),'CODE');
                            steps=flip(steps);
                            if any(cellfun(@isempty,steps))
                                return;
                            end

                            % #.#.#. Hypothetical offsets
                            % e.g. [Velocity%2]+[Velocity%4] for steps=1:5
                            % For [Velocity%2], it can be assumed that it is possible to subtract 1 and add 3 to the step index
                            indCurrentStep=zeros(1,numel(indToLook)); % Index of the current step in the stepList
                            deltaTemp=zeros(numel(indToLook),2); % [subtraction allowed, addition allowed]
                            for dataNum=1:numel(indToLook)
                                indTemp=find(strcmp(sort(steps),info.selection.arg1.global.format.cat{indToLook(dataNum)}),1,'first');
                                if isempty(indTemp)
                                    indCurrentStep(dataNum)=1; % Avoids errors
                                else
                                    indCurrentStep(dataNum)=indTemp;
                                end
                                deltaTemp(dataNum,:)=[indCurrentStep(dataNum)-1 numel(steps)-indCurrentStep(dataNum)];
                            end

                            % #.#.#. Actual offsets
                            % In reality, we can only add 1 to the 1st [] because we will have:
                            % [Velocity%3]+[Velocity%5]
                            deltaReal=min(deltaTemp,[],1);

                            % #.#.#. Step offset
                            if contains(offset,'S')
                                offsetReal=str2double(erase(offset,'S'));
                            else
                                offsetReal=round(str2double(erase(offset,'%')))/100;
                                offsetReal=round(offsetReal*(deltaReal(1)+deltaReal(2)));
                            end
                            if offsetReal>0 && offsetReal>deltaReal(2)
                                offsetReal=deltaReal(2);
                            elseif offsetReal<0 && abs(offsetReal)>deltaReal(1)
                                offsetReal=-deltaReal(1);
                            end
                            if offsetReal==0
                                continue; % If a higher step cannot be selected
                            end

                            % #.#.#. Refreshes the indices
                            %dataSave=info.selection.arg1.global.rough.X;
                            for dataNum=1:numel(indToLook)
                                argNew=steps{numel(steps)-(indCurrentStep(dataNum)+offsetReal)+1};
                                indOpen=strfind(info.selection.arg1.global.rough.X{indToLook(dataNum)},'%');
                                indClose=strfind(info.selection.arg1.global.rough.X{indToLook(dataNum)},']');
                                info.selection.arg1.global.rough.X{indToLook(dataNum)}=...
                                    [info.selection.arg1.global.rough.X{indToLook(dataNum)}(1:indOpen)...
                                    argNew ...
                                    info.selection.arg1.global.rough.X{indToLook(dataNum)}(indClose:end)];
                            end
                            varOld=info.selection.data.global.withoutArg2.rough.X;
                            varNew=strcat(erase(info.selection.data.global.withoutArgs.rough.X,']'),info.selection.arg1.global.rough.X,']');
                            info.selection.equation.global.rough.X=replace(info.selection.equation.global.rough.X,varOld,varNew);
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(app.id.gridGraphVar).Children(app.id.graphXEqNumberVar).Value=info.selection.equation.global.rough.X;
                            
                            % #.#.#. Plot
                            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                            displayGraphMain(app,[],tabNumber,info,'refresh');

                        end
                    end
                end
            end

            % #. Loop on the graphs
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.displayCurrentStateNumber).Value
                for graphNum=1:4
                    % #.#. Indices
                    graphNumFunction(app, graphNum);
                    if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                        info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                        if any(strcmpi(info.selection.class.global.cat,'MATRIX'))
                            % #.#. If and only if it is a plot
                            displayGraphMain(app,[],tabNumber,info,'refresh');
                        end
                    end
                end
            end

        end

        function stepListValueChangedFunction(app,tabNumber)
            % Abstract: displays the current time

            % #. Time
            timeSimulationDisplay(app,tabNumber);

        end

        function sliderValue=stepsToSlider(app,info)
            % Abstract: converts the steps used in an equation into a slider
            % value (i.e. calculating the range of available data)
            
            % #. Initialization
            sliderValue=[];
            indToLook=find(contains(info.selection.arg1.global.rough.cat,'%') & strcmpi(info.selection.type.global.cat,'STEPS'));
            
            if ~isempty(indToLook)
                % #. Steps available
                [~,~,~,stepList]=loadStepsListFunction(app,info.selection.other.pathSimu,info.selection.file.global.cat(indToLook),'CODE');
                % #. Individual range
                % e.g. [Velocity%2]+[Velocity%4] for steps=1:5
                % For [Velocity%2], it can be assumed that it is possible to subtract 1 and add 3 to the step index
                indCurrentStep=zeros(1,numel(indToLook)); % Index of the current step in the stepList
                deltaTemp=zeros(numel(indToLook),2); % [subtraction allowed, addition allowed]
                for dataNum=1:numel(indToLook)
                    indCurrentStep(dataNum)=find(strcmp(sort(stepList{dataNum}),info.selection.arg1.global.format.cat{indToLook(dataNum)}),1,'first');
                    deltaTemp(dataNum,:)=[indCurrentStep(dataNum)-1 numel(stepList{dataNum})-indCurrentStep(dataNum)];
                end
                % #. Real range
                % e.g. In this case, it is not possible to add more than 1 step:
                % [Velocity%3]+[Velocity%5]
                deltaReal=min(deltaTemp,[],1);
                % #. Slider value
                sliderValue=deltaReal(1)/(deltaReal(1)+deltaReal(2));
                if sliderValue<0 || sliderValue>1
                    msgbox('Out of range.','Information','help');
                    return;
                end
            end
        end

        function tabCreation(app)
            % Abstract: creation of a new tab
            
            % #. Tab creation
            copyobj(app.TabGroup.Children(1), app.TabGroup,'legacy'); % Legacy allows buttons to be associated with a callback rather than non-reactive components

            % #. Tools
            % In matlab 2024b, copyobj does not support uitree, so the
            % object is hard-coded.
            % #.#. Main folders
            pathTools=[app.pwdSave filesep 'Tools'];
            folders=dir(pathTools);
            dirFlags=[folders.isdir];
            folders={folders(dirFlags).name};
            folders(ismember(folders,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
            % #.#. Tree
            % adds subfolders using recursion
            tree=uitree(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.TOOLSPanelNumber).Children(app.id.gridToolsNumber), ...
                'checkbox', ...
                'BackgroundColor', app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).BackgroundColor, ...
                'FontColor', app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).FontColor, ...
                'FontName', app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).FontName, ...
                'FontSize', app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).FontSize, ...
                'SelectionChangedFcn',@(tree,event) toolsProcessingSelection(app), ...
                'Tag','toolsTree');
            app.id.toolsTreeNumber=numel(app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.TOOLSPanelNumber).Children(app.id.gridToolsNumber));
            for folderNum=1:numel(folders)
                rootNode=uitreenode(tree,'Text',folders{folderNum},'NodeData',[pathTools filesep folders{folderNum}]);
                toolsSubFolders(app,rootNode);
            end
            % #.#. Keeps only folders with mains
            % #.#.#. Tree object
            treeSub=findobj(tree,'type','uitreenode');
            treeSubPaths={treeSub.NodeData};
            % #.#.#. Tools folder
            % Sub folders
            toolsDir=dir([app.pwdSave filesep 'Tools/**/*.*']); % Gives all the subfolders
            [~,toolsFiles]=fileparts({toolsDir.name});
            toolsPaths={toolsDir.folder};
            indToKeep=strcmpi(toolsFiles,'mainOpen') | strcmpi(toolsFiles,'mainRun');
            % Upper folders
            % Folders that contain subfolders that contain main files must
            % be kept in the tree
            indToKeepUpper=find(~indToKeep);
            for pathNum=1:numel(indToKeepUpper)
                indToKeep(indToKeepUpper(pathNum))=any(contains(toolsPaths(indToKeep),toolsPaths(indToKeepUpper(pathNum))));
            end
            toolsDir(~indToKeep)=[];
            toolsPaths={toolsDir.folder};
            % #.#.#. Delete
            indToKeep=ismember(treeSubPaths,toolsPaths);
            delete(treeSub(~indToKeep));
            % #.#. Expand
            %expand(tree);

            % #. Format
            app.TabGroup.Children(end).Title=['#' num2str(size(app.TabGroup.Children,1)-2,'%02.f')];
            set(app.TabGroup.Children(end),'ForegroundColor',[0 0 0]);
            app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).BorderColor=...
                app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.TOOLSPanelNumber).BorderColor=...
                app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.TOOLSPanelNumber).BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).BorderColor=...
                app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).BorderColor=...
                app.TabGroup.Children(end).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            
        end

        function tabNumber = findTab(app)
            % Abstract: finds the selected tab

            tabNumber=find(strcmp({app.TabGroup.Children.Title},app.TabGroup.SelectedTab.Title),1,'first');
        end

        function timeSimulationDisplay(app,tabNumber)
            % Abstract: finds the time associated with the selected STEP
            % (i.e. simulation time) and displays the result

            % #. No step
            if numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items)==0
                return;
            end
            
            % #. Paths
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathCode=[pathSimu filesep 'CODE'];
            step=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value;
            if isempty(step); return; end

            % #. Current file
            [~,~,dataType,dataFile,dataExt,~,~]=dataMainListFunction(app,tabNumber,'Refresh');
            indData=find(strcmpi(dataType,'STEPS'));
            if isempty(indData); return; end
            paths=strcat(pathCode,filesep,dataFile(indData),step,dataExt(indData));
            indFile=find(isfile(paths),1,'first');
            if isempty(indFile); return; end
            file=dataFile{indData(indFile)};
            ext=dataExt{indData(indFile)};
            
            % #. Time
            timeInSimu=timeSimulationRead(app,pathSimu,file,step,ext);
            
            % #. Display
            msg=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.displayTimeNumber).Text;
            ind=find(contains(lower(msg),'current'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' timeInSimu];
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.displayTimeNumber).Text=msg;

        end

        function [time,timeNum]=timeSimulationRead(~,pathSimu,file,step,ext)
            % Abstract: gives the corresponding time a step file

            % #. Initialization
            time=[]; timeNum=[];
            fileName=[file,step,ext];
            pathName=[pathSimu filesep 'CODE' filesep fileName];
            if ~isfile(pathName)
                return;
            end

            % #. Read
            try
                fileID=fopen(pathName);
                fgetl(fileID);
                lineSplit=split(fgetl(fileID),' ');
                indTime=find(strcmpi(lineSplit,'Time'),1,'last');
                if ~isempty(indTime)
                    time=lineSplit{indTime+1};
                    timeNum=str2double(time);
                end
            catch
            end
            fclose(fileID);

        end

        function timeSaveDurationDisplay(app,tabNumber)
            % #. Display several durations of the simulation

            % #. At least 2 steps are required
            if numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items)<2
                return;
            end

            % #. Paths
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathMat=[pathSimu filesep 'SAVE' filesep 'TIME_save.mat'];
            if ~isfile(pathMat); return; end

            % #. Importation
            load(pathMat,'timeExport');
            timeExport(isnan(timeExport.VsPt),:)=[]; % removes steps that are ignored

            % #. Regression
            % #.#. Steps
            steps=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items;
            stepsNum=str2double(steps);
            % #.#. Order of regression
            n=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Children(app.id.regressionValueNumber).Value;
            if n>numel(timeExport.VsPt)-1
                % If the order of the regression is greater than
                % the number of data, we take the maximum
                % possible order
                n=numel(timeExport.VsPt)-1;
                if n<0; return; end % empty data
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Children(app.id.regressionValueNumber).Value=n;
                msgbox(['The maximum regression order is ' num2str(n) ' due to the number of available data.'],'Information','help');
            end
            % #.#.#. Regression
            p=polyfit(str2double(timeExport.step),timeExport.VsPt,n);

            % #. Duration
            % #.#. Numeric
            dTotalNum=(max(stepsNum)-min(stepsNum)+1)/mean(timeExport.VsPt);
            dSNum=polyval(p,max(stepsNum))*1;
            dMnNum=polyval(p,max(stepsNum))*60;
            dHNum=polyval(p,max(stepsNum))*3600;
            dDNum=polyval(p,max(stepsNum))*86400;
            dWkNum=polyval(p,max(stepsNum))*604800;
            dMoNum=polyval(p,max(stepsNum))*2628000;
            % #.#. Format
            dTotal=seconds(dTotalNum);
            dTotal.Format='dd:hh:mm:ss';
            dTotal=char(dTotal);
            dS=num2str(dSNum);
            dMn=num2str(dMnNum);
            dH=num2str(dHNum);
            dD=num2str(dDNum);
            dWk=num2str(dWkNum);
            dMo=num2str(dMoNum);


            % #. Display
            % Takes the last regressed speed for step estimation
            msg=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.displayTimeNumber).Text;
            % #.#. Total duration
            ind=find(contains(lower(msg),'total'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' dTotal];
            % #.#. Second
            ind=find(contains(lower(msg),'1 s:'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' dS ' step(s)'];
            % #.#. Minute
            ind=find(contains(lower(msg),'1 min:'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' dMn ' step(s)'];
            % #.#. Hour
            ind=find(contains(lower(msg),'1 h:'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' dH ' step(s)'];
            % #.#. Day
            ind=find(contains(lower(msg),'1 d:'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' dD ' step(s)'];
            % #.#. Week
            ind=find(contains(lower(msg),'1 wk:'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' dWk ' step(s)'];
            % #.#. Month
            ind=find(contains(lower(msg),'1 mo:'),1,'last');
            line=strsplit(msg{ind},':'); % allows to change format more easily
            msg{ind}=[line{1} ': ' dMo ' step(s)'];
            % #.#. Assignment
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.displayTimeNumber).Text=msg;

            % #. Plot
            if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Visible,'on')
                ax=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Children(app.id.timeGraphNumber);
                tagSave=ax.Tag; % the tag is not stored
                plot(ax,str2double(timeExport.step),timeExport.VsPt,'xk');
                hold(ax,'on');
                xReg=linspace(min(str2double(timeExport.step)),max(str2double(timeExport.step)),100);
                yReg=polyval(p,xReg);
                plot(ax,xReg,yReg,'k--');
                xlabel(ax,'Step [-]');
                ylabel(ax,'Computation speed [step.s^{-1}]');
                grid(ax,'on');
                ax.Tag=tagSave;
                ax.Visible='on';
            end

        end

        function timeSaveMainDisplay(app,tabNumber)
            % Abstract: displays the different times on the interface
            
            % #. No step
            if numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items)==0
                return;
            end
            
            % #. Time in simulation
            timeSimulationDisplay(app,tabNumber);

            % #. Duration
            timeSaveDurationDisplay(app,tabNumber);
            
        end

        function timeSaveReset(app,tabNumber)
            % Abstract: TIME_save.mat updated according to STEP files in
            % CODE folder

            % #. Path
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            if ~isfile([pathSimu filesep 'SAVE' filesep 'TIME_save.mat'])
                return;
            end

            % #. Loading the variable to be processed
            load([pathSimu filesep 'SAVE' filesep 'TIME_save.mat'],'timeExport');

            % #. Loading the STEP list
            [~,~,~,stepsUnion,~]=loadListStep(app,tabNumber);

            % #. timeExport row names not found in the list
            if isempty(stepsUnion)
                timeExport(:,:)=[];
            else
                timeExport(~ismember(timeExport.step,stepsUnion),:)=[];
            end

            % #. Processing
            timeExport=sortrows(timeExport,1,'descend');

            % #. Save
            save([pathSimu filesep 'SAVE' filesep 'TIME_save.mat'],'timeExport');

        end

        function timeSaveWritter(app,tabNumber,steps,updateMode,autoMode)
            % Abstract: Function for saving computation speed and
            % simulation time for a given STEP in a .mat file
            % updateMode: force computation of previously ignored steps
            % autoMode: automatic detection of steps to ignore

            % #. At least 2 steps
            if numel(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items)<2
                return;
            end
           
            % #. File to check time
            % Takes the file that contains the last step and then the
            % maximum number of steps
            % #.#. Files that correspond to steps
            [~,~,dataType,dataFile,dataExt,~,~]=dataMainListFunction(app,tabNumber,'Refresh');
            indSteps=find(strcmpi(dataType,'STEPS'));
            if isempty(indSteps); return; end
            [filesList,ind]=unique(dataFile(indSteps));
            extList=dataExt(indSteps(ind));
            % #.#. Steps
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            [~,~,~,stepsAll]=loadStepsListFunction(app,pathSimu,filesList,'code');
            indToRemove=cellfun(@isempty,stepsAll); if all(indToRemove); return; end
            filesList(indToRemove)=[];
            extList(indToRemove)=[];
            stepsAll(indToRemove)=[];
            maxSteps=reshape(cellfun(@(x)max(str2double(x)),stepsAll),[],1); % last step of each file
            numelSteps=reshape(cellfun(@numel,stepsAll),[],1); % number of steps per file
            % #.#. Final processing
            [~,indSort]=sortrows([maxSteps numelSteps],[1,2],{'descend' 'descend'});
            [~,indSort]=min(indSort);
            file=filesList{indSort};
            ext=extList{indSort};

            % #. Steps
            if isempty(steps) % no input steps
                steps=loadStepsListFunction(app,pathSimu,file,'Code');
                stepsNum=str2double(steps);
                indToKeep=[false stepsNum(1:end-1)+1==stepsNum(2:end)]; % keeps successive steps
                steps=steps(indToKeep);
            end
            if isempty(steps); return; end
            
            % #. Initialization
            if isfile([pathSimu filesep 'SAVE' filesep 'TIME_save.mat'])
                load([pathSimu filesep 'SAVE' filesep 'TIME_save.mat'],'timeExport');
            else
                timeExport=table({},[],[],datetime([],[],[]),'VariableNames',{'step','VsPt' 'tSimu' 'tReal'});
            end

            % #. Already computed
            % If update, force reload
            if ~updateMode
                steps(ismember(steps,timeExport.step))=[];
            end
            
            % #. t1 (most recent of the 2 STEPS)
            if numel(steps)<2; return; end
            pathStep=[pathSimu filesep 'CODE' filesep file steps{1} ext];
            [~,t1Simu]=timeSimulationRead(app,pathSimu,file,steps{1},ext);
            dirTemp=dir(pathStep);
            t1RealTemp=datetime(dirTemp.datenum,'ConvertFrom','datenum'); % datenum avoids errors with local format
            try
                t1Real=datetime(GetFileTime(pathStep, 'Local', 'Creation')); % Use of a custom function as matlab only gives the date of the last modification
            catch
                t1Real=t1RealTemp; % mex issues for macOS
            end
            if t1Real>t1RealTemp
                % When importing files from the cluster, you can keep the
                % modification date, but the creation date corresponds to
                % the download date, which means that the creation date
                % is more recent than the last modification date
                t1Real=t1RealTemp;
            end

            % #. New STEPS 
            stepsNumber=size(timeExport,1);
            for stepNum=1:numel(steps)-1
                % #.#. t0 (STEP the least recent of the 2)
                pathStep=[pathSimu filesep 'CODE' filesep file steps{stepNum+1} ext];
                [~,t0Simu]=timeSimulationRead(app,pathSimu,file,steps{stepNum+1},ext);
                % #.#. Retrieve file creation date
                dirTemp=dir(pathStep);
                t0RealTemp=datetime(dirTemp.datenum,'ConvertFrom','datenum'); % datenum avoids errors with local format
                try
                    t0Real=datetime(GetFileTime(pathStep,'Local','Creation')); % Use of a custom function as matlab only gives the date of the last modification
                catch
                    t0Real=t0RealTemp;
                end
                if t0Real>t0RealTemp
                    % When importing files from the cluster, you can keep the
                    % modification date, but the creation date corresponds to
                    % the download date, which means that the creation date
                    % is more recent than the last modification date
                    t0Real=t0RealTemp;
                end
                % #.#. Computation speed
                DT=seconds(t1Real-t0Real);
                DS=abs(str2double(steps{stepNum+1})-str2double(steps{stepNum}));
                VsPt=DS/DT;
                % #.#. Output
                if VsPt~=0 ...
                        && abs(VsPt)~=inf ...
                        && VsPt>=0 % Negative VsPt may occur in certain cases, such as the time change (03am on the cluster).
                    indStep=find(strcmpi(timeExport.step,steps(stepNum)),1,'first');
                    if isempty(indStep)
                        stepsNumber=stepsNumber+1;
                        timeExport(stepsNumber,:)=table(steps(stepNum),VsPt,t1Simu,t1Real);
                    else
                        timeExport(indStep,:)=table(steps(stepNum),VsPt,t1Simu,t1Real); % Enables a step to be rewritten if the user clicks on update
                    end
                end
                % #.#. The current least recent becomes the most recent thereafter
                t1Simu=t0Simu;
                t1Real=t0Real;
            end

            % #. Exportation
            if ~exist([pathSimu filesep 'SAVE'],'dir')
                mkdir([pathSimu filesep 'SAVE']);
            end
            timeExport=sortrows(timeExport,1,'descend');

            % #. Automatically removes outliers
            if autoMode && size(timeExport,1)>1
                % Detects points that appear to be aberrant. The
                % difficulty lies in the fact that we can have several
                % zones with close intra-zone speeds, but far apart
                % between zones. What's more, the orders of magnitude
                % of these variations are quite different from one
                % simulation to another.
                % The “generalized extreme Studentized deviate” method gives good results
                VsPtArray=table2array(timeExport(:,2));
                indToChange=isoutlier(VsPtArray,'gesd');
                timeExport(indToChange,2)=table(NaN); % Prevents subsequent loading
            end

            % #. Save
            save([pathSimu filesep 'SAVE' filesep 'TIME_save.mat'],'timeExport');

        end

        function toolsProcessingCheck(app)
            % Abstract: opens or launches a file according to the
            % selection in tool

            % #. Initialization
            tabNumber=findTab(app);
            tree=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.TOOLSPanelNumber).Children(app.id.gridToolsNumber).Children(app.id.toolsTreeNumber);
            pathTool=tree.SelectedNodes.NodeData;
            drawnow;

            % #. Path save
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            save([app.pwdSave filesep 'Save' filesep 'path_save'],'pathSimu'); % Can be useful

            if ~isempty(tree.CheckedNodes)
                % #. Directory
                files=dir(pathTool);
                files={files.name};
                [~,filesWithoutExt,ext]=fileparts(files);

                % #. Processing
                % #.#. Open
                indOpen=find(strcmpi(filesWithoutExt,'mainOpen') ...
                    & ~strcmpi(ext,'.asv')); % prevents the launch of a temporary file
                for fileNum=1:numel(indOpen)
                    openFile(app,[pathTool filesep files{indOpen(fileNum)}]);
                end
                % #.#. Run
                indRun=find(strcmpi(filesWithoutExt,'mainRun') ...
                    & ~strcmpi(ext,'.asv')); % prevents the launch of a temporary file
                for fileNum=1:numel(indRun)
                    oldPwd=pwd;
                    cd(pathTool);
                    try
                        run([pathTool filesep files{indRun(fileNum)}]);
                    catch ME
                        msgbox([ME.identifier newline ME.message],...
                            "Error","error");
                    end
                    cd(oldPwd);
                end
            end

            % #.#. Unselected/Unchecked
            drawnow;
            tree.SelectedNodes=[];
            tree.CheckedNodes=[];
            drawnow;

        end

        function toolsProcessingSelection(app)
            % Abstract: opens the readme file

            % #. Busy
            busyOn(app);

            % #. Initialization
            tabNumber=findTab(app);
            tree=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.TOOLSPanelNumber).Children(app.id.gridToolsNumber).Children(app.id.toolsTreeNumber);
            pathTool=tree.SelectedNodes.NodeData;
            drawnow;
            pause(0.5); % Otherwise the CheckedNodes value update is not detected, no solution with MATLAB2024b and the problem seems old

            if ~isempty(tree.CheckedNodes)
                % #. Check
                toolsProcessingCheck(app);
            else
                % #. Selection only
                % #.#. Directory
                files=dir(pathTool);
                files={files.name};
                [~,filesWithoutExt]=fileparts(files);
                ind=find(strcmpi(filesWithoutExt,'readme'),1,'first');
                % #.#. Opening
                if ~isempty(ind)
                    openFile(app,[pathTool filesep files{ind}]);
                end
            end

            % #.#. Unselected/Unchecked
            tree.SelectedNodes=[];
            tree.CheckedNodes=[];

            % #. Busy
            busyOff(app);

        end

        function toolsSubFolders(app,parentNode)
            % Abstract: find all the subfolders and create a subtree for each one

            % #. Get the directory path from the parent node
            parentDir = parentNode.NodeData;

            % #. Get list of subfolders
            dirInfo = dir(parentDir);
            subFolders = dirInfo([dirInfo.isdir] & ~ismember({dirInfo.name},{'.' '..' '.DS_Store' 'Thumbs.db'}));

            % #. Add each subfolder as a child node
            for k = 1:length(subFolders)
                subFolderName = subFolders(k).name;
                subFolderPath = fullfile(parentDir, subFolderName);
                childNode = uitreenode(parentNode, 'Text', subFolderName,'NodeData', subFolderPath);
                toolsSubFolders(app,childNode); % recursively add subfolders of the child node
            end

        end

        function isRunning=verifExecution(app,tabNumber)
            % Abstract: checks if an execution is in progress
            
            % #. Initialization
            isRunning=false;
            
            % #. Verification
            if ~isempty(app.computationRenamed) ...
                    && numel(app.computationRenamed)>=tabNumber ...
                    && ~isempty(app.computationRenamed{tabNumber})
            [~,cmdout]=system(['TASKLIST /FI "IMAGENAME eq ' app.computationRenamed{tabNumber} '"']);
            if contains(cmdout,'=') % If an execution is in progress, the headers contain '==='
                isRunning=true;
            end
            end
            
        end

        function verifExecutionMain(app,tabNumberSave)
            % Abstract: periodically checks with a timer whether job is running
            
            if ~verifExecution(app,tabNumberSave) % Job is running

                % #. Log
                % Set before so that the line appears before those of a voluntary stop
                pathSimu=app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                if ~exist([pathSimu filesep 'SAVE'],'dir')
                    mkdir([pathSimu filesep 'SAVE']);
                end
                [~,~]=LogSave({''},{''},'Job is no longer in progress', [pathSimu filesep 'SAVE' filesep 'LOG_save.log']);
                
                % #. Job stop
                app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                runFunction(app,tabNumberSave,0);
                
                % #. Timer deletion
                msgbox(['Job is no longer in progress.' newline 'Execution concerning the following tab: ' newline app.TabGroup.Children(tabNumberSave).Title],'Job',"help");
                if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumberSave) 'Run']))
                    stop(timerfind('Name',['timerGUITab' num2str(tabNumberSave) 'Run']));
                    delete(timerfind('Name',['timerGUITab' num2str(tabNumberSave) 'Run']));
                end

            end
        end
        
        function viewTypeFunction(app,tabNumber)
            % Abstract: Selection of display type for GRAPHICS
            
            if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'reload')
                % #. Reload
                % Selection of display type via a list.
                % Added because, for a selected display type, if you
                % want to modify its parameters, you have to click on
                % another display type (and therefore a load) and then
                % click on the previous one again.
                % #.#. User selection
                list=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Items(2:end);
                while 1
                    [indType,~] = listdlg('PromptString',{'Select a view type:'},'ListString',list,'SelectionMode','single');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if ~isempty(indType)
                        break;
                    else
                        % It is impossible not to have selected a type
                        waitfor(msgbox('Please select one of the following options.','Information','help'));
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    end
                end
                % #. Applying the results
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value...
                    =app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Items{indType+1};
            end
            
            % #. Parameters to be adapted
            if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'eulerian')
                % #.#. 2D - Euler
                var='eulerView';
                prompt=[];
            elseif strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'lagrangian')
                % #.#. 2D - Lagrangian
                var='lagrangianView';
                prompt = {'Reference body:','Reference node:'};
                % #.#.#. Node selection by user
                [body,~,nodeLoc]=graphSelection(app,tabNumber,[],[],[]); % Use of the local node because the global will change according to the creation of points for the previous bodies
                % #.#.#. Assignment
                if ~isempty(nodeLoc) && ~isempty(body) % Detects the absence of a selection and keeps the previous one
                    % Most selected body
                    bodiesList=unique(body);
                    Ncount=histcounts(body,[bodiesList;Inf]);
                    [~,indBody]=max(Ncount);
                    app.(var){1,tabNumber}=bodiesList(indBody);
                    app.(var){2,tabNumber}=reshape(nodeLoc(body==app.(var){1,tabNumber}),1,[]);
                end
            elseif contains(lower(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value),'planar')
                % #.#. 3D - planar
                var='planarView';
                prompt = {'Enter the thickness:'};
            elseif contains(lower(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value),'radial')
                % #.#. 3D - radial
                var='radialView';
                prompt = {'Enter the minimum radius:','Enter the maximum radius:','Enter the number of repetitions (set inf if you want a closed volume):'};
            end
            
            if ~isempty(prompt)

                % #. Shared parameters
                dlgtitle = 'Input';
                dims = [1 80];
                
                % #. Initialization
                % #.#. Empty values
                definput = cell(1,numel(prompt));
                definput(:)={''};
                % #.#. Loading possible old values
                if size(app.(var),2)>=tabNumber && size(app.(var),1)>=numel(prompt) % 2nd condition because a tabNumber*0 cell can create a problem later on
                    if all(~cellfun(@isempty,app.(var)(1:numel(prompt)-1,tabNumber)))
                        definput(1:size(app.(var)(:,tabNumber),1))=cellfun(@num2str,app.(var)(:,tabNumber),'UniformOutput',false);
                    end
                end
                
                % #. User enters parameters
                while 1
                    % #.#. Dialog box
                    result=inputdlg(prompt,dlgtitle,dims,definput);
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(result)
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value...
                            =app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Items{2};
                        return;
                    end
                    % #.#. Checking results
                    if all(~cellfun(@isempty,result(1:end-1))) % Only the last value can be empty
                        break;
                    else
                        waitfor(msgbox(['Following arguments are missing:' strrep(prompt(cellfun(@isempty,result(1:end-1))),':','')],'Information','help'));
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    end
                end
                
                % #. Parameter assignment
                app.(var)(1:numel(prompt),tabNumber)=cell(1,numel(prompt));
                app.(var)(:,tabNumber)=cellfun(@str2num,result(1:end)','UniformOutput',false);

            end
            
            % #. Updating graphics
            for graphNum=1:4
                graphNumFunction(app, graphNum);
                if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                    info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                    if any(strcmpi(info.selection.class.global.cat,'SURFACE')) % class.main is unknown without data load
                        if numel(axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar)))/2==2 ...
                                && (strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'eulerian') ...
                                || strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'lagrangian'))
                        % 2D -> 2D
                        displayGraphMain(app,[],tabNumber,info,'refresh');
                        else
                            % 2D <-> 3D or 3D -> 3D
                            displayGraphMain(app,[],tabNumber,info,'clear');
                        end
                    end
                end
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Abstract: loads the GUI
            
            % #. Centering
            movegui(app.mainUIFigure,'center');
            
            % #. Busy
            busyOn(app);

            % #. Shortcuts
            addlistener(app.mainUIFigure,'WindowKeyPress',@(o,e)figureKeyPress(app,e)); % for some reasons callback does not work

            % #. GUI main folder
            pathTemp=mfilename('fullpath'); % This folder can be different from the pwd
            ind=strfind(pathTemp,filesep);
            app.pwdSave=pathTemp(1:ind(end-2)-1); % Keeps only the main folder (the app is in several subfolders)
            
            % #. Component indices
            % Working with indices allows to deal with several tabs and keep
            % a unique reference to each type of buttons (i.e. one reference
            % for one button that is duplicated in several tabs)
            % IndexName=[Tag 'Number']
            app.id.([app.gridTab.Tag 'Number'])=1;
            for i=1:numel(app.gridTab.Children)
                % #.#. ~ Panels
                app.id.([app.gridTab.Children(i).Tag 'Number'])=i;
                if strcmpi(app.gridTab.Children(i).Type,'uipanel') ...
                        || strcmpi(app.gridTab.Children(i).Type,'uigridlayout') ...
                        || strcmpi(app.gridTab.Children(i).Type,'uibuttongroup')
                for j=1:numel(app.gridTab.Children(i).Children)
                    % #.#.#. ~ Grids inside the panels
                    app.id.([app.gridTab.Children(i).Children(j).Tag 'Number'])=j;
                    if strcmpi(app.gridTab.Children(i).Children(j).Type,'uipanel') ...
                            || strcmpi(app.gridTab.Children(i).Children(j).Type,'uigridlayout') ...
                            || strcmpi(app.gridTab.Children(i).Children(j).Type,'uibuttongroup')
                    for k=1:numel(app.gridTab.Children(i).Children(j).Children)
                        % #.#.#.#. ~ Buttons inside grids inside the panels (+ grids)
                        app.id.([app.gridTab.Children(i).Children(j).Children(k).Tag 'Number'])=k;
                        if strcmpi(app.gridTab.Children(i).Children(j).Children(k).Type,'uipanel') ...
                                || strcmpi(app.gridTab.Children(i).Children(j).Children(k).Type,'uigridlayout') ...
                                || strcmpi(app.gridTab.Children(i).Children(j).Children(k).Type,'uibuttongroup')
                        for l=1:numel(app.gridTab.Children(i).Children(j).Children(k).Children)
                            % #.#.#.#.#. ~ Buttons inside grids inside grids inside the panels (+ radiobuttons)
                            app.id.([app.gridTab.Children(i).Children(j).Children(k).Children(l).Tag 'Number'])=l;
                        end
                        end
                    end
                    end
                end
                end
            end
            
            % #. Initialization
            % #.#. Component indices
            app.id.graphNumberVar=0;
            app.id.graphPanelNumberVar=0;
            app.id.gridGraphVar=0;
            app.id.graphEndNumberVar=0;
            app.id.graphStartNumberVar=0;
            app.id.graphXListNumberVar=0;
            app.id.graphYEqNumberVar=0;
            app.id.graphXEqNumberVar=0;
            app.id.graphYListNumberVar=0;
            app.fontSize=app.graph1.XLabel.FontSize;
            % #.#. Cluster
            load([app.pwdSave filesep 'Save' filesep 'cluster_save.mat'],'info');
            app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items= ...
                [app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items(1) ...
                info.servers];
            clusterInfoInitialization(app);

            % #. Masking panel
            delete(app.maskingPanel.Toolbar);
            app.maskingPanel.HitTest='off';

            % #. Packages loading
            packageList=dir([app.pwdSave filesep 'Packages']);
            packageList={packageList.name};
            packageList(ismember(packageList,{'.' '..' '.DS_Store' 'Thumbs.db'}))=[];
            app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.packageModeListNumber).Items=packageList;

            % #. Subfolders
            % Adds the subfolders otherwise loading functions into them is not possible
            subFoldersLevel=3; % Do not read all the subfolders as this may create errors with unavailable names
            folderNames=dir([app.pwdSave '/**/*.*']); % Gives all the subfolders
            folderNames(contains({folderNames.folder},[app.pwdSave filesep 'Tools']))=[]; % Remove the tools folder to prevent the user from defining duplicate function names
            folderNames=unique({folderNames.folder});
            folderNames(cell2mat(cellfun(@(x)numel(strfind(x,filesep)),folderNames,'UniformOutput',false))-numel(strfind(app.pwdSave,filesep))>subFoldersLevel)=[]; % Ignores subfolders of a higher level
            cellfun(@addpath,folderNames);

            % #. Settings
            settingsLoad(app,1);

            % #. Format
            %app.TabGroup.Children(1).Title=['#' num2str(size(app.TabGroup.Children,1)-2,'%02.f')];
            %set(app.TabGroup.Children(1),'ForegroundColor',[0 0 0]);
            app.DIRECTORYPanel.BorderColor=app.DIRECTORYPanel.BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            app.TOOLSPanel.BorderColor=app.TOOLSPanel.BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            app.EDITORPanel.BorderColor=app.EDITORPanel.BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            app.COMPUTATIONPanel.BorderColor=app.COMPUTATIONPanel.BackgroundColor; % Some issues with appdesigner, border color cannot be saved
            
            % #. Tabs
            % #.#. Selection
            if isfile([app.pwdSave filesep 'Save' filesep 'gui_save.mat'])
                % #.#.#. Previous tab names
                load([app.pwdSave filesep 'Save' filesep 'gui_save.mat'],'guiExport');
                if all(cellfun(@isempty,guiExport.displayPathExport))
                    % #.#.#. Empty save
                    answer='No';
                else
                    % #.#.#. DLG
                    tabToLoad=cell(1,size(guiExport,1));
                    for tabNumberVar=1:numel(tabToLoad)
                        tabToLoad{tabNumberVar}=['- ' nameSimuFunction(app,tabNumberVar+2,guiExport.displayPathExport{tabNumberVar},guiExport.sizePathDisplayExport{tabNumberVar})];
                    end
                    answer = questdlg(['Do you want to load the previous tabs:' tabToLoad], ...
                        'Previous tabs','Yes','No','No');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                end
            else
                % #.#.#. No tabs to load
                answer='No';
            end
            % #.#. Processing
            if isempty(answer) || strcmpi(answer,'No')
                % #.#.#. One new tab
                tabCreation(app);
            else
                % #.#.#. Loads the previous tabs
                % #.#.#.#. Loading
                guiStateImport(app);
                % #.#.#.#. Checks if a computation is in progress
                for tabNumberVar=3:size(app.TabGroup.Children,1)
                    findExecution(app,tabNumberVar);
                end
            end
            % #.#. Current tab
            app.TabGroup.SelectedTab=app.TabGroup.Children(3);
            tabNumberSave=3;
            save([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave');
            
            % #. Busy
            busyOff(app);
            
        end

        % Button pushed function: animation
        function animationButtonPushed(app, event)
            % Abstract: creates an animation
            %
            % Several choices:
            % Type: overview, current tab, step graph (velocity, etc.)
            % Range: from step XXXXX to step XXXXX
            % Scaling factor: size of the text
            % Time: duration and target frame rate
            % Format: avi, png, etc.
            % Name: an available name is found automatically (it can be changed)
            % Crop: defined by a graphical selection
            %
            % The real frame rate is found automatically to obtain the exact
            % input duration and an acceptable frame rate (to avoid a too
            % high frame rate that could create latency). To do this, some steps can
            % be skipped to obtain a frame rate close to the target.
            %
            % Multiple animations can be created with a single selection (e.g. for
            % several directions, several formats, etc.).The program will
            % automatically remove the animations that are duplicated
            % (for example, magnitude and X direction for a field with one
            % dimension).
            
            % #. Busy
            busyOn(app);
            
            % #. Types of animations
            % #.#. Inputs
            % Data
            tabNumber=findTab(app);
            [dataList,~,dataType,~,~,~,~,~,~,~,~,~,customList]=dataMainListFunction(app,tabNumber,'Refresh');
            indData=strcmpi(dataType,'STEPS');
            % Custom
            customData=DataManager([],[],[]);
            indCustom=false(1,numel(customList));
            for customNum=1:numel(customList)
                dataTemp=customData{customNum,3};
                indDataTemp=ismember(dataList,dataTemp);
                if any(strcmpi(dataType(indDataTemp),'STEPS'))
                    indCustom(customNum)=1;
                end
            end
            % Concat
            dataAvailable=[dataList(indData) strcat(customList(indCustom),{' '},'(custom)')]; % all customs are added, even if in practice some may not contain steps (i.e. not animated)
            typesAvailable=['Overview' 'Current tab' 'Select tabs' 'Enter paths' dataAvailable]; % Type of animations that are available
            while 1
                % #.#. Selection
                [types,~] = listdlg('PromptString',{'Select the types of animations:'},'ListString',typesAvailable); % Types of animations that will be created
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(types); busyOff(app); return; end
                % #.#. Valid selection
                if numel(types)>1 && any(types<=4)
                    % Several animations can be created in one selection for certain types only
                    waitfor(msgbox('If it is not a data item that is selected, only one selection is allowed.','Information','help')); continue;
                end
                % #.#. Other paths
                if any(contains(typesAvailable(types),'Overview'))
                    tabListSelected=3:numel(app.TabGroup.Children);
                    break;
                elseif any(contains(typesAvailable(types),'Current tab'))
                    tabListSelected=tabNumber;
                    break;
                elseif any(contains(typesAvailable(types),'Select tabs'))
                    % #.#.#. Select tabs
                    % #.#.#.#. List of the tabs
                    tabList=cell(1,numel(app.TabGroup.Children)-2);
                    kTab=0;
                    for tabNumberVar=3:numel(app.TabGroup.Children)
                        kTab=kTab+1;
                        tabList{kTab}=app.TabGroup.Children(tabNumberVar).Title;
                    end
                    % #.#.#.#. DLG
                    [tabListSelected,~]=listdlg('PromptString',{'Select the tabs:'},'ListString',tabList); figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(tabListSelected); busyOff(app); return; end
                    % #.#.#.#. Processing
                    tabListSelected=tabListSelected+2;
                    pathSimuSave=cell(1,numel(tabListSelected));
                    kTab=0;
                    for tabNumberVar=tabListSelected
                        kTab=kTab+1;
                        pathSimuSave{kTab}=app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                    end
                elseif any(contains(typesAvailable(types),'Enter paths'))
                    % #.#.#. Enter paths
                    tabListSelected=[];
                    prompt = {'Enter the paths (separated by a ;)'};
                    dlgtitle = 'Enter paths'; fieldsize = [1 40]; definput = {app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value};
                    answer=inputdlg(prompt,dlgtitle,fieldsize,definput); figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(answer); busyOff(app); return; end
                    pathSimuSave=strip(strsplit(answer{1},';'));
                else
                    % #.#.#. Data
                    tabListSelected=tabNumber;
                    pathSimuSave={app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value};
                    break;
                end
                % #.#.#. Existing folder
                indToRemove=cellfun(@(x)exist(x,'dir')==0,pathSimuSave);
                pathSimuSave(indToRemove)=[];
                % #.#.#. Data selection
                [types,~]=listdlg('PromptString',{'Select the types of animations:'},'ListString',dataAvailable); % Types of animations that will be created
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(types); busyOff(app); return; end
                types=types+numel(typesAvailable)-numel(dataAvailable);
                break;
            end
            
            % #. Information
            if all(types<=2)

                % #.#. Overview and current tab
                graphIndSave=cell(1,numel(types));
                infoSave=cell(1,numel(types));
                viewOption=cell(1,numel(types));
                axesSave=cell(1,numel(types));

                for animNum=1:numel(types)   % Facilitates future implementation
                    kGroup=0;
                    for tabNumberVar=tabListSelected
                        if ~app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.ignoredOverviewNumber).Value || all(types==2)
                            kGroup=kGroup+1; kGraph=0;
                            for graphNum=1:4
                                graphNumFunction(app,graphNum);
                                if ~isempty(app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                                    % #.#.#. Informations
                                    kGraph=kGraph+1;
                                    graphIndSave{animNum}{kGroup}{kGraph}=graphNumFunction(app,graphNum);
                                    infoSave{animNum}{kGroup}{kGraph}.info.selection=selectionInfo(app,tabNumberVar,graphNum,[],[]);
                                    axesSave{animNum}{kGroup}{kGraph}=[];
                                    % #.#.#. viewOption
                                    if all(types==1)
                                        if app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.spaceGraphicsNumber).Value
                                            if strcmpi(app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value,'lagrangian')
                                                viewOption{kGroup}='Follow'; % Otherwise it is not possible to set the limits and also be centered according to a node. Therefore, the length and width are set according to the input limits and the central value is set according to the node position
                                            else
                                                viewOption{kGroup}='Equal';
                                            end
                                        else
                                            viewOption{kGroup}='';
                                        end
                                    end
                                end
                            end
                            if kGraph==0; kGroup=kGroup-1; end % No graph has been found
                        end
                    end
                    if kGroup==0
                        msgbox('No graphics were found.','Information','help'); busyOff(app); return;
                    end
                end

            else

                % #.#. Single graphs
                infoSave=cell(1,numel(pathSimuSave)*numel(types));
                animNum=0;
                for pathNum=1:numel(pathSimuSave)
                    for typeNum=1:numel(types)
                        animNum=animNum+1;
                        % #.#.#. Equation
                        if contains(typesAvailable{types(typeNum)},'(custom)')
                            customData=DataManager([],[],[]);
                            typeFormatTemp=erase(typesAvailable{types(typeNum)},' (custom)');
                            indCustomData=strcmp(customData(:,1),typeFormatTemp);
                            typeFormatTemp=lower(replace(typeFormatTemp,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                            eq=strjoin(strcat('[',[{typeFormatTemp} customData{indCustomData,3}],']'),'#');
                        else
                            eq=strcat('[',typesAvailable{types(typeNum)},']');
                        end
                        % #.#.#. Information
                        if ~isempty(tabListSelected)
                            % Allows current options to be used if and
                            % only if the current tab is used
                            infoSave{animNum}{1}{1}.info.selection=selectionInfo(app,tabListSelected(pathNum),[],{eq ''},[]);
                        else
                            infoSave{animNum}{1}{1}.info.selection=selectionInfo(app,pathSimuSave{pathNum},[],{eq ''},[]);
                        end
                        infoSave{animNum}{1}{1}.info.selection.other.pathSimu=pathSimuSave{pathNum};
                        infoSave{animNum}{1}{1}.info.selection.other.customMode=true;
                    end
                end

            end

            % #. Steps available
            animationsNumber=numel(infoSave);
            stepAvailableMinDouble=cell(1,animationsNumber); stepAvailableMaxDouble=cell(1,animationsNumber); stepAvailableNumber=cell(1,animationsNumber);
            for animNum=1:animationsNumber
                groupsNumberTemp=numel(infoSave{animNum});
                stepAvailableMinDouble{animNum}=cell(1,groupsNumberTemp); stepAvailableMaxDouble{animNum}=cell(1,groupsNumberTemp); stepAvailableNumber{animNum}=cell(1,groupsNumberTemp);
                for groupNum=1:groupsNumberTemp
                    graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                    stepAvailableMinDouble{animNum}{groupNum}=cell(1,graphsNumberTemp); stepAvailableMaxDouble{animNum}{groupNum}=cell(1,graphsNumberTemp); stepAvailableNumber{animNum}{groupNum}=cell(1,graphsNumberTemp);
                    for graphNum=1:graphsNumberTemp
                        if any(strcmpi(infoSave{animNum}{groupNum}{graphNum}.info.selection.type.global.cat,'steps'))
                            [~,~,~,stepsAvailable,~]=loadListStep(app,infoSave{animNum}{groupNum}{graphNum}.info.selection.other.pathSimu);
                            stepAvailableMinDouble{animNum}{groupNum}{graphNum}=min(str2double(stepsAvailable));
                            stepAvailableMaxDouble{animNum}{groupNum}{graphNum}=max(str2double(stepsAvailable));
                            stepAvailableNumber{animNum}{groupNum}{graphNum}=stepAvailableMaxDouble{animNum}{groupNum}{graphNum}-stepAvailableMinDouble{animNum}{groupNum}{graphNum}+1;
                        else
                            stepAvailableMinDouble{animNum}{groupNum}{graphNum}=[];
                            stepAvailableMaxDouble{animNum}{groupNum}{graphNum}=[];
                            stepAvailableNumber{animNum}{groupNum}{graphNum}=[];
                        end
                    end
                end
            end
           
            % #. Steps selected
            
            % #.#. Default scaling factor
            if all(types==1)
                scalingDefault=1.2; % 1 = GUI value, 3 = significant value
            elseif all(types==2)
                scalingDefault=1.5; % 1 = GUI value, 3 = significant value
            else
                scalingDefault=1.8; % 1 = GUI value, 6 = significant value
            end

            % #.#. Initialization
            stepSelectedMinDouble=cell(1,animationsNumber); stepSelectedMaxDouble=cell(1,animationsNumber); stepsSelectedNumber=cell(1,animationsNumber);
            
            if all(types==1)
                
                % #.#. Overview
                
                for animNum=1:animationsNumber

                    % #.#.#. MSG
                    groupsNumberTemp=numel(infoSave{animNum});
                    prompt=cell(1,groupsNumberTemp+2); definput=cell(1,groupsNumberTemp+2);
                    for groupNum=1:groupsNumberTemp
                        stepMinTemp=min([stepAvailableMinDouble{animNum}{groupNum}{:}]);
                        stepMaxTemp=max([stepAvailableMaxDouble{animNum}{groupNum}{:}]);
                        stepsNumberTemp=stepMaxTemp-stepMinTemp+1;
                        prompt{groupNum}=[app.TabGroup.Children(infoSave{animNum}{groupNum}{1}.info.selection.other.tabNumber).Title ': [Min (' num2str(stepMinTemp) ') : Max (' num2str(stepMaxTemp) ') - ' num2str(stepsNumberTemp)  ']'];
                        definput{groupNum}=[num2str(stepMinTemp) ':' num2str(stepMaxTemp)];
                    end
                    prompt{groupsNumberTemp+1}='Same duration for all graphs (1) or one step per frame (0):';
                    definput{groupsNumberTemp+1}='1';
                    prompt{groupsNumberTemp+2}='Scalling factor:';
                    definput{groupsNumberTemp+2}=num2str(scalingDefault);

                    % #.#.#. DLG
                    paramSimu = inputdlg(prompt,'Options for the simulation',1,definput);
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if isempty(paramSimu)
                        busyOff(app); return;
                    end

                    % #.#.#. Processing
                    for groupNum=1:groupsNumberTemp
                        % #.#.#.#. Initialization
                        graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                        paramSimuTemp=split(paramSimu{groupNum},':');
                        for graphNum=1:graphsNumberTemp
                            if isempty(stepAvailableNumber{animNum}{groupNum}{graphNum})
                                stepSelectedMinDouble{animNum}{groupNum}{graphNum}=[];
                                stepSelectedMaxDouble{animNum}{groupNum}{graphNum}=[];
                                stepsSelectedNumber{animNum}{groupNum}{graphNum}=[];
                                continue;
                            else
                                % #.#.#.#. Min
                                paramSimuTempMin=paramSimuTemp{1};
                                if paramSimuTempMin(1)=='+' % Relative to the first step
                                    paramSimuTempMin=num2str(stepAvailableMinDouble{animNum}{groupNum}{graphNum}+str2double(erase(paramSimuTempMin,'+')));
                                elseif paramSimuTempMin(1)=='-' % Relative to the last step
                                    paramSimuTempMin=num2str(stepAvailableMaxDouble{animNum}{groupNum}{graphNum}-str2double(erase(paramSimuTempMin,'-')));
                                end
                                stepSelectedMinDouble{animNum}{groupNum}{graphNum}=str2double(paramSimuTempMin);
                                % #.#.#.#. Max
                                paramSimuTempMax=paramSimuTemp{end};
                                if paramSimuTempMax(1)=='+' % Relative to the first step
                                    paramSimuTempMax=num2str(stepAvailableMinDouble{animNum}{groupNum}{graphNum}+str2double(erase(paramSimuTempMax,'+')));
                                elseif paramSimuTempMax(1)=='-' % Relative to the last step
                                    paramSimuTempMax=num2str(stepAvailableMaxDouble{animNum}{groupNum}{graphNum}-str2double(erase(paramSimuTempMax,'-')));
                                end
                                stepSelectedMaxDouble{animNum}{groupNum}{graphNum}=str2double(paramSimuTempMax);
                                % #.#.#.#. Steps number
                                stepsSelectedNumber{animNum}{groupNum}{graphNum}=stepSelectedMaxDouble{animNum}{groupNum}{graphNum}-stepSelectedMinDouble{animNum}{groupNum}{graphNum}+1;
                            end
                        end
                    end

                end

            else

                % #.#. Current tab or single graphs

                flag=0; % One selection for all animations: ignored if -1, dlg if 0, apply if 1

                for animNum=1:animationsNumber

                    % #.#.#. DLG
                    if flag~=1
                        mess={['Lower limit:' newline ...
                            ['Absolute: xxxxx (current first = ' num2str(min(cell2mat([stepAvailableMinDouble{animNum}{:}]))) ')'] newline ...
                            'Relative to first step: +x'  newline ...
                            'Relative to last step: -x' newline ...
                            'If relative, can be applied to all animations' newline ...
                            'If lower than first step, takes the first available'] ...
                            ['Upper limit:'  newline ...
                            ['Absolute: xxxxx (current last = ' num2str(max(cell2mat([stepAvailableMaxDouble{animNum}{:}]))) ')'] newline ...
                            ['Relative to first step: +x (current last = +' num2str(max(cell2mat([stepAvailableNumber{animNum}{:}]))-1) ' / overall last = +' num2str(min(cell2mat(cellfun(@cell2mat,[stepAvailableNumber{:}],'UniformOutput',false)))-1) ')']  newline ...
                            'Relative to last step: -x' newline ...
                            'If relative, can be applied to all animations' newline ...
                            'If greater than last step, takes the last available'] ...
                            'Same duration for all graphs (1) or one step per frame (0):' 'Scalling factor:'};
                        paramSimu=inputdlg(mess,'Options for the simulation',1,{'+0'  ['+' num2str(max(cell2mat([stepAvailableNumber{animNum}{:}]))-1)] '1' num2str(scalingDefault)});
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                        if isempty(paramSimu)
                            busyOff(app); return;
                        end
                    end

                    % #.#.#. Processing
                    groupsNumberTemp=numel(infoSave{animNum});
                    for groupNum=1:groupsNumberTemp  % Facilitates future implementation
                        graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                        for graphNum=1:graphsNumberTemp  % Facilitates future implementation

                            if isempty(stepAvailableNumber{animNum}{groupNum}{graphNum})
                                stepSelectedMinDouble{animNum}{groupNum}{graphNum}=[];
                                stepSelectedMaxDouble{animNum}{groupNum}{graphNum}=[];
                                stepsSelectedNumber{animNum}{groupNum}{graphNum}=[];
                                continue;
                            else

                                % #.#.#.#. Inputs
                                stepsTemp=paramSimu(1:2);
                                for stepNum=1:numel(stepsTemp) % min and max
                                    if stepsTemp{stepNum}(1)=='+' % Relative to the first step
                                        stepsTemp{stepNum}=num2str(stepAvailableMinDouble{animNum}{groupNum}{graphNum}+str2double(erase(stepsTemp{stepNum},'+')));
                                    elseif stepsTemp{stepNum}(1)=='-' % Relative to the last step
                                        stepsTemp{stepNum}=num2str(stepAvailableMaxDouble{animNum}{groupNum}{graphNum}-str2double(erase(stepsTemp{stepNum},'-')));
                                    end
                                    if str2double(stepsTemp{stepNum})<stepAvailableMinDouble{animNum}{groupNum}{graphNum}
                                        stepsTemp{stepNum}=num2str(stepAvailableMinDouble{animNum}{groupNum}{graphNum});
                                    elseif str2double(stepsTemp{stepNum})>stepAvailableMaxDouble{animNum}{groupNum}{graphNum}
                                        stepsTemp{stepNum}=num2str(stepAvailableMaxDouble{animNum}{groupNum}{graphNum});
                                    end
                                end

                                % #.#.#.#. Assignment
                                stepSelectedMinDouble{animNum}{groupNum}{graphNum}=str2double(stepsTemp{1});
                                stepSelectedMaxDouble{animNum}{groupNum}{graphNum}=str2double(stepsTemp{2});
                                stepsSelectedNumber{animNum}{groupNum}{graphNum}=stepSelectedMaxDouble{animNum}{groupNum}{graphNum}-stepSelectedMinDouble{animNum}{groupNum}{graphNum}+1;
                            
                            end

                        end
                    end

                    % #.#.#. Apply to all animations
                    % If relative steps are entered, apply selection to all animations
                    if all(contains(paramSimu(1:2),'-') | contains(paramSimu(1:2),'+')) && animNum<animationsNumber && flag==0
                        % DLG
                        if animationsNumber>1
                            answer=questdlg('Apply the selection to all remaining animations?','Selection','Yes','No','No');
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                        else
                            answer={'Yes'};
                        end
                        if isempty(answer)
                            % Cancel
                            busyOff(app); return;
                        elseif strcmpi(answer,'No')
                            % Subsequently ignored
                            flag=-1;
                        else
                            % Apply selection
                            flag=1;
                        end
                    end

                end
            end
            sameDuration=str2double(paramSimu{end-1});
            scalingFactor=str2double(paramSimu{end});
            
            % #. Duration, expected frame rate, frames number
            % The real frame rate is found automatically to obtain the exact
            % input duration and an acceptable frame rate (to avoid a too
            % high frame rate that could create latency). To do this, some steps can
            % be skipped to obtain a frame rate close to the target.
            % #.#. Initialization
            durationTemp=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.durationAnimationDisplayNumber).Value; % Setting value
            framesNumber=zeros(1,animationsNumber);
            frameRate=zeros(1,animationsNumber);
            frameRateTargetTemp=35;
            step=zeros(1,animationsNumber);
            while 1
                % #.#. DLG
                answer=inputdlg({'Duration [s]:' 'Target frame rate [frame/s]:'},'Inputs',[1 50],{num2str(durationTemp) num2str(frameRateTargetTemp)});
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(answer)
                    busyOff(app); return;
                end
                durationTemp=str2double(answer{1});
                frameRateTargetTemp=str2double(answer{2});
                % #.#. Processing
                for animNum=1:animationsNumber
                    % #.#.#. Frame rate without step
                    frameRateWithoutStep=max(cell2mat([stepsSelectedNumber{animNum}{:}]))/durationTemp; % if all the steps are displayed (i.e. step = 1)
                    % #.#.#. Frame rate with step
                    stepTemp=round(frameRateWithoutStep/frameRateTargetTemp); % Number of steps to skip
                    if stepTemp<1 % Input frame rate is too high
                        stepTemp=1;
                    end
                    step(animNum)=stepTemp;
                    groupsNumberTemp=numel(infoSave{animNum});
                    for groupNum=1:groupsNumberTemp
                        graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                        for graphNum=1:graphsNumberTemp
                            framesNumber(animNum)=max([framesNumber(animNum) numel(stepSelectedMinDouble{animNum}{groupNum}{graphNum}:step(animNum):stepSelectedMaxDouble{animNum}{groupNum}{graphNum})]); % maximum number of diplayed step within the group
                        end
                    end
                    frameRate(animNum)=framesNumber(animNum)/durationTemp;
                end
                % #.#. Validation
                convertVerif=questdlg([{['Duration: ' num2str(durationTemp) ' s']}, ...
                    {strcat('Framerate:',{' '},strjoin(string(frameRate'),'/'),' frame/s')}, ...
                    {strcat('Number of frames:',{' '},strjoin(string(framesNumber'),'/'))}, ...
                    {strcat('Step: 1 frame generated every',{' '},strjoin(string(step'),'/'),{' '},'step(s)' )}], ...
                    'Inputs','Continue','Change the inputs','Change the inputs');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(convertVerif)
                    busyOff(app); return;
                elseif strcmpi(convertVerif,'Continue')                  
                    break;
                end
            end
            
            % #. Steps displayed
            % #.#. Initialization
            stepsDisplayed=cell(1,animationsNumber);
            for animNum=1:animationsNumber
                % #.#. Initialization
                groupsNumberTemp=numel(infoSave{animNum});
                stepsDisplayed{animNum}=cell(1,numel(infoSave{animNum}));
                for groupNum=1:groupsNumberTemp
                    % #.#. Initialization
                    graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                    stepsDisplayed{animNum}{groupNum}=cell(1,numel(infoSave{animNum}{groupNum}));
                    for graphNum=1:graphsNumberTemp
                        % #.#. Initialization
                        stepsDisplayed{animNum}{groupNum}{graphNum}=cell(1,framesNumber(animNum));
                        if isempty(stepSelectedMinDouble{animNum}{groupNum}{graphNum}); continue; end
                        % #.#. Available steps
                        [~,~,~,stepsAvailableTemp,~]=loadListStep(app,infoSave{animNum}{groupNum}{graphNum}.info.selection.other.pathSimu);
                        stepsAvailableDoubleTemp=str2double(stepsAvailableTemp);
                        % #.#. Displayed steps (double)
                        if sameDuration
                            stepsDisplayedDoubleTemp=round(linspace(stepSelectedMinDouble{animNum}{groupNum}{graphNum},stepSelectedMaxDouble{animNum}{groupNum}{graphNum},framesNumber(animNum)));
                        else
                            stepsDisplayedDoubleTemp=round(linspace(stepSelectedMinDouble{animNum}{groupNum}{graphNum},stepSelectedMaxDouble{animNum}{groupNum}{graphNum},numel(1:step(animNum):stepsSelectedNumber{animNum}{groupNum}{graphNum})));
                        end
                        % #.#. Displayed steps (char)
                        % Find allows compatibility with any format
                        indOld=[];
                        for stepNum=1:numel(stepsDisplayedDoubleTemp)
                            indNew=find(stepsAvailableDoubleTemp==stepsDisplayedDoubleTemp(stepNum),1,'first');
                            if isempty(indNew)
                                stepsDisplayed{animNum}{groupNum}{graphNum}{stepNum}=[];
                            elseif isequal(indNew,indOld) % Avoid loading duplicates
                                stepsDisplayed{animNum}{groupNum}{graphNum}{stepNum}=[];
                            else
                                stepsDisplayed{animNum}{groupNum}{graphNum}{stepNum}=stepsAvailableTemp{indNew};
                            end
                            indOld=indNew;
                        end
                    end
                end
            end
            
            % #. Formats
            formatExtension={'.avi' '.mp4' '.png' '.jpg' '.fig' '.pdf' '.eps'};
            if all(types<=2)
                formatMsg={'AVI (animation)' 'MPEG-4 (animation)' 'PNG (images)' 'JPG (images)' 'FIG (MATLAB format)'};
            else
                formatMsg={'AVI (animation)' 'MPEG-4 (animation)' 'PNG (images)' 'JPG (images)' 'FIG (MATLAB format)' 'PDF (vectorized images)' 'EPS (vectorized images)'};
            end
            [indFormat,~] = listdlg('PromptString',{'Select the file formats:'},'ListString',formatMsg);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(indFormat)
                busyOff(app); return;
            end
            format=formatExtension(indFormat); % List of the formats that will be created
            
            % #. Simulation name
            % #.#. Default names
            if all(types<=2)
                % #.#.#. Several data per animation -> global name
                namesSimuIni{1}='animation';
            else
                % #.#.#. One data per animation -> data name
                namesSimuIni=cell(1,numel(types));
                for typeNum=1:numel(types)
                    if contains(typesAvailable{types(typeNum)},'(custom)')
                        nameTemp=erase(typesAvailable{types(typeNum)},' (custom)');
                    else
                        nameTemp=typesAvailable{types(typeNum)};
                    end
                    namesSimuIni{typeNum}=lower(replace(nameTemp,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                end
            end
            % #.#. Saving path
            if all(types==1)
                % #.#.#. Linked to several simulations -> a folder is selected
                opts.DefaultFolder=infoSave{1}{1}{1}.info.selection.other.pathSimu; % Starts from the current simulation folder
            else
                % #.#.#. Each animation is linked to one simulation -> saves in the ANIMATIONS folder
                pathsNumberTemp=numel([stepsDisplayed{:}]);
                pathsTemp=cell(1,pathsNumberTemp);
                kPath=0;
                for animNum=1:animationsNumber
                    groupsNumberTemp=numel(infoSave{animNum});
                    for groupNum=1:groupsNumberTemp
                        graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                        for graphNum=1:graphsNumberTemp
                            kPath=kPath+1;
                            pathsTemp{kPath}=strcat(infoSave{animNum}{groupNum}{graphNum}.info.selection.other.pathSimu,filesep,'ANIMATIONS');
                        end
                    end
                end
                indToKeep=~cell2mat(cellfun(@isempty,pathsTemp,'UniformOutput',false));
                opts.Folder=unique(pathsTemp(indToKeep));
            end
            % #.#. DLG
            [animationName,~,~,animationFolder]=GetFreeNames('DefaultNames',namesSimuIni,'Format',format,'ImagesMode','Folder',opts);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(animationName)
                busyOff(app); return;
            end
            if ~iscell(animationFolder)
                animationFolder={animationFolder};
            end
            namesNumber=numel(animationName);
            animationName=repmat(animationName,1,numel(animationFolder));
            animationFolder=reshape(repmat(animationFolder,namesNumber,1),1,[]);

            % #. Crop
            flag=0; % One selection for all animations: ignored if -1, dlg if 0, apply if 1
            if all(types>2)
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.maskingPanelNumber).Visible='on'; % Masking panel
                for graphNum=1:4
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.(['graph' num2str(graphNum) 'Number'])).HitTest='off';
                end

                for animNum=1:animationsNumber
                    groupsNumberTemp=numel(infoSave{animNum});
                    for groupNum=1:groupsNumberTemp
                        graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                        for graphNum=1:graphsNumberTemp

                            if flag~=1
                                % #.#. Initialization
                                infoTemp=struct();
                                % #.#.#.#. Buttons
                                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.continueFullScreenNumber).Value=0;
                                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.cancelFullScreenNumber).Value=0;
                                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Visible='on';
                                % #.#. Graph
                                % #.#.#. Equation
                                if contains(typesAvailable{types(1)},'(custom)')
                                    customData=DataManager([],[],[]);
                                    typeFormatTemp=erase(typesAvailable{types(1)},' (custom)');
                                    indCustomData=strcmp(customData(:,1),typeFormatTemp);
                                    typeFormatTemp=lower(replace(typeFormatTemp,{'[',' ','!','"','#','$','%','&','(',')','*','+',',','-','.','/',':',';','<','=','>','?','@','\','^','_','`','''','{','|','}','~',']'},'_'));
                                    eq=strjoin(strcat('[',[{typeFormatTemp} customData{indCustomData,3}],']'),'#');
                                else
                                    eq=infoSave{animNum}{groupNum}{graphNum}.info.selection.equation.global.rough.X;
                                    %eq=strcat('[',typesAvailable{types(1)},']');
                                end
                                % #.#.#. Info without step
                                infoTemp.selection=selectionInfo(app,infoSave{animNum}{groupNum}{graphNum}.info.selection.other.pathSimu,5,{eq ''},infoSave{animNum}{groupNum}{graphNum}.info.selection);
                                % #.#.#. Info with step
                                indStep=strcmpi(infoTemp.selection.type.global.cat,'STEPS');
                                varOld=strcat(infoTemp.selection.data.global.withoutArg2.rough.cat(indStep));
                                varNew=strcat(erase(infoTemp.selection.data.global.withoutArgs.rough.cat(indStep),']'),'%',stepsDisplayed{animNum}{groupNum}{graphNum}{1},']');
                                eq=replace(eq,varOld,varNew);
                                infoTemp.selection=selectionInfo(app,infoSave{animNum}{groupNum}{graphNum}.info.selection.other.pathSimu,5,{eq ''},infoTemp.selection);
                                % #.#.#. Display
                                displayGraphMain(app,[],tabNumber,infoTemp,'clear');
                                app.id.graphNumberVar=app.id.graph5Number;
                                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Visible='on';
                                % #.#. Waiting
                                waitfor(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.continueFullScreenNumber),'Value');
                                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.continueFullScreenNumber).Value=0;
                                if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.cancelFullScreenNumber).Value
                                    flag=1;
                                end
                                % #.#. Saves the current view
                                v=axis(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar)); % [xMin xMax yMin yMax zMin zMax]
                                [vRot1,vRot2]=view(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar));
                                vRot=[vRot1 vRot2]; % [azimuth elevation]
                                % #.#. Reset
                                clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber),'graph5');
                            end
                            % #.#. Save
                            infoSave{animNum}{groupNum}{graphNum}.info.opts.View=v;
                            infoSave{animNum}{groupNum}{graphNum}.info.opts.Rotation=vRot;
                            % #.#. All animations
                            if flag==0
                                if animNum<animationsNumber
                                    answer=questdlg('Apply the selection to all remaining graphs?','Selection','Yes','No','No');
                                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                                else
                                    answer={'Yes'};
                                end
                                if isempty(answer)
                                    busyOff(app); return;
                                elseif strcmpi(answer,'No')
                                    flag=-1;
                                else
                                    flag=1;
                                end
                            end
                        end
                    end
                end
                % #.#. Visibility
                % #.#.#. Components
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graph5Number).Visible='off';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Visible='off';
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.maskingPanelNumber).Visible='off'; % Masking panel
                for graphNum=1:4
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.(['graph' num2str(graphNum) 'Number'])).HitTest='on';
                end
                % #.#.#. Cancel
                if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.cancelFullScreenNumber).Value
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.cancelFullScreenNumber).Value=0;
                    busyOff(app); return;
                end
            end

            % #. Loading missing steps
            % #.#. Initialization
            maxFilesNumber=numel([stepsSelectedNumber{:}]);
            pathSimuLoading=cell(1,maxFilesNumber);
            filesNamesLoading=cell(1,maxFilesNumber);
            stepsLoading=cell(1,maxFilesNumber);
            kPath=0;
            % #.#. Steps to load
            for animNum=1:animationsNumber
                    groupsNumberTemp=numel(infoSave{animNum});
                    for groupNum=1:groupsNumberTemp
                        graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                        for graphNum=1:graphsNumberTemp
                            indStep=find(strcmpi(infoSave{animNum}{groupNum}{graphNum}.info.selection.type.global.cat,'steps')); % Files containing steps
                            if ~isempty(indStep)
                                % #.#.#. Path
                                pathSimuTemp=infoSave{animNum}{groupNum}{graphNum}.info.selection.other.pathSimu;
                                indPath=find(strcmpi(pathSimuLoading,pathSimuTemp),1,'first'); % Find already existing path
                                if isempty(indPath)
                                    kPath=kPath+1;
                                    indPath=kPath;
                                    pathSimuLoading{indPath}=pathSimuTemp; % Initialization
                                end
                                % #.#.#. File
                                for stepNum=1:numel(indStep)
                                    fileNameTemp=infoSave{animNum}{groupNum}{graphNum}.info.selection.file.global.cat{indStep(stepNum)};
                                    indFile=find(strcmpi(filesNamesLoading{indPath},fileNameTemp),1,'first'); % Find already existing file
                                    if isempty(indFile)
                                        indFile=numel(filesNamesLoading{indPath})+1;
                                        filesNamesLoading{indPath}{indFile}=fileNameTemp; % Initialization
                                    end
                                    % #.#.#. Steps
                                    if indFile>numel(stepsLoading{indPath})
                                        stepsLoading{indPath}{indFile}=''; % Initialization
                                    end
                                    indEmpty=cellfun(@isempty,stepsDisplayed{animNum}{groupNum}{graphNum});
                                    stepsLoading{indPath}{indFile}=unique([stepsLoading{indPath}{indFile} [stepsDisplayed{animNum}{groupNum}{graphNum}(~indEmpty)]]);
                                end
                            end
                        end
                    end
            end
            % #.#. Processing
            pathSimuLoading(kPath+1:end)=[];
            filesNamesLoading(kPath+1:end)=[];
            stepsLoading(kPath+1:end)=[];
            loadGraphicsFunction(app,pathSimuLoading,filesNamesLoading,stepsLoading,0);

            % #. Figure
            fig=cell(1,animationsNumber);
            for animNum=1:animationsNumber % For each type
                if any(strcmpi(format,'.fig'))
                    fig{animNum}=figure('units','normalized','Position',[0 0 1 1],'Color',[1 1 1],'Visible','off');
                else
                    fig{animNum}=figure('units','normalized','Position',[0 0 1 1],'Color',[1 1 1],'MenuBar','none','ToolBar','none','Visible','off');
                end
            end
            
            % #. Animations
            msg=[]; % must be initialized because of the try
            skipPreview=-1;
            for animNum=1:animationsNumber

                try

                    % #.#. Figure initialization

                    for animNumIni=animNum:animationsNumber

                        if isempty(fig{animNumIni}.Children)
                            if all(types==1)

                                % #.#.#. Overview

                                % #.#.#.#. Labels dlg
                                labSave=cell(numel(infoSave{animNumIni}),1);
                                for groupNum=1:numel(infoSave{animNumIni})
                                    [nameTab]=nameSimuFunction(app,infoSave{animNumIni}{groupNum}{1}.info.selection.other.tabNumber,[],[]);
                                    nameSimuSplit=strsplit(nameTab,' ');
                                    labSave{groupNum}=erase(join(nameSimuSplit{2:end}),{'(' ')'});
                                end
                                answer=inputdlg(strcat('Group',{' '},cellfun(@num2str,num2cell(1:numel(infoSave{animNumIni})),'UniformOutput',false),':'),'Labels',[1 100],labSave);
                                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                                if ~isempty(answer)
                                    labSave=answer;
                                end
                                % #.#.#.#. Axes creation
                                fig{animNumIni}.Visible='on'; % otherwise there is some issues during resizing
                                drawnow;
                                valueSave=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.manualPosNumber).Value;
                                app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.manualPosNumber).Value=1; % To select the position of each group of axes independently of the setting value
                                overViewFunction(app,fig{animNumIni},scalingFactor*app.fontSize,labSave);
                                app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.manualPosNumber).Value=valueSave;

                            elseif all(types==2)

                                % #.#.#. Current tab

                                % #.#.#.#. Axes creation
                                % Note that it is not possible to copy directly the panel
                                % without having some issues with the resizing behavior
                                % Initialization
                                groupsNumberTemp=numel(infoSave{animNumIni}{1});
                                objects=cell(1,groupsNumberTemp); indObjects=cell(1,groupsNumberTemp); indAxes=cell(1,groupsNumberTemp);
                                % Indexes
                                for graphNum=1:groupsNumberTemp
                                    [objects{graphNum},indObjects{graphNum},indAxes{graphNum}]=FindObjects(app.TabGroup.Children(infoSave{animNumIni}{1}{graphNum}.info.selection.other.tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(graphIndSave{animNumIni}{1}{graphNum}.graphNumber));
                                end
                                % Copy
                                set(0,'showhiddenhandles','on'); % To copy the colorbars
                                for graphNum=1:groupsNumberTemp
                                    indToCopy=sort([indObjects{graphNum} indAxes{graphNum}]);
                                    if ~isempty(indToCopy)
                                        if isscalar(app.TabGroup.Children(infoSave{animNumIni}{1}{graphNum}.info.selection.other.tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(indAxes{graphNum}).YAxis)
                                            % One Y axis
                                            copyobj(app.TabGroup.Children(infoSave{animNumIni}{1}{graphNum}.info.selection.other.tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(indToCopy),fig{animNumIni});
                                        else
                                            % Several Y axis
                                            % Matlab cannot copy axes with several Y axis, therefore a custom function is used
                                            ax=CopyobjYyaxis(app.TabGroup.Children(infoSave{animNumIni}{1}{graphNum}.info.selection.other.tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(indAxes{graphNum}),fig{animNumIni});
                                            pos=ObjectPosition(app.TabGroup.Children(infoSave{animNumIni}{1}{graphNum}.info.selection.other.tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(indAxes{graphNum}),'Type','Position','Units','Pixels');
                                            ObjectPosition(ax,'Type','Position','Units','Pixels','Set',pos);
                                        end
                                    end
                                end
                                drawnow;
                                set(0,'showhiddenhandles','off');
                                % #.#.#.#. FontSize
                                % Objects
                                findColorbar=findall(fig{animNumIni},'type','ColorBar');
                                findAxes=findall(fig{animNumIni},'type','axes');
                                % Colorbars
                                if ~isempty(findColorbar)
                                    [findColorbar(:).FontSize]=deal(scalingFactor*app.fontSize);
                                end
                                % Axes
                                if ~isempty(findAxes)
                                    [findAxes(:).FontSize]=deal(scalingFactor*app.fontSize);
                                    for graphNum=1:numel(findAxes)
                                        TicksOffset(findAxes(graphNum)); % As the font size has changed, some ticks may be outisde the frame
                                    end
                                end
                                % Colors of the labels
                                objects=[objects{:}]; % Remove empty objects
                                for colorbarNum=1:numel(findColorbar)
                                    findColorbar(colorbarNum).Label.Color=objects(numel(objects)-colorbarNum+1).Label.Color; % copyobj does not keep some properties
                                end

                            else

                                % #.#.#. Single graph

                                % #.#.#.#. Axes creation
                                set(fig{animNumIni},'Position',[-10000 -10000 1 1]);
                                fig{animNumIni}.Visible='On';
                                ax=axes('units','normalized','Position',[0 0 1 1],'Parent',fig{animNumIni});
                                % #.#.#.#. Info without step
                                % infoSave{animNumIni}{1}{1}.info.selection.other.graphNum=[];
                                % infoSave{animNumIni}{1}{1}.info.opts.View=v{animNumIni};
                                % infoSave{animNumIni}{1}{1}.info.opts.Rotation=vRot{animNumIni};
                                % #.#.#.#. Info with step
                                eq=infoSave{animNumIni}{1}{1}.info.selection.equation.global.rough.X;
                                indStep=strcmpi(infoSave{animNumIni}{1}{1}.info.selection.type.global.cat,'STEPS');
                                varOld=strcat(infoSave{animNumIni}{1}{1}.info.selection.data.global.withoutArg2.rough.cat(indStep));
                                varNew=strcat(erase(infoSave{animNumIni}{1}{1}.info.selection.data.global.withoutArgs.rough.cat(indStep),']'),'%',stepsDisplayed{animNumIni}{1}{1}{1},']');
                                eq=replace(eq,varOld,varNew);
                                infoSave{animNumIni}{1}{1}.info.selection=selectionInfo(app,infoSave{animNumIni}{groupNum}{graphNum}.info.selection.other.pathSimu,[],{eq ''},infoSave{animNumIni}{1}{1}.info.selection);
                                % #.#.#.#. Display
                                displayGraphMain(app,ax,infoSave{animNumIni}{1}{1}.info.selection.other.tabNumber,infoSave{animNumIni}{1}{1}.info,'clear');
                                % #.#.#.#. Font size
                                % Objects
                                findColorbar=findall(fig{animNumIni},'type','ColorBar');
                                findAxes=findall(fig{animNumIni},'type','axes');
                                % Colorbars
                                if ~isempty(findColorbar)
                                    [findColorbar(:).FontSize]=deal(scalingFactor*app.fontSize);
                                end
                                % Axes
                                if ~isempty(findAxes)
                                    [findAxes(:).FontSize]=deal(scalingFactor*app.fontSize);
                                    for axesNum=1:numel(findAxes)
                                        TicksOffset(findAxes(axesNum)); % As the font size has changed, some ticks may be outisde the frame
                                    end
                                end

                            end

                            % #.#. Borders
                            fig{animNumIni}.Visible='On';
                            drawnow;
                            BordersRemoving(fig{animNumIni},'Type','figure');

                        end

                        % #.#. Preview
                        if skipPreview==1
                            break;
                        else
                            % #.#.#. Pre-processing
                            movegui(fig{animNumIni},'center');
                            fig{animNumIni}.Visible='On';
                            % #.#.#. DLG
                            margins='0 0 0 0';
                            while 1
                                figure(app.mainUIFigure);
                                figure(fig{animNumIni});
                                answer = questdlg('Do you want to continue?','Preview','Yes','Options','Borders','Yes');
                                figure(app.mainUIFigure);
                                figure(fig{animNumIni});
                                if isempty(answer)
                                    cellfun(@delete,fig); busyOff(app); return;
                                elseif strcmpi(answer,'Yes')
                                    break;
                                elseif strcmpi(answer,'Options')
                                    optionsGraphFunction(app, tabNumber,fig{animNumIni});
                                elseif strcmpi(answer,'Borders')
                                    margins=inputdlg({'Enter margin values which are normalized according to height (left bottom right top):'},'Input',[1 100],{margins});
                                    if isempty(margins); continue; end
                                    margins=margins{1};
                                    marginsDouble=str2double(strsplit(margins));
                                    BordersRemoving(fig{animNumIni},'Type','figure','Margins',marginsDouble);
                                end
                            end
                            fig{animNumIni}.Position(1:2)=[-10000 -10000];
                            fig{animNumIni}.Visible='Off';
                            % #.#.#. Toolbars removing
                            % It may be displayed by mistake during the animation if during the preview the cursor activates it
                            arrayfun(@(x)axtoolbar(x,'Visible','off'),findobj(fig{animNumIni},'type','axes'));
                            arrayfun(@(x)axtoolbar(x,'Visible','on'),findobj(fig{animNumIni},'type','axes'));
                            % #.#.#. Skip preview
                            if animNumIni==animationsNumber
                                skipPreview=1; break;
                            else
                                answer = questdlg({'Do you want to preview the next animation?' ...
                                    '- Yes: requires simultaneous storage of each figure, which may cause slowdowns' ...
                                    '- No: any changes made during the preview will not be taken into account for the next animations'} ...
                                    ,'Preview','Yes','No','No');
                                figure(app.mainUIFigure);
                                if isempty(answer)
                                    cellfun(@delete,fig); busyOff(app); return;
                                elseif strcmpi(answer,'Yes')
                                    skipPreview=0;
                                elseif strcmpi(answer,'No')
                                    skipPreview=1; break;
                                end
                            end
                        end

                    end

                    % #.#. Waitbar
                    if animNum==1
                        tStart=tic;
                        msg=waitbar(0,['Animation 1/' num2str(animationsNumber) ' - Loading 0.0%'],'Name','Progress');
                    end

                    % #.#. Visiblity
                    fig{animNum}.Visible='On';
                    ax=findobj(fig{animNum},'type','axes','-or','type','uiaxes');

                    % #.#. Creation of the frames
                    % #.#.#. Initialization
                    F=struct('cdata', cell(1,framesNumber(animNum)),'colormap',cell(1,framesNumber(animNum)));
                    for frameNum=1:framesNumber(animNum)

                        % #.#.#. Information

                        groupsNumberTemp=numel(infoSave{animNum});
                        for groupNum=1:groupsNumberTemp
                            graphsNumberTemp=numel(infoSave{animNum}{groupNum});
                            for graphNum=1:graphsNumberTemp

                                if ~isempty(stepsDisplayed{animNum}{groupNum}{graphNum}{frameNum}) ... % If the step is not already plotted or it is not a step
                                        % #.#.#.#. Update equation
                                    eqOld=infoSave{animNum}{groupNum}{graphNum}.info.selection.equation.global.rough.X;
                                    indRelative=find(contains(infoSave{animNum}{groupNum}{graphNum}.info.selection.arg1.global.rough.X,'%'));
                                    if isempty(indRelative); continue; end
                                    dataOld=infoSave{animNum}{groupNum}{graphNum}.info.selection.data.global.withoutArg2.rough.cat(indRelative);
                                    dataNew=strcat('[', ...
                                        erase(infoSave{animNum}{groupNum}{graphNum}.info.selection.data.global.withoutArg1.rough.cat(indRelative),{'[' ']'}), ...
                                        '%',stepsDisplayed{animNum}{groupNum}{graphNum}{frameNum},...
                                        ']');
                                    eqNew=replace(eqOld,dataOld,dataNew);
                                    % #.#.#.#. Update info
                                    infoSave{animNum}{groupNum}{graphNum}.info.selection=...
                                        selectionInfo(...
                                        app,...
                                        infoSave{animNum}{groupNum}{graphNum}.info.selection.other.pathSimu,infoSave{animNum}{groupNum}{graphNum}.info.selection.other.graphNum,...
                                        {eqNew ''},...
                                        infoSave{animNum}{groupNum}{graphNum}.info.selection);
                                end

                            end
                        end

                        % #.#.#. Display

                        if all(types<=2)

                            % #.#.#.#. Overview and current tab
                            for groupNum=1:numel(stepsDisplayed{animNum})
                                for graphNum=1:numel(stepsDisplayed{animNum}{groupNum})
                                    if ~isempty(stepsDisplayed{animNum}{groupNum}{graphNum}{frameNum}) ... % If the step is not already plotted or it is not a step
                                            || frameNum==1 % Allows initialization
                                        % Tab update
                                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(graphIndSave{1}{groupNum}{graphNum}.gridGraph).Children(graphIndSave{1}{groupNum}{graphNum}.graphXEqNumber).Value= ...
                                            infoSave{animNum}{groupNum}{graphNum}.info.selection.equation.global.rough.X;
                                        app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphButtonsNumber).Children(graphIndSave{1}{groupNum}{graphNum}.gridGraph).Children(graphIndSave{1}{groupNum}{graphNum}.graphYEqNumber).Value= ...
                                            infoSave{animNum}{groupNum}{graphNum}.info.selection.equation.global.rough.Y;
                                        % Graph update
                                        [axesSave{animNum}{groupNum}{graphNum},infoSave{animNum}{groupNum}{graphNum}.info]=displayGraphMain(...
                                            app,...
                                            [],...
                                            infoSave{animNum}{groupNum}{graphNum}.info.selection.other.tabNumber,...
                                            infoSave{animNum}{groupNum}{graphNum}.info,...
                                            'refresh');
                                    end
                                end
                            end
                            % Overview update
                            OverViewRefresh(fig{animNum},axesSave{animNum},'DataTips',any(strcmpi(format,'.fig')),'View',viewOption); % Datatips are activated only if a fig is exported

                        else

                            % #.#.#.#. Single graph
                            for groupNum=1:numel(stepsDisplayed{animNum}) % add for clarity
                                for graphNum=1:numel(stepsDisplayed{animNum}{groupNum}) % add for clarity
                                    if ~isempty(stepsDisplayed{animNum}{groupNum}{graphNum}{frameNum}) % If the step is not already plotted or it is not a step
                                        displayGraphMain(...
                                            app,...
                                            ax,...
                                            infoSave{animNum}{groupNum}{graphNum}.info.selection.other.tabNumber,...
                                            infoSave{animNum}{groupNum}{graphNum}.info,...
                                            'refresh');
                                    end
                                end
                            end

                        end

                        % #.#.#. Exportation
                        drawnow;
                        if ~exist(animationFolder{animNum},'dir')
                            mkdir(animationFolder{animNum});
                        end
                        % #.#.#.#. EPS
                        if any(strcmpi(format,'.eps'))
                            if ~isfile([animationFolder{animNum} filesep animationName{animNum} 'EPS'])
                                mkdir([animationFolder{animNum} filesep animationName{animNum} 'EPS']);
                            end
                            exportgraphics(fig{animNum},[animationFolder{animNum} filesep animationName{animNum} 'EPS' filesep 'frame_'  num2str(frameNum-1,['%0' num2str(app.stepDigits) '.f']) '.eps'],'ContentType','vector');
                        end
                        % #.#.#.#. PDF
                        if any(strcmpi(format,'.pdf'))
                            if ~exist([animationFolder{animNum} filesep animationName{animNum} 'PDF'],'dir')
                                mkdir([animationFolder{animNum} filesep animationName{animNum} 'PDF']);
                            end
                            exportgraphics(fig{animNum},[animationFolder{animNum} filesep animationName{animNum} 'PDF' filesep 'frame_'  num2str(frameNum-1,['%0' num2str(app.stepDigits) '.f']) '.pdf'],'ContentType','vector');
                        end
                        % #.#.#.#. FIG
                        if any(strcmpi(format,'.fig'))
                            if ~exist([animationFolder{animNum} filesep animationName{animNum} 'FIG'],'dir')
                                mkdir([animationFolder{animNum} filesep animationName{animNum} 'FIG']);
                            end
                            if types>2
                                set(fig{animNum},'ToolBar', 'figure');
                                set(fig{animNum},'MenuBar', 'figure');
                            end
                            savefig(fig{animNum},[animationFolder{animNum} filesep animationName{animNum} 'FIG' filesep 'frame_'  num2str(frameNum-1,['%0' num2str(app.stepDigits) '.f'])]);
                        end
                        % #.#.#. PNG
                        if any(strcmpi(format,'.png'))
                            if ~exist([animationFolder{animNum} filesep animationName{animNum} 'PNG'],'dir')
                                mkdir([animationFolder{animNum} filesep animationName{animNum} 'PNG']);
                            end
                            frame= frame2im(getframe(fig{animNum}));
                            imwrite(frame,[animationFolder{animNum} filesep animationName{animNum} 'PNG' filesep 'frame_'  num2str(frameNum-1,['%0' num2str(app.stepDigits) '.f']) '.png'])
                        end
                        % #.#.#. JPG
                        if any(strcmpi(format,'.jpg'))
                            if ~exist([animationFolder{animNum} filesep animationName{animNum} 'JPG'],'dir')
                                mkdir([animationFolder{animNum} filesep animationName{animNum} 'JPG']);
                            end
                            frame=frame2im(getframe(fig{animNum}));
                            imwrite(frame,[animationFolder{animNum} filesep animationName{animNum} 'JPG' filesep 'frame_'  num2str(frameNum-1,['%0' num2str(app.stepDigits) '.f']) '.jpg'])
                        end
                        % #.#.#. Other
                        if any(strcmpi(format,'.avi') | strcmpi(format,'.mp4'))
                            F(frameNum)=getframe(fig{animNum});
                        end
                        % #.#.#. Waitbar
                        if ishandle(msg)
                            % #.#.#.#. Refresh
                            waitbar(...
                                (sum(framesNumber(1:animNum-1))+frameNum)/sum(framesNumber)... % Normalized progress
                                ,msg,['Animation ' num2str(...
                                animNum... % Number of animations that have been created
                                ) '/' num2str(...
                                animationsNumber... % Total number of animations to be created
                                ) ' - Loading ' num2str(...
                                ((sum(framesNumber(1:animNum-1))+frameNum)/sum(framesNumber))*100 ... % Progress in %
                                ,'%.1f') '% - Remaining time: '  char(duration([0,0,...
                                ((sum(framesNumber)-(sum(framesNumber(1:animNum-1))+frameNum))*(toc(tStart)/(sum(framesNumber(1:animNum-1))+frameNum)))... % Remaining time
                                ]))]);
                        else
                            % #.#.#.#. Cancel
                            cellfun(@delete,fig); delete(msg); msgbox('Animation has been stopped.','Information','help'); busyOff(app); return;
                        end
                    end

                    % #.#. Export
                    % #.#.#. MP4
                    if any(strcmpi(format,'.mp4'))
                        writerObj=VideoWriter([animationFolder{animNum} filesep animationName{animNum}],'MPEG-4'); % Inconsistent warning message
                        writerObj.FrameRate=frameRate(animNum);
                        writerObj.Quality=100;
                        open(writerObj);
                        for frameNum=1:length(F)
                            frame=F(frameNum) ;
                            writeVideo(writerObj,frame);
                        end
                        close(writerObj);
                    end
                    % #.#.#. AVI
                    if any(strcmpi(format,'.avi'))
                        writerObj=VideoWriter([animationFolder{animNum} filesep animationName{animNum}],'Uncompressed AVI'); % Inconsistent warning message
                        writerObj.FrameRate=frameRate(animNum);
                        open(writerObj);
                        for frameNum=1:length(F)
                            frame=F(frameNum) ;
                            writeVideo(writerObj,frame);
                        end
                        close(writerObj);
                    end

                    % #.#. Clear
                    if all(types<=2)
                        axesSave{animNum}=[];
                    end

                    % #.#. Overview panel
                    if all(types==1)
                        app.sliderOverview.Visible='on';
                        app.changeOverview.Visible='on';
                        app.optionOverview.Visible='on';
                        app.hideOverview.Visible='on';
                        app.stepSBOverview.Visible='on';
                        app.stepFBOverview.Visible='on';
                        app.stepSFOverview.Visible='on';
                        app.stepFFOverview.Visible='on';
                    end

                    delete(fig{animNum});

                catch
                    delete(fig{animNum}); msgbox('Error','Information','help'); continue;
                    %msgbox(['Error on path: ' multiStepsPaths{typesind(typeNum)}],'Information','help'); continue;
                end

            end

            % #. Closing
            cellfun(@delete,fig)
            if ishandle(msg)
                delete(msg);
            end

            % #. Opens the file or the folder
            answer = questdlg('Animation is done.','Success','Open','Close','Close');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if strcmpi(answer,'Open')
                if isscalar(format) && animationsNumber==1
                    % #.#. One animation
                    if any(strcmpi(format,'.avi') | strcmpi(format,'.mp4'))
                        % #.#.#. Video -> Opens the file
                        openFile(app,[animationFolder{animNum} filesep animationName{1} format{1}]);
                    else
                        % #.#.#. Images -> Opens the folder that contains these images
                        openFile(app,[animationFolder{animNum} filesep animationName{1} upper(erase(format{1},'.'))]);
                    end
                else
                    % #.#. Several animations -> Opens the folder
                    openFile(app,animationFolder{animNum});
                end
            end

            % #. Busy
            busyOff(app);

        end

        % Value changed function: autoRefresh
        function autoRefreshValueChanged(app, event)
            % Abstract: automatically refreshes the graphs in the current tab
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber=findTab(app);
            
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value
                
                % #. Starts the refresh
                
                % #.#. Initialization reminder
                if ~app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.initializationNumber).Value
                    for graphNum=1:4
                        graphNumFunction(app, graphNum);
                        if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                            selection=selectionInfo(app,tabNumber,graphNum,[],[]);
                            if any(strcmpi(selection.type.global.cat,'STEPS')) % Only if a loading is necessary
                                msgbox('Reminder: initialization is disabled.','Information','help'); break;
                            end
                        end
                    end
                end
                
                % #.#. Starts the timer
                t=timer('Name',['timerGUITab' num2str(tabNumber) 'Plot'],...
                    'BusyMode','queue',...
                    'StartDelay',app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Value,...
                    'Period',app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Value,...
                    'ExecutionMode','fixedSpacing',...
                    'TimerFcn', @(~,~) refreshGraphicsFunction(app,tabNumber));
                start(t);
                
                % #.#. Activation
                var='off';
                
            else
                
                % #. Stops the refresh
                
                % #.#. Stops the timer
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value=0;
                if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Plot']))
                    stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Plot']));
                    delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Plot']));
                end
                
                % #.#. Deactivation
                var='on';
                
            end
            
            % #. Activation/Deactivation
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Editable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.loadPathNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.savePathNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Enable=var;
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCancelNumber).Enable=var;
            
            % #. Busy
            busyOff(app);
        end

        % Value changed function: cancelFullScreen
        function cancelFullScreenValueChanged(app, event)
            % Abstract: allows to cancel a selection
            % Changes the value of the continue button which is mandatory
            % to break the 'waitfor' command. The selection will be
            % cancelled if continue and cancel values are equal to one.
            
            busyOn(app);
            tabNumber = findTab(app);
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.gridContinueNumber).Children(app.id.continueFullScreenNumber).Value=1;
            busyOff(app);
        end

        % Button pushed function: changeOverview
        function changeOverviewButtonPushed(app, event)
            % Abstract: changes the position of graphs in overview
            
            busyOn(app);
            valueSave=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.manualPosNumber).Value;
            app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.manualPosNumber).Value=1; % Forces the manual positioning
            delete(app.overviewPanel.Children);
            overViewFunction(app,app.overviewPanel,[],[]);
            app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.manualPosNumber).Value=valueSave;
            busyOff(app);
        end

        % Button pushed function: clearSection
        function clearSectionButtonPushed(app, event)
            % Abstract: resets the section panel

            busyOn(app);
            tabNumber = findTab(app);
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Value=...
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Items{1};
            filesListButtonValueChanged(app,event);
            busyOff(app);

        end

        % Close request function: mainUIFigure
        function closeFcn(app, event)
            % Abstract: closes the GUI
            
            % #. Busy
            busyOn(app);
            
            % #. DLG
            closeGUI=questdlg('Close the interface?','Warning','Yes (save the GUI)','No (save the GUI)','Cancel (without saving the GUI)','Cancel (without saving the GUI)');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(closeGUI) || contains(closeGUI,'Cancel')
                busyOff(app); return;
            end
            
            % #. Checks if a computation is in progress
            for tabNumberVar1=3:numel(app.TabGroup.Children)
                if strcmpi(app.TabGroup.Children(tabNumberVar1).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'On') ...
                        && strcmpi(app.TabGroup.Children(tabNumberVar1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local')
                % #.#. Computation(s) in progress
                answer=questdlg('One or several computations are in progress, do you want to stop them?','Warning','Yes','No','Cancel','Cancel');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(answer) || contains(answer,'Cancel')
                    % #.#.#. Cancels the closing
                    busyOff(app); return;
                elseif contains(answer,'Yes')
                    % #.#.#. Stops the computation(s)
                    for tabNumberVar2=tabNumberVar1:numel(app.TabGroup.Children)
                        if strcmpi(app.TabGroup.Children(tabNumberVar2).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'On') ...
                                && strcmpi(app.TabGroup.Children(tabNumberVar2).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local')
                        app.TabGroup.Children(tabNumberVar2).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value='Off';
                        runFunction(app,tabNumberVar2,0);
                        end
                    end
                end
                break;
                end
            end
            
            % #. Hides handles if they are still displayed due to an error
            set(0,'showhiddenhandles','off');
            
            % #. Timers deletion
            timerStruct=timerfind;
            if ~isempty(timerStruct)
                timersToDel=string({timerStruct(contains({timerStruct.Name},'timerGUITab')).Name});
                for timerName=timersToDel
                    stop(timerfind('Name',timerName));
                    delete(timerfind('Name',timerName));
                end
            end
            
            % #. Tabs save
            guiStateExport(app);
            
            % #. GUI closing
            if contains(closeGUI,'No')
                busyOff(app);
            else
                delete(app);
            end

        end

        % Button pushed function: runModeListEdit
        function clusterEditServers(app, event)
            % Abstract: edits server list
            
            % #. Busy
            busyOn(app);

            % #. Server selection
            tabNumber=findTab(app);
            serverList=[app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items ...
                'Add'];
            serverInd=listdlg('Name','Servers','PromptString','Select the server to be edited:','listString',serverList(2:end),'SelectionMode','single');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(serverInd); return; end
            serverInd=serverInd+1; % Local is removed from the list as it must not be modified

            % #. Server editing
            prompt={'Enter new value (an empty value removes the server):'};
            dlgtitle='Server editing';
            fieldsize=[1 75];
            definput=serverList(serverInd);
            answer=inputdlg(prompt,dlgtitle,fieldsize,definput);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(answer); return; end
            serverList{serverInd}=answer{1};
            if serverInd~=numel(serverList); serverList(end)=[]; end % removes 'Add'
            serverList(cellfun(@isempty,serverList))=[]; % removes empty value

            % #. Processing
            for tabNumberVar=[1 3:numel(app.TabGroup.Children)]
                app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items=serverList;
                app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value= ...
                   app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items{1}; % Local reset
            end
            findExecution(app,[1 3:numel(app.TabGroup.Children)]);

            % #. Settings save
            settingsValueChanged(app,event);
            clusterInfoInitialization(app);

            % #. Busy
            busyOff(app);
        end

        % Button pushed function: clusterInfo
        function clusterInfoButtonPushed(app, event)
            % Abstract: cluster processing

            % #. Busy
            busyOn(app);

            % #. Message
            answer = questdlg({'What do you want to open?'},'Cluster','File manager','List of jobs on the cluster','Cancel','Cancel');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background

            % #. Processing
            if strcmp(answer,'File manager')
                % #.#. Information
                tabNumber=findTab(app);
                s_conn=clusterLoadInfo(app,[],[]);
                if isempty(s_conn)
                    busyOff(app); return;
                end
                % #. GUI
                clusterFileManager(app,tabNumber,s_conn);
            elseif strcmp(answer,'List of jobs on the cluster')
                % #.#. List of cluster jobs
                squeueFunction(app);
            end

            % #. Busy
            busyOff(app);
        end

        % Value changed function: displayPath
        function displayPathValueChanged(app, event)
            % Abstract: updates path
            
            busyOn(app);
            load([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave'); % Index is loaded because if the text is modified and an other tab is directly selected, it will be the color of the last selected tab that will be changed
            app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).FontColor=app.fontColorChanged;
            answer = questdlg({'The path has been modified. Would you like to load it?'  ...
                'Warning: strongly recommended, as all operations will be performed according to this new path, even without loading.'},'Warning','Yes','No','Cancel','Cancel');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(answer) || strcmpi(answer,'Cancel')
                busyOff(app); return;
            elseif strcmpi(answer,'yes')
                tabNumber=findTab(app);
                loadPathFunctionMain(app,tabNumber);
            end
            busyOff(app);

        end

        % Callback function
        function figureKeyPress(app, event)
            % Abstract: shortcuts

            % #. Inputs
            if ~all(strcmpi(event.Modifier,'alt')) || isempty(event.Modifier) || strcmpi(event.Key,'alt')
                % Allows only key combinations. Otherwise, it will create a
                % lot of problems, for example if a character is entered in
                % an equation and it also corresponds to a shortcut key.
                % Currently, no solution has been found to remove the
                % shortcuts callback if a text is selected.
                % Alt is used because control and shift are associated
                % with many existing shortcuts (e.g. ctrl+C, etc.)
                return;
            end
            key=event.Key;
            tabNumber=findTab(app);
            %disp(key)
            
            % #. Busy
            busyOn(app);
            
            % #. All tabs
            
            switch key
                case 'equal' % '+'
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                    app.TabGroup.SelectedTab=app.TabGroup.Children(1);
                    TabGroupSelectionChanged(app,event);
                case 'f12'
                    % #.#. Export a graph
                    exportGraphFunction(app)
                case 'q'
                    % #.#. S{Q}ueue command on the cluster
                    squeueFunction(app)
            end
            
            if tabNumber>2
                
                % #. TABS
                
                switch key
                    case 'rightarrow'
                        % #.#. Selects the tab to the {RIGHT}
                        if numel(app.TabGroup.Children)>tabNumber
                            app.TabGroup.SelectedTab=app.TabGroup.Children(tabNumber+1);
                        end
                    case 'leftarrow'
                        % #.#. Selects the tab to the {LEFT}
                        if tabNumber>3
                            app.TabGroup.SelectedTab=app.TabGroup.Children(tabNumber-1);
                        end
                    case 'backspace'
                        % #.#. Closes the current tab
                        deleteTabFunction(app);
                    case 'f'
                        % #.#. Opens the {F}older
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                        openPathPushed(app,[])
                    case 'a'
                        % #.#. Opens the {A}nimation folder
                        pathToOpen=[app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value filesep 'ANIMATIONS'];
                        if exist(pathToOpen,'dir')
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                            openFile(app,pathToOpen);
                        end
                    case 'c'
                        % #.#. Opens the {C}ode folder
                        pathToOpen=[app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value filesep 'CODE'];
                        if exist(pathToOpen,'dir')
                            figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                            openFile(app,pathToOpen);
                        end
                    case 'g'
                        % #.#. {G}ets a new folder
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                        getPathButtonPushed(app,event);
                    case 'o'
                        % #.#. {O}pens the edited file
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                        openEditedFileButtonPushed(app,event);
                    case 'e'
                        % #.#. Opens the last st{E}p file
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                        valueSave=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value;
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value=...
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items{1};
                        stepOpenButtonPushed(app,event);
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value=valueSave;
                    case 't'
                        % #.#. Opens the file {T}ransfer
                        % #.#.#. Servers
                        serversList=app.TabGroup.Children(1).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Items(2:end);
                        ind=listdlg('PromptString',{'Select the server:'},'listString',serversList,'SelectionMode','single');
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                        if isempty(ind)
                            return;
                        else
                            server=serversList(ind);
                        end
                        % #.#.#. Connection
                        s_conn=clusterLoadInfo(app,[],server);
                        if isempty(s_conn)
                            busyOff(app); return;
                        end
                        % #.#.#. GUI
                        clusterFileManager(app,tabNumber,s_conn)
                        % #.#.#. Closing the connection
                        ssh2_close(s_conn);
                        clearvars('s_conn');
                    case 'u'
                        % #.#. Opens the {U}ser guide
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                        userGuidePackageButtonPushed(app,event);
                    case 'i'
                        % #.#. Opens the shortcuts list
                        shortcutsListButtonPushed(app,event)
                    case 'v'
                        % #.#. Same {V}iew
                        propagateView(app,tabNumber,[]);
                    case 'f5'
                        % #.#. Refreshes all the tab
                        refreshStepButtonButtonPushed(app,event);
                        refreshGraphicsButtonPushed(app,event);
                    case 'f6'
                        % #.#. Refreshes all the tabs
                        for tabNumberVar=3:numel(app.TabGroup.Children)
                            try
                                refreshSteps(app,tabNumberVar,1);
                                refreshGraphicsFunction(app,tabNumberVar);
                            catch
                            end
                        end
                    case 'p'
                        % #.#. Opens or closes the {P}arameters panel
                        if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.settingsNumber).Value
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.settingsNumber).Value=0;
                        else
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.settingsNumber).Value=1;
                        end
                        settingsValueChanged(app,event);
                    case 'l'
                        % #.#. {L}oads the GRAPHIC files
                        figure(app.mainUIFigure); % otherwise the interface is sent back to the background % Otherwise it will be opened in the background
                        loadGraphicsButtonPushed(app,[]);
                    case 'return'
                        % #.#. Starts/Stops a local animation
                        if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Value==1
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Value=0;
                        else
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Value=1;
                        end
                        graphicsGUIAnimValueChanged(app,event);
                    case 'uparrow'
                        % #.#. GRAPHICS {UP}ward shift
                        graphicsFButtonPushed(app,event);
                    case 'downarrow'
                        % #.#. GRAPHICS {DOWN}ward shift
                        graphicsBButtonPushed(app,event);
                    case 'f1'
                        % #.#. Slider value = 0
                        % 'f1' is used because MATLAB does not detect '²'
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0;
                        sliderFunction (app,tabNumber);
                    case '1'
                        % #.#. Slider value = 0.{1}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.1;
                        sliderFunction (app,tabNumber);
                    case '2'
                        % #.#. Slider value = 0.{2}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.2;
                        sliderFunction (app,tabNumber);
                    case '3'
                        % #.#. Slider value = 0.{3}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.3;
                        sliderFunction (app,tabNumber);
                    case '4'
                        % #.#. Slider value = 0.{4}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.4;
                        sliderFunction (app,tabNumber);
                    case '5'
                        % #.#. Slider value = 0.{5}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.5;
                        sliderFunction (app,tabNumber);
                    case '6'
                        % #.#. Slider value = 0.{6}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.6;
                        sliderFunction (app,tabNumber);
                    case '7'
                        % #.#. Slider value = 0.{7}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.7;
                        sliderFunction (app,tabNumber);
                    case '8'
                        % #.#. Slider value = 0.{8}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.8;
                        sliderFunction (app,tabNumber);
                    case '9'
                        % #.#. Slider value = 0.{9}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=0.9;
                        sliderFunction (app,tabNumber);
                    case '0'
                        % #.#. Slider value = 1.{0}
                        app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=1;
                        sliderFunction (app,tabNumber);
                end
                
            elseif tabNumber==2
                
                % #. OVERVIEW
                
                switch key
                    case 'v'
                        % #.#. Same {V}iew
                        propagateView(app,tabNumber,[]);
                    case 'h'
                        % #.#. {H}ides or displays the overview panel
                        app.sliderOverview.Visible='on';
                        app.changeOverview.Visible='on';
                        app.optionOverview.Visible='on';
                        app.hideOverview.Visible='on';
                        app.stepSBOverview.Visible='on';
                        app.stepFBOverview.Visible='on';
                        app.stepSFOverview.Visible='on';
                        app.stepFFOverview.Visible='on';
                    case 'uparrow'
                        % #.#. GRAPHICS {UP}ward shift
                        stepSFOverviewButtonPushed(app,event);
                    case 'downarrow'
                        % #.#. GRAPHICS {DOWN}ward shift
                        stepSBOverviewButtonPushed(app,event);
                    case 'f1'
                        % #.#. Slider value = 0
                        % 'f1' is used because MATLAB does not detect '²'
                        app.sliderOverview.Value=0;
                        overViewStepChange(app,'slider');
                    case '1'
                        % #.#. Slider value = 0.{1}
                        app.sliderOverview.Value=0.1;
                        overViewStepChange(app,'slider');
                    case '2'
                        % #.#. Slider value = 0.{2}
                        app.sliderOverview.Value=0.2;
                        overViewStepChange(app,'slider');
                    case '3'
                        % #.#. Slider value = 0.{3}
                        app.sliderOverview.Value=0.3;
                        overViewStepChange(app,'slider');
                    case '4'
                        % #.#. Slider value = 0.{4}
                        app.sliderOverview.Value=0.4;
                        overViewStepChange(app,'slider');
                    case '5'
                        % #.#. Slider value = 0.{5}
                        app.sliderOverview.Value=0.5;
                        overViewStepChange(app,'slider');
                    case '6'
                        % #.#. Slider value = 0.{6}
                        app.sliderOverview.Value=0.6;
                        overViewStepChange(app,'slider');
                    case '7'
                        % #.#. Slider value = 0.{7}
                        app.sliderOverview.Value=0.7;
                        overViewStepChange(app,'slider');
                    case '8'
                        % #.#. Slider value = 0.{8}
                        app.sliderOverview.Value=0.8;
                        overViewStepChange(app,'slider');
                    case '9'
                        % #.#. Slider value = 0.{9}
                        app.sliderOverview.Value=0.9;
                        overViewStepChange(app,'slider');
                    case '0'
                        % #.#. Slider value = 1.{0}
                        app.sliderOverview.Value=1;
                        overViewStepChange(app,'slider');
                end
            end
            
            % #. Busy
            busyOff(app);

        end

        % Value changed function: filesListButton
        function filesListButtonValueChanged(app, event)
            % Abstract: displays a particular section of the control file
            
            % #. Busy
            busyOn(app);

            % #. Inputs
            tabNumber=findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;

            % #. List
            editorFiles=dir([pathSimu filesep 'CODE']);
            editorFilesList={editorFiles.name};
            indToRemove=ismember(editorFilesList,{'.' '..' '.DS_Store' 'Thumbs.db'});
            editorFilesList(indToRemove)=[];
            [~,indSort]=sort(lower(editorFilesList));
            editorFilesList=editorFilesList(indSort); % case-insensitive display
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Items=[{''} editorFilesList];
            
            % #. New text;
            processingEditedText(app,tabNumber,0);
            
            % #. Color update
            load([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave'); % Index is loaded because if the text is modified and an other tab is directly selected, it will be the color of the last selected tab that will be changed
            app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;
            
            % #. Busy
            busyOff(app);

        end

        % Button pushed function: loadPath
        function getPathButtonPushed(app, event)
            % Abstract: gets new simulation folder(s)
            
            % #. Busy
            busyOn(app);
            
            % #. Input
            tabNumber=findTab(app);
            tabsNumberBeforeChanges=numel(app.TabGroup.Children);
            
            % #. Computation in progress
            if strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.runButtonNumber).Value,'On')
                answer = questdlg('A computation is in progress, do you want to continue?','Warning','Yes','No','Cancel','Cancel');
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(answer) || strcmpi(answer,'Cancel')
                    busyOff(app); return; % Cancel
                end
                if ~isempty(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']))
                    stop(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
                    delete(timerfind('Name',['timerGUITab' num2str(tabNumber) 'Run']));
                end
            end
            
            % #. Starting path
            % An existing path is searched from the current tab to the left and then from the current tab to the right
            pathDefault=app.pwdSave; % Default value
            for tabNumberVar=[tabNumber:-1:3 tabNumber+1:tabsNumberBeforeChanges]
                pathTemp=app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                if exist(pathTemp,'dir')
                    ind=strfind(pathTemp,filesep);
                    pathDefault=pathTemp(1:ind(end)-1); % Get the upper folder
                    break;
                end
            end
            
            % #. Folders selection
            selpathVect=uigetfile_n_dir(pathDefault, 'Select path(s)'); % Custom function because MATLAB does not offer the possibility to get several folders in a single selection
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(selpathVect)
                busyOff(app); return;
            end
            pathsNumber=numel(selpathVect);
            
            % #. Mode
            % tabsSwap: Depending on the selection, the position of some
            % tabs can be changed after the reading process. Indeed, it is
            % possible to add a tab only at the end. However, it is more
            % convenient to keep the selection in adjacent tabs, which
            % requires a post-processing of the tab positions but also of
            % the stored data associated to a tab index.
            if pathsNumber==1
                % #.#. Existing tab without tab swap
                tabsSwap=0;
            else
                if tabNumber<tabsNumberBeforeChanges-1
                    % #.#. Existing/New tabs with or without tabs swap
                    answer=questdlg('How do you want to apply the selection?','Selection','Add tabs next to the current one','Update tabs next to the current one','Cancel','Cancel');
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    if strcmpi(answer,'Add tabs next to the current one')
                        tabsSwap=1;
                    elseif strcmpi(answer,'Update tabs next to the current one')
                        tabsSwap=0;
                    else
                        busyOff(app); return; % Cancel
                    end
                else % Tabs are added at the end which does not require tabs swap
                    % #.#. Existing/New tabs without tabs swap
                    tabsSwap=0;
                end
            end
            
            % #. Waitbar
            if pathsNumber>1
                msg=waitbar(0,['Loading tabs (0/' num2str(pathsNumber) ')'],'Name','Progress');
            end
            
            % #. Reading of each folder
            for pathNum=1:pathsNumber
                % #.#. Tab to be modified
                if tabsSwap==0 || pathNum==1
                    tabNumberVar=tabNumber-1+pathNum;
                else
                    tabNumberVar=inf;
                end
                % #.#. Tab creation
                if tabNumberVar>=numel(app.TabGroup.Children) % Even for the replace mode, the selection can be greater than the number of tabs
                    tabCreation(app);
                    tabNumberVar=numel(app.TabGroup.Children)-1;  % Last tab is always empty
                end
                app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value=selpathVect{pathNum};
                % #.#. Potential file copying
                if pathsNumber==1
                    copyFilesFunction(app,tabNumberVar);
                end
                % #.#. Loading
                loadPathFunctionMain(app,tabNumberVar);
                % #.#. Waitbar
                if pathsNumber>1
                    if ishandle(msg)
                        waitbar(pathNum/pathsNumber,msg,['Loading tabs (' num2str(pathNum) '/' num2str(pathsNumber) ')']);
                    else
                        delete(msg);
                        msgbox('Loading has been canceled.','Information','help');
                        busyOff(app);
                        return;
                    end
                end
                % #.#. EULER view by default
                % Note that it must be outside the load function otherwise
                % during the GUI opening with a tabs import, the previous view
                % cannot be loaded
                app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Value=...
                    app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.viewTypeListNumber).Items{2};
            end
            
            % #. Moving tabs
            % If several tabs are added, the tabs after the first one will
            % be added at the end which can be inconvenient if they are in
            % the same group than the current tab.
            if tabsSwap==1 && tabNumber<tabsNumberBeforeChanges-1 % If the current tab was the second last or the last, a change is not necessary.
                % #.#. Moving
                app.TabGroup.Children=[app.TabGroup.Children(1:tabNumber);app.TabGroup.Children(tabsNumberBeforeChanges:end-1);app.TabGroup.Children(tabNumber+1:tabsNumberBeforeChanges-1);app.TabGroup.Children(end)];
                % #.#. Updating variables that are associated to a tab index
                tabChange=[...
                    (1:numel(app.TabGroup.Children))',...
                    [1:tabNumber tabsNumberBeforeChanges:numel(app.TabGroup.Children)-1 tabNumber+1:tabsNumberBeforeChanges-1 numel(app.TabGroup.Children)]'];
                refreshTabVar(app,tabChange);
                % #.#. Updating tab names
                for tabNumberVar=tabNumber:numel(app.TabGroup.Children)
                    [nameSimu]=nameSimuFunction(app,tabNumberVar,[],[]);
                    app.TabGroup.Children(tabNumberVar).Title=nameSimu;
                end
            end
            
            % #. Waitbar
            if pathsNumber>1 ...
                    && ishandle(msg)
            delete(msg);
            end
            
            % #. Busy
            busyOff(app);

        end

        % Button pushed function: graph1Cancel
        function graph1CancelButtonPushed(app, event)
            % Abstract: resets the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=1;
            cancelGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph1Limits
        function graph1LimitsButtonPushed(app, event)
            % Abstract: resets the limits of the current graph
            
            busyOn(app);
            graphLimitsFunction(app,1);
            busyOff(app);
        end

        % Button pushed function: graph1Opts
        function graph1OptsButtonPushed(app, event)
            % Abstract: modifies the properties of the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=1;
            optionsGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph1Plot
        function graph1PlotButtonPushed(app, event)
            % Abstract: plots the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=1;
            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            displayGraphMain(app,[],tabNumber,info,'clear');
            busyOff(app);
        end

        % Button pushed function: graph1SameView
        function graph1SameViewPushed(app, event)
            % Abstract: identical views (X and Y limits) for all the GRAPHICS
            
            busyOn(app);
            tabNumber=findTab(app);
            propagateView(app,tabNumber,1);
            busyOff(app);
            
        end

        % Value changed function: graph1XList
        function graph1XListValueChanged(app, event)
            % Abstract: executes the X list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=1;
            graphXCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Value changed function: graph1YList
        function graph1YListValueChanged(app, event)
            % Abstract: executes the Y list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=1;
            graphYCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Button pushed function: graph2Cancel
        function graph2CancelButtonPushed(app, event)
            % Abstract: resets the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=2;
            cancelGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph2Limits
        function graph2LimitsButtonPushed(app, event)
            % Abstract: resets the limits of the current graph
            
            busyOn(app);
            graphLimitsFunction(app,2);
            busyOff(app);
        end

        % Button pushed function: graph2Opts
        function graph2OptsButtonPushed(app, event)
            % Abstract: modifies the properties of the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=2;
            optionsGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph2Plot
        function graph2PlotButtonPushed(app, event)
            % Abstract: plots the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=2;
            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            displayGraphMain(app,[],tabNumber,info,'clear');
            busyOff(app);
        end

        % Button pushed function: graph2SameView
        function graph2SameViewButtonPushed(app, event)
            % Abstract: identical views (X and Y limits) for all the GRAPHICS
            
            busyOn(app);
            tabNumber=findTab(app);
            propagateView(app,tabNumber,2);
            busyOff(app);
        end

        % Value changed function: graph2XList
        function graph2XListValueChanged(app, event)
            % Abstract: executes the X list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=2;
            graphXCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Value changed function: graph2YList
        function graph2YListValueChanged(app, event)
            % Abstract: executes the Y list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=2;
            graphYCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Button pushed function: graph3Cancel
        function graph3CancelButtonPushed(app, event)
            % Abstract: resets the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=3;
            cancelGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph3Limits
        function graph3LimitsButtonPushed(app, event)
            % Abstract: resets the limits of the current graph
            
            busyOn(app);
            graphLimitsFunction(app,3);
            busyOff(app);
        end

        % Button pushed function: graph3Opts
        function graph3OptsButtonPushed(app, event)
            % Abstract: modifies the properties of the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=3;
            optionsGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph3Plot
        function graph3PlotButtonPushed(app, event)
            % Abstract: plots the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=3;
            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            displayGraphMain(app,[],tabNumber,info,'clear');
            busyOff(app);
        end

        % Button pushed function: graph3sameView
        function graph3sameViewButtonPushed(app, event)
            % Abstract: identical views (X and Y limits) for all the GRAPHICS
            
            busyOn(app);
            tabNumber=findTab(app);
            propagateView(app,tabNumber,3);
            busyOff(app);
        end

        % Value changed function: graph3XList
        function graph3XListValueChanged(app, event)
            % Abstract: executes the X list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=3;
            graphXCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Value changed function: graph3YList
        function graph3YListValueChanged(app, event)
            % Abstract: executes the Y list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=3;
            graphYCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Button pushed function: graph4Cancel
        function graph4CancelButtonPushed(app, event)
            % Abstract: resets the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=4;
            cancelGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph4Limits
        function graph4LimitsButtonPushed(app, event)
            % Abstract: resets the limits of the current graph
            
            busyOn(app);
            graphLimitsFunction(app,4);
            busyOff(app);
        end

        % Button pushed function: graph4Opts
        function graph4OptsButtonPushed(app, event)
            % Abstract: modifies the properties of the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=4;
            optionsGraphFunction(app,tabNumber,graphNum);
            busyOff(app);
        end

        % Button pushed function: graph4Plot
        function graph4PlotButtonPushed(app, event)
            % Abstract: plots the current graph
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=4;
            info.selection=selectionInfo(app,tabNumber,graphNum,[],[]);
            displayGraphMain(app,[],tabNumber,info,'clear');
            busyOff(app);
        end

        % Button pushed function: graph4SameView
        function graph4SameViewButtonPushed(app, event)
            % Abstract: identical views (X and Y limits) for all the GRAPHICS
            
            busyOn(app);
            tabNumber=findTab(app);
            propagateView(app,tabNumber,4);
            busyOff(app);
        end

        % Value changed function: graph4XList
        function graph4XListValueChanged(app, event)
            % Abstract: executes the X list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=4;
            graphXCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Value changed function: graph4YList
        function graph4YListValueChanged(app, event)
            % Abstract: executes the Y list function
            
            busyOn(app);
            tabNumber=findTab(app);
            graphNum=4;
            graphYCallbackFunction(app,tabNumber,graphNum,'add');
            busyOff(app);
        end

        % Button pushed function: stepSB
        function graphicsBButtonPushed(app, event)
            % Abstract: changes the step of the plotted data
            % shift='XX' offset in %
            % shift='XXS' offset in step
            
            busyOn(app);
            tabNumber=findTab(app);
            stepForwardBackward(app,tabNumber,'slowBackward');
            busyOff(app);
        end

        % Button pushed function: stepFB
        function graphicsFBButtonPushed(app, event)
            % Abstract: changes the step of the plotted data
            % shift='XX' offset in %
            % shift='XXS' offset in step
            
            busyOn(app);
            tabNumber=findTab(app);
            stepForwardBackward(app,tabNumber,'fastBackward');
            busyOff(app);
        end

        % Button pushed function: stepSF
        function graphicsFButtonPushed(app, event)
            % Abstract: changes the step of the plotted data
            % shift='XX' offset in %
            % shift='XXS' offset in step
            
            busyOn(app);
            tabNumber=findTab(app);
            stepForwardBackward(app,tabNumber,'slowForward');
            busyOff(app);

        end

        % Button pushed function: stepFF
        function graphicsFFButtonPushed(app, event)
            % Abstract: changes the step of the plotted data
            % shift='XX' offset in %
            % shift='XXS' offset in step
            
            busyOn(app);
            tabNumber=findTab(app);
            stepForwardBackward(app,tabNumber,'fastForward');
            busyOff(app);

        end

        % Value changed function: stepRefr
        function graphicsGUIAnimValueChanged(app, event)
            % Abstract: starts or stops an animation from the GUI.
            % The animation starts from the current slider value and lasts
            % the input duration specified in the settings.
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber=findTab(app);
            
            % #. Animation
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Value
                if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value~=1 % Prevents an animation from starting if the last step is already selected
                    
                    % #. Inputs
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Text='•';
                    durationValue=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.durationAnimationDisplayNumber).Value;
                    minSlider=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value; maxSlider=1;
                    
                    % #. Animation
                    % #.#. Steps
                    tStart=tic;
                    while toc(tStart)<=durationValue
                        if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Value
                            % #.#. Starts
                            actualSlider=(toc(tStart)/durationValue)*(maxSlider-minSlider)+minSlider;
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=actualSlider;
                            sliderFunction(app,tabNumber);
                            drawnow; % Mandatory otherwise the graph is not updated dynamically.
                        else
                            % #.#. Stops
                            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Text='►';
                            busyOff(app);
                            return;
                        end
                    end
                    % #.#. Last step
                    % If the last step is not displayed because the time
                    % reaches the duration (and not because the animation
                    % has been stopped), the last step is displayed
                    if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Value ...
                            && actualSlider~=maxSlider
                    app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.graphicsSliderNumber).Value=maxSlider;
                    sliderFunction(app,tabNumber);
                    end
                end
            end
            
            % #. Refreshes the GUI
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Text='►';
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridGraphicsNumber).Children(app.id.gridGraphicsControlNumber).Children(app.id.stepRefrNumber).Value=0;
            
            % #. Busy
            busyOff(app);

        end

        % Value changed function: graphicsSlider
        function graphicsSliderValueChanged(app, event)
            % Abstract: changes the step of the plotted data
            
            busyOn(app);
            tabNumber=findTab(app);
            sliderFunction(app,tabNumber);
            busyOff(app);

        end

        % Button pushed function: hideBodiesButton
        function hideBodiesButtonPushed(app, event)
            % Abstract: hides certain bodies
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber = findTab(app);
            
            % #. Bodies already hidden
            if numel(app.bodiesToRemove)>=tabNumber ...
                    && ~isempty(app.bodiesToRemove)
            defaultBodies=app.bodiesToRemove{tabNumber};
            else
                defaultBodies=[];
            end
            
            % #. Selection
            app.bodiesToRemove{tabNumber}=bodiesSelection(app,tabNumber,defaultBodies,[],[]);
            
            % #. Refreshes graphs
            reloadGraph(app,tabNumber,'reset',{'SURFACE' 'IMAGEXYZ' 'IMAGE'});
            
            % #. Busy
            busyOff(app);
        end

        % Button pushed function: hideOverview
        function hideOverviewButtonPushed(app, event)
            % Abstract: hides or displays (by a shortcut) the overview panel
            
            busyOn(app);
            app.sliderOverview.Visible='off';
            app.changeOverview.Visible='off';
            app.optionOverview.Visible='off';
            app.hideOverview.Visible='off';
            app.stepSBOverview.Visible='off';
            app.stepFBOverview.Visible='off';
            app.stepSFOverview.Visible='off';
            app.stepFFOverview.Visible='off';
            busyOff(app);
        end

        % Button pushed function: loadGraphics
        function loadGraphicsButtonPushed(app, event)
            % Abstract: converts the selected steps into .mat
            
            % #. Busy
            busyOn(app);
            
            % #. Inputs
            tabNumber = findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            
            % #. File
            [~,~,dataType,dataFile,~,~,~,~,~,~,~]=dataMainListFunction(app,tabNumber,'Reset');
            indSteps=strcmpi(dataType,'STEPS');
            files=unique(dataFile(indSteps));

            % #. Steps
            [~,~,stepsUnion,~]=loadStepsListFunction(app,pathSimu,files,'CODE');

            %.#. Selection of the steps
            selectionList=[stepsUnion 'Current tab (periodically loaded)' 'All tabs (1 time)' 'All tabs (periodically loaded)' 'Enter paths (1 time)' 'Enter paths (periodically loaded)'];
            selectionInd=listdlg('PromptString',{'Select the steps that' 'you want to load:'},'Name','Load','ListString',selectionList,'InitialValue',1:numel(stepsUnion));
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            if isempty(selectionInd)
                busyOff(app); return;
            end
            
            % #. Wrong selection
            if numel(selectionInd)>1 && any(selectionInd>numel(selectionList))
                msgbox('Multiple selection is only available for steps.','Information','help'); return;
            end
            
            % #. Path simu
            if any(contains(selectionList(selectionInd),'All tabs'))
                % #.#. All tabs
                pathSimu=cell(1,numel(app.TabGroup.Children));
                for tabNumberVar=3:numel(app.TabGroup.Children)
                    pathSimu{tabNumberVar}=app.TabGroup.Children(tabNumberVar).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
                end
                pathSimu(1:2)=[];
            elseif any(contains(selectionList(selectionInd),'Enter paths'))
                % #.#. Enter paths
                prompt = {'Enter the paths (separated by a ;)'};
                dlgtitle = 'Enter paths'; fieldsize = [1 40]; definput = {app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(answer)
                busyOff(app); return;
                end
                pathSimu=erase(strsplit(answer{1},';'),' ');
            else
                % #.#. Current path
                pathSimu={app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value};
            end
            % #.#. Non-existing path
            indToDel=cellfun(@(x) exist(x,'dir'),pathSimu)==0;
            pathSimu(indToDel)=[];
            if isempty(pathSimu)
                busyOff(app); return;
            end

            % #. Types
            typeNamesCell=repmat({},1,numel(pathSimu));
            for pathNum=1:numel(pathSimu)
                [~,~,dataType,dataFile,~,~,~,~,~,~,~]=dataMainListFunction(app,pathSimu{pathNum},'Refresh');
                indSteps=strcmpi(dataType,'STEPS');
                typeNamesCell{pathNum}=unique(dataFile(indSteps));
            end
            
            % #. Processing
            if any(contains(selectionList(selectionInd),'periodically'))
                % #.#. Periodically loaded
                % #.#.#. Updating period
                inputsTimer=inputdlg({'Delay to start the loading [s]:','Enter the updating period [s]:'},'Input',[1 35],{'0',num2str(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.timeRefreshValueNumber).Value)});
                figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                if isempty(inputsTimer); return; end
                inputsTimer=str2double(inputsTimer);
                if any(isnan(inputsTimer)); msgbox('Invalid selection.','Information','help'); return; end
                % #.#.#. Msg
                msg=msgbox('Loading in progress... (press ok to abort)','Information','help');
                set(msg,'Deletefcn',@(~,~) deleteTimerloadGraphics(app,tabNumber));
                % #.#.#. Timer
                busyOff(app);
                t=timer('Name',['timerGUITab' num2str(tabNumber) 'Load'],...
                    'BusyMode','drop',...
                    'StartDelay',inputsTimer(1),...
                    'Period',inputsTimer(2),...
                    'ExecutionMode','fixedSpacing',...
                    'TimerFcn', @(~,~) loadGraphicsFunction(app,pathSimu,typeNamesCell,{},0));
                start(t);
            else
                % #.#. 1 time
                if all(selectionInd<=numel(stepsUnion))
                    % #.#.#. Selected steps
                    stepNamesCell={repmat({selectionList(selectionInd)},1,numel(typeNamesCell{1}))};
                    loadGraphicsFunction(app,pathSimu,typeNamesCell,stepNamesCell,1);
                else
                    % #.#.#. All steps
                    loadGraphicsFunction(app,pathSimu,typeNamesCell,{},1)
                end
                busyOff(app);
            end
        end

        % Button pushed function: savePath
        function loadPathButtonPushed(app, event)
            % Abstract: loads a simulation folder
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber=findTab(app);
            
            % #. Load
            loadPathFunctionMain(app,tabNumber);
            
            % #. Busy
            busyOff(app);

        end

        % Button pushed function: logButton
        function logButtonPushed(app, event)
            % Abstract: opens the log file
            
            % #. Busy
            busyOn(app);
            
            % #. Inputs
            tabNumber = findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathLog=[pathSimu filesep 'SAVE' filesep 'LOG_save.log'];
            
            % #. Processing
            if ~isempty(pathSimu)
                % #.#. Initialization
                if ~exist([pathSimu filesep 'SAVE'],'dir')
                    mkdir([pathSimu filesep 'SAVE']);
                end
                if ~isfile(pathLog)
                    LogSave({''},{''},'',pathLog);
                end
                % #.#. Opens the file
                openFile(app,pathLog);
            else
                % #.#. Empty path
                msgbox('Cannot open the log file if there is no simulation path.','Information','help');
            end
            
            % #. Busy
            busyOff(app);

        end

        % Button pushed function: openControl
        function openEditedFileButtonPushed(app, event)
            % Abstract: opens the edited file
            
            busyOn(app);
            tabNumber=findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            pathFile=[pathSimu filesep 'CODE' filesep app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.filesListButtonNumber).Value];
            if isfile(pathFile)
                openFile(app,pathFile);
            else
                msgbox('File not found','Warning','warn');
            end
            busyOff(app);

        end

        % Button pushed function: openPath
        function openPathPushed(app, event)
            % Abstract: opens the simulation folder
            
            % #. Busy
            busyOn(app);
            
            % #. Inputs
            tabNumber = findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            
            % #. Processing
            if ~isempty(pathSimu)
                if exist(pathSimu,'dir')
                    % #.#. Opens the path if it exists
                    openFile(app,pathSimu);
                else
                    % #.#. Path does not exist
                    msgbox('This path does not exist.','Information','help');
                    busyOff(app); return;
                end
            else
                % #.#. Empty path
                msgbox('No path to open.','Information','help');
                busyOff(app); return;
            end
            
            % #. Busy
            busyOff(app);

        end

        % Button pushed function: optionOverview
        function optionOverviewButtonPushed(app, event)
            % Abstract: options for axes in overview

            busyOn(app);
            optionsGraphFunction(app,[],app.overviewPanel);
            busyOff(app);

        end

        % Button pushed function: plotEndButton
        function plotEndButtonPushed(app, event)
            % Abstract: assigns the upper limit of the plot
            
            busyOn(app);
            tabNumber=findTab(app);
            plotBoundariesFunction(app,tabNumber,2);
            busyOff(app);

        end

        % Button pushed function: exportButton
        function plotExportButtonPushed(app, event)
            % Abstract: exports the selected data to a .mat file

            % #. Busy
            busyOn(app);

            % #. Message
            answer = questdlg({'What do you want to export?'},'Export','Graph','Data','Cancel','Cancel');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background

            % #. Processing
            if strcmp(answer,'Graph')
                % #.#. Graph
                exportGraphFunction(app);
            elseif strcmp(answer,'Data')
                % #.#. Data
                dataExportFunction(app)
            end

            % #. Busy
            busyOff(app);
        end

        % Button pushed function: plotStartButton
        function plotStartButtonPushed(app, event)
            % Abstract: assigns the lower limit of the plot
            
            busyOn(app);
            tabNumber=findTab(app);
            plotBoundariesFunction(app,tabNumber,1);
            busyOff(app);
        end

        % Button pushed function: refreshGraphs
        function refreshGraphicsButtonPushed(app, event)
            % Abstract: displays graphs with the latest available data
            
            busyOn(app);
            tabNumber=findTab(app);
            refreshGraphicsFunction(app,tabNumber);
            busyOff(app);
        end

        % Button pushed function: refreshStepsButton
        function refreshStepButtonButtonPushed(app, event)
            % Abstract: refreshes the step list and some GUI components
            
            % #. Busy
            busyOn(app);
            
            % #. Local update
            tabNumber = findTab(app);
            refreshSteps(app,tabNumber,1);
            
            % #. Cluster update
            if ~strcmpi(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.runModeListNumber).Value,'local')
                
                % #.#. Information
                s_conn=clusterLoadInfo(app,tabNumber,[]);
                if isempty(s_conn)
                    busyOff(app); return;
                end
                
                % #.#. Processing
                clusterRefresh(app,tabNumber,s_conn);
                
                % #.#. Closing
                ssh2_close(s_conn);
                clearvars('s_conn');
            end
            
            % #. Busy
            busyOff(app);
        end

        % Button pushed function: regressionButton
        function regressionButtonButtonPushed(app, event)
            % Abstract: runs the duration calculation with
            % potentially a new value for the regression order
            
            busyOn(app);
            tabNumber = findTab(app);
            clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber),'timeGraph');
            timeSaveMainDisplay(app,tabNumber); % Displays time values
            busyOff(app);
        end

        % Button pushed function: infoButton
        function rulerButtonPushed(app, event)
            % Abstract: gives information about a graphical selection
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber=findTab(app);
            
            % #. Find the first non-empty surface graph
            for graphNum=1:4
                graphNumFunction(app, graphNum);
                if ~isempty(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children)
                    if isprop(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children(1),'Vertices')
                        break;
                    end
                end
                if graphNum==4
                    msgbox('Unable to find a compatible graph.','Information','help');
                    busyOff(app);
                    return;
                end
            end
            
            % #. Selection of the nodes
            [body,nodeGlob,nodeLoc]=graphSelection(app,tabNumber,[],[],[]);
            if isempty(body) || isempty(nodeGlob) || isempty(nodeLoc)
                busyOff(app); return;
            end
            
            % #. Figure
            fig=uifigure('Name','Ruler','Units','Normalized','Position',[0.3 0.1 0.4 0.8],'ToolBar','none','Color','w');
            posFig=ObjectPosition(fig,'Units','Pixels','Type','Position');
            uieditfield(fig,'Numeric','Position',[0 0.97 0.5 0.03].*posFig([3 4 3 4]),'ToolTip','Enter a value between 0 (smooth) and 1 (sharp) to refresh the contour.','Value',0.8,'Tag','rulerContourCoef');
            uibutton(fig,'Push','Position',[0.5 0.97 0.5 0.03].*posFig([3 4 3 4]),'Text','Refresh','fontweight','bold','fontsize',12,'ButtonPushedFcn',@(contourButton,event) infoFunction(app,fig));
            ax=uiaxes(fig,'Units','Normalized','Position',[0 0.5 1 0.47],'Tag','rulerContourAxes');
            textBox=uitextarea(fig,'Position',[0 0 1 0.5].*posFig([3 4 3 4]),'Editable','off','Tag','rulerContourText');
            
            % #. Data
            graphNumFunction(app,graphNum);
            x=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children(1).Vertices(nodeGlob,1); % This method may have some issues with multigrounds graph
            y=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridGraphNumber).Children(app.id.graphNumberVar).Children(1).Vertices(nodeGlob,2); % This method may have some issues with multigrounds graph
            
            % #. Initialization
            msg=cell(1,100); k=0;
            
            % #. General information
            k=k+1; msg{k}='#. Number';
            k=k+1; msg{k}=['Number of nodes: ' num2str(length(nodeGlob))];
            k=k+1; msg{k}=' ';
            
            if numel(nodeGlob)<3
                
                % #. Less than 3 nodes
                for nodeNum=1:numel(nodeGlob)
                    % #.#. Coordinates
                    k=k+1; msg{k}=['#. Node ' num2str(nodeNum) ':'];
                    k=k+1; msg{k}=['Body: ' num2str(body(nodeNum))];
                    k=k+1; msg{k}=['Global node index*: ' num2str(nodeGlob(nodeNum))];
                    k=k+1; msg{k}=['Local node index*: ' num2str(nodeLoc(nodeNum))];
                    k=k+1; msg{k}=['X coordinate: ' num2str(x(nodeNum))];
                    k=k+1; msg{k}=['Y coordinate: ' num2str(y(nodeNum))];
                    k=k+1; msg{k}=' ';
                    % #.#. Plots the nodes
                    plot(ax,x(nodeNum),y(nodeNum),'rx','DisplayName',['Node ' num2str(nodeNum)]); hold(ax,'on');
                end
                % #.#. Plots the segment between the nodes
                plot(ax,x,y,'b--','HandleVisibility','off');
                axis(ax,'equal'); grid(ax,'on'); xlabel(ax,'X'); ylabel(ax,'Y'); legend(ax);
                
            else
                
                % #. More than 2 nodes
                plot(ax,x,y,'rx','DisplayName','Selected nodes'); hold(ax,'on'); axis(ax,'equal'); grid(ax,'on'); xlabel(ax,'X'); ylabel(ax,'Y'); legend(ax);
                
            end
            
            if numel(nodeGlob)==2
                
                % #. 2 nodes: distance between them
                % #.#. Calculation
                x1=x(2); x0=x(1); y1=y(2); y0=y(1);
                xDistance=abs(x1-x0); yDistance=abs(y1-y0);
                distance=sqrt(xDistance^2 + yDistance^2);
                % #.#.#. Distance
                k=k+1; msg{k}='#. Distance';
                k=k+1; msg{k}=['X distance: ' num2str(xDistance)];
                k=k+1; msg{k}=['Y distance: ' num2str(yDistance)];
                k=k+1; msg{k}=['Total distance: ' num2str(distance)];
                k=k+1; msg{k}=' ';
                % #.#.#. Mid-distance
                k=k+1; msg{k}='#. Mid-distance';
                k=k+1; msg{k}=['X mid-coordinate: ' num2str((x0+x1)/2)];
                k=k+1; msg{k}=['Y mid-coordinate: ' num2str((y0+y1)/2)];
                k=k+1; msg{k}=['X mid-distance: ' num2str(xDistance/2)];
                k=k+1; msg{k}=['Y mid-distance: ' num2str(yDistance/2)];
                k=k+1; msg{k}=['Total mid-distance: ' num2str(distance/2)];
                k=k+1; msg{k}=' ';
                
            elseif numel(nodeGlob)>2
                
                % #. More than 2 nodes: extremal coordinates
                k=k+1; msg{k}='#. Min coordinates';
                k=k+1; msg{k}=['X min coordinate: ' num2str(min(x))];
                k=k+1; msg{k}=['Y min coordinate: ' num2str(min(y))];
                k=k+1; msg{k}=' ';
                k=k+1; msg{k}='#. Max coordinates';
                k=k+1; msg{k}=['X max coordinate: ' num2str(max(x))];
                k=k+1; msg{k}=['Y max coordinate: ' num2str(max(y))];
                k=k+1; msg{k}=' ';
                k=k+1; msg{k}='#. Mean coordinates';
                k=k+1; msg{k}=['X mean coordinate: ' num2str(mean(x))];
                k=k+1; msg{k}=['Y mean coordinate: ' num2str(mean(y))];
                k=k+1; msg{k}=' ';
                k=k+1; msg{k}='#. Median coordinates';
                k=k+1; msg{k}=['X median coordinate: ' num2str(median(x))];
                k=k+1; msg{k}=['Y median coordinate: ' num2str(median(y))];
                k=k+1; msg{k}=' ';
                k=k+1; msg{k}='#. Contours';
                
            end
            
            % #. Information
            if numel(nodeGlob)==2
                k=k+1; msg{k}='*: first node index is equal to 1 (i.e.~=0)';
                k=k+1; msg{k}='Global node index takes as reference the first node of the first body';
                k=k+1; msg{k}='Local node index takes as reference the first node of the corresponding body';
            end
            
            % #. Assignment
            msg(k+1:end)=[];
            textBox.Value=msg;
            
            % #. Contour
            infoFunction(app,fig); % An external function is necessary because the contour can be recalculated without recreating a figure
            
            % #. Busy
            busyOff(app);
        end

        % Value changed function: runButton
        function runButtonValueChanged(app, event)
            % Abstract: runs or stops the main computation program
            
            busyOn(app);
            tabNumber=findTab(app);
            runFunction(app,tabNumber,1);
            busyOff(app);

        end

        % Button pushed function: saveControl
        function saveControlButtonPushed(app, event)
            % Abstract: Saves the changes made to the CONTROL file
            
            % #. Busy
            busyOn(app);
            
            % #. New text
            tabNumber=findTab(app);
            processingEditedText(app,tabNumber,1);
            
            % #. Color update
            load([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave'); % Index is loaded because if the text is modified and an other tab is directly selected, it will be the color of the last selected tab that will be changed
            app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;
            
            % #. Busy
            busyOff(app);
        end

        % Value changed function: sectionsListButton
        function sectionsListButtonValueChanged(app, event)
            % Abstract: updates text color

            % #. Busy
            busyOn(app);
            
            % #. New text
            tabNumber=findTab(app);
            processingEditedText(app,tabNumber,0);
            
            % #. Color update
            load([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave'); % Index is loaded because if the text is modified and an other tab is directly selected, it will be the color of the last selected tab that will be changed
            app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorUnchanged;
            
            % #. Busy
            busyOff(app);
            
        end

        % Value changed function: settings
        function settingsValueChanged(app, event)
            % Abstract: displays the settings panel and potentially refreshes the GUI
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber = findTab(app);
            
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.settingsNumber).Value
                
                % #. Opens the settings
                
                % #.#. Displays the panel
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Visible='on';
                % #.#. Saves the current settings
                % Allows to determine which setting is changed when the settings are closed
                app.sett{tabNumber}=struct();
                for compNum=1:size(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children,1)
                    if isprop(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum),'Value')
                        app.sett{tabNumber}.(app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Tag)...
                            ={app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(compNum).Value}; % Cell is used because sizes are non-uniforms (e.g. 1, 'turbo', etc.)
                    end
                end
                
            else
                
                % #. Closes the settings
                
                % #.#. Hides the panel
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Visible='off';
                % #.#. Processing
                settingsSave(app,tabNumber); % SAVE + apply to all tabs + refreshes the GUI
                app.sett{tabNumber}=struct(); % Allows the function to be called without opening the panel
                
            end
            
            % #. Busy
            busyOff(app);

        end

        % Button pushed function: shortcutsListButton
        function shortcutsListButtonPushed(app, event)
            % Abstract: list of shortcuts
            
            % #. Busy
            busyOn(app);
            
            % #. List
            text={...
                '#. Tabs :'
                'Alt + +: add a new tab'
                'Alt + BACKSPACE: close the current tab'
                'Alt + LEFT: select the tab to the left'
                'Alt + RIGHT: select the tab to the right'
                ''
                '#. SAVE:'
                'Alt + F: open the simulation folder'
                'Alt + C: open the CODE folder'
                'Alt + A: open the ANIMATIONS folder'
                'Alt + G: get a simulation folder'
                ''
                '#. EDITOR:'
                'Alt + O: open the edited file'
                ''
                '#. STEPS:'
                'Alt + E: open the last STEP'
                ''
                '#. Cluster:'
                'Alt + Q: open the queue list (computations that are running on the cluster)'
                'Alt + T: open the file transfer manager'
                ''
                '#. GRAPHS:'
                'Alt + V: apply the same view to all the graphs (view of the first GRAPHIC graph that is found). Note that it works in the overview tab.'
                'Alt + L: load GRAPHICS (convert .vtk into .mat)'
                'Alt + ENTER: playing an animation'
                'Alt + DOWN: move backward'
                'Alt + UP: move forward'
                'Alt + F1/1/2/3/4/5/6/7/8/9/0: slider will respectively take the value of 0, 0.1, etc. up to 1 (F1 is used because ² is not detected). Note that it works in the overview tab.'
                'Alt + F5: plot the latest graphs'
                'Alt + F12: export a graph in an independent figure'
                ''
                '#. Other:'
                'Alt + U: open the user guide'
                'Alt + I: open the list of shortcuts'
                'Alt + P: open the parameters/settings panel'
                'Alt + H: hide or display the overview panel'};
            
            % #. Figure
            fig=uifigure('Units','Normalized','Position',[0.2 0.1 0.6 0.8],'Name','Shortcuts');
            set(fig,'Units','Pixels');
            uitextarea(fig,'Position',[0 0 fig.Position(3:4)],'Editable','off','Value',text);
            
            % #. Busy
            busyOff(app);

        end

        % Value changed function: sliderOverview
        function sliderOverviewValueChanged(app, event)
            % Abstract: updates the overview graphs
            
            busyOn(app);
            overViewStepChange(app,'slider');
            busyOff(app);
            
        end

        % Button pushed function: stepOpen
        function stepOpenButtonPushed(app, event)
            % Abstract: opens step files
            
            % #. Inputs
            busyOn(app);
            tabNumber=findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            step=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Value;
            % #. Files
            stepFiles=dir([pathSimu filesep 'CODE']);
            stepFilesList={stepFiles.name};
            stepFilesList(~contains(stepFilesList,step))=[];
            % #. Selection
            ind=listdlg('PromptString',{'Select the file to open'},'ListString',stepFilesList);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            % #. Opening
            for fileNum=1:numel(ind)
                openFile(app,[pathSimu filesep 'CODE' filesep stepFilesList{ind(fileNum)}]);
            end
            busyOff(app);

        end

        % Button pushed function: stepSFOverview
        function stepSFOverviewButtonPushed(app, event)
            % Abstract: updates the overview graphs
            
            busyOn(app);
            overViewStepChange(app,'slowForward');
            busyOff(app);

        end

        % Button pushed function: stepFFOverview
        function stepFFOverviewButtonPushed(app, event)
            % Abstract: updates the overview graphs
            
            busyOn(app);
            overViewStepChange(app,'fastForward');
            busyOff(app);

        end

        % Button pushed function: stepSBOverview
        function stepSBOverviewButtonPushed(app, event)
            % Abstract: updates the overview graphs
            
            busyOn(app);
            overViewStepChange(app,'slowBackward');
            busyOff(app);

        end

        % Button pushed function: stepFBOverview
        function stepFBOverviewButtonPushed(app, event)
            % Abstract: updates the overview graphs
            
            busyOn(app);
            overViewStepChange(app,'fastBackward');
            busyOff(app);

        end

        % Value changed function: stepsList
        function stepsListValueChanged(app, event)
            % Abstract: refreshes some GUI components
            
            busyOn(app);
            tabNumber = findTab(app);
            stepListValueChangedFunction(app,tabNumber);
            busyOff(app);

        end

        % Button pushed function: stopCancel
        function stopCancelButtonPushed(app, event)
            % Abstract: deletes the criterion equation
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber=findTab(app);
            
            % #. Stops the calculation of the criterion if a refresh is in progress
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value ...
                    && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value=0;
            end
            
            % #. Deletes the equation
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Value...
                =app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Items{1};
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value='';
            
            % #. Busy
            busyOff(app);
        end

        % Value changed function: stopCriterionList
        function stopCriterionListValueChanged(app, event)
            % Abstract: adds a data for the calculation of the criterion
            
            % #. Busy
            busyOn(app);
            
            % #. Information
            tabNumber=findTab(app);
            pathSimu=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.SAVEPanelNumber).Children(app.id.gridSaveNumber).Children(app.id.displayPathNumber).Value;
            
            % #. Stops the calculation of the criterion if a refresh is in progress
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value ...
                    && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value=0;
            end
            
            % #. Data to add
            % #.#. Name
            dataToAdd=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCriterionListNumber).Value;
            if contains(dataToAdd,'%'); busyOff(app); return; end
            % #.#. Type
            [dataList,~,dataType,dataFile]=dataMainListFunction(app,tabNumber,'Refresh');
            ind=find(strcmp(dataList,dataToAdd),1,'first');
            type=dataType{ind};

            % #. Format
            if strcmpi(type,'CUSTOM')
                % #. CUSTOM
                customData=DataManager([],[],[]);
                indCustomData=strcmp(customData(:,1),dataToAdd);
                dataToAdd=customData{indCustomData,3};
            else
                % #. Data
                dataToAdd={dataToAdd};
            end

            % #. Add the step if applicable
            for dataNum=1:numel(dataToAdd)
                ind=find(strcmp(dataList,dataToAdd{dataNum}),1,'first');
                if isempty(ind); continue; end
                type=dataType{ind};
                if strcmpi(type,'STEPS')
                    file=dataFile{ind};
                    steps=loadStepsListFunction(app,pathSimu,file,'CODE');
                    if ~isempty(steps)
                        dataToAdd{dataNum}=[dataToAdd{dataNum} '%' steps{1}];
                    end
                end
            end

            % #. Processing
            dataToAdd=strjoin(strcat('[',dataToAdd,']'));
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value=...
                [app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopEqNumber).Value dataToAdd];

            % #. Busy
            busyOff(app);
        end

        % Value changed function: stopEq
        function stopEqValueChanged(app, event)
            % Abstract: when the equation is changed, stops the calculation of
            % the criterion if a refresh is in progress
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber=findTab(app);
            
            % #. Processing
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.autoRefreshNumber).Value ...
                    && app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value
            app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridButtonsNumber).Children(app.id.gridPlotNumber).Children(app.id.stopCheckNumber).Value=0;
            end
            
            % #. Busy
            busyOff(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Abstract: operations when a new tab is selected
            
            % #. Busy
            busyOn(app);
            
            % #. Input
            tabNumber = findTab(app);
            
            % #. Resets the overview
            load([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave');
            if tabNumberSave==2 % Mandatory only if the overview was selected before the current tab
                delete(app.overviewPanel.Children);
            end
            
            % #. Saves the current tab index
            % Useful for several operations (when an operation must be made on the previous tab)
            drawnow; % Otherwise the tab index may not be up to date
            tabNumberSave=tabNumber;
            save([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave');
            
            if tabNumber==1
                
                % #. Tabs creation
                
                % #.#. Number of tabs to create
                app.addCount=app.addCount+1; % Number of successive clicks
                if app.addCount>1
                    % #.#.#. Adds N tabs
                    % If at least 2 successive clicks
                    numTabs = inputdlg({'Enter the number of tabs to add:'},'',1,{'1'});
                    figure(app.mainUIFigure); % otherwise the interface is sent back to the background
                    numTabs=str2double(numTabs{1});
                    if isnan(numTabs)
                        busyOff(app); return;
                    elseif numTabs<1
                        busyOff(app);  return;
                    end
                else
                    % #.#.#. Adds 1 tab
                    numTabs=1;
                end
                % #.#. Addition of tabs
                for tabNumberVar=1:numTabs
                    tabCreation(app);
                end
                % #.#. New current tab
                tabNumberSave=numel(app.TabGroup.Children)-numTabs+1;
                app.TabGroup.SelectedTab=app.TabGroup.Children(tabNumberSave);
                save([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave');
                
            elseif tabNumber==2
                
                % #. Overview
                
                % #.#. Resets successive clicks
                app.addCount=0;
                % #.#. Displays the slider panel
                app.sliderOverview.Visible='on';
                app.changeOverview.Visible='on';
                app.optionOverview.Visible='on';
                app.hideOverview.Visible='on';
                app.stepSBOverview.Visible='on';
                app.stepFBOverview.Visible='on';
                app.stepSFOverview.Visible='on';
                app.stepFFOverview.Visible='on';
                % #.#. Importation of the graphs
                overViewFunction(app,app.overviewPanel,[],[]);
                
            else
                
                % #. Other
                
                % #.#. Resets successive clicks
                app.addCount=0;
                
            end
            
            % #. Busy
            busyOff(app);

        end

        % Button pushed function: tabMove
        function tabMoveButtonPushed(app, event)
            % Abstract: deletes a tab oe changes the order of the tabs
            
            % #. Busy
            busyOn(app);

            % #. Message
            answer = questdlg({'What do you want to do with this tab?'},'Tab','Delete it','Moving it','Cancel','Cancel');
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background

            % #. Processing
            if strcmp(answer,'Delete it')
                % #.#. Deletes the tab
                deleteTabFunction(app);
            elseif strcmp(answer,'Moving it')
                % #.#. Moves the tab
                moveTabsFunction(app);
            end

            % #. Busy
            busyOff(app);

        end

        % Value changed function: textControl
        function textControlValueChanged(app, event)
            % Abstract: changes the text color when it is modified
            
            busyOn(app);
            load([app.pwdSave filesep 'Save' filesep 'tab_save'],'tabNumberSave'); % Index is loaded because if the text is modified and an other tab is directly selected, it will be the color of the last selected tab that will be changed
            app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.textControlNumber).FontColor=app.fontColorChanged;
            %app.TabGroup.Children(tabNumberSave).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.EDITORPanelNumber).Children(app.id.gridControlNumber).Children(app.id.sectionsListButtonNumber).FontColor=app.fontColorChanged;
            busyOff(app);

        end

        % Value changed function: time
        function timeValueChanged(app, event)
            % Abstract: runs the duration calculation of the simulation
            
            % #. Busy
            busyOn(app);
            
            % #. tabNumber
            tabNumber = findTab(app);
            
            if app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.timeNumber).Value
                % #. Runs the calculation and displays the time panel
                clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber),'timeGraph');
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Visible='on';
                refreshSteps(app,tabNumber,1);
                steps=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items;
                timeSaveWritter(app,tabNumber,steps,0,0); % Updates time values
                timeSaveMainDisplay(app,tabNumber); % Displays time values
            else
                % #. Hides the time panel
                app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber).Visible='off';
                clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber),'timeGraph');
            end
            
            % #. Busy
            busyOff(app);
        end

        % Button pushed function: updateTimeSave
        function updateTimeSaveButtonPushed(app, event)
            % Abstract: selects the steps that have been calculated
            % successively and runs the duration calculation
            
            % #. Busy
            busyOn(app);
            
            % #. Inputs
            tabNumber=findTab(app);
            stepList=['Automatic detection' app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridProcessingNumber).Children(app.id.STEPSPanelNumber).Children(app.id.gridStepsNumber).Children(app.id.stepsListNumber).Items];
            
            % #. Selection of steps
            % #.#. DLG
            [indSteps,~] = listdlg('PromptString',{'Select the steps'  'computed successively:'},'ListString',stepList);
            figure(app.mainUIFigure); % otherwise the interface is sent back to the background
            % #.#. Invalid selection
            if isempty(indSteps)
                busyOff(app); return;
            elseif numel(indSteps)<2 && ~any(indSteps==1)
                msgbox('At least 2 steps are needed to find a computing speed.','Information','help');
                busyOff(app);
                return;
            end
            
            % #. Mode
            if any(indSteps==1)
                % #.#. Automatic detection
                % Uses the generalized extreme Studentized deviate method
                % to detect the successive steps. It gives very good results.
                autoMode=1;
                indSteps=2:numel(stepList);
            else
                % #.#. Manual detection
                autoMode=0;
            end
            
            % #. All the steps must succeed each other
            stepListNum=cellfun(@str2num,stepList(indSteps));
            delta=stepListNum(1:end-1)-stepListNum(2:end);
            if any(delta<0)
                msgbox('Steps must succeed each other.','Information','help'); busyOff(app); return;
            end
            
            % #. Duration calculation
            timeSaveWritter(app,tabNumber,stepList(indSteps),1,autoMode); % Updates time values
            clearGraph(app,app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridTimeNumber),'timeGraph');
            timeSaveMainDisplay(app,tabNumber) % Displays time values
            
            % #. Busy
            busyOff(app);
        end

        % Button pushed function: userGuideButton
        function userGuideButtonPushed(app, event)
            % Abstract: opens the GUI user guide

            busyOn(app);
            dirGuide=dir([app.pwdSave filesep 'UserGuide']);
            indGuide=find((contains(lower({dirGuide.name}),'user') | contains(lower({dirGuide.name}),'guide')) ...
                & (contains(lower({dirGuide.name}),'.txt') | contains(lower({dirGuide.name}),'.pdf')),1,'last'); % Allows to update the user guide easily
            if ~isempty(indGuide)
                openFile(app,[app.pwdSave filesep 'UserGuide' filesep dirGuide(indGuide).name]);
            end
            busyOff(app);

        end

        % Button pushed function: userGuidePackageButton
        function userGuidePackageButtonPushed(app, event)
            % Abstract: opens the package user guide
            
            busyOn(app);
            tabNumber=findTab(app);
            selectedPackage=app.TabGroup.Children(tabNumber).Children(app.id.gridTabNumber).Children(app.id.gridSettingsNumber).Children(app.id.packageModeListNumber).Value;
            dirGuide=dir([app.pwdSave filesep 'Packages' filesep selectedPackage]);
            indGuide=find((contains(lower({dirGuide.name}),'user') | contains(lower({dirGuide.name}),'guide')) ...
                & (contains(lower({dirGuide.name}),'.txt') | contains(lower({dirGuide.name}),'.pdf')),1,'last'); % Allows to update the user guide easily
            if ~isempty(indGuide)
                openFile(app,[app.pwdSave filesep 'Packages' filesep  selectedPackage filesep dirGuide(indGuide).name]);
            end
            busyOff(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainUIFigure = uifigure('Visible', 'off');
            app.mainUIFigure.Name = 'ModLab';
            app.mainUIFigure.Icon = 'icon.png';
            app.mainUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.TabGroup = uitabgroup(app.mainUIFigure);
            app.TabGroup.Tooltip = {''};
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Tooltip = {''};
            app.Tab.Title = '   [+]';
            app.gridTab = uigridlayout(app.Tab);
            app.gridTab.Tag = 'gridTab';
            app.gridGraph = uigridlayout(app.gridTab);
            app.gridGraph.Tag = 'gridGraph';
            app.graph4 = uiaxes(app.gridGraph);
            app.graph4.Tag = 'graph4';
            app.graph4.Visible = 'off';
            app.graph3 = uiaxes(app.gridGraph);
            app.graph3.Tag = 'graph3';
            app.graph3.Visible = 'off';
            app.graph2 = uiaxes(app.gridGraph);
            app.graph2.Tag = 'graph2';
            app.graph2.Visible = 'off';
            app.graph1 = uiaxes(app.gridGraph);
            app.graph1.Tag = 'graph1';
            app.graph1.Visible = 'off';
            app.maskingPanel = uiaxes(app.gridGraph);
            app.maskingPanel.XLimitMethod = 'tight';
            app.maskingPanel.YLimitMethod = 'tight';
            app.maskingPanel.ZLimitMethod = 'tight';
            app.maskingPanel.XTick = [];
            app.maskingPanel.YTick = [];
            app.maskingPanel.ZTick = [];
            app.maskingPanel.Tag = 'maskingPanel';
            app.maskingPanel.Visible = 'off';
            app.graph5 = uiaxes(app.gridGraph);
            app.graph5.Tag = 'graph5';
            app.graph5.Visible = 'off';
            app.gridContinue = uigridlayout(app.gridGraph);
            app.gridContinue.Tag = 'gridContinue';
            app.gridContinue.Visible = 'off';
            app.continueFullScreen = uibutton(app.gridContinue, 'state');
            app.continueFullScreen.Tag = 'continueFullScreen';
            app.continueFullScreen.Tooltip = {'Tip: you can propagate the view of another graph by pressing the dedicated button or by using the shortcut (Alt +V)'};
            app.continueFullScreen.Text = 'Continue';
            app.cancelFullScreen = uibutton(app.gridContinue, 'state');
            app.cancelFullScreen.ValueChangedFcn = createCallbackFcn(app, @cancelFullScreenValueChanged, true);
            app.cancelFullScreen.Tag = 'cancelFullScreen';
            app.cancelFullScreen.Tooltip = {'Tip: you can propagate the view of another graph by pressing the dedicated button or by using the shortcut (Alt +V)'};
            app.cancelFullScreen.Text = 'Cancel';
            app.gridProcessing = uigridlayout(app.gridTab);
            app.gridProcessing.Tag = 'gridProcessing';
            app.COMPUTATIONPanel = uipanel(app.gridProcessing);
            app.COMPUTATIONPanel.Title = 'COMPUTATION';
            app.COMPUTATIONPanel.Tag = 'STEPSPanel';
            app.gridSteps = uigridlayout(app.COMPUTATIONPanel);
            app.gridSteps.Tag = 'gridSteps';
            app.clusterInfo = uibutton(app.gridSteps, 'push');
            app.clusterInfo.ButtonPushedFcn = createCallbackFcn(app, @clusterInfoButtonPushed, true);
            app.clusterInfo.Tag = 'clusterInfo';
            app.clusterInfo.Tooltip = {'Give information about the simulations on the cluster (Alt+Q) or propose to open the file transfer manager (Alt+T).'; ''; 'For external transfer software, remember to activate the “preserve timestamps” option to be able to determine computation speed.'};
            app.clusterInfo.Text = 'cluster';
            app.runButton = uiswitch(app.gridSteps, 'slider');
            app.runButton.ValueChangedFcn = createCallbackFcn(app, @runButtonValueChanged, true);
            app.runButton.Tag = 'runButton';
            app.runButton.Tooltip = {'Computation'; ''; 'Colored in green if a folder is found in the selected remote server.'};
            app.displayTime = uilabel(app.gridSteps);
            app.displayTime.Tag = 'displayTime';
            app.displayTime.Tooltip = {''};
            app.displayTime.Text = '';
            app.time = uibutton(app.gridSteps, 'state');
            app.time.ValueChangedFcn = createCallbackFcn(app, @timeValueChanged, true);
            app.time.Tag = 'time';
            app.time.Tooltip = {'Open the duration panel'; ''; 'Compute and display computing speed'};
            app.time.Text = 'duration';
            app.refreshStepsButton = uibutton(app.gridSteps, 'push');
            app.refreshStepsButton.ButtonPushedFcn = createCallbackFcn(app, @refreshStepButtonButtonPushed, true);
            app.refreshStepsButton.Tag = 'refreshStepsButton';
            app.refreshStepsButton.Tooltip = {'Refresh steps'};
            app.refreshStepsButton.Text = 'refresh';
            app.stepOpen = uibutton(app.gridSteps, 'push');
            app.stepOpen.ButtonPushedFcn = createCallbackFcn(app, @stepOpenButtonPushed, true);
            app.stepOpen.Tag = 'stepOpen';
            app.stepOpen.Tooltip = {'Open files associated with the current step (Alt+E to open the last)'};
            app.stepOpen.Text = 'open';
            app.stepsList = uidropdown(app.gridSteps);
            app.stepsList.Items = {''};
            app.stepsList.ValueChangedFcn = createCallbackFcn(app, @stepsListValueChanged, true);
            app.stepsList.Tag = 'stepsList';
            app.stepsList.Tooltip = {'List of steps'};
            app.stepsList.Value = '';
            app.EDITORPanel = uipanel(app.gridProcessing);
            app.EDITORPanel.Title = 'EDITOR';
            app.EDITORPanel.Tag = 'EDITORPanel';
            app.gridControl = uigridlayout(app.EDITORPanel);
            app.gridControl.Tag = 'gridControl';
            app.saveControl = uibutton(app.gridControl, 'push');
            app.saveControl.ButtonPushedFcn = createCallbackFcn(app, @saveControlButtonPushed, true);
            app.saveControl.Tag = 'saveControl';
            app.saveControl.Tooltip = {'Save modifications'};
            app.saveControl.Text = 'save';
            app.textControl = uitextarea(app.gridControl);
            app.textControl.ValueChangedFcn = createCallbackFcn(app, @textControlValueChanged, true);
            app.textControl.Tag = 'textControl';
            app.textControl.Tooltip = {''};
            app.openControl = uibutton(app.gridControl, 'push');
            app.openControl.ButtonPushedFcn = createCallbackFcn(app, @openEditedFileButtonPushed, true);
            app.openControl.Tag = 'openControl';
            app.openControl.Tooltip = {'Open selected file (Alt+O)'};
            app.openControl.Text = 'open';
            app.filesListButton = uidropdown(app.gridControl);
            app.filesListButton.Items = {''};
            app.filesListButton.ValueChangedFcn = createCallbackFcn(app, @filesListButtonValueChanged, true);
            app.filesListButton.Tag = 'filesListButton';
            app.filesListButton.Tooltip = {'List of files'};
            app.filesListButton.Value = '';
            app.sectionsListButton = uidropdown(app.gridControl);
            app.sectionsListButton.Items = {''};
            app.sectionsListButton.ValueChangedFcn = createCallbackFcn(app, @sectionsListButtonValueChanged, true);
            app.sectionsListButton.Tag = 'sectionsListButton';
            app.sectionsListButton.Tooltip = {'List of sections'; ''; 'Defined according to the key entered in the settings.'};
            app.sectionsListButton.Value = '';
            app.clearSection = uibutton(app.gridControl, 'push');
            app.clearSection.ButtonPushedFcn = createCallbackFcn(app, @clearSectionButtonPushed, true);
            app.clearSection.Tag = 'clearSection';
            app.clearSection.Tooltip = {'Reset selection'};
            app.clearSection.Text = 'x';
            app.TOOLSPanel = uipanel(app.gridProcessing);
            app.TOOLSPanel.Title = 'TOOLS';
            app.TOOLSPanel.Tag = 'TOOLSPanel';
            app.gridTools = uigridlayout(app.TOOLSPanel);
            app.gridTools.Tag = 'gridTools';
            app.gridTools.Tooltip = {'Tools'; ''; 'Opens readme file if selected, executes tool if checked.'; ''; 'Refer to the user guide to add tools.'};
            app.DIRECTORYPanel = uipanel(app.gridProcessing);
            app.DIRECTORYPanel.Title = 'DIRECTORY';
            app.DIRECTORYPanel.Tag = 'SAVEPanel';
            app.gridSave = uigridlayout(app.DIRECTORYPanel);
            app.gridSave.Tag = 'gridSave';
            app.tabMove = uibutton(app.gridSave, 'push');
            app.tabMove.ButtonPushedFcn = createCallbackFcn(app, @tabMoveButtonPushed, true);
            app.tabMove.Tag = 'tabMove';
            app.tabMove.Tooltip = {'Delete (Alt+BACKSPACE) or move the tab'};
            app.tabMove.Text = '↔';
            app.settings = uibutton(app.gridSave, 'state');
            app.settings.ValueChangedFcn = createCallbackFcn(app, @settingsValueChanged, true);
            app.settings.Tag = 'settings';
            app.settings.Tooltip = {'Open the settings panel (Alt+P)'};
            app.settings.Text = '⌂';
            app.logButton = uibutton(app.gridSave, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.logButton.Tag = 'logButton';
            app.logButton.Tooltip = {'Open the log file'; ''; 'This file lists all the modifications made to the simulation files.'};
            app.logButton.Text = 'log';
            app.savePath = uibutton(app.gridSave, 'push');
            app.savePath.ButtonPushedFcn = createCallbackFcn(app, @loadPathButtonPushed, true);
            app.savePath.Tag = 'savePath';
            app.savePath.Tooltip = {'Load the simulation folder'};
            app.savePath.Text = 'load';
            app.openPath = uibutton(app.gridSave, 'push');
            app.openPath.ButtonPushedFcn = createCallbackFcn(app, @openPathPushed, true);
            app.openPath.Tag = 'openPath';
            app.openPath.Tooltip = {'Open the simulation folder (Alt+F)'};
            app.openPath.Text = 'open';
            app.loadPath = uibutton(app.gridSave, 'push');
            app.loadPath.ButtonPushedFcn = createCallbackFcn(app, @getPathButtonPushed, true);
            app.loadPath.Tag = 'loadPath';
            app.loadPath.Tooltip = {'Get a simulation folder (Alt+G)'};
            app.loadPath.Text = '...';
            app.displayPath = uieditfield(app.gridSave, 'text');
            app.displayPath.ValueChangedFcn = createCallbackFcn(app, @displayPathValueChanged, true);
            app.displayPath.Tag = 'displayPath';
            app.displayPath.Tooltip = {'Simulation folder'; ''; 'There are no special requirements for the simulation folder. The only one is that the simulation files must be located in a \CODE folder within the simulation folder.'};
            app.gridButtons = uigridlayout(app.gridTab);
            app.gridButtons.Tag = 'gridButtons';
            app.gridGraphButtons = uigridlayout(app.gridButtons);
            app.gridGraphButtons.Tag = 'gridGraphButtons';
            app.gridGraph4 = uigridlayout(app.gridGraphButtons);
            app.gridGraph4.Tag = 'gridGraph4';
            app.graph4XList = uidropdown(app.gridGraph4);
            app.graph4XList.Items = {''};
            app.graph4XList.ValueChangedFcn = createCallbackFcn(app, @graph4XListValueChanged, true);
            app.graph4XList.Tag = 'graph4XList';
            app.graph4XList.Tooltip = {'Main list (X)'};
            app.graph4XList.Value = '';
            app.graph4XEq = uieditfield(app.gridGraph4, 'text');
            app.graph4XEq.Tag = 'graph4XEq';
            app.graph4XEq.Tooltip = {'Main equation (X)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph4Start = uieditfield(app.gridGraph4, 'text');
            app.graph4Start.Tag = 'graph4Start';
            app.graph4Start.Tooltip = {'Colorbar lower limit'; ''; '- Defines the lower limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph4YList = uidropdown(app.gridGraph4);
            app.graph4YList.Items = {''};
            app.graph4YList.ValueChangedFcn = createCallbackFcn(app, @graph4YListValueChanged, true);
            app.graph4YList.Tag = 'graph4YList';
            app.graph4YList.Tooltip = {'Sub list (Y)'};
            app.graph4YList.Value = '';
            app.graph4YEq = uieditfield(app.gridGraph4, 'text');
            app.graph4YEq.Tag = 'graph4YEq';
            app.graph4YEq.Tooltip = {'Sub equation (Y)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph4End = uieditfield(app.gridGraph4, 'text');
            app.graph4End.Tag = 'graph4End';
            app.graph4End.Tooltip = {'Colorbar upper limit'; ''; '- Defines the upper limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph4Plot = uibutton(app.gridGraph4, 'push');
            app.graph4Plot.ButtonPushedFcn = createCallbackFcn(app, @graph4PlotButtonPushed, true);
            app.graph4Plot.Tag = 'graph4Plot';
            app.graph4Plot.Tooltip = {'Plot'};
            app.graph4Plot.Text = '✓';
            app.graph4Cancel = uibutton(app.gridGraph4, 'push');
            app.graph4Cancel.ButtonPushedFcn = createCallbackFcn(app, @graph4CancelButtonPushed, true);
            app.graph4Cancel.Tag = 'graph4Cancel';
            app.graph4Cancel.Tooltip = {'Delete the graph'};
            app.graph4Cancel.Text = 'X';
            app.graph4Opts = uibutton(app.gridGraph4, 'push');
            app.graph4Opts.ButtonPushedFcn = createCallbackFcn(app, @graph4OptsButtonPushed, true);
            app.graph4Opts.Tag = 'graph4Opts';
            app.graph4Opts.Tooltip = {'Options'};
            app.graph4Opts.Text = 'O';
            app.graph4Limits = uibutton(app.gridGraph4, 'push');
            app.graph4Limits.ButtonPushedFcn = createCallbackFcn(app, @graph4LimitsButtonPushed, true);
            app.graph4Limits.Tag = 'graph4Limits';
            app.graph4Limits.Tooltip = {'Reset the view'};
            app.graph4Limits.Text = '☼';
            app.graph4SameView = uibutton(app.gridGraph4, 'push');
            app.graph4SameView.ButtonPushedFcn = createCallbackFcn(app, @graph4SameViewButtonPushed, true);
            app.graph4SameView.Tag = 'graph4SameView';
            app.graph4SameView.Tooltip = {'Propagate the view (Alt+V)'; ''; 'Applies the current view to all other graphs'};
            app.graph4SameView.Text = '=';
            app.gridGraph3 = uigridlayout(app.gridGraphButtons);
            app.gridGraph3.Tag = 'gridGraph3';
            app.graph3YList = uidropdown(app.gridGraph3);
            app.graph3YList.Items = {''};
            app.graph3YList.ValueChangedFcn = createCallbackFcn(app, @graph3YListValueChanged, true);
            app.graph3YList.Tag = 'graph3YList';
            app.graph3YList.Tooltip = {'Sub list (Y)'};
            app.graph3YList.Value = '';
            app.graph3YEq = uieditfield(app.gridGraph3, 'text');
            app.graph3YEq.Tag = 'graph3YEq';
            app.graph3YEq.Tooltip = {'Sub equation (Y)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph3End = uieditfield(app.gridGraph3, 'text');
            app.graph3End.Tag = 'graph3End';
            app.graph3End.Tooltip = {'Colorbar upper limit'; ''; '- Defines the upper limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph3Start = uieditfield(app.gridGraph3, 'text');
            app.graph3Start.Tag = 'graph3Start';
            app.graph3Start.Tooltip = {'Colorbar lower limit'; ''; '- Defines the lower limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph3XEq = uieditfield(app.gridGraph3, 'text');
            app.graph3XEq.Tag = 'graph3XEq';
            app.graph3XEq.Tooltip = {'Main equation (X)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph3XList = uidropdown(app.gridGraph3);
            app.graph3XList.Items = {''};
            app.graph3XList.ValueChangedFcn = createCallbackFcn(app, @graph3XListValueChanged, true);
            app.graph3XList.Tag = 'graph3XList';
            app.graph3XList.Tooltip = {'Main list (X)'};
            app.graph3XList.Value = '';
            app.graph3Plot = uibutton(app.gridGraph3, 'push');
            app.graph3Plot.ButtonPushedFcn = createCallbackFcn(app, @graph3PlotButtonPushed, true);
            app.graph3Plot.Tag = 'graph3Plot';
            app.graph3Plot.Tooltip = {'Plot'};
            app.graph3Plot.Text = '✓';
            app.graph3Cancel = uibutton(app.gridGraph3, 'push');
            app.graph3Cancel.ButtonPushedFcn = createCallbackFcn(app, @graph3CancelButtonPushed, true);
            app.graph3Cancel.Tag = 'graph3Cancel';
            app.graph3Cancel.Tooltip = {'Delete the graph'};
            app.graph3Cancel.Text = 'X';
            app.graph3Opts = uibutton(app.gridGraph3, 'push');
            app.graph3Opts.ButtonPushedFcn = createCallbackFcn(app, @graph3OptsButtonPushed, true);
            app.graph3Opts.Tag = 'graph3Opts';
            app.graph3Opts.Tooltip = {'Options'};
            app.graph3Opts.Text = 'O';
            app.graph3Limits = uibutton(app.gridGraph3, 'push');
            app.graph3Limits.ButtonPushedFcn = createCallbackFcn(app, @graph3LimitsButtonPushed, true);
            app.graph3Limits.Tag = 'graph3Limits';
            app.graph3Limits.Tooltip = {'Reset the view'};
            app.graph3Limits.Text = '☼';
            app.graph3sameView = uibutton(app.gridGraph3, 'push');
            app.graph3sameView.ButtonPushedFcn = createCallbackFcn(app, @graph3sameViewButtonPushed, true);
            app.graph3sameView.Tag = 'graph3SameView';
            app.graph3sameView.Tooltip = {'Propagate the view (Alt+V)'; ''; 'Applies the current view to all other graphs'};
            app.graph3sameView.Text = '=';
            app.gridGraph2 = uigridlayout(app.gridGraphButtons);
            app.gridGraph2.Tag = 'gridGraph2';
            app.graph2XList = uidropdown(app.gridGraph2);
            app.graph2XList.Items = {};
            app.graph2XList.ValueChangedFcn = createCallbackFcn(app, @graph2XListValueChanged, true);
            app.graph2XList.Tag = 'graph2XList';
            app.graph2XList.Tooltip = {'Main list (X)'};
            app.graph2XList.Value = {};
            app.graph2XEq = uieditfield(app.gridGraph2, 'text');
            app.graph2XEq.Tag = 'graph2XEq';
            app.graph2XEq.Tooltip = {'Main equation (X)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph2Start = uieditfield(app.gridGraph2, 'text');
            app.graph2Start.Tag = 'graph2Start';
            app.graph2Start.Tooltip = {'Colorbar lower limit'; ''; '- Defines the lower limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph2YList = uidropdown(app.gridGraph2);
            app.graph2YList.Items = {''};
            app.graph2YList.ValueChangedFcn = createCallbackFcn(app, @graph2YListValueChanged, true);
            app.graph2YList.Tag = 'graph2YList';
            app.graph2YList.Tooltip = {'Sub list (Y)'};
            app.graph2YList.Value = '';
            app.graph2YEq = uieditfield(app.gridGraph2, 'text');
            app.graph2YEq.Tag = 'graph2YEq';
            app.graph2YEq.Tooltip = {'Sub equation (Y)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph2End = uieditfield(app.gridGraph2, 'text');
            app.graph2End.Tag = 'graph2End';
            app.graph2End.Tooltip = {'Colorbar upper limit'; ''; '- Defines the upper limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph2Plot = uibutton(app.gridGraph2, 'push');
            app.graph2Plot.ButtonPushedFcn = createCallbackFcn(app, @graph2PlotButtonPushed, true);
            app.graph2Plot.Tag = 'graph2Plot';
            app.graph2Plot.Tooltip = {'Plot'};
            app.graph2Plot.Text = '✓';
            app.graph2Cancel = uibutton(app.gridGraph2, 'push');
            app.graph2Cancel.ButtonPushedFcn = createCallbackFcn(app, @graph2CancelButtonPushed, true);
            app.graph2Cancel.Tag = 'graph2Cancel';
            app.graph2Cancel.Tooltip = {'Delete the graph'};
            app.graph2Cancel.Text = 'X';
            app.graph2Opts = uibutton(app.gridGraph2, 'push');
            app.graph2Opts.ButtonPushedFcn = createCallbackFcn(app, @graph2OptsButtonPushed, true);
            app.graph2Opts.Tag = 'graph2Opts';
            app.graph2Opts.Tooltip = {'Options'};
            app.graph2Opts.Text = 'O';
            app.graph2Limits = uibutton(app.gridGraph2, 'push');
            app.graph2Limits.ButtonPushedFcn = createCallbackFcn(app, @graph2LimitsButtonPushed, true);
            app.graph2Limits.Tag = 'graph2Limits';
            app.graph2Limits.Tooltip = {'Reset the view'};
            app.graph2Limits.Text = '☼';
            app.graph2SameView = uibutton(app.gridGraph2, 'push');
            app.graph2SameView.ButtonPushedFcn = createCallbackFcn(app, @graph2SameViewButtonPushed, true);
            app.graph2SameView.Tag = 'graph2SameView';
            app.graph2SameView.Tooltip = {'Propagate the view (Alt+V)'; ''; 'Applies the current view to all other graphs'};
            app.graph2SameView.Text = '=';
            app.gridGraph1 = uigridlayout(app.gridGraphButtons);
            app.gridGraph1.Tag = 'gridGraph1';
            app.graph1Start = uieditfield(app.gridGraph1, 'text');
            app.graph1Start.Tag = 'graph1Start';
            app.graph1Start.Tooltip = {'Colorbar lower limit'; ''; '- Defines the lower limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph1End = uieditfield(app.gridGraph1, 'text');
            app.graph1End.Tag = 'graph1End';
            app.graph1End.Tooltip = {'Colorbar upper limit'; ''; '- Defines the upper limit of the colorbar for surfaces'; ''; '- A value for each ground can be specified by adding a "#" (e.g. 2#4)'};
            app.graph1XList = uidropdown(app.gridGraph1);
            app.graph1XList.Items = {''};
            app.graph1XList.ValueChangedFcn = createCallbackFcn(app, @graph1XListValueChanged, true);
            app.graph1XList.Tag = 'graph1XList';
            app.graph1XList.Tooltip = {'Main list (X)'};
            app.graph1XList.Value = '';
            app.graph1XEq = uieditfield(app.gridGraph1, 'text');
            app.graph1XEq.Tag = 'graph1XEq';
            app.graph1XEq.Tooltip = {'Main equation (X)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph1YList = uidropdown(app.gridGraph1);
            app.graph1YList.Items = {''};
            app.graph1YList.ValueChangedFcn = createCallbackFcn(app, @graph1YListValueChanged, true);
            app.graph1YList.Tag = 'graph1YList';
            app.graph1YList.Tooltip = {'Sub list (Y)'};
            app.graph1YList.Value = '';
            app.graph1YEq = uieditfield(app.gridGraph1, 'text');
            app.graph1YEq.Tag = 'graph1YEq';
            app.graph1YEq.Tooltip = {'Sub equation (Y)'; ''; '- Data:'; '    > Data is added by clicking on it in the list.'; '    > The location in the equation will be found automatically. If no location is found, the equation will be reset.'; '    > Matrix format (./ or .*) should be used if necessary.'; '- Grounds:'; '    > Whatever the type of graph, several grounds can be added.'; '    > Each ground must be separated by a "#" (e.g. [Time S1]#[Time S2])'; '    > For plot graphs, if there is 1 X ground and 2 Y grounds, each Y ground will be plotted on a different side.'; '- Index:'; '    > Index can be specified by adding round brackets right after the DATA (e.g. [Time S1](1:2:end) or [Solid Fraction](1:3,1:3)).'; '- Step:'; '    > If applicable, step is specified by adding a "%" or a "$" right before the end square bracket (e.g. [Velocity%10] or [Velocity$10]).'; '    > "%" will follow the selected step (e.g. step=15: [Velocity%15] ; step=20: [Velocity%20).'; '    > "$" will ignore the selected step (e.g. step=15: [Velocity$15] ; step=20: [Velocity$15]).'; ''; '-> For a custom post-processing, PlotManager.m and DataManager.m can be easily modified in \Functions\Management, see user guide <-'};
            app.graph1Plot = uibutton(app.gridGraph1, 'push');
            app.graph1Plot.ButtonPushedFcn = createCallbackFcn(app, @graph1PlotButtonPushed, true);
            app.graph1Plot.Tag = 'graph1Plot';
            app.graph1Plot.Tooltip = {'Plot'};
            app.graph1Plot.Text = '✓';
            app.graph1Cancel = uibutton(app.gridGraph1, 'push');
            app.graph1Cancel.ButtonPushedFcn = createCallbackFcn(app, @graph1CancelButtonPushed, true);
            app.graph1Cancel.Tag = 'graph1Cancel';
            app.graph1Cancel.Tooltip = {'Delete the graph'};
            app.graph1Cancel.Text = 'X';
            app.graph1Opts = uibutton(app.gridGraph1, 'push');
            app.graph1Opts.ButtonPushedFcn = createCallbackFcn(app, @graph1OptsButtonPushed, true);
            app.graph1Opts.Tag = 'graph1Opts';
            app.graph1Opts.Tooltip = {'Options'};
            app.graph1Opts.Text = 'O';
            app.graph1Limits = uibutton(app.gridGraph1, 'push');
            app.graph1Limits.ButtonPushedFcn = createCallbackFcn(app, @graph1LimitsButtonPushed, true);
            app.graph1Limits.Tag = 'graph1Limits';
            app.graph1Limits.Tooltip = {'Reset the view'};
            app.graph1Limits.Text = '☼';
            app.graph1SameView = uibutton(app.gridGraph1, 'push');
            app.graph1SameView.ButtonPushedFcn = createCallbackFcn(app, @graph1SameViewPushed, true);
            app.graph1SameView.Tag = 'graph1SameView';
            app.graph1SameView.Tooltip = {'Propagate the view (Alt+V)'; ''; 'Applies the current view to all other graphs'};
            app.graph1SameView.Text = '=';
            app.gridSpies = uigridlayout(app.gridButtons);
            app.gridSpies.Tag = 'gridSpies';
            app.exportButton = uibutton(app.gridSpies, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @plotExportButtonPushed, true);
            app.exportButton.Tag = 'exportButton';
            app.exportButton.Tooltip = {'Export graph or data'; ''; 'Exports a graph to an external window (Alt+F12) or brushed data to a .mat file.'};
            app.exportButton.Text = 'export';
            app.plotEndButton = uibutton(app.gridSpies, 'push');
            app.plotEndButton.ButtonPushedFcn = createCallbackFcn(app, @plotEndButtonPushed, true);
            app.plotEndButton.Tag = 'plotEndButton';
            app.plotEndButton.Tooltip = {'Plot upper index'; ''; 'Change the upper index of a plot by using the brush tool.'};
            app.plotEndButton.Text = '>>';
            app.plotStartButton = uibutton(app.gridSpies, 'push');
            app.plotStartButton.ButtonPushedFcn = createCallbackFcn(app, @plotStartButtonPushed, true);
            app.plotStartButton.Tag = 'plotStartButton';
            app.plotStartButton.Tooltip = {'Plot lower index'; ''; 'Change the lower index of a plot by using the brush tool.'};
            app.plotStartButton.Text = '<<';
            app.gridGraphics = uigridlayout(app.gridButtons);
            app.gridGraphics.Tag = 'gridGraphics';
            app.refreshGraphs = uibutton(app.gridGraphics, 'push');
            app.refreshGraphs.ButtonPushedFcn = createCallbackFcn(app, @refreshGraphicsButtonPushed, true);
            app.refreshGraphs.Tag = 'refreshGraphs';
            app.refreshGraphs.Tooltip = {'Display the latest data for the current tab (Alt+F5) or all the tabs (Alt+F6)'};
            app.refreshGraphs.Text = 'refresh';
            app.animation = uibutton(app.gridGraphics, 'push');
            app.animation.ButtonPushedFcn = createCallbackFcn(app, @animationButtonPushed, true);
            app.animation.Tag = 'animation';
            app.animation.Tooltip = {'Animations'};
            app.animation.Text = 'animation';
            app.loadGraphics = uibutton(app.gridGraphics, 'push');
            app.loadGraphics.ButtonPushedFcn = createCallbackFcn(app, @loadGraphicsButtonPushed, true);
            app.loadGraphics.Tag = 'loadGraphics';
            app.loadGraphics.Tooltip = {'Load output files (Alt+L)'; ''; 'Convert .vtk into .mat.'};
            app.loadGraphics.Text = 'load';
            app.infoButton = uibutton(app.gridGraphics, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @rulerButtonPushed, true);
            app.infoButton.Tag = 'infoButton';
            app.infoButton.Tooltip = {'Ruler'; ''; 'Compute several geometric quantities based on a selection of nodes.'};
            app.infoButton.Text = 'ruler';
            app.hideBodiesButton = uibutton(app.gridGraphics, 'push');
            app.hideBodiesButton.ButtonPushedFcn = createCallbackFcn(app, @hideBodiesButtonPushed, true);
            app.hideBodiesButton.Tag = 'hideBodiesButton';
            app.hideBodiesButton.Tooltip = {'Hide bodies'; ''; 'Hide certain bodies with different body selection methods.'; ''; 'Be sure to set periodic boundaries if applicable.'};
            app.hideBodiesButton.Text = 'hide';
            app.graphicsSlider = uislider(app.gridGraphics);
            app.graphicsSlider.Limits = [0 1];
            app.graphicsSlider.MajorTicks = [];
            app.graphicsSlider.ValueChangedFcn = createCallbackFcn(app, @graphicsSliderValueChanged, true);
            app.graphicsSlider.MinorTicks = [];
            app.graphicsSlider.Tag = 'graphicsSlider';
            app.graphicsSlider.Tooltip = {'Slider (Alt+F1/1/2/3/4/5/6/7/8/9/0)'};
            app.gridGraphicsControl = uigridlayout(app.gridGraphics);
            app.gridGraphicsControl.Tag = 'gridGraphicsControl';
            app.stepFF = uibutton(app.gridGraphicsControl, 'push');
            app.stepFF.ButtonPushedFcn = createCallbackFcn(app, @graphicsFFButtonPushed, true);
            app.stepFF.Tag = 'stepFF';
            app.stepFF.Tooltip = {'Fast forward'; ''; '+10 steps'};
            app.stepFF.Text = '>>';
            app.stepSF = uibutton(app.gridGraphicsControl, 'push');
            app.stepSF.ButtonPushedFcn = createCallbackFcn(app, @graphicsFButtonPushed, true);
            app.stepSF.Tag = 'stepSF';
            app.stepSF.Tooltip = {'Slow forward (Alt+Up)'; ''; '+1 step'};
            app.stepSF.Text = '>';
            app.stepRefr = uibutton(app.gridGraphicsControl, 'state');
            app.stepRefr.ValueChangedFcn = createCallbackFcn(app, @graphicsGUIAnimValueChanged, true);
            app.stepRefr.Tag = 'stepRefr';
            app.stepRefr.Tooltip = {'Play (Alt+Enter)'; ''; 'Note that the time step is defined according to the default duration, the initial step of graphs and the last available (i.e. loaded) step of these graphs.'};
            app.stepRefr.Text = '►';
            app.stepSB = uibutton(app.gridGraphicsControl, 'push');
            app.stepSB.ButtonPushedFcn = createCallbackFcn(app, @graphicsBButtonPushed, true);
            app.stepSB.Tag = 'stepSB';
            app.stepSB.Tooltip = {'Slow backward (Alt+Down)'; ''; '-1 step'};
            app.stepSB.Text = '<';
            app.stepFB = uibutton(app.gridGraphicsControl, 'push');
            app.stepFB.ButtonPushedFcn = createCallbackFcn(app, @graphicsFBButtonPushed, true);
            app.stepFB.Tag = 'stepFB';
            app.stepFB.Tooltip = {'Fast backward'; ''; '-10 steps'};
            app.stepFB.Text = '<<';
            app.gridPlot = uigridlayout(app.gridButtons);
            app.gridPlot.Tag = 'gridPlot';
            app.stopEq = uieditfield(app.gridPlot, 'text');
            app.stopEq.ValueChangedFcn = createCallbackFcn(app, @stopEqValueChanged, true);
            app.stopEq.Tag = 'stopEq';
            app.stopEq.Tooltip = {'Stop criterion'; ''; '- Scalar'; 'The indicated value is the number of steps to be computed before stopping.'; ''; '- Equation'; 'Steps are replaced by the last step found if indicated by % (e.g. [Velocity%00001]), and preserve if indicated by $ (e.g. [Velocity$00001]).'; 'Make sure to use a scalar evaluation and not a matrix. For example, [rowMatrix]>1 will check whether each value is greater than 1, which is not correct. A correct criterion would be [rowMatrix](end)>1 or any([rowMatrix]>1).'};
            app.stopCriterionList = uidropdown(app.gridPlot);
            app.stopCriterionList.Items = {''};
            app.stopCriterionList.ValueChangedFcn = createCallbackFcn(app, @stopCriterionListValueChanged, true);
            app.stopCriterionList.Tag = 'stopCriterionList';
            app.stopCriterionList.Tooltip = {''};
            app.stopCriterionList.Value = '';
            app.stopCheck = uicheckbox(app.gridPlot);
            app.stopCheck.Tag = 'stopCheck';
            app.stopCheck.Tooltip = {'Stop computation if the criterion becomes true'};
            app.stopCheck.Text = 'Stop if:';
            app.timeRefreshValue = uieditfield(app.gridPlot, 'numeric');
            app.timeRefreshValue.Limits = [0 Inf];
            app.timeRefreshValue.Tag = 'timeRefreshValue';
            app.timeRefreshValue.Tooltip = {'Refreshment period'};
            app.timeRefreshValue.Value = 30;
            app.autoRefresh = uicheckbox(app.gridPlot);
            app.autoRefresh.ValueChangedFcn = createCallbackFcn(app, @autoRefreshValueChanged, true);
            app.autoRefresh.Tag = 'autoRefresh';
            app.autoRefresh.Tooltip = {'Graphs refresh'};
            app.autoRefresh.Text = 'Refresh [s]:';
            app.stopCancel = uibutton(app.gridPlot, 'push');
            app.stopCancel.ButtonPushedFcn = createCallbackFcn(app, @stopCancelButtonPushed, true);
            app.stopCancel.Tag = 'stopCancel';
            app.stopCancel.Tooltip = {'Reset criterion'};
            app.stopCancel.Text = 'X';
            app.gridTime = uigridlayout(app.gridTab);
            app.gridTime.Tag = 'gridTime';
            app.gridTime.Visible = 'off';
            app.timeGraph = uiaxes(app.gridTime);
            zlabel(app.timeGraph, 'Z')
            app.timeGraph.Tag = 'timeGraph';
            app.timeGraph.Visible = 'off';
            app.regressionText = uilabel(app.gridTime);
            app.regressionText.Tag = 'regressionText';
            app.regressionText.Text = 'Regression order :';
            app.regressionValue = uieditfield(app.gridTime, 'numeric');
            app.regressionValue.Tag = 'regressionValue';
            app.regressionValue.Tooltip = {'Regression order'; ''; 'Most of the time, low orders give satisfactory results.'};
            app.regressionButton = uibutton(app.gridTime, 'push');
            app.regressionButton.ButtonPushedFcn = createCallbackFcn(app, @regressionButtonButtonPushed, true);
            app.regressionButton.Tag = 'regressionButton';
            app.regressionButton.Tooltip = {'Regression computation'};
            app.regressionButton.Text = 'OK';
            app.updateTimeSave = uibutton(app.gridTime, 'push');
            app.updateTimeSave.ButtonPushedFcn = createCallbackFcn(app, @updateTimeSaveButtonPushed, true);
            app.updateTimeSave.Tag = 'updateTimeSave';
            app.updateTimeSave.Tooltip = {'Computation speed update'; ''; 'If some speeds are missing (e.g. files imported from the cluster), it is possible to automatically detect  which steps have been computed successively (to compute the corresponding speeds).'};
            app.updateTimeSave.Text = 'Update';
            app.gridSettings = uigridlayout(app.gridTab);
            app.gridSettings.Tag = 'gridSettings';
            app.gridSettings.Visible = 'off';
            app.settingsLabel = uilabel(app.gridSettings);
            app.settingsLabel.Tag = 'settingsLabel';
            app.settingsLabel.Text = 'Settings:';
            app.displayCMD = uicheckbox(app.gridSettings);
            app.displayCMD.Tag = 'displayCMD';
            app.displayCMD.Tooltip = {'Display the command prompt when running an executable file'};
            app.displayCMD.Text = 'Display the command prompt';
            app.executableFromFolder = uicheckbox(app.gridSettings);
            app.executableFromFolder.Tag = 'executableFromFolder';
            app.executableFromFolder.Tooltip = {'Executables root'; ''; 'If disabled, pastes all necessary files into the simulation folder and deletes them after execution.'};
            app.executableFromFolder.Text = 'Run package from the simulation folder';
            app.initialization = uicheckbox(app.gridSettings);
            app.initialization.Tag = 'initialization';
            app.initialization.Tooltip = {'Steps initialization'; ''; 'Loads all the steps before the selected one (i.e. conversion from .vtk into .mat).'};
            app.initialization.Text = 'Initialization of steps';
            app.initialization.Value = true;
            app.displayCurrentState = uicheckbox(app.gridSettings);
            app.displayCurrentState.Tag = 'displayCurrentState';
            app.displayCurrentState.Enable = 'off';
            app.displayCurrentState.Tooltip = {'Not updated :('; ''; 'Current state marker'; ''; 'Displays a marker on the plot graphs corresponding to the state of the first step graph found.'};
            app.displayCurrentState.Text = 'Display current state';
            app.displayMesh = uicheckbox(app.gridSettings);
            app.displayMesh.Tag = 'displayMesh';
            app.displayMesh.Tooltip = {'Display mesh'};
            app.displayMesh.Text = 'Display mesh';
            app.spaceGraphics = uicheckbox(app.gridSettings);
            app.spaceGraphics.Tag = 'spaceGraphics';
            app.spaceGraphics.Tooltip = {'Same spatial limits for graphs in overview'; ''; 'In overview, each graph (associated with spatial coordinates) from this tab will share common spatial limits with the other tabs where this option is enabled.'};
            app.spaceGraphics.Text = 'Same spatial limits in overview';
            app.scaleGraphicsText = uilabel(app.gridSettings);
            app.scaleGraphicsText.Tag = 'scaleGraphicsText';
            app.scaleGraphicsText.Tooltip = {'Common data range (surfaces)'; ''; 'Number of "parts" that will be taken into account to define a common data range (limits for colorbars and colormaps) for each surface in overview.'; ''; 'Note that strictly speaking, this value indicates the end index and not the number of parts. For example, a negative value will start the indexing at the end, but the first word will always be the first part (see examples).'; ''; '- e.g. 1: Value=1'; 'Velocity tensor (magnitude) -> Velocity'; 'Each surface which after transformation is called Velocity will have the same data range.'; '- e.g. 2: Value=-1'; 'Velocity tensor (magnitude) -> Velocity tensor'; '- e.g. 3: Value=inf'; 'Gives a unique data range'};
            app.scaleGraphicsText.Text = 'Number of "parts" for scaling (surfaces)';
            app.scaleGraphicsDisplay = uieditfield(app.gridSettings, 'numeric');
            app.scaleGraphicsDisplay.Tag = 'scaleGraphicsDisplay';
            app.scaleGraphicsDisplay.Tooltip = {'Common data range (surfaces)'; ''; 'Number of "parts" that will be taken into account to define a common data range (limits for colorbars and colormaps) for each surface in overview.'; ''; 'Note that strictly speaking, this value indicates the end index and not the number of parts. For example, a negative value will start the indexing at the end, but the first word will always be the first part (see examples).'; ''; '- e.g. 1: Value=1'; 'Velocity tensor (magnitude) -> Velocity'; 'Each surface which after transformation is called Velocity will have the same data range.'; '- e.g. 2: Value=-1'; 'Velocity tensor (magnitude) -> Velocity tensor'; '- e.g. 3: Value=inf'; 'Gives a unique data range'};
            app.scaleGraphicsDisplay.Value = -1;
            app.scalePlotDisplay = uieditfield(app.gridSettings, 'numeric');
            app.scalePlotDisplay.Tag = 'scalePlotDisplay';
            app.scalePlotDisplay.Tooltip = {'Common data range (plots)'; ''; 'Number of "parts" that will be taken into account to define a common data range (axis limits) for each plot in overview. Each axis is considered independently.'; ''; 'Note that strictly speaking, this value indicates the end index and not the number of parts. For example, a negative value will start the indexing at the end, but the first word will always be the first part (see examples).'; ''; '- e.g. 1: Value=1'; '[Force X Contact]*[Force Y Coulomb] -> [Force]*[Force]'; 'Each axis which after transformation is called [Force]*[Force] will have the same data range.'; '- e.g. 2: Value=-1'; '[Force X Contact]*[Force Y Coulomb] -> [Force X]*[Force Y]'; '- e.g. 3: Value=inf'; 'Gives a unique data range'};
            app.scalePlotDisplay.Value = -1;
            app.scalePlotText = uilabel(app.gridSettings);
            app.scalePlotText.Tag = 'scalePlotText';
            app.scalePlotText.Tooltip = {'Common data range (plots)'; ''; 'Number of "parts" that will be taken into account to define a common data range (axis limits) for each plot in overview. Each axis is considered independently.'; ''; 'Note that strictly speaking, this value indicates the end index and not the number of parts. For example, a negative value will start the indexing at the end, but the first word will always be the first part (see examples).'; ''; '- e.g. 1: Value=1'; '[Force X Contact]*[Force Y Coulomb] -> [Force]*[Force]'; 'Each axis which after transformation is called [Force]*[Force] will have the same data range.'; '- e.g. 2: Value=-1'; '[Force X Contact]*[Force Y Coulomb] -> [Force X]*[Force Y]'; '- e.g. 3: Value=inf'; 'Gives a unique data range'};
            app.scalePlotText.Text = 'Number of "parts" for scaling (plots)';
            app.sizePathDisplay = uieditfield(app.gridSettings, 'numeric');
            app.sizePathDisplay.Tag = 'sizePathDisplay';
            app.sizePathDisplay.Tooltip = {'Tab name'; ''; 'Number of sub-folder names that are used to define the tab name.'};
            app.sizePathDisplay.Value = 1;
            app.sizePathText = uilabel(app.gridSettings);
            app.sizePathText.Tag = 'sizePathText';
            app.sizePathText.Tooltip = {'Tab name'; ''; 'Number of sub-folder names that are used to define the tab name.'};
            app.sizePathText.Text = 'Number of subfolders for tab name';
            app.durationAnimationDisplay = uieditfield(app.gridSettings, 'numeric');
            app.durationAnimationDisplay.Tag = 'durationAnimationDisplay';
            app.durationAnimationDisplay.Tooltip = {'Default duration'; ''; 'Default duration for animations that will be proposed in the animations menu and that will be apply for "►".'};
            app.durationAnimationDisplay.Value = 10;
            app.durationAnimationText = uilabel(app.gridSettings);
            app.durationAnimationText.Tag = 'durationAnimationText';
            app.durationAnimationText.Tooltip = {'Default duration'; ''; 'Default duration for animations that will be proposed in the animations menu and that will be apply for "►".'};
            app.durationAnimationText.Text = 'Default duration of an animation [s]';
            app.colormapLabel = uilabel(app.gridSettings);
            app.colormapLabel.Tag = 'colormapLabel';
            app.colormapLabel.Tooltip = {'Colormap for surfaces'};
            app.colormapLabel.Text = 'Colormap:';
            app.colormapList = uidropdown(app.gridSettings);
            app.colormapList.Items = {'Turbo', 'Jet', 'Parula', 'Hsv', 'Hot', 'Cool', 'Spring', 'Summer', 'Autumn', 'Winter', 'Gray', 'Bone', 'Copper', 'Pink', 'Lines', 'Colorcube', 'Prism', 'Flag', 'White', 'Sky', 'Abyss'};
            app.colormapList.Tag = 'colormapList';
            app.colormapList.Tooltip = {'Colormap for surfaces'};
            app.colormapList.Value = 'Turbo';
            app.backgroundLabel = uilabel(app.gridSettings);
            app.backgroundLabel.Tag = 'backgroundLabel';
            app.backgroundLabel.Tooltip = {'Background color for surfaces'; ''; 'Colors of the ticks and labels will be found automatically.'};
            app.backgroundLabel.Text = 'Background:';
            app.backgroundValue = uieditfield(app.gridSettings, 'text');
            app.backgroundValue.Tag = 'backgroundValue';
            app.backgroundValue.Tooltip = {'Background color for surfaces'; ''; 'Colors of the ticks and labels will be found automatically.'};
            app.backgroundValue.Value = '#52576e';
            app.manualPos = uicheckbox(app.gridSettings);
            app.manualPos.Tag = 'manualPos';
            app.manualPos.Tooltip = {'Overview manual positioning'; ''; 'If disabled, it automatically finds the best positions for graphs according to a screen ratio criterion.'};
            app.manualPos.Text = 'Manual positioning in overview';
            app.displayNodes = uicheckbox(app.gridSettings);
            app.displayNodes.Tag = 'displayNodes';
            app.displayNodes.Tooltip = {'Display nodes'};
            app.displayNodes.Text = 'Display nodes';
            app.settingsButton = uigridlayout(app.gridSettings);
            app.settingsButton.Tag = 'settingsButton';
            app.shortcutsListButton = uibutton(app.settingsButton, 'push');
            app.shortcutsListButton.ButtonPushedFcn = createCallbackFcn(app, @shortcutsListButtonPushed, true);
            app.shortcutsListButton.Tag = 'shortcutsListButton';
            app.shortcutsListButton.Tooltip = {'Open the shortchuts list (I)'};
            app.shortcutsListButton.Text = 'SHORTCUTS';
            app.userGuideButton = uibutton(app.settingsButton, 'push');
            app.userGuideButton.ButtonPushedFcn = createCallbackFcn(app, @userGuideButtonPushed, true);
            app.userGuideButton.Tag = 'userGuideButton';
            app.userGuideButton.Tooltip = {'Open the GUI user guide'};
            app.userGuideButton.Text = 'USER GUIDE';
            app.ignoredOverview = uicheckbox(app.gridSettings);
            app.ignoredOverview.Tag = 'ignoredOverview';
            app.ignoredOverview.Tooltip = {'Ignore this tab in overview'};
            app.ignoredOverview.Text = 'Ignored in overview';
            app.viewTypeLabel = uilabel(app.gridSettings);
            app.viewTypeLabel.Tag = 'viewTypeLabel';
            app.viewTypeLabel.Tooltip = {'View type'; ''; '- Reload'; 'Allows to change the parameters of the current view.'; ''; '- Eulerian'; 'View with a fixed frame according to the coordinates baseframe.'; ''; '- Lagrangian'; 'View with a fixed frame according to a node.'; 'Be sure to set periodic boundaries if applicable.'; ''; '- 3D extrapolation (planar)*'; '3D view that is extrapolated from 2D data based on a planar symmetry assumption. '; ''; '- 3D extrapolation (radial)*'; '3D view that is extrapolated from 2D data based on a combination of axisymmetric and planar assumptions. The number of repetitions can be infinite, it will be corrected by the minimum number of repetitions that allows the volume to be closed (but beware of the computation time).'; ''; '*Note that 3D graphs may exceed the axis limits if you zoom in, but MATLAB does not currently offer a satisfactory solution.'};
            app.viewTypeLabel.Text = 'View type:';
            app.viewTypeList = uidropdown(app.gridSettings);
            app.viewTypeList.Items = {'Reload', 'Eulerian', 'Lagrangian', '3D extrapolation (planar)', '3D extrapolation (radial)'};
            app.viewTypeList.Tag = 'viewTypeList';
            app.viewTypeList.Tooltip = {'View type'; ''; '- Reload'; 'Allows to change the parameters of the current view.'; ''; '- Eulerian'; 'View with a fixed frame according to the coordinates baseframe.'; ''; '- Lagrangian'; 'View with a fixed frame according to a node.'; 'Be sure to set periodic boundaries if applicable.'; ''; '- 3D extrapolation (planar)*'; '3D view that is extrapolated from 2D data based on a planar symmetry assumption. '; ''; '- 3D extrapolation (radial)*'; '3D view that is extrapolated from 2D data based on a combination of axisymmetric and planar assumptions. The number of repetitions can be infinite, it will be corrected by the minimum number of repetitions that allows the volume to be closed (but beware of the computation time).'; ''; '*Note that 3D graphs may exceed the axis limits if you zoom in, but MATLAB does not currently offer a satisfactory solution.'};
            app.viewTypeList.Value = 'Reload';
            app.runModeLabel = uilabel(app.gridSettings);
            app.runModeLabel.Tag = 'runModeLabel';
            app.runModeLabel.Tooltip = {'Running mode'; ''; '- Local use'; 'Computation will be run on the computer.'; ''; '- Cluster use'; 'Computation will be run on the cluster (identifiers are required).'};
            app.runModeLabel.Text = 'Running mode:';
            app.runModeList = uidropdown(app.gridSettings);
            app.runModeList.Items = {'Local', 'lamcoscalc.insa-lyon.fr', 'p2chpd-login3.univ-lyon1.fr'};
            app.runModeList.Tag = 'runModeList';
            app.runModeList.Tooltip = {'Running mode'; ''; '- Local use'; 'Computation will be run on the computer.'; ''; '- Cluster use'; 'Computation will be run on the cluster (identifiers are required).'};
            app.runModeList.Value = 'Local';
            app.packageModeLabel = uilabel(app.gridSettings);
            app.packageModeLabel.Tag = 'packageModeLabel';
            app.packageModeLabel.Tooltip = {'Package to be executed'};
            app.packageModeLabel.Text = 'Package';
            app.packageModeList = uidropdown(app.gridSettings);
            app.packageModeList.Items = {};
            app.packageModeList.Tag = 'packageModeList';
            app.packageModeList.Tooltip = {'Package to be executed'};
            app.packageModeList.Value = {};
            app.FilestodeleteLabel = uilabel(app.gridSettings);
            app.FilestodeleteLabel.Tooltip = {'Automatic deletion'; ''; 'Deletes selected files when execution is stopped. Note that if the stop is not initiated by the user (error detected, stop criterion, etc.) files are not deleted to avoid an error or a pending instruction.'};
            app.FilestodeleteLabel.Text = 'Files to delete:';
            app.filestodeleteListBox = uilistbox(app.gridSettings);
            app.filestodeleteListBox.Items = {};
            app.filestodeleteListBox.Multiselect = 'on';
            app.filestodeleteListBox.Tag = 'filestodeleteListBox';
            app.filestodeleteListBox.Tooltip = {'Automatic deletion'; ''; 'Deletes selected files when execution is stopped. Note that if the stop is not initiated by the user (error detected, stop criterion, etc.) files are not deleted to avoid an error or a pending instruction.'};
            app.filestodeleteListBox.Value = {};
            app.sectionSeparatorText = uilabel(app.gridSettings);
            app.sectionSeparatorText.Tag = 'sectionSeparatorText';
            app.sectionSeparatorText.Tooltip = {'Section separator'; ''; 'Key searched in the file selected in editor to separate the file into several sections.'};
            app.sectionSeparatorText.Text = 'Section separator in editor';
            app.sectionSeparatorDisplay = uieditfield(app.gridSettings, 'text');
            app.sectionSeparatorDisplay.Tag = 'sectionSeparatorDisplay';
            app.sectionSeparatorDisplay.Tooltip = {'Section separator'; ''; 'Key searched in the file selected in editor to separate the file into several sections.'};
            app.sectionSeparatorDisplay.Value = '%%%';
            app.sizeWarningText = uilabel(app.gridSettings);
            app.sizeWarningText.Tag = 'sizeWarningText';
            app.sizeWarningText.Tooltip = {'File size warning'; ''; 'File size for which a warning appears when opened in the editor.'};
            app.sizeWarningText.Text = 'File size (in MB) warning';
            app.sizeWarningDisplay = uieditfield(app.gridSettings, 'numeric');
            app.sizeWarningDisplay.Tag = 'sizeWarningDisplay';
            app.sizeWarningDisplay.Tooltip = {'File size warning'; ''; 'File size for which a warning appears when opened in the editor.'};
            app.sizeWarningDisplay.Value = 5;
            app.periodicboundariesLabel = uilabel(app.gridSettings);
            app.periodicboundariesLabel.Tag = 'periodicboundariesLabel';
            app.periodicboundariesLabel.Tooltip = {'Periodic boundaries'; ''; '- Format:'; 'xMin; xMax; yMin; yMax; zMin; zMax'; ''; '- Used for certain functions which requires the body index (e.g. lagrangian view); an infinite value means that there are no periodic conditions.'};
            app.periodicboundariesLabel.Text = 'Periodic boundaries:';
            app.periodicboundariesText = uieditfield(app.gridSettings, 'text');
            app.periodicboundariesText.Tag = 'periodicboundariesText';
            app.periodicboundariesText.Tooltip = {'Periodic boundaries'; ''; '- Format:'; 'xMin; xMax; yMin; yMax; zMin; zMax'; ''; '- Used for certain functions which requires the body index (e.g. lagrangian view); an infinite value means that there are no periodic conditions.'};
            app.periodicboundariesText.Value = '-inf;inf;-inf;inf;-inf;inf';
            app.userGuidePackageButton = uibutton(app.gridSettings, 'push');
            app.userGuidePackageButton.ButtonPushedFcn = createCallbackFcn(app, @userGuidePackageButtonPushed, true);
            app.userGuidePackageButton.Tag = 'userGuidePackageButton';
            app.userGuidePackageButton.Tooltip = {'Open the package user guide (U)'};
            app.userGuidePackageButton.Text = 'USER GUIDE';
            app.stretchToFillCheck = uicheckbox(app.gridSettings);
            app.stretchToFillCheck.Tag = 'stretchToFillCheck';
            app.stretchToFillCheck.Tooltip = {'Stretch-to-fill'; ''; '- If activated: spatial limits are defined to fill the available space on the interface.'; ''; '- If deactivated: spatial limits are defined solely according to extremum coordinates.'};
            app.stretchToFillCheck.Text = 'Stretch-to-fill (surfaces)';
            app.stretchToFillCheck.Value = true;
            app.runModeListEdit = uibutton(app.gridSettings, 'push');
            app.runModeListEdit.ButtonPushedFcn = createCallbackFcn(app, @clusterEditServers, true);
            app.runModeListEdit.Tag = 'runModeListEdit';
            app.runModeListEdit.Tooltip = {'Edit list of available servers'; ''; 'Names must be entered in raw format. Refer to the user guide for the format to be used in the package.'};
            app.runModeListEdit.Text = 'EDIT';
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Tooltip = {''};
            app.OverviewTab.Title = '   [Overview]';
            app.gridOverview = uigridlayout(app.OverviewTab);
            app.gridOverview.Tag = 'gridOverview';
            app.overviewPanel = uipanel(app.gridOverview);
            app.overviewPanel.Tooltip = {''};
            app.hideOverview = uibutton(app.gridOverview, 'push');
            app.hideOverview.ButtonPushedFcn = createCallbackFcn(app, @hideOverviewButtonPushed, true);
            app.hideOverview.Tag = 'hideOverview';
            app.hideOverview.Tooltip = {'Hide/Display this panel (Alt+H)'};
            app.hideOverview.Text = 'hide';
            app.optionOverview = uibutton(app.gridOverview, 'push');
            app.optionOverview.ButtonPushedFcn = createCallbackFcn(app, @optionOverviewButtonPushed, true);
            app.optionOverview.Tag = 'optionOverview';
            app.optionOverview.Tooltip = {'Options'};
            app.optionOverview.Text = 'O';
            app.changeOverview = uibutton(app.gridOverview, 'push');
            app.changeOverview.ButtonPushedFcn = createCallbackFcn(app, @changeOverviewButtonPushed, true);
            app.changeOverview.Tag = 'changeOverview';
            app.changeOverview.Tooltip = {'Change the position of graphs by indicating the line numbers'};
            app.changeOverview.Text = 'change';
            app.sliderOverview = uislider(app.gridOverview);
            app.sliderOverview.Limits = [0 1];
            app.sliderOverview.MajorTicks = [];
            app.sliderOverview.ValueChangedFcn = createCallbackFcn(app, @sliderOverviewValueChanged, true);
            app.sliderOverview.MinorTicks = [];
            app.sliderOverview.Tag = 'sliderOverview';
            app.sliderOverview.Tooltip = {'Slider (Alt+F1/1/2/3/4/5/6/7/8/9/0)'; ''; 'Relative to each tab, the step jump can therefore be different for each graph'};
            app.stepFFOverview = uibutton(app.gridOverview, 'push');
            app.stepFFOverview.ButtonPushedFcn = createCallbackFcn(app, @stepFFOverviewButtonPushed, true);
            app.stepFFOverview.Tag = 'stepFFOverview';
            app.stepFFOverview.Tooltip = {'Fast forward'; ''; '+10 steps'};
            app.stepFFOverview.Text = '>>';
            app.stepSFOverview = uibutton(app.gridOverview, 'push');
            app.stepSFOverview.ButtonPushedFcn = createCallbackFcn(app, @stepSFOverviewButtonPushed, true);
            app.stepSFOverview.Tag = 'stepSFOverview';
            app.stepSFOverview.Tooltip = {'Slow forward (Alt+Up)'; ''; '+1 step'};
            app.stepSFOverview.Text = '>';
            app.stepFBOverview = uibutton(app.gridOverview, 'push');
            app.stepFBOverview.ButtonPushedFcn = createCallbackFcn(app, @stepFBOverviewButtonPushed, true);
            app.stepFBOverview.Tag = 'stepFBOverview';
            app.stepFBOverview.Tooltip = {'Fast backward'; ''; '-10 steps'};
            app.stepFBOverview.Text = '<<';
            app.stepSBOverview = uibutton(app.gridOverview, 'push');
            app.stepSBOverview.ButtonPushedFcn = createCallbackFcn(app, @stepSBOverviewButtonPushed, true);
            app.stepSBOverview.Tag = 'stepSBOverview';
            app.stepSBOverview.Tooltip = {'Slow backward (Alt+Down)'; ''; '-1 step'};
            app.stepSBOverview.Text = '<';
            % Show the figure after all components are created
            app.mainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainUIFigure)
        end
    end
end